classdef Quant4D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Quant4D_Fig                   matlab.ui.Figure
        total_panel                   matlab.ui.container.Panel
        detector_layout               matlab.ui.control.UIAxes
        stack_slider                  matlab.ui.control.Slider
        intermediate_data_tabgroup    matlab.ui.container.TabGroup
        adf_tab                       matlab.ui.container.Tab
        adf_image                     matlab.ui.control.UIAxes
        ColorwheelTab                 matlab.ui.container.Tab
        colorwheel_plot               matlab.ui.control.UIAxes
        AlignVirtualApertureTab       matlab.ui.container.Tab
        AlignmentModeButtonGroup      matlab.ui.container.ButtonGroup
        CartesianButton               matlab.ui.control.RadioButton
        PolarButton                   matlab.ui.control.RadioButton
        ApertureSymmetrySpinnerLabel  matlab.ui.control.Label
        aperture_symmetry             matlab.ui.control.Spinner
        ap_alignPanel                 matlab.ui.container.Panel
        ApertureDistanceLabel         matlab.ui.control.Label
        aperture_x                    matlab.ui.control.Slider
        ApertureRotationLabel         matlab.ui.control.Label
        aperture_y                    matlab.ui.control.Slider
        ap_x_plus                     matlab.ui.control.Button
        ap_x_minus                    matlab.ui.control.Button
        ap_y_plus                     matlab.ui.control.Button
        ap_y_minus                    matlab.ui.control.Button
        ap_x_reset                    matlab.ui.control.Button
        ap_y_reset                    matlab.ui.control.Button
        ap_right_rot                  matlab.ui.control.Button
        adf_inner_mrad_2              matlab.ui.control.Label
        aperture_x_text               matlab.ui.control.NumericEditField
        aperture_y_Label              matlab.ui.control.Label
        aperture_y_text               matlab.ui.control.NumericEditField
        ap_left_rot                   matlab.ui.control.Button
        ap_rotAngle                   matlab.ui.control.DropDown
        ap_size_plus                  matlab.ui.control.Button
        ap_size_minus                 matlab.ui.control.Button
        ApertureSizeLabel             matlab.ui.control.Label
        aperture_size                 matlab.ui.control.Slider
        adf_inner_mrad_4              matlab.ui.control.Label
        ap_size_text                  matlab.ui.control.NumericEditField
        xSpinnerLabel                 matlab.ui.control.Label
        detx                          matlab.ui.control.Spinner
        ySpinnerLabel                 matlab.ui.control.Label
        dety                          matlab.ui.control.Spinner
        outputs_tabgroup              matlab.ui.container.TabGroup
        bfdf_tab                      matlab.ui.container.Tab
        bfdf_image                    matlab.ui.control.UIAxes
        dpc_tab                       matlab.ui.container.Tab
        vect_mag                      matlab.ui.control.UIAxes
        vect_angle                    matlab.ui.control.UIAxes
        seg_ac                        matlab.ui.control.UIAxes
        seg_bd                        matlab.ui.control.UIAxes
        dpc_dropdown1                 matlab.ui.control.DropDown
        dpc_dropdown2                 matlab.ui.control.DropDown
        com_tab                       matlab.ui.container.Tab
        com_x                         matlab.ui.control.UIAxes
        com_y                         matlab.ui.control.UIAxes
        com_mag                       matlab.ui.control.UIAxes
        com_angle                     matlab.ui.control.UIAxes
        vectorField_tab               matlab.ui.container.Tab
        vectorField                   matlab.ui.control.UIAxes
        VectorMenuPanel               matlab.ui.container.Panel
        ZoomButton_vect               matlab.ui.control.StateButton
        PanButton_vect                matlab.ui.control.StateButton
        ResetZoomButton_vect          matlab.ui.control.Button
        resampleDropDown              matlab.ui.control.DropDown
        vectButton_group              matlab.ui.container.ButtonGroup
        VectorButton                  matlab.ui.control.RadioButton
        MagnitudeButton               matlab.ui.control.RadioButton
        AngleButton                   matlab.ui.control.RadioButton
        controlPanel                  matlab.ui.container.Panel
        diff_pattern_radio_group      matlab.ui.container.ButtonGroup
        sum_patterns                  matlab.ui.control.RadioButton
        all_patterns                  matlab.ui.control.RadioButton
        display_images                matlab.ui.control.Button
        save_images                   matlab.ui.control.Button
        import_data                   matlab.ui.control.Button
        controlTab                    matlab.ui.container.TabGroup
        CalibrateTab                  matlab.ui.container.Tab
        align_trans_beam              matlab.ui.control.Button
        trans_panel                   matlab.ui.container.Panel
        trans_beam_x_label            matlab.ui.control.Label
        trans_beam_x                  matlab.ui.control.Slider
        trans_beam_y_label            matlab.ui.control.Label
        trans_beam_y                  matlab.ui.control.Slider
        trans_beam_radius_label       matlab.ui.control.Label
        trans_beam_radius             matlab.ui.control.Slider
        conv_angle_label              matlab.ui.control.Label
        conv_angle                    matlab.ui.control.NumericEditField
        trans_beam_x_plus             matlab.ui.control.Button
        trans_beam_x_minus            matlab.ui.control.Button
        trans_beam_y_plus             matlab.ui.control.Button
        trans_beam_y_minus            matlab.ui.control.Button
        trans_beam_rad_plus           matlab.ui.control.Button
        trans_beam_rad_minus          matlab.ui.control.Button
        align_scan_rot                matlab.ui.control.Button
        scan_rot_panel                matlab.ui.container.Panel
        ScanRotationLabel             matlab.ui.control.Label
        scan_rot                      matlab.ui.control.Slider
        scan_rot_plus                 matlab.ui.control.Button
        scan_rot_minus                matlab.ui.control.Button
        det_angle_deg_2               matlab.ui.control.Label
        scan_rot_value                matlab.ui.control.NumericEditField
        scan_rot_mirror               matlab.ui.control.CheckBox
        scan_rot_label                matlab.ui.control.Label
        DetectorsTab                  matlab.ui.container.Tab
        detector_panel                matlab.ui.container.Panel
        adf_inner_mrad                matlab.ui.control.Label
        adf_inner_value               matlab.ui.control.NumericEditField
        adf_outer_mrad                matlab.ui.control.Label
        adf_outer_value               matlab.ui.control.NumericEditField
        nseg_label                    matlab.ui.control.Label
        nseg                          matlab.ui.control.NumericEditField
        adf_inner_label               matlab.ui.control.Label
        det_angle_label               matlab.ui.control.Label
        det_angle                     matlab.ui.control.Slider
        det_angle_deg                 matlab.ui.control.Label
        det_angle_value               matlab.ui.control.NumericEditField
        adf_inner                     matlab.ui.control.Slider
        adf_outer_label               matlab.ui.control.Label
        ofRungsLabel                  matlab.ui.control.Label
        nrung                         matlab.ui.control.NumericEditField
        adf_outer                     matlab.ui.control.Slider
        adf_inner_plus                matlab.ui.control.Button
        adf_inner_minus               matlab.ui.control.Button
        det_rot_minus                 matlab.ui.control.Button
        adf_outer_plus                matlab.ui.control.Button
        adf_outer_minus               matlab.ui.control.Button
        det_rot_plus                  matlab.ui.control.Button
        DetectorTypeButtonGroup       matlab.ui.container.ButtonGroup
        SegmentedButton               matlab.ui.control.RadioButton
        AnnularRoundButton            matlab.ui.control.RadioButton
        VirtualApertureButton         matlab.ui.control.RadioButton
        CenterofMassButton            matlab.ui.control.RadioButton
        BrightnessContrastTab         matlab.ui.container.Tab
        ImageLabel                    matlab.ui.control.Label
        ImageDropDown                 matlab.ui.control.DropDown
        histogram_plot                matlab.ui.control.UIAxes
        histogram_panel               matlab.ui.container.Panel
        BrightnessLabel               matlab.ui.control.Label
        brightness                    matlab.ui.control.Slider
        ContrastLabel                 matlab.ui.control.Label
        contrast                      matlab.ui.control.Slider
        GammaLabel                    matlab.ui.control.Label
        gamma                         matlab.ui.control.Slider
        brightness_reset              matlab.ui.control.Button
        contrast_reset                matlab.ui.control.Button
        gamma_reset                   matlab.ui.control.Button
        trans_zoom_panel              matlab.ui.container.Panel
        ZoomButton_trans              matlab.ui.control.StateButton
        PanButton_trans               matlab.ui.control.StateButton
        ResetZoomButton_trans         matlab.ui.control.Button
    end

    
    properties (Access = public)
        filename
        nopiy
        nopix
        framesy
        framesx
        frames
        bitdepth
        offset
        gap
        ordering
        data
        center
        center0
        rmax
        radius
        ap_radius
        disk_edge
        seg_edge1
        seg_edge2
        seg_edge3
        seg_edge4
        seg_edge5
        seg_edge6
        seg_edge7
        img
        img_temp
        sum_image
        adf
        adf_inner_angle
        adf_outer_angle
        inner_disk
        outer_disk
        seg
        seg_label
        temp_angle
        mrad_per_pix
        alpha
        stack_pos
        marker_pos
        frame_num
        marker
        images
        mask
        dpc_angle
        dpc_mag
        dpc_mag_norm
        colorwheel_image
        subsample
        vectors
        color
        ax_limits
        dp_limits
        det_area
        com
        seg_adf
        ap_center
        ap_edge1
        ap_edge2
        ap_edge3
        ap_edge4
        ap_edge5
        ap_edge6
        scan_rot_angle
        scan_x
        text_x
        scan_y
        text_y
        image_choices
        im_choice
        ax_choice
        brightness_value
        contrast_value
        gamma_value
        gamma_text
        cont_bounds
    end
    
    methods (Access = public)
        
        function import4Ddata(app,nopiy_in,nopix_in,framesy_in,framesx_in,offset_in,gap_in,ordering_in,type_in)
            % nopiy, nopix      -	number of pixels in the diffraction pattern
            % framesy, framesx	-	number of probe positions in the image
            % bitdepth          -       bitdepth of the dataset
            % offset            -       BYTES before first diffraction pattern
            % gap               -       BYTES between diffraction patterns
            app.nopiy=nopiy_in;
            app.nopix=nopix_in;
            app.framesy=framesy_in;
            app.framesx=framesx_in;
            app.offset=offset_in;
            app.gap=gap_in;
            app.ordering=ordering_in;
            app.bitdepth=type_in{:};
        end
        
    end
    
    methods (Access = private)
        
        function plot_circle(app,name,ax,radius,center,color)
            ax.NextPlot='add';
            app.(name)=plot(ax,...
                            cos(0:pi/60:2*pi)*radius+center(1),...
                            sin(0:pi/60:2*pi)*radius+center(2),...
                            'Color',color,'LineWidth',4);
            ax.NextPlot='replace';
        end
        
        function plot_segments(app)
             det='A':'Z';
             clear_detectors(app)

             app.temp_angle=app.det_angle.Value;
            
            if app.nseg.Value>1
                app.det_angle.Enable='on'; app.det_angle_label.Enable='on'; app.det_angle_value.Enable='on';
                
                for j=1:app.nrung.Value
                    k1=(j-1)*(app.adf_outer_angle-app.adf_inner_angle)/app.nrung.Value+app.adf_inner_angle;
                    k2=(j)*(app.adf_outer_angle-app.adf_inner_angle)/app.nrung.Value+app.adf_inner_angle;
                    if j<app.nrung.Value
                        plot_circle(app,sprintf('seg_edge%d',j),app.detector_layout,k2,app.center,'w')
                    end
                    app.detector_layout.NextPlot='add';
                    for i=1:app.nseg.Value                   
                        if app.nrung.Value>1
                            temp_name=sprintf('%s%d',det(i),j);
                        else
                            temp_name=det(i);
                        end

                        app.seg.(temp_name)=plot(app.detector_layout,...
                                                 cos(app.temp_angle-pi/app.nseg.Value)*[k1 k2]+app.center(1),...
                                                 sin(app.temp_angle-pi/app.nseg.Value)*[k1 k2]+app.center(2),...
                                                 ':b','LineWidth',4);
                        app.seg_label.(temp_name)=text(app.detector_layout,...
                                                       app.center(1)+0.5*(k1+k2)*cos(app.temp_angle),...
                                                       app.center(2)+0.5*(k1+k2)*sin(app.temp_angle),...
                                                       temp_name,'FontSize',20,'FontWeight','bold','Color','r','HorizontalAlignment','center');
                        app.temp_angle=app.temp_angle+2*pi/app.nseg.Value;
                    end
                end
                app.detector_layout.NextPlot='replace';
            else
                app.det_angle.Enable='off'; app.det_angle_label.Enable='off'; app.det_angle_value.Enable='off';
            end
        end
        
        function integrate_masks(app)

            % Remove any previous images and masks if they exist
            try app=rmfield(app,{'images','mask','total_mask'}); end
            % Make pixel array for arctanget
            [xgrid, ygrid] = meshgrid(1:size(app.img,2), 1:size(app.img,1));
            
            % Preallocate an image that will be the sum of all segments (i.e. BF or ADF)
            app.adf=zeros(app.framesy,app.framesx);
            
            % Calculate the atan of all pixel positions within the image
            a=atan2(ygrid-app.center(2),xgrid-app.center(1));                                           % Get atan from -pi to pi
                a = (a-app.det_angle.Value+pi/app.nseg.Value) .* (a >= 0) + (a + 2 * pi-app.det_angle.Value+pi/app.nseg.Value) .* (a < 0);  % shift atan by the detector rotation angle
                a = a .* (a >= 0) + (a + 2 * pi) .* (a < 0);                                            % shift the negative values of atan up by 2pi
                a = a .* (a<2*pi) + (a-2*pi) .* (a>=2*pi);                                              % Get total range of atan from 0 to 2pi

                if app.SegmentedButton.Value==1
                    det='A':'Z';
                    
                    % Loop over the number of detector segments, define detector mask, integrate within each segment
                    for i=1:app.nseg.Value
                        app.det_area.(det(i))=0;
                    end
                    for j=1:app.nrung.Value
                        for i=1:app.nseg.Value
                            % Define vectors k1 and k2 that correspond to the inner and outer angles of the detector for a given number of rungs
                            k1=(j-1)*(app.adf_outer.Value-app.adf_inner.Value)/app.nrung.Value+app.adf_inner.Value;
                            k2=(j)*(app.adf_outer.Value-app.adf_inner.Value)/app.nrung.Value+app.adf_inner.Value;
                            
                            if app.nrung.Value>1
                                temp_name=sprintf('%s%d',det(i),j);
                            else
                                temp_name=det(i);
                            end
                            
                            app.mask.(temp_name)=((xgrid-app.center(1)).^2 + (ygrid-app.center(2)).^2) <= k2^2 & ... % less than outer angle
                                                 ((xgrid-app.center(1)).^2 + (ygrid-app.center(2)).^2) >= k1^2 & ... % greater than inner angle
                                                 a >= (i-1)*2*pi/app.nseg.Value & ...                                % greater than the start of the segment angle
                                                 a <  (i)*2*pi/app.nseg.Value;                                       % less than the end of the segmenet angle
                                             
                            % Integrate wihtin each mask
                            app.images.(temp_name)=reshape(sum(app.data.raw(app.mask.(temp_name)==1,:)),[app.framesx app.framesy])';
                            
                            % Add to the total detector image
                            app.adf=app.adf+app.images.(temp_name);
                           
                            % Sum all rungs in a given segment (i.e. A=A1+A2+...+An) to find the total area of that segment
                            app.det_area.(det(i))=app.det_area.(det(i))+sum(app.mask.(temp_name)(:));
                            
                            % Find center of mass of each segment in each rung
                            com_length=2*sin(2*pi/app.nseg.Value/2)/(3*2*pi/app.nseg.Value/2)*(k1^3-k2^3)/(k1^2-k2^2);
                            app.com.(temp_name)=com_length*[cos((i-1)*2*pi/app.nseg.Value+app.det_angle.Value),...
                                                            sin((i-1)*2*pi/app.nseg.Value+app.det_angle.Value)];
                        end
                    end
                    
                    % weight the individual segment/rung images by area fraction and the
                    % magnitude of the COM vector, then sum into an image for each segment
                    if app.nrung.Value>1
                        % Prepopulate summed segment images with zeros
                        for i=1:app.nseg.Value
                            app.images.(det(i))=zeros(app.framesx,app.framesy);
                            %app.images.com=
                        end
                        for i=1:app.nseg.Value
                            for j=1:app.nrung.Value
                                temp_name=sprintf('%s%d',det(i),j);
                                app.seg_adf.(temp_name)=sum(app.mask.(temp_name)(:))/app.det_area.(det(i));
                                app.images.(det(i))=app.images.(det(i))+app.images.(temp_name)*sqrt(sum(app.com.(temp_name).^2))*app.seg_adf.(temp_name);
                                %app.images.com=app.images.com+
                            end
                        end
                    end
                    
                elseif app.AnnularRoundButton.Value==1
                    app.mask.bfdf=((xgrid-app.center(1)).^2 + (ygrid-app.center(2)).^2) <= app.adf_outer.Value^2 & ...
                                  ((xgrid-app.center(1)).^2 + (ygrid-app.center(2)).^2) >= app.adf_inner.Value^2;
                                  
                    % Integrate wihtin the mask
                    app.images.bfdf=reshape(sum(app.data.raw(app.mask.bfdf==1,:)),[app.framesx app.framesy])';
                    app.adf=app.images.bfdf;
                elseif app.VirtualApertureButton.Value==1
                    app.mask.bfdf=zeros(app.nopix,app.nopiy);
                    
                    for ii=1:app.aperture_symmetry.Value
                        theta=2*pi/app.aperture_symmetry.Value*(ii-1);
                        R = [cos(theta) -sin(theta); sin(theta) cos(theta)];
                        temp_center=(app.ap_center-app.center)*R+app.center;
                        app.mask.bfdf=app.mask.bfdf+(((xgrid-temp_center(1)).^2 + (ygrid-temp_center(2)).^2) <= app.ap_radius^2);
                    end
                    % Integrate wihtin the mask
                    app.images.bfdf=reshape(sum(app.data.raw(app.mask.bfdf==1,:)),[app.framesx app.framesy])';
                    app.adf=app.images.bfdf;
                elseif app.CenterofMassButton.Value==1
                    
%                     % Center of Mass
%                     sz = size(test);
%                     nd = ndims(test);
%                     M = sum(test(:));
%                     C = zeros(1,nd);
%                     for ii = 1:nd
%                         shp = ones(1,nd);
%                         shp(ii) = sz(ii);
%                         rep = sz;
%                         rep(ii) = 1;
%                         ind = repmat(reshape(1:sz(ii),shp),rep);
%                         C(ii) = sum(ind(:).*test(:))./M;
%                     end
%                     C=[C(2) C(1)];
%                     round(C-center,2)
                end
        end
        
        function display_DPC(app)
            % Make difference images
            app.images.ac=app.images.A-app.images.C;imagesc(app.seg_ac,app.images.ac);app.seg_ac.Visible='on';
            app.images.bd=app.images.B-app.images.D;imagesc(app.seg_bd,app.images.bd);app.seg_bd.Visible='on';
            
            set(app.seg_ac,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'Tag','seg_ac','YDir','normal','XLim',[1 app.framesx],'YLim',[1 app.framesy]);
            set(app.seg_bd,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'Tag','seg_bd','YDir','normal','XLim',[1 app.framesx],'YLim',[1 app.framesy]);
            colormap(app.seg_ac,gray);
            colormap(app.seg_bd,gray);
            
            
            %%% Make DPC images
            
            % DPC angle is calculated from the arctangent of the difference images (AC and BD)
            app.dpc_angle=atan2(-app.images.bd,-app.images.ac)-app.scan_rot_angle+app.det_angle.Value;
            app.dpc_angle = app.dpc_angle .* (app.dpc_angle >= 0) + (app.dpc_angle + 2 * pi) .* (app.dpc_angle < 0); %adjust atan2 range from [-pi/2:pi/2) to [0:2pi)

            % DPC magnitude is the length of the vector
            app.dpc_mag=sqrt(app.images.ac.^2+app.images.bd.^2);
            % Normalize dpc_mag range to [0:1] for alpha channel 
            app.dpc_mag_norm=(app.dpc_mag-min(min(app.dpc_mag)))/(max(max(app.dpc_mag))-min(min(app.dpc_mag)));
            
            % Make iDPC and dDPC images
            [k.x,k.y]=meshgrid(floor(-app.framesx/2):floor(-app.framesx/2)+app.framesx-1,...
                               floor(-app.framesy/2):floor(-app.framesy/2)+app.framesy-1);
            k.radius=sqrt(k.x.^2+k.y.^2);
            k.radius(k.radius==0)=1;
            
            k.x=fftshift(k.x);
            k.y=fftshift(k.y);
            k.radius=fftshift(k.radius);
            
            app.images.idpc=real(ifft2(k.x.*fft2(app.images.ac)./(2*pi*1j*k.radius.^2))+...
                                 ifft2(k.y.*fft2(app.images.bd)./(2*pi*1j*k.radius.^2)));
                
            app.images.ddpc=real(ifft2(2*pi*1i*k.x.*fft2(app.images.ac)+2*pi*1i*k.y.*fft2(app.images.bd)));
            
            % Display DPC images
            app.vect_angle.NextPlot='replace';
            imagesc(app.vect_angle,app.dpc_angle,'AlphaData',app.dpc_mag_norm);app.vect_angle.Visible='on';
            set(app.vect_angle,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'Tag','vect_angle','YDir','normal','Color','k','XLim',[1 app.framesx],'YLim',[1 app.framesy]);
            colormap(app.vect_angle,hsv);
            app.dpc_dropdown2.Value=app.dpc_dropdown2.Items{2};
            
            imagesc(app.vect_mag,app.dpc_mag);app.vect_mag.Visible='on';
            set(app.vect_mag,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'Tag','vect_mag','YDir','normal','XLim',[1 app.framesx],'YLim',[1 app.framesy]);
            colormap(app.vect_mag,gray);
            
            % Display BF/DF image as well
            imagesc(app.bfdf_image,app.adf);
            set(app.bfdf_image,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'Tag','bfdf','YDir','normal','XLim',[1 app.framesx],'YLim',[1 app.framesy]);
            colormap(app.bfdf_image,gray);
            app.dpc_dropdown1.Value=app.dpc_dropdown1.Items{2};
            
        end
        
        function makeFullScreen(app)
            screenSize=get(0,'ScreenSize');     % Get user's screensize
            guiSize=[1 1 1600 975];             % Defined when the GUI was created
            
            % Assuming most screen aspect ratios are 16:9, the GUI size will be defined by the screen height (screensize(4))
            % with the width (screensize(4)) following based on the designed GUI aspect ratio of 1600:975
            
            % Set the figure to fullscreen
            app.Quant4D_Fig.Position=screenSize;
            
            % Check screensize ratio compared to that of the GUI
            if screenSize(3)/screenSize(4)<guiSize(3)/guiSize(4) && screenSize(3)>guiSize(3)        % i.e. a screen that is larger than the GUI with more height
                app.total_panel.Position(3)=screenSize(3);
                app.total_panel.Position(4)=guiSize(4)*screenSize(3)/guiSize(3);
                app.total_panel.Position(2)=(screenSize(4)-app.total_panel.Position(4))/2;
                app.controlPanel.Position(2)=(app.total_panel.Position(4)-app.controlPanel.Position(4))/2;
                
            elseif screenSize(3)/screenSize(4)<guiSize(3)/guiSize(4) && screenSize(4)<guiSize(4)    % i.e. a screen that is smaller than the original GUI size
                %app.total_panel.Position(4)=screenSize(4);
                app.total_panel.Position(4)=900; % bare minimum for text, sliders, buttons, etc to display
                app.controlPanel.Position(2)=-13; % bare minimum to keep display/save image buttons at bottom of screen
                app.total_panel.Position(3)=screenSize(4)*(guiSize(3)/guiSize(4));
                app.total_panel.Position(2)=(screenSize(4)-app.total_panel.Position(4))/2;
                
                app.outputs_tabgroup.Position=[610 0 728 0.95*900];
                app.intermediate_data_tabgroup.Position=[250 0 352 368];
                
            elseif screenSize(3)/screenSize(4)>guiSize(3)/guiSize(4) && screenSize(4)<guiSize(4)    % i.e. a screen that is 16:9 with height=900 pixels
                %app.total_panel.Position(4)=screenSize(4);
                app.total_panel.Position(4)=900; % bare minimum for text, sliders, buttons, etc to display
                app.controlPanel.Position(2)=-13; % bare minimum to keep display/save image buttons at bottom of screen
                app.total_panel.Position(3)=screenSize(4)/guiSize(4)*screenSize(3);
                app.total_panel.Position(1)=(screenSize(3)-app.total_panel.Position(3))/2;
                
            elseif screenSize(3)/screenSize(4)>guiSize(3)/guiSize(4) && screenSize(4)>=guiSize(4)    % i.e. a screen that is 16:9 with height>975 pixels
                app.total_panel.Position(4)=screenSize(4);
                app.total_panel.Position(3)=screenSize(4)/guiSize(4)*screenSize(3);
                app.total_panel.Position(1)=(screenSize(3)-app.total_panel.Position(3))/2;
                app.controlPanel.Position(2)=(app.total_panel.Position(4)-app.controlPanel.Position(4))/2;
            end
        end
        
        function plotVectorField(app)
            app.ZoomButton_vect.Visible='on';
            app.ResetZoomButton_vect.Visible='on';
            app.PanButton_vect.Visible='on';
            app.vectButton_group.Visible='on';
            app.resampleDropDown.Visible='on';
            
            app.vectorField.NextPlot='replace';
            imagesc(app.vectorField,app.dpc_angle,'AlphaData',app.dpc_mag_norm);app.vectorField.Visible='on';
            set(app.vectorField,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'Tag','vectorField','YDir','normal','Color','k');
            axis(app.vectorField,app.ax_limits);
            colormap(app.vectorField,hsv);
            
            set(app.vectorField,'NextPlot','add');
            app.vectors=quiver(1:app.subsample:app.framesx,...
                               1:app.subsample:app.framesy,...
                               -app.images.ac(1:app.subsample:end,1:app.subsample:end)./app.subsample,...
                               -app.images.bd(1:app.subsample:end,1:app.subsample:end)./app.subsample,...
                               1/app.subsample,app.color,'Parent', app.vectorField);%'LineWidth',2,
            set(app.vectorField,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'Tag','vectorField','YDir','normal','NextPlot','replace');
            axis(app.vectorField,app.ax_limits);
        end
        
        function plotMarker(app)
            try delete(app.marker_pos); end
            app.adf_image.NextPlot='add';
            app.marker_pos=plot(app.adf_image,app.marker(1),app.marker(2),'o','Color','r','MarkerFace','r','MarkerSize',12);
            app.adf_image.NextPlot='replace';
        end        
                
        function display_bfdf(app)
            integrate_masks(app)
            app.bfdf_image.NextPlot='replace';
            imagesc(app.bfdf_image,app.images.bfdf);
            set(app.bfdf_image,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'Tag','bfdf','YDir','normal','XLim',[1 app.framesx],'YLim',[1 app.framesy]);
            colormap(app.bfdf_image,gray);
        end
        
        function display_COM(app)
            
        end
        
        function plot_detector(app)
            clear_detectors(app);
            if app.SegmentedButton.Value==1
                plot_segments(app);
                plot_circle(app,'inner_disk',app.detector_layout,app.adf_inner_angle,app.center,'g')
                plot_circle(app,'outer_disk',app.detector_layout,app.adf_outer_angle,app.center,'r')
            elseif app.AnnularRoundButton.Value==1
                plot_circle(app,'inner_disk',app.detector_layout,app.adf_inner_angle,app.center,'g')
                plot_circle(app,'outer_disk',app.detector_layout,app.adf_outer_angle,app.center,'r')
            elseif app.VirtualApertureButton.Value==1
                for ii=1:app.aperture_symmetry.Value
                    theta=2*pi/app.aperture_symmetry.Value*(ii-1);
                    R = [cos(theta) -sin(theta); sin(theta) cos(theta)];
                    temp_center=(app.ap_center-app.center)*R+app.center;
                    plot_circle(app,strcat('ap_edge',num2str(ii)),app.detector_layout,app.ap_radius,temp_center,'b')
                end
            end
        end
        
        function clear_detectors(app)
            det='A':'Z';
            try delete(app.disk_edge); end
            try delete(app.inner_disk); end
            try delete(app.outer_disk); end
            for j=1:app.nrung.Limits(2)
                for i=1:app.nseg.Limits(2)
                    try
                        delete(app.seg.(sprintf('%s%d',det(i),j)));
                        delete(app.seg_label.(sprintf('%s%d',det(i),j)));
                    end
                    try
                        delete(app.seg.(det(i)));
                        delete(app.seg_label.(det(i)));
                    end
                end
                try delete(eval(sprintf('app.seg_edge%d',j)));end
            end
            for ii=1:app.aperture_symmetry.Limits(2)
                try delete(eval(sprintf('app.ap_edge%d',ii)));end
            end
        end
        
        function clear_plots(app)
            app.bfdf_image.Visible='off';cla(app.bfdf_image);
            app.vect_mag.Visible='off';cla(app.vect_mag);
            app.vect_angle.Visible='off';cla(app.vect_angle);
            app.seg_ac.Visible='off';cla(app.seg_ac);
            app.seg_bd.Visible='off';cla(app.seg_bd);
            app.com_x.Visible='off';cla(app.com_x);
            app.com_y.Visible='off';cla(app.com_y);
            app.com_mag.Visible='off';cla(app.com_mag);
            app.com_angle.Visible='off';cla(app.com_angle);
            app.vectorField.Visible='off';cla(app.vectorField);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %makeFullScreen(app)
            movegui(app.Quant4D_Fig,'center')
            
            app.detector_layout.Tag='detector_layout';
            app.trans_beam_x.Tag='trans_beam_x';
            app.trans_beam_y.Tag='trans_beam_y';
            app.trans_beam_radius.Tag='trans_beam_radius';
            app.sum_patterns.Tag='sum_patterns';
            app.all_patterns.Tag='all_patterns';
            app.stack_pos.Tag='stack_pos';
            app.detx.Tag='detx';
            app.dety.Tag='dety';
            app.align_trans_beam.Text='Finish Alignment';
            app.VectorButton.Tag='VectorButton';
            app.MagnitudeButton.Tag='MagnitudeButton';
            app.AngleButton.Tag='AngleButton';
            app.aperture_x.Tag='aperture_rad';
            app.aperture_x_text.Tag='aperture_x_text';
            app.aperture_y.Tag='aperture_ang';
            app.aperture_y_text.Tag='aperture_y_text';
            app.aperture_size.Tag='aperture_size';
            app.ap_size_text.Tag='ap_size_text';
            app.trans_beam_x_minus.Tag='trans_beam_x_minus';
            app.trans_beam_x_plus.Tag='trans_beam_x_plus';
            app.trans_beam_y_minus.Tag='trans_beam_y_minus';
            app.trans_beam_y_plus.Tag='trans_beam_y_plus';
            app.trans_beam_rad_minus.Tag='trans_beam_rad_minus';
            app.trans_beam_rad_plus.Tag='trans_beam_rad_plus';
            app.ap_x_minus.Tag='ap_rad_minus';
            app.ap_x_plus.Tag='ap_rad_plus';
            app.ap_x_reset.Tag='ap_rad_reset';
            app.ap_y_minus.Tag='ap_ang_minus';
            app.ap_y_plus.Tag='ap_ang_plus';
            app.ap_y_reset.Tag='ap_ang_reset';
            app.ap_size_minus.Tag='ap_size_minus';
            app.ap_size_plus.Tag='ap_size_plus';
            app.ap_left_rot.Tag='ap_left_rot';
            app.ap_right_rot.Tag='ap_right_rot';
            app.adf_inner_minus.Tag='adf_inner_minus';
            app.adf_inner_plus.Tag='adf_inner_plus';
            app.adf_outer_minus.Tag='adf_outer_minus';
            app.adf_outer_plus.Tag='adf_outer_plus';
            app.det_rot_minus.Tag='det_rot_minus';
            app.det_rot_plus.Tag='det_rot_plus';
            app.scan_rot_minus.Tag='scan_rot_minus';
            app.scan_rot_plus.Tag='scan_rot_plus';
            app.brightness.Tag='brightness';
            app.brightness_reset.Tag='brightness_reset';
            app.contrast.Tag='contrast';
            app.contrast_reset.Tag='contrast_reset';
            app.gamma.Tag='gamma';
            app.gamma_reset.Tag='gamma_reset';
            app.ZoomButton_trans.Tag='ZoomButton_trans';
            app.PanButton_trans.Tag='PanButton_trans';
            app.ResetZoomButton_trans.Tag='ResetZoomButton_trans';
            app.ZoomButton_vect.Tag='ZoomButton_vect';
            app.PanButton_vect.Tag='PanButton_vect';
            app.ResetZoomButton_vect.Tag='ResetZoomButton_vect';
            app.dpc_dropdown1.Tag='dpc_dropdown1';
            app.dpc_dropdown2.Tag='dpc_dropdown2';
            
            app.marker=[1 1];
            app.alpha=12;
            app.nseg.Value=4;
            app.det_angle.Value=0;app.det_angle_value.Value=0;
            set(app.det_angle,'Limits',[-pi pi],'Value',0);app.det_angle_value.Value=0;
            app.detx.Value=1;app.dety.Value=1;
            app.stack_slider.Value=1;
            app.subsample=1;
            app.color='w';
            app.scan_rot.Value=0;
            app.scan_rot_value.Value=0;
            
            set(app.trans_panel.Children,'Enable','off');
            set(app.trans_zoom_panel.Children,'Enable','off');
            set(app.detector_panel.Children,'Enable','off');
            app.sum_patterns.Enable='off';app.all_patterns.Enable='off';
            app.align_trans_beam.Enable='off';
            set(app.VectorMenuPanel.Children,'Enable','off');
            app.VectorButton.Enable='off';app.MagnitudeButton.Enable='off';app.AngleButton.Enable='off';
            app.AnnularRoundButton.Value=1;
            
            app.image_choices={'Diffraction Pattern','app.detector_layout','app.img',0,0,1;
                              'Sum Image','app.adf_image','app.sum_image',0,0,1;
                              'BF/DF','app.bfdf_image','app.images.bfdf',0,0,1;
                              'DPC AC','app.seg_ac','app.images.ac',0,0,1;
                              'DPC BD','app.seg_bd','app.images.bd',0,0,1;
                              'DPC Magnitude','app.vect_mag','app.dpc_mag',0,0,1;
                              'COM X','app.com_x','app.images.comx',0,0,1;
                              'COM Y','app.com_y','app.images.comy',0,0,1;
                              'COM Magnitude','app.com_mag','app.com_mag',0,0,1};
            
            clear_plots(app);
        end

        % Button pushed function: import_data
        function import_dataButtonPushed(app, event)
            % Allow for importing binary files with extensions:
            %   .mib	-  Medipix
            %   .raw	-  EMPAD, ImageJ, Fiji, Matlab, etc.
            try app=rmfield(app,{'data','images','mask'}); end
            startupFcn(app)
            app.detector_layout.Visible='off';cla(app.detector_layout);
            app.Quant4D_Fig.Visible='off';
            [app.filename,pathname]=uigetfile({'*.raw';'*.mib'});
            app.Quant4D_Fig.Visible='on';
            app.controlTab.SelectedTab=app.CalibrateTab;
            if app.filename==0
                app.adf_image.Visible='off';cla(app.adf_image);
                app.align_trans_beam.Text='Align Transmitted Beam';
                app.align_scan_rot.Enable='off';
                app.display_images.Enable='off';
                app.save_images.Enable='off';
                app.AnnularRoundButton.Enable='off';
                app.VirtualApertureButton.Enable='off';
                app.CenterofMassButton.Enable='off';
                app.SegmentedButton.Enable='off';
                app.colorwheel_plot.Visible='off';cla(app.colorwheel_plot);
                return;
            else
            cd(pathname)
            s=dir(strcat(pathname,app.filename));
            
            % Ask the user for some basic parameters of the 4D data set and save them
            % to the GUI structure (app)
            
            % Import the 4D dataset
            appImport=import4Dstem(app,app.filename);
            uiwait(appImport.import_4d)
            
            if app.nopiy==0 || app.nopix==0 || app.framesy==0 || app.framesx==0
                app.adf_image.Visible='off';cla(app.adf_image);
                app.align_trans_beam.Text='Align Transmitted Beam';
                app.align_scan_rot.Enable='off';
                app.display_images.Enable='off';
                app.save_images.Enable='off';
                app.AnnularRoundButton.Enable='off';
                app.VirtualApertureButton.Enable='off';
                app.CenterofMassButton.Enable='off';
                app.SegmentedButton.Enable='off';
                app.colorwheel_plot.Visible='off';cla(app.colorwheel_plot);
                return;
            else
            % Calculate the total number of frames; update the stack slider for later use
            app.frames=app.framesx*app.framesy;
            app.stack_slider.Limits=[1 app.frames];
            
            % Set the framesize for the importbar
            filesize=s.bytes;
            framesize=filesize/app.frames;
            
            % Open the user-selected file for reading
            fid=fopen(strcat(pathname,app.filename),'r');
            
            % Pre-allocate the array for speed
            app.data.raw=zeros(app.nopiy*app.nopix,app.frames,app.bitdepth);
            
            % Read in the 4D dataset in bunches the size of one diffraction pattern and
            % loop over the total number of diffraction patterns (# of probe positions)
            import_bar=waitbar(0,'Importing Data...');
            fread(fid,app.offset,app.bitdepth,0,app.ordering);
            for j=1:app.frames
                if mod(j,100)==0 tic; end
                app.data.raw(1:app.nopiy*app.nopix,j)=fread(fid,app.nopiy*app.nopix,app.bitdepth,0,app.ordering);
                fread(fid,app.gap,app.bitdepth,0,app.ordering);
                if mod(j,100)==0 waitbar(j/app.frames,import_bar,sprintf('%.1f%% Complete\n%.2f MB/s',100*j/app.frames,framesize/toc/1e6)); end
            end
            waitbar(1,import_bar,sprintf('100%% Complete!\nForming Images... Please Wait'));
            fclose(fid);
            
            % Create a summed image of all diffraction patterns simply for display
            % purposes and alignment and calibration
            app.ax_limits=[1 app.framesx 1 app.framesy];
            app.dp_limits=[1 app.nopix 1 app.nopiy];
            app.img=sum(reshape(app.data.raw,[app.nopix app.nopiy app.frames]),3);
            app.detector_layout.Visible='on';
            imagesc(app.detector_layout,app.img);
            set(app.detector_layout,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'CLim',[0 max(app.img(:))],'Tag','detector_layout','YDir','normal');
            axis(app.detector_layout,app.dp_limits);
            colormap(app.detector_layout,gray);
            delete(import_bar)
            
            % Align transmitted beam
            app.center0=[app.nopix/2 app.nopiy/2];
            app.center=[app.nopix/2 app.nopiy/2];
            app.ap_center=app.center;
            app.rmax=min(app.nopiy-app.center0(1),app.nopix-app.center0(2));
            set(app.trans_panel.Children,'Enable','on')
            set(app.trans_zoom_panel.Children,'Enable','on');
            app.align_trans_beam.Enable='on';
            app.sum_patterns.Enable='on';
            app.all_patterns.Enable='on';
            
            set(app.trans_beam_x,'Limits',[-app.rmax,app.rmax],'Value',0);
            set(app.trans_beam_y,'Limits',[-app.rmax,app.rmax],'Value',0);
            set(app.trans_beam_radius,'Limits',[0,app.rmax],'Value',app.rmax/3);
            set(app.stack_slider,'Limits',[1,app.frames]);
            
            app.radius=min(app.nopix/2,app.nopiy/2)/2;
            app.adf_inner_angle=app.radius;
            app.adf_outer_angle=app.rmax;
            app.mrad_per_pix=app.alpha/app.radius;
            app.aperture_x.Limits=[0 app.rmax];
            app.aperture_y.Limits=[-pi pi];
            app.scan_rot.Limits=[-pi pi];
            app.scan_rot_angle=0;
            
            plot_circle(app,'disk_edge',app.detector_layout,app.radius,app.center0,'b')
            
            app.sum_image=reshape(sum(app.data.raw),[app.framesx app.framesy]);
            imagesc(app.adf_image,app.sum_image);
            set(app.adf_image,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'CLim',[min(app.sum_image(:)) max(app.sum_image(:))],'Tag','adf_image','YDir','normal');
            axis(app.adf_image,app.ax_limits);
            colormap(app.adf_image,gray);
            app.intermediate_data_tabgroup.SelectedTab=app.adf_tab;
            app.adf_tab.Title='Sum Image';
            
            % Make colorwheel
            width=app.nopix;cw_radius=width/2;
            [xgrid, ygrid] = meshgrid(1:width, 1:width);
            a=atan2(ygrid-app.center(2),xgrid-app.center(1));                                           % Get atan from -pi to pi
                a = a .* (a >= 0) + (a + 2 * pi) .* (a < 0);                                            % shift the negative values of atan up by 2pi
            app.colorwheel_image=a;
            angles=0:pi/100:2*pi;
            imagesc(app.colorwheel_plot,app.colorwheel_image);
            colormap(app.colorwheel_plot,hsv(512));
            set(app.colorwheel_plot,'YDir','normal','NextPlot','add');
            fill(app.colorwheel_plot,[cos(angles)*cw_radius+cw_radius,width,width,0,0,width],[sin(angles)*cw_radius+cw_radius,cw_radius,0,0,width,width],'k');
            set(app.colorwheel_plot,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'Tag','colowheel_plot','XLim',[0 width],'YLim',[0 width]);
            set(app.colorwheel_plot,'Visible','on','NextPlot','replace');
            colorbar(app.colorwheel_plot,'off')
            
            image_controls(app, event)
            end
            end
        end

        % Callback function: adf_inner, adf_inner, adf_inner_minus, 
        % adf_inner_plus, adf_inner_value
        function adf_inner_Callback(app, event)
            if strcmp(event.Source.Type,'uislider')
                if event.Value>app.adf_outer.Value
                    app.adf_inner.Value=app.adf_outer.Value;
                    app.adf_inner_angle=app.adf_outer.Value;
                else
                    app.adf_inner_angle=event.Value;
                end
                app.adf_inner_value.Value=app.adf_inner_angle*app.mrad_per_pix;
            elseif strcmp(event.Source.Type,'uinumericeditfield')
                if event.Value>app.adf_outer.Value*app.mrad_per_pix
                    app.adf_inner_value.Value=app.adf_outer.Value*app.mrad_per_pix;
                    app.adf_inner.Value=app.adf_outer.Value;
                elseif event.Value<0
                    app.adf_inner.Value=0;
                else
                    app.adf_inner.Value=event.Value/app.mrad_per_pix;
                end
                app.adf_inner_angle=app.adf_inner.Value;
            elseif strcmp(event.Source.Tag,'adf_inner_minus')
                if app.adf_inner.Value-0.25>=0
                    app.adf_inner.Value=app.adf_inner.Value-0.25;
                    app.adf_inner_value.Value=app.adf_inner_value.Value-0.25*app.mrad_per_pix;
                    app.adf_inner_angle=app.adf_inner.Value;
                else
                    app.adf_inner.Value=0;
                    app.adf_inner_value.Value=0;
                    app.adf_inner_angle=0;
                end
            elseif strcmp(event.Source.Tag,'adf_inner_plus')
                if app.adf_inner.Value+0.25<app.adf_outer.Value
                    app.adf_inner.Value=app.adf_inner.Value+0.25;
                    app.adf_inner_value.Value=app.adf_inner_value.Value+0.25*app.mrad_per_pix;
                    app.adf_inner_angle=app.adf_inner.Value;
                else
                    app.adf_inner.Value=app.adf_outer.Value;
                    app.adf_inner_value.Value=app.adf_outer_value.Value;
                    app.adf_inner_angle=app.adf_outer.Value;
                end
            end
            
            plot_detector(app)
        end

        % Callback function: trans_beam_rad_minus, 
        % trans_beam_rad_plus, trans_beam_radius, trans_beam_radius, 
        % trans_beam_x, trans_beam_x, trans_beam_x_minus, 
        % trans_beam_x_plus, trans_beam_y, trans_beam_y, 
        % trans_beam_y_minus, trans_beam_y_plus
        function trans_beam_Callback(app, event)
            % Slider actions
            if strcmp(event.Source.Tag,'trans_beam_x')
                app.center=app.center0+[event.Value app.trans_beam_y.Value];
            elseif strcmp(event.Source.Tag,'trans_beam_y')
                app.center=app.center0+[app.trans_beam_x.Value event.Value];
            elseif strcmp(event.Source.Tag,'trans_beam_radius')
                app.radius=event.Value;
                app.mrad_per_pix=app.alpha/app.radius;
            end
            
            % Button actions
            if strcmp(event.Source.Tag,'trans_beam_x_plus')
                app.center=app.center+[0.25 0];
                app.trans_beam_x.Value=app.trans_beam_x.Value+0.25;
            elseif strcmp(event.Source.Tag,'trans_beam_x_minus')
                app.center=app.center-[0.25 0];
                app.trans_beam_x.Value=app.trans_beam_x.Value-0.25;
            elseif strcmp(event.Source.Tag,'trans_beam_y_plus')
                app.center=app.center+[0 0.5];
                app.trans_beam_x.Value=app.trans_beam_y.Value+0.5;
            elseif strcmp(event.Source.Tag,'trans_beam_y_minus')
                app.center=app.center-[0 0.5];
                app.trans_beam_x.Value=app.trans_beam_y.Value-0.5;
            elseif strcmp(event.Source.Tag,'trans_beam_rad_plus')
                app.radius=app.radius+0.5;
                app.trans_beam_radius.Value=app.trans_beam_radius.Value+0.5;
            elseif strcmp(event.Source.Tag,'trans_beam_rad_minus')
                app.radius=app.radius-0.5;
                app.trans_beam_radius.Value=app.trans_beam_radius.Value-0.5;
            end
            
            app.rmax=min(app.nopiy-app.center(1),app.nopix-app.center(2));
            app.ap_radius=app.radius;
            
            try delete(app.disk_edge); end
            
            plot_circle(app,'disk_edge',app.detector_layout,app.radius,app.center,'b')
        end

        % Selection changed function: diff_pattern_radio_group
        function pattern_radio(app, event)
            XLim_temp=app.detector_layout.XLim;
            YLim_temp=app.detector_layout.YLim;
            
            if strcmp(event.Source.SelectedObject.Tag,'sum_patterns')
                try app.stack_pos.Visible='off'; end
                try delete(app.marker_pos);end
                app.stack_slider.Visible='off';
                app.detx.Visible='off';app.dety.Visible='off';
                imagesc(app.detector_layout,imadjust(app.img/max(app.img(:)),[],[],app.image_choices{1,6})*max(app.img(:)))
                set(app.detector_layout,'CLim',[min(app.img(:)) max(app.img(:))]-...
                                                app.brightness.Value*range(app.img(:))-...
                                                app.contrast.Value*range(app.img(:))*[-1 1]);
                app.image_choices(1,3)={'app.img'};
            else
                app.frame_num=round(app.stack_slider.Value);
                app.detx.Visible='on';app.dety.Visible='on';
                app.detx.Limits=[1 app.framesx];app.dety.Limits=[1 app.framesy];
                app.detx.Value=app.marker(1);app.dety.Value=app.marker(2);
                app.stack_slider.Visible='on';
                plotMarker(app);
                app.frame_num=round(get(app.stack_slider,'Value'));
                app.img_temp=double(reshape(app.data.raw(:,app.frame_num),[app.nopix app.nopiy]));
                imagesc(app.detector_layout,imadjust(app.img_temp/max(app.img_temp(:)),[],[],app.image_choices{1,6})*max(app.img_temp(:)))
                set(app.detector_layout,'CLim',[min(app.img_temp(:)) max(app.img_temp(:))]-...
                                                app.brightness.Value*range(app.img_temp(:))-...
                                                app.contrast.Value*range(app.img_temp(:))*[-1 1]);
                app.intermediate_data_tabgroup.SelectedTab=app.adf_tab;
                app.image_choices(1,3)={'app.img_temp'};
            end
            set(app.detector_layout,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'XLim',XLim_temp,'YLim',YLim_temp,'Tag','detector_layout','YDir','normal');

            colormap(app.detector_layout,gray);
            
            if strcmp(app.align_trans_beam.Text,'Finish Alignment')
                plot_circle(app,'disk_edge',app.detector_layout,app.radius,app.center,'b')
            else
                plot_detector(app);
            end
        end

        % Callback function: detx, detx, dety, dety, stack_slider, 
        % stack_slider
        function stack_slider_Callback(app, event)
            if strcmp(event.Source.Type,'uislider')
                app.frame_num=round(event.Value);
                app.marker=[floor(app.frame_num/app.framesx)+1.*(mod(app.frame_num,app.framesx)~=0),...
                            mod(app.frame_num,app.framesx)+app.framesx.*(mod(app.frame_num,app.framesx)==0)];
                app.detx.Value=app.marker(1);app.dety.Value=app.marker(2);
            else
                app.marker=[app.detx.Value,app.dety.Value];
                app.frame_num=(app.detx.Value-1)*app.framesx+app.dety.Value;
                app.stack_slider.Value=app.frame_num;
            end
            
            XLim_temp=app.detector_layout.XLim;
            YLim_temp=app.detector_layout.YLim;
            app.img_temp=double(reshape(app.data.raw(:,app.frame_num),[app.nopix app.nopiy]));
            imagesc(app.detector_layout,imadjust(app.img_temp/max(app.img_temp(:)),[],[],app.image_choices{1,6})*max(app.img_temp(:)))
            set(app.detector_layout,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'XLim',XLim_temp,'YLim',YLim_temp,'Tag','detector_layout','YDir','normal');
            
            set(app.detector_layout,'CLim',[min(app.img_temp(:)) max(app.img_temp(:))]-...
                                            app.brightness.Value*range(app.img_temp(:))-...
                                            app.contrast.Value*range(app.img_temp(:))*[-1 1]);
            colormap(app.detector_layout,gray);
            
            if strcmp(app.align_trans_beam.Text,'Finish Alignment')
                plot_circle(app,'disk_edge',app.detector_layout,app.radius,app.center,'b')
            else
                plot_detector(app);
            end
            
            plotMarker(app);
        end

        % Button pushed function: align_trans_beam
        function align_trans_beam_Callback(app, event)
            if strcmp(event.Source.Text,'Finish Alignment')
                app.align_trans_beam.Text='Align Transmitted Beam';
                
                app.display_images.Enable='on';
                set(app.detector_panel.Children,'Enable','on');
                set(app.trans_panel.Children,'Enable','off');
                set(app.trans_zoom_panel.Children,'Enable','on');
                app.conv_angle.Enable='on';app.conv_angle_label.Enable='on';
                set(app.adf_inner,'Limits',[0,app.rmax],'Value',app.adf_inner_angle);app.adf_inner_value.Value=app.adf_inner_angle*app.mrad_per_pix;
                set(app.adf_outer,'Limits',[0,app.rmax],'Value',app.rmax);app.adf_outer_value.Value=app.adf_outer_angle*app.mrad_per_pix;
                set(app.det_angle,'Limits',[-pi,pi],'Value',app.det_angle_value.Value*pi/180);app.det_angle_value.Value=app.det_angle.Value*180/pi;
                app.SegmentedButton.Enable='on';
                app.AnnularRoundButton.Enable='on';
                app.VirtualApertureButton.Enable='on';
                app.CenterofMassButton.Enable='on';
                app.align_scan_rot.Enable='on';
                app.import_data.Enable='on';
                
                if isempty(app.center)
                    app.center=app.center; end
                
                det_type_Callback(app,event);
            else
                delete(app.disk_edge);
                
                set(app.detector_panel.Children,'Enable','off')
                set(app.trans_panel.Children,'Enable','on')
                
                app.import_data.Enable='off';
                app.align_trans_beam.Enable='on';
                app.sum_patterns.Enable='on';
                app.all_patterns.Enable='on';
                app.conv_angle.Enable='on';app.conv_angle_label.Enable='on';
                app.display_images.Enable='off';
                app.align_scan_rot.Enable='off';
                app.import_data.Enable='off';
                
                app.align_trans_beam.Text='Finish Alignment';
                
                clear_detectors(app);
                plot_circle(app,'disk_edge',app.detector_layout,app.radius,app.center,'b')
            end
        end

        % Value changed function: conv_angle
        function alpha_Callback(app, event)
            app.alpha = app.conv_angle.Value;
            app.mrad_per_pix=app.alpha/app.radius;
            app.rmax=min(app.nopiy-app.center0(1),app.nopix-app.center0(2));
            
            app.adf_inner.Limits=[0,app.rmax];
            app.adf_inner.Value=app.adf_inner_angle;
            app.adf_inner_value.Value=app.adf_inner_angle*app.mrad_per_pix;
            
            app.adf_inner.Limits=[0,app.rmax];
            app.adf_outer.Value=app.adf_outer_angle;
            app.adf_outer_value.Value=app.adf_outer_angle*app.mrad_per_pix;
        end

        % Callback function: adf_outer, adf_outer, adf_outer_minus, 
        % adf_outer_plus, adf_outer_value
        function adf_outer_Callback(app, event)
            if strcmp(event.Source.Type,'uislider')
                if event.Value<app.adf_inner.Value
                    app.adf_outer.Value=app.adf_inner.Value;
                    app.adf_outer_angle=app.adf_inner.Value;
                else
                    app.adf_outer_angle=event.Value;
                end
                app.adf_outer_value.Value=app.adf_outer_angle*app.mrad_per_pix;
            elseif strcmp(event.Source.Type,'uinumericeditfield')
                if event.Value>app.rmax*app.mrad_per_pix
                    app.adf_outer.Value=app.rmax*app.mrad_per_pix;
                elseif event.Value<app.adf_inner_value.Value
                    app.adf_outer.Value=app.adf_inner.Value;
                    app.adf_outer_value.Value=app.adf_inner.Value*app.mrad_per_pix;
                else
                    app.adf_outer.Value=event.Value/app.mrad_per_pix;
                end
                app.adf_outer_angle=app.adf_outer.Value;
            elseif strcmp(event.Source.Tag,'adf_outer_minus')
                if app.adf_outer.Value-0.25>=app.adf_inner.Value
                    app.adf_outer.Value=app.adf_outer.Value-0.25;
                    app.adf_outer_value.Value=app.adf_outer_value.Value-0.25*app.mrad_per_pix;
                    app.adf_outer_angle=app.adf_outer.Value;
                else
                    app.adf_outer.Value=app.adf_inner.Value;
                    app.adf_outer_value.Value=app.adf_inner_value.Value;
                    app.adf_outer_angle=app.adf_inner.Value;
                end
            elseif strcmp(event.Source.Tag,'adf_outer_plus')
                if app.adf_outer.Value+0.25<app.rmax
                    app.adf_outer.Value=app.adf_outer.Value+0.25;
                    app.adf_outer_value.Value=app.adf_outer_value.Value+0.25*app.mrad_per_pix;
                    app.adf_outer_angle=app.adf_outer.Value;
                else
                    app.adf_outer.Value=app.rmax;
                    app.adf_outer_value.Value=app.rmax*app.mrad_per_pix;
                    app.adf_outer_angle=app.rmax;
                end
            end

            plot_detector(app);
        end

        % Callback function: det_angle, det_angle, det_angle_value, 
        % det_rot_minus, det_rot_plus
        function det_angle_Callback(app, event)
            if strcmp(event.Source.Type,'uislider')
                app.det_angle.Value=event.Value;
                app.det_angle_value.Value=rad2deg(app.det_angle.Value);
            elseif strcmp(event.Source.Type,'uinumericeditfield')
                app.det_angle_value.Value=app.det_angle_value.Value+...
                                          360.*(app.det_angle_value.Value<-180)-...
                                          360.*(app.det_angle_value.Value>180);
                app.det_angle.Value=deg2rad(event.Value);
            elseif strcmp(event.Source.Tag,'det_rot_minus')
                app.det_angle_value.Value=app.det_angle_value.Value-0.25+...
                                          360.*(app.det_angle_value.Value-0.25<-180);
                app.det_angle.Value=deg2rad(app.det_angle_value.Value);
            elseif strcmp(event.Source.Tag,'det_rot_plus')
                app.det_angle_value.Value
                app.det_angle_value.Value=app.det_angle_value.Value+0.25+...
                                          360.*(app.det_angle_value.Value+0.25>180);
                app.det_angle.Value=deg2rad(app.det_angle_value.Value);
            end
            
            plot_detector(app)
        end

        % Value changed function: nseg
        function change_segments_Callback(app, event)
            if app.nseg.Value>app.nseg.Limits(2)
                app.nseg.Value=app.nseg.Limits(2);
            elseif app.nseg.Value<1
                app.nseg.Value=1;
            end
            app.nseg.Value=round(app.nseg.Value);
            
            plot_detector(app)
        end

        % Button pushed function: display_images
        function display_images_Callback(app, event)
            app.save_images.Enable='on';

            integrate_masks(app)
            
            if app.AnnularRoundButton.Value==1 || app.VirtualApertureButton.Value==1
                display_bfdf(app)
                app.VectorButton.Enable='off';app.MagnitudeButton.Enable='off';app.AngleButton.Enable='off';
            elseif app.SegmentedButton.Value==1
                display_DPC(app)
                set(app.VectorMenuPanel.Children,'Enable','on');
                app.VectorButton.Enable='on';app.MagnitudeButton.Enable='on';app.AngleButton.Enable='on';
                set(app.VectorMenuPanel.Children,'Enable','on');
                app.dpc_dropdown1.Visible='on';app.dpc_dropdown2.Visible='on';
                plotVectorField(app);
            elseif app.CenterofMassButton.Value==1
                display_COM(app)
                set(app.VectorMenuPanel.Children,'Enable','on');
                app.VectorButton.Enable='on';app.MagnitudeButton.Enable='on';app.AngleButton.Enable='on';
                plotVectorField(app);
            end
            
            app.image_choices(2,3)={'app.adf'};
            imagesc(app.adf_image,app.adf);
            set(app.adf_image,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'Tag','adf','YDir','normal','XLim',[1 app.framesx],'YLim',[1 app.framesy]);
            colormap(app.adf_image,gray);
            caxis(app.adf_image,[min(app.adf(:)) max(app.adf(:))]);
        end

        % Button pushed function: save_images
        function save_images_Callback(app, event)
            save4Dimages(app);
        end

        % Value changed function: ZoomButton_trans, ZoomButton_vect
        function zoomIn(app, event)
            if strcmp(event.Source.Tag,'ZoomButton_vect')
                if app.ZoomButton_vect.Value==1
                    zoom(app.vectorField,'on');
                    pan(app.vectorField,'off');
                    app.PanButton_vect.Value=0;
                else
                    zoom(app.vectorField,'off');
                end
            elseif strcmp(event.Source.Tag,'ZoomButton_trans')
                if app.ZoomButton_trans.Value==1
                    zoom(app.detector_layout,'on');
                    pan(app.detector_layout,'off');
                    app.PanButton_trans.Value=0;
                else
                    zoom(app.detector_layout,'off');
                end
            end
        end

        % Button pushed function: ResetZoomButton_trans, 
        % ResetZoomButton_vect
        function resetZoom(app, event)
            if strcmp(event.Source.Tag,'ResetZoomButton_vect')
                app.ax_limits=[1 app.framesx 1 app.framesy];
                axis(app.vectorField,app.ax_limits);
                app.PanButton_vect.Value=0;
                app.ZoomButton_vect.Value=0;
                zoom(app.vectorField,'off');
                pan(app.vectorField,'off');
            elseif strcmp(event.Source.Tag,'ResetZoomButton_trans')
                app.dp_limits=[1 app.nopix 1 app.nopiy];
                axis(app.detector_layout,app.dp_limits);
                app.PanButton_trans.Value=0;
                app.ZoomButton_trans.Value=0;
                zoom(app.detector_layout,'off');
                pan(app.detector_layout,'off');
            end
        end

        % Value changed function: PanButton_trans, PanButton_vect
        function pan(app, event)
            if strcmp(event.Source.Tag,'PanButton_vect')
                if app.PanButton_vect.Value==1
                    pan(app.vectorField,'on');
                    zoom(app.vectorField,'off');
                    app.ZoomButton_vect.Value=0;
                else
                    pan(app.vectorField,'off');
                end
            elseif strcmp(event.Source.Tag,'PanButton_trans')
                if app.PanButton_trans.Value==1
                    pan(app.detector_layout,'on');
                    zoom(app.detector_layout,'off');
                    app.ZoomButton_trans.Value=0;
                else
                    pan(app.detector_layout,'off');
                end
            end
        end

        % Selection changed function: vectButton_group
        function vectButton_radio(app, event)
            app.ax_limits=[app.vectorField.XLim app.vectorField.YLim];
            app.vectorField.NextPlot='replace';
            if strcmp(event.Source.SelectedObject.Tag,'VectorButton')
                imagesc(app.vectorField,app.dpc_angle,'AlphaData',app.dpc_mag_norm);app.vectorField.Visible='on';
                set(app.vectorField,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'Tag','vectorField','YDir','normal','Color','k');
                colormap(app.vectorField,hsv);
                app.color='w';
            elseif strcmp(event.Source.SelectedObject.Tag,'AngleButton')
                app.vectorField.NextPlot='replace';
                imagesc(app.vectorField,app.dpc_angle);app.vectorField.Visible='on';
                set(app.vectorField,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'Tag','vectorField','YDir','normal','Color','k');
                colormap(app.vectorField,hsv);
                app.color='w';
            else
                imagesc(app.vectorField,app.dpc_mag);app.vectorField.Visible='on';
                set(app.vectorField,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'Tag','vectorField','YDir','normal');
                colormap(app.vectorField,gray);
                app.color='r';
            end
            
            set(app.vectorField,'NextPlot','add');
            app.vectors=quiver(1:app.subsample:app.framesx,...
                               1:app.subsample:app.framesy,...
                               -app.images.ac(1:app.subsample:end,1:app.subsample:end)./app.subsample,...
                               -app.images.bd(1:app.subsample:end,1:app.subsample:end)./app.subsample,...
                               1/app.subsample,app.color,'Parent', app.vectorField);%'LineWidth',2,
            set(app.vectorField,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'Tag','vectorField','YDir','normal','NextPlot','replace');
            axis(app.vectorField,app.ax_limits);
        end

        % Value changed function: resampleDropDown
        function subsampleCallback(app, event)
            app.subsample=find(ismember(app.resampleDropDown.Items,app.resampleDropDown.Value));
            if find(ismember(app.resampleDropDown.Items,app.resampleDropDown.Value))>2
                app.subsample=2^(app.subsample-2);
            else
                app.subsample=1;
            end
            
            try delete(app.vectors);delete(get(app.vectors,'Children')); end
            
            app.ax_limits=[app.vectorField.XLim app.vectorField.YLim];
            set(app.vectorField,'NextPlot','add');
            app.vectors=quiver(1:app.subsample:app.framesx,...
                               1:app.subsample:app.framesy,...
                               -app.images.ac(1:app.subsample:end,1:app.subsample:end)./app.subsample,...
                               -app.images.bd(1:app.subsample:end,1:app.subsample:end)./app.subsample,...
                               1/app.subsample,app.color,'Parent', app.vectorField);%'LineWidth',2,
            set(app.vectorField,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'Tag','vect_map','YDir','normal','NextPlot','replace');
            axis(app.vectorField,app.ax_limits);
        end

        % Value changed function: nrung
        function nrung_Callback(app, event)
            plot_detector(app);
        end

        % Selection changed function: DetectorTypeButtonGroup
        function det_type_Callback(app, event)
            clear_plots(app);
            app.images={};
            if app.SegmentedButton.Value==1
                app.outputs_tabgroup.SelectedTab=app.dpc_tab;
                set(app.ap_alignPanel.Children,'Enable','off');
                set(app.detector_panel.Children,'Enable','on');
                app.intermediate_data_tabgroup.SelectedTab=app.ColorwheelTab;
                app.nseg.Value=4;
                app.nrung.Value=1;
                app.CartesianButton.Enable='off';app.PolarButton.Enable='off';
                app.aperture_symmetry.Enable='off';
                set(app.ap_alignPanel.Children,'Enable','off');
                app.ImageDropDown.Items={'Diffraction Pattern';
                                         'Sum Image';
                                         'DPC X';
                                         'DPC Y';
                                         'DPC Magnitude'};
            elseif app.AnnularRoundButton.Value==1
                set(app.detector_panel.Children,'Enable','on');
                app.nseg.Enable='off';app.nseg_label.Enable='off';
                app.nrung.Enable='off';app.ofRungsLabel.Enable='off';
                app.det_angle.Enable='off';app.det_angle_label.Enable='off';
                app.det_angle_value.Enable='off';app.det_rot_plus.Enable='off';app.det_rot_minus.Enable='off';
                app.nrung.Value=1;
                app.nseg.Value=1;
                app.outputs_tabgroup.SelectedTab=app.bfdf_tab;
                app.CartesianButton.Enable='off';app.PolarButton.Enable='off';
                app.aperture_symmetry.Enable='off';
                set(app.ap_alignPanel.Children,'Enable','off');
                app.outputs_tabgroup.SelectedTab=app.bfdf_tab;
                app.intermediate_data_tabgroup.SelectedTab=app.adf_tab;
                app.ImageDropDown.Items={'Diffraction Pattern';
                                         'Sum Image';
                                         'BF/DF'};
            elseif app.VirtualApertureButton.Value==1
                set(app.detector_panel.Children,'Enable','off');
                app.CartesianButton.Enable='on';app.PolarButton.Enable='on';
                app.aperture_symmetry.Enable='on';
                app.outputs_tabgroup.SelectedTab=app.bfdf_tab;
                app.intermediate_data_tabgroup.SelectedTab=app.AlignVirtualApertureTab;
                set(app.ap_alignPanel.Children,'Enable','on');
                set(app.aperture_size,'Limits',[0 app.rmax/2],'Value',app.radius)
                app.ap_size_text.Value=app.radius*app.mrad_per_pix;
                app.ImageDropDown.Items={'Diffraction Pattern';
                                         'Sum Image';
                                         'BF/DF'};
                app.aperture_symmetry.Value=1;
                app.aperture_x.Value=0;app.aperture_y.Value=0;
            elseif app.CenterofMassButton.Value==1
                app.outputs_tabgroup.SelectedTab=app.com_tab;
                set(app.ap_alignPanel.Children,'Enable','off');
                set(app.detector_panel.Children,'Enable','on');
                app.intermediate_data_tabgroup.SelectedTab=app.ColorwheelTab;
                app.nseg.Value=1;
                app.nrung.Value=1;
                app.CartesianButton.Enable='off';app.PolarButton.Enable='off';
                app.aperture_symmetry.Enable='off';
                set(app.ap_alignPanel.Children,'Enable','off');
                app.ImageDropDown.Items={'Diffraction Pattern';
                                         'Sum Image';
                                         'COM X';
                                         'COM Y';
                                         'COM Magnitude'};
            end
            
            % Temporarily plot a detector sum image in place of the BF/DF image 
            imagesc(app.adf_image,app.sum_image);
            set(app.adf_image,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'CLim',[min(app.sum_image(:)) max(app.sum_image(:))],'Tag','adf_image','YDir','normal');
            axis(app.adf_image,app.ax_limits);
            colormap(app.adf_image,gray);
            app.adf_tab.Title='Sum Image';
            
            plot_detector(app);
        end

        % Callback function: ap_left_rot, ap_right_rot, ap_rotAngle, 
        % ap_size_minus, ap_size_plus, ap_size_text, ap_x_minus, 
        % ap_x_plus, ap_x_reset, ap_y_minus, ap_y_plus, ap_y_reset, 
        % aperture_size, aperture_size, aperture_symmetry, 
        % aperture_x, aperture_x, aperture_x_text, aperture_y, 
        % aperture_y, aperture_y_text
        function virtual_aperture(app, event)
            % On symmetry # change (ignore symmetry value of 5)
            if strcmp(event.Source.Type,'uispinner')
                if event.Value==5 && event.PreviousValue<5
                    app.aperture_symmetry.Value=6;
                elseif event.Value==5 && event.PreviousValue==6
                    app.aperture_symmetry.Value=4;
                end
            end
            
            % Drop down
            rot_value=deg2rad(str2double(app.ap_rotAngle.Value));
            
            % Slider actions
            if strcmp(event.Source.Type,'uislider')
                if app.CartesianButton.Value==1
                    if strcmp(event.Source.Tag,'aperture_x') % x
                        app.ap_center=app.center+[event.Value app.aperture_y.Value];
                        app.aperture_x_text.Value=event.Value*app.mrad_per_pix;
                    elseif strcmp(event.Source.Tag,'aperture_y') % y
                        app.ap_center=app.center+[app.aperture_x.Value event.Value];
                        app.aperture_y_text.Value=event.Value*app.mrad_per_pix;
                    elseif strcmp(event.Source.Tag,'aperture_size') % size
                        app.ap_radius=event.Value;
                        app.ap_size_text.Value=event.Value*app.mrad_per_pix;
                    end
                elseif app.PolarButton.Value==1
                    if strcmp(event.Source.Tag,'aperture_rad') % distance
                        app.ap_center=app.center+event.Value*[cos(app.aperture_y.Value) sin(app.aperture_y.Value)];
                        app.aperture_x_text.Value=event.Value*app.mrad_per_pix;
                    elseif strcmp(event.Source.Tag,'aperture_ang') % angle
                        app.ap_center=app.center+app.aperture_x.Value*[cos(event.Value) sin(event.Value)];
                        app.aperture_y_text.Value=rad2deg(event.Value);
                    elseif strcmp(event.Source.Tag,'aperture_size') % size
                        app.ap_radius=event.Value;
                        app.ap_size_text.Value=event.Value*app.mrad_per_pix;
                    end
                end
            end
            
            % Button actions
            if strcmp(event.Source.Type,'uibutton')
                if strcmp(event.Source.Tag,'ap_x_plus')
                    app.ap_center=app.ap_center+[0.25 0].*(app.aperture_x.Value+0.25<=app.aperture_x.Limits(2)); % && sqrt((app.aperture_x.Value+0.2)^2+()^2)<=min(app.aperture_x.Limit()
                    app.aperture_x.Value=app.aperture_x.Value+0.25.*(app.aperture_x.Value+0.25<=app.aperture_x.Limits(2));
                elseif strcmp(event.Source.Tag,'ap_x_minus')
                    app.ap_center=app.ap_center-[0.25 0].*(app.aperture_x.Value-0.25>=app.aperture_x.Limits(1));
                    app.aperture_x.Value=app.aperture_x.Value-0.25.*(app.aperture_x.Value-0.25>=app.aperture_x.Limits(1));
                elseif strcmp(event.Source.Tag,'ap_y_plus')
                    app.ap_center=app.ap_center+[0 0.25].*(app.aperture_y.Value+0.25<=app.aperture_y.Limits(2));
                    app.aperture_y.Value=app.aperture_y.Value+0.25.*(app.aperture_y.Value+0.25<=app.aperture_y.Limits(2));
                elseif strcmp(event.Source.Tag,'ap_y_minus')
                    app.ap_center=app.ap_center-[0 0.25].*(app.aperture_y.Value-0.25>=app.aperture_y.Limits(1));
                    app.aperture_y.Value=app.aperture_y.Value-0.25.*(app.aperture_y.Value-0.25>=app.aperture_y.Limits(1));
                elseif strcmp(event.Source.Tag,'ap_size_plus')
                    app.ap_radius=app.ap_radius+0.25.*(app.aperture_size.Value+0.25<=app.aperture_size.Limits(2));
                    app.aperture_size.Value=app.aperture_size.Value+0.25.*(app.aperture_size.Value+0.25<=app.aperture_size.Limits(2));
                elseif strcmp(event.Source.Tag,'ap_size_minus')
                    app.ap_radius=app.ap_radius-0.25.*(app.aperture_size.Value-0.25>=0);
                    app.aperture_size.Value=app.aperture_size.Value-0.25.*(app.aperture_size.Value-0.25>0);
                elseif strcmp(event.Source.Tag,'ap_rad_plus')
                    app.aperture_x.Value=app.aperture_x.Value+0.25.*(app.aperture_x.Value+0.25<=app.aperture_x.Limits(2));
                    app.ap_center=app.center+app.aperture_x.Value*[cos(app.aperture_y.Value) sin(app.aperture_y.Value)];
                elseif strcmp(event.Source.Tag,'ap_rad_minus')
                    app.aperture_x.Value=app.aperture_x.Value-0.25.*(app.aperture_x.Value-0.25>=0);
                    app.ap_center=app.center+app.aperture_x.Value*[cos(app.aperture_y.Value) sin(app.aperture_y.Value)];
                elseif strcmp(event.Source.Tag,'ap_rad_reset')
                    app.ap_center=app.center;
                    app.aperture_x.Value=0;
                elseif strcmp(event.Source.Tag,'ap_ang_reset')
                    app.ap_center=app.center+[app.aperture_x.Value 0];
                    app.aperture_y.Value=0;
                elseif strcmp(event.Source.Tag,'ap_ang_plus')
                    app.aperture_y.Value=app.aperture_y.Value+deg2rad(0.25)-range(app.aperture_y.Limits).*(app.aperture_y.Value+deg2rad(0.25)>app.aperture_y.Limits(2));
                    app.ap_center=app.center+app.aperture_x.Value*[cos(app.aperture_y.Value) sin(app.aperture_y.Value)];
                elseif strcmp(event.Source.Tag,'ap_ang_minus')
                    app.aperture_y.Value=app.aperture_y.Value-deg2rad(0.25)+range(app.aperture_y.Limits).*(app.aperture_y.Value-deg2rad(0.25)<app.aperture_y.Limits(1));
                    app.ap_center=app.center+app.aperture_x.Value*[cos(app.aperture_y.Value) sin(app.aperture_y.Value)];
                elseif strcmp(event.Source.Tag,'ap_x_reset')
                    app.ap_center=[app.center(1) app.ap_center(2)];
                    app.aperture_x.Value=0;
                elseif strcmp(event.Source.Tag,'ap_y_reset')
                    app.ap_center=[app.ap_center(1) app.center(2)];
                    app.aperture_y.Value=0;
                elseif strcmp(event.Source.Tag,'ap_left_rot')
                    if app.CartesianButton.Value==1
                        temp_rad=sqrt(sum((app.ap_center-app.center).^2)).*(sqrt(sum((app.ap_center-app.center).^2))<=min(app.aperture_x.Limits(2),app.aperture_y.Limits(2)))+...
                            min(app.aperture_x.Limits(2),app.aperture_y.Limits(2)).*(sqrt(sum((app.ap_center-app.center).^2))>min(app.aperture_x.Limits(2),app.aperture_y.Limits(2)));
                        temp_ang=atan2(app.ap_center(2)-app.center(2),app.ap_center(1)-app.center(1))+rot_value;
                        app.aperture_x.Value=temp_rad*cos(temp_ang);
                        app.aperture_y.Value=temp_rad*sin(temp_ang);
                        app.ap_center=app.center+[app.aperture_x.Value app.aperture_y.Value];
                    elseif app.PolarButton.Value==1
                        app.aperture_y.Value=app.aperture_y.Value+rot_value-2*pi.*(app.aperture_y.Value+rot_value>pi);
                        app.ap_center=app.center+app.aperture_x.Value*[cos(app.aperture_y.Value) sin(app.aperture_y.Value)];
                    end
                elseif strcmp(event.Source.Tag,'ap_right_rot')
                    if app.CartesianButton.Value==1
                        temp_rad=sqrt(sum((app.ap_center-app.center).^2)).*(sqrt(sum((app.ap_center-app.center).^2))<=min(app.aperture_x.Limits(2),app.aperture_y.Limits(2)))+...
                            min(app.aperture_x.Limits(2),app.aperture_y.Limits(2)).*(sqrt(sum((app.ap_center-app.center).^2))>min(app.aperture_x.Limits(2),app.aperture_y.Limits(2)));
                        temp_ang=atan2(app.ap_center(2)-app.center(2),app.ap_center(1)-app.center(1))-rot_value;
                        app.aperture_x.Value=temp_rad*cos(temp_ang);
                        app.aperture_y.Value=temp_rad*sin(temp_ang);
                        app.ap_center=app.center+[app.aperture_x.Value app.aperture_y.Value];
                    elseif app.PolarButton.Value==1
                        app.aperture_y.Value=app.aperture_y.Value-rot_value+2*pi.*(app.aperture_y.Value-rot_value<-pi);
                        app.ap_center=app.center+app.aperture_x.Value*[cos(app.aperture_y.Value) sin(app.aperture_y.Value)];
                    end
                end
                
                if app.CartesianButton.Value==1
                    app.aperture_x_text.Value=app.aperture_x.Value*app.mrad_per_pix;
                    app.aperture_y_text.Value=app.aperture_y.Value*app.mrad_per_pix;
                    app.ap_size_text.Value=app.aperture_size.Value*app.mrad_per_pix;
                elseif app.PolarButton.Value==1
                    app.aperture_x_text.Value=app.aperture_x.Value*app.mrad_per_pix;
                    app.aperture_y_text.Value=rad2deg(app.aperture_y.Value);
                    app.ap_size_text.Value=app.aperture_size.Value*app.mrad_per_pix;
                end
            end
            
            % Textbox actions
            if strcmp(event.Source.Type,'uinumericeditfield')
                % Make sure that the X value is between the limits, or set it equal to the closest limit value
                if (app.aperture_x_text.Value/app.mrad_per_pix>=app.aperture_x.Limits(1)) && (app.aperture_x_text.Value/app.mrad_per_pix<=app.aperture_x.Limits(2))
                    app.aperture_x.Value=app.aperture_x_text.Value/app.mrad_per_pix;
                else
                    [~,indexOf]=min(app.aperture_x.Value/app.mrad_per_pix-app.aperture_x.Limits);
                    app.aperture_x.Limits
                    app.aperture_x.Limits(indexOf)
                    app.aperture_x.Value=app.aperture_x.Limits(indexOf);
                    app.aperture_x_text.Value=app.aperture_x.Limits(indexOf)*app.mrad_per_pix;
                end
                
                % Make sure that the SIZE value is between the limits, or set it equal to the closest limit value
                if (app.ap_size_text.Value/app.mrad_per_pix>=app.aperture_size.Limits(1)) && (app.ap_size_text.Value/app.mrad_per_pix<=app.aperture_size.Limits(2))
                    app.aperture_size.Value=app.ap_size_text.Value/app.mrad_per_pix;
                else
                    [~,indexOf]=min(app.ap_size_text.Value/app.mrad_per_pix-app.aperture_size.Limits);
                    app.aperture_size.Value=app.aperture_size.Limits(indexOf);
                    app.ap_size_text.Value=app.aperture_size.Limits(indexOf);
                end
                app.ap_radius=app.aperture_size.Value;
                
                % Make sure that the Y or ANGLE value is between the limits, or set it equal to the closest limit value
                if strcmp(event.Source.Tag,'aperture_y_text') % y or angle
                    if app.CartesianButton.Value==1 % y
                        if (app.aperture_y_text.Value/app.mrad_per_pix>=app.aperture_y.Limits(1)) && (app.aperture_y_text.Value/app.mrad_per_pix<=app.aperture_y.Limits(2))
                            app.aperture_y.Value=app.aperture_y_text.Value/app.mrad_per_pix;
                        else
                            [~,indexOf]=min(app.aperture_y.Value/app.mrad_per_pix-app.aperture_y.Limits);
                            app.aperture_y.Value=app.aperture_y.Limits(indexOf);
                            app.aperture_y_text.Value=app.aperture_y.Limits(indexOf)*app.mrad_per_pix;
                        end
                    elseif app.PolarButton.Value==1 % angle
                        if (deg2rad(app.aperture_y_text.Value)>=app.aperture_y.Limits(1) && deg2rad(app.aperture_y_text.Value)<=app.aperture_y.Limits(2))
                            app.aperture_y.Value=deg2rad(app.aperture_y_text.Value);
                        else
                            app.aperture_y.Value=deg2rad(mod(app.aperture_y_text.Value,360))+2*pi.*(deg2rad(mod(app.aperture_y_text.Value,360))<-pi)-2*pi.*(deg2rad(mod(app.aperture_y_text.Value,360))>pi);
                            app.aperture_y_text.Value=rad2deg(app.aperture_y.Value);
                        end
                    end
                end
            end
            
            % Update Aperture Position
            %if app.CartesianButton.Value==1
                %if sqrt(app.aperture_x.Value^2+app.aperture_y.Value^2) > min(abs(app.aperture_x.Limits),abs(app.aperture_y.Limits))
                %    [app.aperture_x.Value,app.aperture_y.Value]=pol2cart(atan2(app.aperture_y.Value,app.aperture_x.Value),min(abs(app.aperture_x.Limits),abs(app.aperture_y.Limits)));
                %end
            %    app.ap_center=app.center+[app.aperture_x.Value app.aperture_y.Value];
            %elseif app.PolarButton.Value==1
            %    app.ap_center=app.center+app.aperture_x.Value*[cos(app.aperture_y.Value) sin(app.aperture_y.Value)];
            %end
            
            plot_detector(app);
        end

        % Selection changed function: AlignmentModeButtonGroup
        function ap_align_mode_Callback(app, event)
            if app.CartesianButton.Value==1
                app.ApertureDistanceLabel.Text='Aperture X';
                app.ApertureRotationLabel.Text='Aperture Y';
                app.aperture_y_Label.Text='mrad';
                app.aperture_x.Tag='aperture_x';
                app.aperture_y.Tag='aperture_y';
                app.ap_x_minus.Tag='ap_x_minus';
                app.ap_x_plus.Tag='ap_x_plus';
                app.ap_x_reset.Tag='ap_x_reset';
                app.ap_y_minus.Tag='ap_y_minus';
                app.ap_y_plus.Tag='ap_y_plus';
                app.ap_y_reset.Tag='ap_y_reset';
                app.aperture_x.Limits=[-app.nopix/2 app.nopix/2];
                app.aperture_y.Limits=[-app.nopiy/2 app.nopiy/2];
                app.aperture_x.Value=app.aperture_x.Value*cos(app.aperture_y.Value);
                app.aperture_x_text.Value=app.aperture_x.Value*app.mrad_per_pix;
                app.aperture_y.Value=app.aperture_x.Value*sin(app.aperture_y.Value);
                app.aperture_y_text.Value=app.aperture_y.Value*app.mrad_per_pix;
            else
                app.ApertureDistanceLabel.Text='Aperture Radius';
                app.ApertureRotationLabel.Text='Aperture Angle';
                app.aperture_y_Label.Text='°';
                app.aperture_x.Limits=[0 app.nopix/2];
                app.aperture_y.Limits=[-pi pi];
                app.aperture_x.Value=min(sqrt(sum((app.ap_center-app.center).^2)),app.aperture_x.Limits(2));
                app.aperture_x_text.Value=app.aperture_x.Value*app.mrad_per_pix;
                app.aperture_y.Value=atan2(app.ap_center(2)-app.center(2),app.ap_center(1)-app.center(1));
                app.aperture_y_text.Value=rad2deg(app.aperture_y.Value);
                app.aperture_x.Tag='aperture_rad';
                app.aperture_y.Tag='aperture_ang';
                app.ap_x_minus.Tag='ap_rad_minus';
                app.ap_x_plus.Tag='ap_rad_plus';
                app.ap_x_reset.Tag='ap_rad_reset';
                app.ap_y_minus.Tag='ap_ang_minus';
                app.ap_y_plus.Tag='ap_ang_plus';
                app.ap_y_reset.Tag='ap_ang_reset';
            end
            plot_detector(app);
        end

        % Callback function: scan_rot, scan_rot, scan_rot_minus, 
        % scan_rot_mirror, scan_rot_plus, scan_rot_value
        function scan_rot_Callback(app, event)
         if strcmp(event.Source.Type,'uislider')
             app.scan_rot_angle=event.Value;
             app.scan_rot_value.Value=app.scan_rot_angle*180/pi;
         elseif strcmp(event.Source.Tag,'scan_rot_plus')
             app.scan_rot_angle=app.scan_rot_angle+pi/45-2*pi.*(app.scan_rot_angle+pi/45>180);
             app.scan_rot_value.Value=rad2deg(app.scan_rot_angle);
             app.scan_rot.Value=app.scan_rot_angle;
         elseif strcmp(event.Source.Tag,'scan_rot_minus')
             app.scan_rot_angle=app.scan_rot_angle-pi/45+2*pi.*(app.scan_rot_angle-pi/45<-180);
             app.scan_rot_value.Value=rad2deg(app.scan_rot_angle);
             app.scan_rot.Value=app.scan_rot_angle;
         elseif strcmp(event.Source.Type,'uinumericeditfield')
             app.scan_rot_angle=deg2rad(event.Value);
             app.scan_rot.Value=deg2rad(event.Value);
         end
         
         R = [cos(app.scan_rot_angle) sin(app.scan_rot_angle); -sin(app.scan_rot_angle) cos(app.scan_rot_angle)];
         
         scan_x_vect=[app.rmax/2 0]*R;
         text_x_pos=3*app.rmax/4*[cos(app.scan_rot_angle) sin(app.scan_rot_angle)]+app.center;
         
         if app.scan_rot_mirror.Value==0
             scan_y_vect=[0 app.rmax/2]*R;
             text_y_pos=3*app.rmax/4*[cos(app.scan_rot_angle+pi/2) sin(app.scan_rot_angle+pi/2)]+app.center;
         else
             scan_y_vect=[0 -app.rmax/2]*R;
             text_y_pos=3*app.rmax/4*[cos(app.scan_rot_angle-pi/2) sin(app.scan_rot_angle-pi/2)]+app.center;
         end
         
         try delete(app.scan_x); end
         try delete(app.text_x); end
         try delete(app.scan_y); end
         try delete(app.text_y); end
         
         app.scan_x=quiver(app.detector_layout,app.center(1),app.center(2),scan_x_vect(1),scan_x_vect(2),'LineWidth',4,'Color',[252 99 94]/255);
         app.scan_y=quiver(app.detector_layout,app.center(1),app.center(2),scan_y_vect(1),scan_y_vect(2),'LineWidth',4,'Color',[252 99 94]/255);
         
         app.text_x=text(app.detector_layout,text_x_pos(1),text_x_pos(2),'scan_x','Color',[252 99 94]/255,'FontWeight','bold','FontSize',16,'HorizontalAlignment','center');
         app.text_y=text(app.detector_layout,text_y_pos(1),text_y_pos(2),'scan_y','Color',[252 99 94]/255,'FontWeight','bold','FontSize',16,'HorizontalAlignment','center');
        end

        % Button pushed function: align_scan_rot
        function align_scan_rot_Callback(app, event)
            if strcmp(event.Source.Text,'Finish Alignment')
                app.align_scan_rot.Text='Align Scan Direction';
                
                try delete(app.scan_x); end
                try delete(app.scan_y); end
                try delete(app.text_x); end
                try delete(app.text_y); end
                
                app.display_images.Enable='on';
                set(app.detector_panel.Children,'Enable','on')
                set(app.trans_panel.Children,'Enable','off')
                set(app.scan_rot_panel.Children,'Enable','off')
                app.conv_angle.Enable='on';app.conv_angle_label.Enable='on';
                app.SegmentedButton.Enable='on';
                app.AnnularRoundButton.Enable='on';
                app.VirtualApertureButton.Enable='on';
                app.CenterofMassButton.Enable='on';
                app.align_trans_beam.Enable='on';
                app.import_data.Enable='on';
                
                app.controlTab.SelectedTab=app.DetectorsTab;
                app.SegmentedButton.Value=1;
                app.det_angle.Value=app.scan_rot_angle;
                app.det_angle_value.Value=app.scan_rot_value.Value;
                det_type_Callback(app,event);
            else
                set(app.detector_panel.Children,'Enable','off')
                set(app.trans_panel.Children,'Enable','off')
                set(app.scan_rot_panel.Children,'Enable','on')
                app.align_trans_beam.Enable='off';
                app.import_data.Enable='off';
                
                app.sum_patterns.Enable='on';
                app.all_patterns.Enable='on';
                app.conv_angle.Enable='on';app.conv_angle_label.Enable='on';
                app.display_images.Enable='off';
                
                app.align_scan_rot.Text='Finish Alignment';
                
                clear_detectors(app);
                app.detector_layout.NextPlot='add';
                scan_rot_Callback(app,event);
            end
        end

        % Callback function: ImageDropDown, brightness, brightness, 
        % brightness_reset, contrast, contrast, contrast_reset, 
        % gamma, gamma, gamma_reset
        function image_controls(app, event)
            % Initial contrast will be set as the total range of min:max
            % A change in contrast will expand or contract the width of the range
            % Initial brightness will be also set as the total range of min:max
            % A change in brightness will result in shift of the range up or down
            % Initial gamma is set to 1
            % A change in gamma will result in a powerlaw change in contrast I=I0^gamma
            set(app.histogram_panel.Children,'Enable','on');
            app.ImageDropDown.Enable='on';
            

            if strcmp(event.Source.Type,'uidropdown')
                app.brightness.Value=app.image_choices{strmatch(app.ImageDropDown.Value,app.image_choices(:,1)),4};
                app.contrast.Value=app.image_choices{strmatch(app.ImageDropDown.Value,app.image_choices(:,1)),5};
                app.gamma.Value=app.image_choices{strmatch(app.ImageDropDown.Value,app.image_choices(:,1)),6};
            end

            app.ax_choice=app.image_choices{strmatch(app.ImageDropDown.Value,app.image_choices(:,1)),2};
            app.im_choice=app.image_choices{strmatch(app.ImageDropDown.Value,app.image_choices(:,1)),3};
            temp_ax_limits=axis(eval(app.ax_choice));
            
            h=histogram(app.histogram_plot,eval(app.im_choice),100);

            if strcmp(event.Source.Tag,'brightness') 
                app.brightness.Value=event.Value;
            elseif strcmp(event.Source.Tag,'brightness_reset')
                app.brightness.Value=0;
            elseif strcmp(event.Source.Tag,'contrast')
                app.contrast.Value=event.Value;
            elseif strcmp(event.Source.Tag,'contrast_reset')
                app.contrast.Value=0;
            elseif strcmp(event.Source.Tag,'gamma')
                app.gamma.Value=event.Value;
                imagesc(eval(app.ax_choice),imadjust(eval(app.im_choice)/max(max(eval(app.im_choice))),[],[],app.gamma.Value)*max(max(eval(app.im_choice))));
                set(eval(app.ax_choice),'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'YDir','normal');
                %if strcmp('Diffraction Pattern',app.ImageDropDown.Value)
                %    axis(eval(app.ax_choice),app.dp_limits);
                %else
                %    axis(eval(app.ax_choice),app.ax_limits);
                %end
                axis(eval(app.ax_choice),temp_ax_limits);
                colormap(app.detector_layout,gray);
                if strcmp('DPC Vector',app.ImageDropDown.Value) || strcmp('COM Vector',app.ImageDropDown.Value)
                    colormap(eval(app.ax_choice),hsv);
                else
                    colormap(eval(app.ax_choice),gray);
                end
            elseif strcmp(event.Source.Tag,'gamma_reset')
                app.gamma.Value=1;
                imagesc(eval(app.ax_choice),eval(app.im_choice));
                set(eval(app.ax_choice),'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'YDir','normal');
                %if strcmp('Diffraction Pattern',app.ImageDropDown.Value)
                %    axis(eval(app.ax_choice),app.dp_limits);
                %else
                %    axis(eval(app.ax_choice),app.ax_limits);
                %end
                axis(eval(app.ax_choice),temp_ax_limits);
                if strcmp('DPC Vector',app.ImageDropDown.Value) || strcmp('COM Vector',app.ImageDropDown.Value)
                    colormap(eval(app.ax_choice),hsv);
                else
                    colormap(eval(app.ax_choice),gray);
                end
            end
            
            app.image_choices(strmatch(app.ImageDropDown.Value,app.image_choices(:,1)),4:6)={app.brightness.Value
                                                                                             app.contrast.Value
                                                                                             app.gamma.Value};
            
            axis(app.histogram_plot,[min(min(eval(app.im_choice)))-0.05*range(eval(strcat(app.im_choice,'(:)'))),...
                                     max(max(eval(app.im_choice)))+0.05*range(eval(strcat(app.im_choice,'(:)'))),...
                                     0,...
                                     mean(h.BinCounts)+std(h.BinCounts)]);
            
            set(eval(app.ax_choice),'CLim',[min(min(eval(app.im_choice))) max(max(eval(app.im_choice)))]-...
                                            app.brightness.Value*range(eval(strcat(app.im_choice,'(:)')))-...
                                            app.contrast.Value*range(eval(strcat(app.im_choice,'(:)')))*[-1 1]);
            
            try delete(app.gamma_text); end
            app.gamma_text=text(app.histogram_plot,double(0.2*range(app.histogram_plot.XLim)+app.histogram_plot.XLim(1)),double(0.8*range(app.histogram_plot.YLim)),strcat('\gamma=',num2str(app.gamma.Value,2)),'FontSize',16);
            
            app.histogram_plot.NextPlot='add';
            plot(app.histogram_plot,min(get(eval(app.ax_choice),'CLim'))*[1 1],app.histogram_plot.YLim,'LineWidth',2,'Color','g');
            plot(app.histogram_plot,max(get(eval(app.ax_choice),'CLim'))*[1 1],app.histogram_plot.YLim,'LineWidth',2,'Color','g');
            plot(app.histogram_plot,linspace(min(get(eval(app.ax_choice),'CLim')),max(get(eval(app.ax_choice),'CLim')),100),(linspace(0,app.histogram_plot.YLim(2),100).^app.gamma.Value)./app.histogram_plot.YLim(2)^app.gamma.Value*app.histogram_plot.YLim(2),'Color','k');
            app.histogram_plot.NextPlot='replace';
            
            app.histogram_plot.XTick = [];
            app.histogram_plot.YTick = [];
            if strcmp(app.align_trans_beam.Text,'Align Transmitted Beam')
                plot_detector(app);
            end
        end

        % Value changed function: dpc_dropdown1, dpc_dropdown2
        function dpc_dropdown(app, event)
            if strcmp('dpc_dropdown1',event.Source.Tag)
                if strcmp('DPC Magnitude',event.Value)
                    imagesc(app.vect_mag,app.dpc_mag);
                elseif strcmp('dDPC',event.Value)
                    imagesc(app.vect_mag,app.images.ddpc);
                else
                    imagesc(app.vect_mag,app.images.idpc);
                end
                set(app.vect_mag,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'Tag','vect_mag','YDir','normal','XLim',[1 app.framesx],'YLim',[1 app.framesy]);
                colormap(app.vect_mag,gray);
                app.vect_mag.Visible='on';
            elseif strcmp('dpc_dropdown2',event.Source.Tag)
                if strcmp('DPC Vector',event.Value)
                    app.vect_angle.NextPlot='replace';
                    imagesc(app.vect_angle,app.dpc_angle,'AlphaData',app.dpc_mag_norm);app.vect_angle.Visible='on';
                    colormap(app.vect_angle,hsv);
                elseif strcmp('dDPC',event.Value)
                    imagesc(app.vect_angle,app.images.ddpc);
                    colormap(app.vect_angle,gray);
                else
                    imagesc(app.vect_angle,app.images.idpc);
                    colormap(app.vect_angle,gray);
                end
                set(app.vect_angle,'DataAspectRatio',[1 1 1],'XTick',[],'Ytick',[],'Tag','vect_angle','YDir','normal','Color','k','XLim',[1 app.framesx],'YLim',[1 app.framesy]);
                app.vect_angle.Visible='on';
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Quant4D_Fig = uifigure;
            app.Quant4D_Fig.Name = 'Quant4D';
            app.total_panel = uipanel(app.Quant4D_Fig);
            app.detector_layout = uiaxes(app.total_panel);
            app.detector_layout.XTick = [];
            app.detector_layout.YTick = [];
            app.detector_layout.YTickLabel = {'[ ]'};
            app.detector_layout.Visible = 'off';
            app.stack_slider = uislider(app.total_panel);
            app.stack_slider.MajorTicks = [];
            app.stack_slider.MajorTickLabels = {};
            app.stack_slider.ValueChangedFcn = createCallbackFcn(app, @stack_slider_Callback, true);
            app.stack_slider.ValueChangingFcn = createCallbackFcn(app, @stack_slider_Callback, true);
            app.stack_slider.MinorTicks = [];
            app.stack_slider.Visible = 'off';
            app.intermediate_data_tabgroup = uitabgroup(app.total_panel);
            app.adf_tab = uitab(app.intermediate_data_tabgroup);
            app.adf_tab.Title = 'BF/DF Image';
            app.adf_image = uiaxes(app.adf_tab);
            app.adf_image.XTick = [];
            app.adf_image.YTick = [];
            app.adf_image.YTickLabel = {'[ ]'};
            app.adf_image.ZTick = [];
            app.adf_image.Visible = 'off';
            app.ColorwheelTab = uitab(app.intermediate_data_tabgroup);
            app.ColorwheelTab.Title = 'Colorwheel';
            app.colorwheel_plot = uiaxes(app.ColorwheelTab);
            app.colorwheel_plot.XTick = [];
            app.colorwheel_plot.YTick = [];
            app.colorwheel_plot.YTickLabel = {'[ ]'};
            app.colorwheel_plot.ZTick = [];
            app.colorwheel_plot.Visible = 'off';
            app.AlignVirtualApertureTab = uitab(app.intermediate_data_tabgroup);
            app.AlignVirtualApertureTab.Title = 'Align Virtual Aperture';
            app.AlignmentModeButtonGroup = uibuttongroup(app.AlignVirtualApertureTab);
            app.AlignmentModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ap_align_mode_Callback, true);
            app.AlignmentModeButtonGroup.Title = 'Alignment Mode:';
            app.CartesianButton = uiradiobutton(app.AlignmentModeButtonGroup);
            app.CartesianButton.Enable = 'off';
            app.CartesianButton.Text = 'Cartesian';
            app.PolarButton = uiradiobutton(app.AlignmentModeButtonGroup);
            app.PolarButton.Enable = 'off';
            app.PolarButton.Text = 'Polar';
            app.PolarButton.Value = true;
            app.ApertureSymmetrySpinnerLabel = uilabel(app.AlignVirtualApertureTab);
            app.ApertureSymmetrySpinnerLabel.Enable = 'off';
            app.ApertureSymmetrySpinnerLabel.Text = 'Aperture Symmetry';
            app.aperture_symmetry = uispinner(app.AlignVirtualApertureTab);
            app.aperture_symmetry.Limits = [1 6];
            app.aperture_symmetry.RoundFractionalValues = 'on';
            app.aperture_symmetry.ValueDisplayFormat = '%.0f';
            app.aperture_symmetry.ValueChangedFcn = createCallbackFcn(app, @virtual_aperture, true);
            app.aperture_symmetry.Enable = 'off';
            app.aperture_symmetry.Value = 1;
            app.ap_alignPanel = uipanel(app.AlignVirtualApertureTab);
            app.ApertureDistanceLabel = uilabel(app.ap_alignPanel);
            app.ApertureDistanceLabel.Enable = 'off';
            app.ApertureDistanceLabel.Text = 'Aperture Distance';
            app.aperture_x = uislider(app.ap_alignPanel);
            app.aperture_x.MajorTicks = [];
            app.aperture_x.ValueChangedFcn = createCallbackFcn(app, @virtual_aperture, true);
            app.aperture_x.ValueChangingFcn = createCallbackFcn(app, @virtual_aperture, true);
            app.aperture_x.MinorTicks = [];
            app.aperture_x.Enable = 'off';
            app.ApertureRotationLabel = uilabel(app.ap_alignPanel);
            app.ApertureRotationLabel.Enable = 'off';
            app.ApertureRotationLabel.Text = 'Aperture Rotation';
            app.aperture_y = uislider(app.ap_alignPanel);
            app.aperture_y.Limits = [-100 100];
            app.aperture_y.MajorTicks = [];
            app.aperture_y.ValueChangedFcn = createCallbackFcn(app, @virtual_aperture, true);
            app.aperture_y.ValueChangingFcn = createCallbackFcn(app, @virtual_aperture, true);
            app.aperture_y.MinorTicks = [];
            app.aperture_y.Enable = 'off';
            app.ap_x_plus = uibutton(app.ap_alignPanel, 'push');
            app.ap_x_plus.ButtonPushedFcn = createCallbackFcn(app, @virtual_aperture, true);
            app.ap_x_plus.Icon = 'plus_icon.png';
            app.ap_x_plus.Enable = 'off';
            app.ap_x_plus.Text = '';
            app.ap_x_minus = uibutton(app.ap_alignPanel, 'push');
            app.ap_x_minus.ButtonPushedFcn = createCallbackFcn(app, @virtual_aperture, true);
            app.ap_x_minus.Icon = 'minus_icon.png';
            app.ap_x_minus.Enable = 'off';
            app.ap_x_minus.Text = '';
            app.ap_y_plus = uibutton(app.ap_alignPanel, 'push');
            app.ap_y_plus.ButtonPushedFcn = createCallbackFcn(app, @virtual_aperture, true);
            app.ap_y_plus.Icon = 'plus_icon.png';
            app.ap_y_plus.Enable = 'off';
            app.ap_y_plus.Text = '';
            app.ap_y_minus = uibutton(app.ap_alignPanel, 'push');
            app.ap_y_minus.ButtonPushedFcn = createCallbackFcn(app, @virtual_aperture, true);
            app.ap_y_minus.Icon = 'minus_icon.png';
            app.ap_y_minus.Enable = 'off';
            app.ap_y_minus.Text = '';
            app.ap_x_reset = uibutton(app.ap_alignPanel, 'push');
            app.ap_x_reset.ButtonPushedFcn = createCallbackFcn(app, @virtual_aperture, true);
            app.ap_x_reset.Enable = 'off';
            app.ap_x_reset.Text = '0';
            app.ap_y_reset = uibutton(app.ap_alignPanel, 'push');
            app.ap_y_reset.ButtonPushedFcn = createCallbackFcn(app, @virtual_aperture, true);
            app.ap_y_reset.Enable = 'off';
            app.ap_y_reset.Text = '0';
            app.ap_right_rot = uibutton(app.ap_alignPanel, 'push');
            app.ap_right_rot.ButtonPushedFcn = createCallbackFcn(app, @virtual_aperture, true);
            app.ap_right_rot.Icon = 'right_rotate.png';
            app.ap_right_rot.Enable = 'off';
            app.ap_right_rot.Text = '';
            app.adf_inner_mrad_2 = uilabel(app.ap_alignPanel);
            app.adf_inner_mrad_2.Enable = 'off';
            app.adf_inner_mrad_2.Text = 'mrad';
            app.aperture_x_text = uieditfield(app.ap_alignPanel, 'numeric');
            app.aperture_x_text.ValueDisplayFormat = '%.2f';
            app.aperture_x_text.ValueChangedFcn = createCallbackFcn(app, @virtual_aperture, true);
            app.aperture_x_text.Enable = 'off';
            app.aperture_y_Label = uilabel(app.ap_alignPanel);
            app.aperture_y_Label.Enable = 'off';
            app.aperture_y_Label.Text = '°';
            app.aperture_y_text = uieditfield(app.ap_alignPanel, 'numeric');
            app.aperture_y_text.ValueDisplayFormat = '%.2f';
            app.aperture_y_text.ValueChangedFcn = createCallbackFcn(app, @virtual_aperture, true);
            app.aperture_y_text.Enable = 'off';
            app.ap_left_rot = uibutton(app.ap_alignPanel, 'push');
            app.ap_left_rot.ButtonPushedFcn = createCallbackFcn(app, @virtual_aperture, true);
            app.ap_left_rot.Icon = 'left_rotate.png';
            app.ap_left_rot.Enable = 'off';
            app.ap_left_rot.Text = '';
            app.ap_rotAngle = uidropdown(app.ap_alignPanel);
            app.ap_rotAngle.Items = {'90°', '60°', '45°', '30°', '22.5°'};
            app.ap_rotAngle.ItemsData = {'90', '60', '45', '30', '22.5'};
            app.ap_rotAngle.ValueChangedFcn = createCallbackFcn(app, @virtual_aperture, true);
            app.ap_rotAngle.Enable = 'off';
            app.ap_rotAngle.Value = '90';
            app.ap_size_plus = uibutton(app.ap_alignPanel, 'push');
            app.ap_size_plus.ButtonPushedFcn = createCallbackFcn(app, @virtual_aperture, true);
            app.ap_size_plus.Icon = 'plus_icon.png';
            app.ap_size_plus.Enable = 'off';
            app.ap_size_plus.Text = '';
            app.ap_size_minus = uibutton(app.ap_alignPanel, 'push');
            app.ap_size_minus.ButtonPushedFcn = createCallbackFcn(app, @virtual_aperture, true);
            app.ap_size_minus.Icon = 'minus_icon.png';
            app.ap_size_minus.Enable = 'off';
            app.ap_size_minus.Text = '';
            app.ApertureSizeLabel = uilabel(app.ap_alignPanel);
            app.ApertureSizeLabel.Enable = 'off';
            app.ApertureSizeLabel.Text = 'Aperture Size';
            app.aperture_size = uislider(app.ap_alignPanel);
            app.aperture_size.MajorTicks = [];
            app.aperture_size.ValueChangedFcn = createCallbackFcn(app, @virtual_aperture, true);
            app.aperture_size.ValueChangingFcn = createCallbackFcn(app, @virtual_aperture, true);
            app.aperture_size.MinorTicks = [];
            app.aperture_size.Enable = 'off';
            app.adf_inner_mrad_4 = uilabel(app.ap_alignPanel);
            app.adf_inner_mrad_4.Enable = 'off';
            app.adf_inner_mrad_4.Text = 'mrad';
            app.ap_size_text = uieditfield(app.ap_alignPanel, 'numeric');
            app.ap_size_text.ValueDisplayFormat = '%.2f';
            app.ap_size_text.ValueChangedFcn = createCallbackFcn(app, @virtual_aperture, true);
            app.ap_size_text.Enable = 'off';
            app.xSpinnerLabel = uilabel(app.total_panel);
            app.xSpinnerLabel.Visible = 'off';
            app.xSpinnerLabel.Text = 'x:';
            app.detx = uispinner(app.total_panel);
            app.detx.ValueChangingFcn = createCallbackFcn(app, @stack_slider_Callback, true);
            app.detx.RoundFractionalValues = 'on';
            app.detx.ValueDisplayFormat = '%.0f';
            app.detx.ValueChangedFcn = createCallbackFcn(app, @stack_slider_Callback, true);
            app.detx.Visible = 'off';
            app.detx.Value = 256;
            app.ySpinnerLabel = uilabel(app.total_panel);
            app.ySpinnerLabel.Visible = 'off';
            app.ySpinnerLabel.Text = 'y:';
            app.dety = uispinner(app.total_panel);
            app.dety.ValueChangingFcn = createCallbackFcn(app, @stack_slider_Callback, true);
            app.dety.RoundFractionalValues = 'on';
            app.dety.ValueDisplayFormat = '%.0f';
            app.dety.ValueChangedFcn = createCallbackFcn(app, @stack_slider_Callback, true);
            app.dety.Visible = 'off';
            app.dety.Value = 256;
            app.outputs_tabgroup = uitabgroup(app.total_panel);
            app.bfdf_tab = uitab(app.outputs_tabgroup);
            app.bfdf_tab.Title = 'BF/DF';
            app.bfdf_image = uiaxes(app.bfdf_tab);
            app.bfdf_image.XTick = [];
            app.bfdf_image.XTickLabel = {'[ ]'};
            app.bfdf_image.YTick = [];
            app.bfdf_image.Visible = 'off';
            app.dpc_tab = uitab(app.outputs_tabgroup);
            app.dpc_tab.Title = 'Differential Phase Contrast';
            app.vect_mag = uiaxes(app.dpc_tab);
            xlabel(app.vect_mag, ' ')
            app.vect_mag.XTick = [];
            app.vect_mag.YTick = [];
            app.vect_mag.YTickLabel = {'[ ]'};
            app.vect_mag.ZTick = [];
            app.vect_mag.Visible = 'off';
            app.vect_angle = uiaxes(app.dpc_tab);
            xlabel(app.vect_angle, ' ')
            app.vect_angle.XTick = [];
            app.vect_angle.YTick = [];
            app.vect_angle.YTickLabel = {'[ ]'};
            app.vect_angle.ZTick = [];
            app.vect_angle.Visible = 'off';
            app.seg_ac = uiaxes(app.dpc_tab);
            xlabel(app.seg_ac, 'A-C')
            app.seg_ac.XTick = [];
            app.seg_ac.YTick = [];
            app.seg_ac.YTickLabel = {'[ ]'};
            app.seg_ac.ZTick = [];
            app.seg_ac.Visible = 'off';
            app.seg_bd = uiaxes(app.dpc_tab);
            xlabel(app.seg_bd, 'B-D')
            app.seg_bd.XTick = [];
            app.seg_bd.YTick = [];
            app.seg_bd.YTickLabel = {'[ ]'};
            app.seg_bd.ZTick = [];
            app.seg_bd.Visible = 'off';
            app.dpc_dropdown1 = uidropdown(app.dpc_tab);
            app.dpc_dropdown1.Items = {'dDPC', 'DPC Magnitude', 'iDPC'};
            app.dpc_dropdown1.ValueChangedFcn = createCallbackFcn(app, @dpc_dropdown, true);
            app.dpc_dropdown1.Visible = 'off';
            app.dpc_dropdown1.Value = 'DPC Magnitude';
            app.dpc_dropdown2 = uidropdown(app.dpc_tab);
            app.dpc_dropdown2.Items = {'dDPC', 'DPC Vector', 'iDPC'};
            app.dpc_dropdown2.ValueChangedFcn = createCallbackFcn(app, @dpc_dropdown, true);
            app.dpc_dropdown2.Visible = 'off';
            app.dpc_dropdown2.Value = 'DPC Vector';
            app.com_tab = uitab(app.outputs_tabgroup);
            app.com_tab.Title = 'Center of Mass';
            app.com_x = uiaxes(app.com_tab);
            xlabel(app.com_x, 'COM X')
            app.com_x.XTick = [];
            app.com_x.YTick = [];
            app.com_x.YTickLabel = {'[ ]'};
            app.com_x.ZTick = [];
            app.com_x.Visible = 'off';
            app.com_y = uiaxes(app.com_tab);
            xlabel(app.com_y, 'COM Y')
            app.com_y.XTick = [];
            app.com_y.YTick = [];
            app.com_y.YTickLabel = {'[ ]'};
            app.com_y.ZTick = [];
            app.com_y.Visible = 'off';
            app.com_mag = uiaxes(app.com_tab);
            xlabel(app.com_mag, 'COM Magnitude')
            app.com_mag.XTick = [];
            app.com_mag.YTick = [];
            app.com_mag.YTickLabel = {'[ ]'};
            app.com_mag.ZTick = [];
            app.com_mag.Visible = 'off';
            app.com_angle = uiaxes(app.com_tab);
            xlabel(app.com_angle, 'COM Image')
            app.com_angle.XTick = [];
            app.com_angle.YTick = [];
            app.com_angle.YTickLabel = {'[ ]'};
            app.com_angle.ZTick = [];
            app.com_angle.Visible = 'off';
            app.vectorField_tab = uitab(app.outputs_tabgroup);
            app.vectorField_tab.Title = 'Vector Field';
            app.vectorField = uiaxes(app.vectorField_tab);
            app.vectorField.XTick = [];
            app.vectorField.XTickLabel = {'[ ]'};
            app.vectorField.YTick = [];
            app.vectorField.Visible = 'off';
            app.VectorMenuPanel = uipanel(app.vectorField_tab);
            app.ZoomButton_vect = uibutton(app.VectorMenuPanel, 'state');
            app.ZoomButton_vect.ValueChangedFcn = createCallbackFcn(app, @zoomIn, true);
            app.ZoomButton_vect.Enable = 'off';
            app.ZoomButton_vect.Icon = 'zoom.png';
            app.ZoomButton_vect.Text = '';
            app.PanButton_vect = uibutton(app.VectorMenuPanel, 'state');
            app.PanButton_vect.ValueChangedFcn = createCallbackFcn(app, @pan, true);
            app.PanButton_vect.Enable = 'off';
            app.PanButton_vect.Icon = 'pan.png';
            app.PanButton_vect.Text = '';
            app.ResetZoomButton_vect = uibutton(app.VectorMenuPanel, 'push');
            app.ResetZoomButton_vect.ButtonPushedFcn = createCallbackFcn(app, @resetZoom, true);
            app.ResetZoomButton_vect.Enable = 'off';
            app.ResetZoomButton_vect.Text = 'Reset Zoom';
            app.resampleDropDown = uidropdown(app.VectorMenuPanel);
            app.resampleDropDown.Items = {'Subsample', '1:1', '1:2', '1:4', '1:8', '1:16'};
            app.resampleDropDown.ValueChangedFcn = createCallbackFcn(app, @subsampleCallback, true);
            app.resampleDropDown.Enable = 'off';
            app.resampleDropDown.Value = 'Subsample';
            app.vectButton_group = uibuttongroup(app.vectorField_tab);
            app.vectButton_group.SelectionChangedFcn = createCallbackFcn(app, @vectButton_radio, true);
            app.VectorButton = uiradiobutton(app.vectButton_group);
            app.VectorButton.Enable = 'off';
            app.VectorButton.Text = 'Vector';
            app.VectorButton.Value = true;
            app.MagnitudeButton = uiradiobutton(app.vectButton_group);
            app.MagnitudeButton.Enable = 'off';
            app.MagnitudeButton.Text = 'Magnitude';
            app.AngleButton = uiradiobutton(app.vectButton_group);
            app.AngleButton.Enable = 'off';
            app.AngleButton.Text = 'Angle';
            app.controlPanel = uipanel(app.total_panel);
            app.diff_pattern_radio_group = uibuttongroup(app.controlPanel);
            app.diff_pattern_radio_group.SelectionChangedFcn = createCallbackFcn(app, @pattern_radio, true);
            app.sum_patterns = uiradiobutton(app.diff_pattern_radio_group);
            app.sum_patterns.Enable = 'off';
            app.sum_patterns.Text = 'Sum all diffraction patterns';
            app.sum_patterns.Value = true;
            app.all_patterns = uiradiobutton(app.diff_pattern_radio_group);
            app.all_patterns.Enable = 'off';
            app.all_patterns.Text = 'Display all diffraction patterns';
            app.display_images = uibutton(app.controlPanel, 'push');
            app.display_images.ButtonPushedFcn = createCallbackFcn(app, @display_images_Callback, true);
            app.display_images.Enable = 'off';
            app.display_images.Text = 'Display Images';
            app.save_images = uibutton(app.controlPanel, 'push');
            app.save_images.ButtonPushedFcn = createCallbackFcn(app, @save_images_Callback, true);
            app.save_images.Enable = 'off';
            app.save_images.Text = 'Save Images';
            app.import_data = uibutton(app.controlPanel, 'push');
            app.import_data.ButtonPushedFcn = createCallbackFcn(app, @import_dataButtonPushed, true);
            app.import_data.Text = 'Import Dataset';
            app.controlTab = uitabgroup(app.total_panel);
            app.CalibrateTab = uitab(app.controlTab);
            app.CalibrateTab.Title = 'Calibrate';
            app.align_trans_beam = uibutton(app.CalibrateTab, 'push');
            app.align_trans_beam.ButtonPushedFcn = createCallbackFcn(app, @align_trans_beam_Callback, true);
            app.align_trans_beam.Enable = 'off';
            app.align_trans_beam.Text = 'Align Transmitted Beam';
            app.trans_panel = uipanel(app.CalibrateTab);
            app.trans_beam_x_label = uilabel(app.trans_panel);
            app.trans_beam_x_label.Enable = 'off';
            app.trans_beam_x_label.Text = 'Transmitted Beam X';
            app.trans_beam_x = uislider(app.trans_panel);
            app.trans_beam_x.MajorTicks = [];
            app.trans_beam_x.ValueChangedFcn = createCallbackFcn(app, @trans_beam_Callback, true);
            app.trans_beam_x.ValueChangingFcn = createCallbackFcn(app, @trans_beam_Callback, true);
            app.trans_beam_x.MinorTicks = [];
            app.trans_beam_x.Enable = 'off';
            app.trans_beam_y_label = uilabel(app.trans_panel);
            app.trans_beam_y_label.Enable = 'off';
            app.trans_beam_y_label.Text = 'Transmitted Beam Y';
            app.trans_beam_y = uislider(app.trans_panel);
            app.trans_beam_y.MajorTicks = [];
            app.trans_beam_y.ValueChangedFcn = createCallbackFcn(app, @trans_beam_Callback, true);
            app.trans_beam_y.ValueChangingFcn = createCallbackFcn(app, @trans_beam_Callback, true);
            app.trans_beam_y.MinorTicks = [];
            app.trans_beam_y.Enable = 'off';
            app.trans_beam_radius_label = uilabel(app.trans_panel);
            app.trans_beam_radius_label.Enable = 'off';
            app.trans_beam_radius_label.Text = 'Transmitted Beam Radius';
            app.trans_beam_radius = uislider(app.trans_panel);
            app.trans_beam_radius.MajorTicks = [];
            app.trans_beam_radius.ValueChangedFcn = createCallbackFcn(app, @trans_beam_Callback, true);
            app.trans_beam_radius.ValueChangingFcn = createCallbackFcn(app, @trans_beam_Callback, true);
            app.trans_beam_radius.MinorTicks = [];
            app.trans_beam_radius.Enable = 'off';
            app.conv_angle_label = uilabel(app.trans_panel);
            app.conv_angle_label.Enable = 'off';
            app.conv_angle_label.Text = 'Convergence Angle (mrad)';
            app.conv_angle = uieditfield(app.trans_panel, 'numeric');
            app.conv_angle.ValueDisplayFormat = '%.1f';
            app.conv_angle.ValueChangedFcn = createCallbackFcn(app, @alpha_Callback, true);
            app.conv_angle.Enable = 'off';
            app.conv_angle.Value = 12;
            app.trans_beam_x_plus = uibutton(app.trans_panel, 'push');
            app.trans_beam_x_plus.ButtonPushedFcn = createCallbackFcn(app, @trans_beam_Callback, true);
            app.trans_beam_x_plus.Icon = 'right_arrow.jpg';
            app.trans_beam_x_plus.Enable = 'off';
            app.trans_beam_x_plus.Text = '';
            app.trans_beam_x_minus = uibutton(app.trans_panel, 'push');
            app.trans_beam_x_minus.ButtonPushedFcn = createCallbackFcn(app, @trans_beam_Callback, true);
            app.trans_beam_x_minus.Icon = 'left_arrow.png';
            app.trans_beam_x_minus.Enable = 'off';
            app.trans_beam_x_minus.Text = '';
            app.trans_beam_y_plus = uibutton(app.trans_panel, 'push');
            app.trans_beam_y_plus.ButtonPushedFcn = createCallbackFcn(app, @trans_beam_Callback, true);
            app.trans_beam_y_plus.Icon = 'up_arrow.png';
            app.trans_beam_y_plus.Enable = 'off';
            app.trans_beam_y_plus.Text = '';
            app.trans_beam_y_minus = uibutton(app.trans_panel, 'push');
            app.trans_beam_y_minus.ButtonPushedFcn = createCallbackFcn(app, @trans_beam_Callback, true);
            app.trans_beam_y_minus.Icon = 'down_arrow.png';
            app.trans_beam_y_minus.Enable = 'off';
            app.trans_beam_y_minus.Text = '';
            app.trans_beam_rad_plus = uibutton(app.trans_panel, 'push');
            app.trans_beam_rad_plus.ButtonPushedFcn = createCallbackFcn(app, @trans_beam_Callback, true);
            app.trans_beam_rad_plus.Icon = 'plus_icon.png';
            app.trans_beam_rad_plus.Enable = 'off';
            app.trans_beam_rad_plus.Text = '';
            app.trans_beam_rad_minus = uibutton(app.trans_panel, 'push');
            app.trans_beam_rad_minus.ButtonPushedFcn = createCallbackFcn(app, @trans_beam_Callback, true);
            app.trans_beam_rad_minus.Icon = 'minus_icon.png';
            app.trans_beam_rad_minus.Enable = 'off';
            app.trans_beam_rad_minus.Text = '';
            app.align_scan_rot = uibutton(app.CalibrateTab, 'push');
            app.align_scan_rot.ButtonPushedFcn = createCallbackFcn(app, @align_scan_rot_Callback, true);
            app.align_scan_rot.Enable = 'off';
            app.align_scan_rot.Text = 'Align Scan Direction';
            app.scan_rot_panel = uipanel(app.CalibrateTab);
            app.ScanRotationLabel = uilabel(app.scan_rot_panel);
            app.ScanRotationLabel.Enable = 'off';
            app.ScanRotationLabel.Text = 'Scan Rotation';
            app.scan_rot = uislider(app.scan_rot_panel);
            app.scan_rot.Limits = [-3.14159 3.14159];
            app.scan_rot.MajorTicks = [];
            app.scan_rot.MajorTickLabels = {};
            app.scan_rot.ValueChangedFcn = createCallbackFcn(app, @scan_rot_Callback, true);
            app.scan_rot.ValueChangingFcn = createCallbackFcn(app, @scan_rot_Callback, true);
            app.scan_rot.MinorTicks = [];
            app.scan_rot.Enable = 'off';
            app.scan_rot_plus = uibutton(app.scan_rot_panel, 'push');
            app.scan_rot_plus.ButtonPushedFcn = createCallbackFcn(app, @scan_rot_Callback, true);
            app.scan_rot_plus.Icon = 'plus_icon.png';
            app.scan_rot_plus.Enable = 'off';
            app.scan_rot_plus.Text = '';
            app.scan_rot_minus = uibutton(app.scan_rot_panel, 'push');
            app.scan_rot_minus.ButtonPushedFcn = createCallbackFcn(app, @scan_rot_Callback, true);
            app.scan_rot_minus.Icon = 'minus_icon.png';
            app.scan_rot_minus.Enable = 'off';
            app.scan_rot_minus.Text = '';
            app.det_angle_deg_2 = uilabel(app.scan_rot_panel);
            app.det_angle_deg_2.Enable = 'off';
            app.det_angle_deg_2.Text = '°';
            app.scan_rot_value = uieditfield(app.scan_rot_panel, 'numeric');
            app.scan_rot_value.Limits = [-180 180];
            app.scan_rot_value.ValueDisplayFormat = '%.2f';
            app.scan_rot_value.ValueChangedFcn = createCallbackFcn(app, @scan_rot_Callback, true);
            app.scan_rot_value.Enable = 'off';
            app.scan_rot_mirror = uicheckbox(app.scan_rot_panel);
            app.scan_rot_mirror.ValueChangedFcn = createCallbackFcn(app, @scan_rot_Callback, true);
            app.scan_rot_mirror.Enable = 'off';
            app.scan_rot_mirror.Text = 'Mirror';
            app.scan_rot_label = uilabel(app.CalibrateTab);
            app.scan_rot_label.Text = 'For DPC and COM Imaging:';
            app.DetectorsTab = uitab(app.controlTab);
            app.DetectorsTab.Title = 'Detectors';
            app.detector_panel = uipanel(app.DetectorsTab);
            app.adf_inner_mrad = uilabel(app.detector_panel);
            app.adf_inner_mrad.Enable = 'off';
            app.adf_inner_mrad.Text = 'mrad';
            app.adf_inner_value = uieditfield(app.detector_panel, 'numeric');
            app.adf_inner_value.ValueDisplayFormat = '%.2f';
            app.adf_inner_value.ValueChangedFcn = createCallbackFcn(app, @adf_inner_Callback, true);
            app.adf_inner_value.Enable = 'off';
            app.adf_outer_mrad = uilabel(app.detector_panel);
            app.adf_outer_mrad.Enable = 'off';
            app.adf_outer_mrad.Text = 'mrad';
            app.adf_outer_value = uieditfield(app.detector_panel, 'numeric');
            app.adf_outer_value.ValueDisplayFormat = '%.2f';
            app.adf_outer_value.ValueChangedFcn = createCallbackFcn(app, @adf_outer_Callback, true);
            app.adf_outer_value.Enable = 'off';
            app.nseg_label = uilabel(app.detector_panel);
            app.nseg_label.Enable = 'off';
            app.nseg_label.Text = '# of Segments:';
            app.nseg = uieditfield(app.detector_panel, 'numeric');
            app.nseg.Limits = [1 16];
            app.nseg.ValueDisplayFormat = '%d';
            app.nseg.ValueChangedFcn = createCallbackFcn(app, @change_segments_Callback, true);
            app.nseg.Enable = 'off';
            app.nseg.Value = 4;
            app.adf_inner_label = uilabel(app.detector_panel);
            app.adf_inner_label.Enable = 'off';
            app.adf_inner_label.Text = 'Detector Inner Angle';
            app.det_angle_label = uilabel(app.detector_panel);
            app.det_angle_label.Enable = 'off';
            app.det_angle_label.Text = 'Detector Rotation';
            app.det_angle = uislider(app.detector_panel);
            app.det_angle.Limits = [-3.14159265358979 3.14159265358979];
            app.det_angle.MajorTicks = [];
            app.det_angle.ValueChangedFcn = createCallbackFcn(app, @det_angle_Callback, true);
            app.det_angle.ValueChangingFcn = createCallbackFcn(app, @det_angle_Callback, true);
            app.det_angle.MinorTicks = [];
            app.det_angle.Enable = 'off';
            app.det_angle_deg = uilabel(app.detector_panel);
            app.det_angle_deg.Enable = 'off';
            app.det_angle_deg.Text = '°';
            app.det_angle_value = uieditfield(app.detector_panel, 'numeric');
            app.det_angle_value.Limits = [-180 180];
            app.det_angle_value.ValueDisplayFormat = '%.2f';
            app.det_angle_value.ValueChangedFcn = createCallbackFcn(app, @det_angle_Callback, true);
            app.det_angle_value.Enable = 'off';
            app.adf_inner = uislider(app.detector_panel);
            app.adf_inner.Limits = [0 1000];
            app.adf_inner.MajorTicks = [];
            app.adf_inner.ValueChangedFcn = createCallbackFcn(app, @adf_inner_Callback, true);
            app.adf_inner.ValueChangingFcn = createCallbackFcn(app, @adf_inner_Callback, true);
            app.adf_inner.MinorTicks = [];
            app.adf_inner.Enable = 'off';
            app.adf_outer_label = uilabel(app.detector_panel);
            app.adf_outer_label.Enable = 'off';
            app.adf_outer_label.Text = 'Detector Outer Angle';
            app.ofRungsLabel = uilabel(app.detector_panel);
            app.ofRungsLabel.Enable = 'off';
            app.ofRungsLabel.Text = '# of Rungs:';
            app.nrung = uieditfield(app.detector_panel, 'numeric');
            app.nrung.Limits = [1 8];
            app.nrung.RoundFractionalValues = 'on';
            app.nrung.ValueDisplayFormat = '%d';
            app.nrung.ValueChangedFcn = createCallbackFcn(app, @nrung_Callback, true);
            app.nrung.Enable = 'off';
            app.nrung.Value = 1;
            app.adf_outer = uislider(app.detector_panel);
            app.adf_outer.Limits = [0 1000];
            app.adf_outer.MajorTicks = [];
            app.adf_outer.ValueChangedFcn = createCallbackFcn(app, @adf_outer_Callback, true);
            app.adf_outer.ValueChangingFcn = createCallbackFcn(app, @adf_outer_Callback, true);
            app.adf_outer.MinorTicks = [];
            app.adf_outer.Enable = 'off';
            app.adf_inner_plus = uibutton(app.detector_panel, 'push');
            app.adf_inner_plus.ButtonPushedFcn = createCallbackFcn(app, @adf_inner_Callback, true);
            app.adf_inner_plus.Icon = 'plus_icon.png';
            app.adf_inner_plus.Enable = 'off';
            app.adf_inner_plus.Text = '';
            app.adf_inner_minus = uibutton(app.detector_panel, 'push');
            app.adf_inner_minus.ButtonPushedFcn = createCallbackFcn(app, @adf_inner_Callback, true);
            app.adf_inner_minus.Icon = 'minus_icon.png';
            app.adf_inner_minus.Enable = 'off';
            app.adf_inner_minus.Text = '';
            app.det_rot_minus = uibutton(app.detector_panel, 'push');
            app.det_rot_minus.ButtonPushedFcn = createCallbackFcn(app, @det_angle_Callback, true);
            app.det_rot_minus.Icon = 'minus_icon.png';
            app.det_rot_minus.Enable = 'off';
            app.det_rot_minus.Text = '';
            app.adf_outer_plus = uibutton(app.detector_panel, 'push');
            app.adf_outer_plus.ButtonPushedFcn = createCallbackFcn(app, @adf_outer_Callback, true);
            app.adf_outer_plus.Icon = 'plus_icon.png';
            app.adf_outer_plus.Enable = 'off';
            app.adf_outer_plus.Text = '';
            app.adf_outer_minus = uibutton(app.detector_panel, 'push');
            app.adf_outer_minus.ButtonPushedFcn = createCallbackFcn(app, @adf_outer_Callback, true);
            app.adf_outer_minus.Icon = 'minus_icon.png';
            app.adf_outer_minus.Enable = 'off';
            app.adf_outer_minus.Text = '';
            app.det_rot_plus = uibutton(app.detector_panel, 'push');
            app.det_rot_plus.ButtonPushedFcn = createCallbackFcn(app, @det_angle_Callback, true);
            app.det_rot_plus.Icon = 'plus_icon.png';
            app.det_rot_plus.Enable = 'off';
            app.det_rot_plus.Text = '';
            app.DetectorTypeButtonGroup = uibuttongroup(app.DetectorsTab);
            app.DetectorTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @det_type_Callback, true);
            app.DetectorTypeButtonGroup.Title = 'Detector Type:';
            app.SegmentedButton = uiradiobutton(app.DetectorTypeButtonGroup);
            app.SegmentedButton.Enable = 'off';
            app.SegmentedButton.Text = 'Segmented';
            app.AnnularRoundButton = uiradiobutton(app.DetectorTypeButtonGroup);
            app.AnnularRoundButton.Enable = 'off';
            app.AnnularRoundButton.Text = 'Annular/Round';
            app.VirtualApertureButton = uiradiobutton(app.DetectorTypeButtonGroup);
            app.VirtualApertureButton.Enable = 'off';
            app.VirtualApertureButton.Text = 'Virtual Aperture';
            app.VirtualApertureButton.Value = true;
            app.CenterofMassButton = uiradiobutton(app.DetectorTypeButtonGroup);
            app.CenterofMassButton.Enable = 'off';
            app.CenterofMassButton.Text = 'Center of Mass';
            app.BrightnessContrastTab = uitab(app.controlTab);
            app.BrightnessContrastTab.Title = 'Brightness/Contrast';
            app.ImageLabel = uilabel(app.BrightnessContrastTab);
            app.ImageLabel.Enable = 'off';
            app.ImageLabel.Text = 'Image:';
            app.ImageDropDown = uidropdown(app.BrightnessContrastTab);
            app.ImageDropDown.Items = {'Diffraction Pattern', 'Sum Image'};
            app.ImageDropDown.ValueChangedFcn = createCallbackFcn(app, @image_controls, true);
            app.ImageDropDown.Enable = 'off';
            app.ImageDropDown.Value = 'Diffraction Pattern';
            app.histogram_plot = uiaxes(app.BrightnessContrastTab);
            app.histogram_plot.XTick = [];
            app.histogram_plot.XTickLabel = {'[ ]'};
            app.histogram_plot.YTick = [];
            app.histogram_plot.YTickLabel = {'[ ]'};
            app.histogram_panel = uipanel(app.BrightnessContrastTab);
            app.BrightnessLabel = uilabel(app.histogram_panel);
            app.BrightnessLabel.Enable = 'off';
            app.BrightnessLabel.Text = 'Brightness';
            app.brightness = uislider(app.histogram_panel);
            app.brightness.Limits = [-0.49999 0.49999];
            app.brightness.MajorTicks = [];
            app.brightness.MajorTickLabels = {};
            app.brightness.ValueChangedFcn = createCallbackFcn(app, @image_controls, true);
            app.brightness.ValueChangingFcn = createCallbackFcn(app, @image_controls, true);
            app.brightness.MinorTicks = [];
            app.brightness.Enable = 'off';
            app.ContrastLabel = uilabel(app.histogram_panel);
            app.ContrastLabel.Enable = 'off';
            app.ContrastLabel.Text = 'Contrast';
            app.contrast = uislider(app.histogram_panel);
            app.contrast.Limits = [-0.49999 0.49999];
            app.contrast.MajorTicks = [];
            app.contrast.MajorTickLabels = {};
            app.contrast.ValueChangedFcn = createCallbackFcn(app, @image_controls, true);
            app.contrast.ValueChangingFcn = createCallbackFcn(app, @image_controls, true);
            app.contrast.MinorTicks = [];
            app.contrast.Enable = 'off';
            app.GammaLabel = uilabel(app.histogram_panel);
            app.GammaLabel.Enable = 'off';
            app.GammaLabel.Text = 'Gamma';
            app.gamma = uislider(app.histogram_panel);
            app.gamma.Limits = [0 5];
            app.gamma.MajorTicks = [];
            app.gamma.MajorTickLabels = {};
            app.gamma.ValueChangedFcn = createCallbackFcn(app, @image_controls, true);
            app.gamma.ValueChangingFcn = createCallbackFcn(app, @image_controls, true);
            app.gamma.MinorTicks = [];
            app.gamma.Enable = 'off';
            app.gamma.Value = 1;
            app.brightness_reset = uibutton(app.histogram_panel, 'push');
            app.brightness_reset.ButtonPushedFcn = createCallbackFcn(app, @image_controls, true);
            app.brightness_reset.Enable = 'off';
            app.brightness_reset.Text = '50%';
            app.contrast_reset = uibutton(app.histogram_panel, 'push');
            app.contrast_reset.ButtonPushedFcn = createCallbackFcn(app, @image_controls, true);
            app.contrast_reset.Enable = 'off';
            app.contrast_reset.Text = '50%';
            app.gamma_reset = uibutton(app.histogram_panel, 'push');
            app.gamma_reset.ButtonPushedFcn = createCallbackFcn(app, @image_controls, true);
            app.gamma_reset.Enable = 'off';
            app.gamma_reset.Text = '1';
            app.trans_zoom_panel = uipanel(app.total_panel);
            app.ZoomButton_trans = uibutton(app.trans_zoom_panel, 'state');
            app.ZoomButton_trans.ValueChangedFcn = createCallbackFcn(app, @zoomIn, true);
            app.ZoomButton_trans.Enable = 'off';
            app.ZoomButton_trans.Icon = 'zoom.png';
            app.ZoomButton_trans.Text = '';
            app.PanButton_trans = uibutton(app.trans_zoom_panel, 'state');
            app.PanButton_trans.ValueChangedFcn = createCallbackFcn(app, @pan, true);
            app.PanButton_trans.Enable = 'off';
            app.PanButton_trans.Icon = 'pan.png';
            app.PanButton_trans.Text = '';
            app.ResetZoomButton_trans = uibutton(app.trans_zoom_panel, 'push');
            app.ResetZoomButton_trans.ButtonPushedFcn = createCallbackFcn(app, @resetZoom, true);
            app.ResetZoomButton_trans.Enable = 'off';
            app.ResetZoomButton_trans.Text = 'Reset Zoom';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Quant4D

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Quant4D_Fig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Quant4D_Fig)
        end
    end
end