classdef import4Dstem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        import_4d              matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        frames_label           matlab.ui.control.Label
        probe_positions_label  matlab.ui.control.Label
        gap_label              matlab.ui.control.Label
        actual_filesize_label  matlab.ui.control.Label
        est_filesize_label     matlab.ui.control.Label
        est_filesize           matlab.ui.control.Label
        actual_filesize        matlab.ui.control.Label
        import_data            matlab.ui.control.Button
        cancel                 matlab.ui.control.Button
        xEditFieldLabel        matlab.ui.control.Label
        nopix                  matlab.ui.control.NumericEditField
        yEditFieldLabel        matlab.ui.control.Label
        nopiy                  matlab.ui.control.NumericEditField
        xEditField_3Label      matlab.ui.control.Label
        framesx                matlab.ui.control.NumericEditField
        yEditField_2Label      matlab.ui.control.Label
        framesy                matlab.ui.control.NumericEditField
        gap                    matlab.ui.control.NumericEditField
        bitdepth               matlab.ui.control.DropDown
        offset_label           matlab.ui.control.Label
        offset                 matlab.ui.control.NumericEditField
        bytesEditFieldLabel_2  matlab.ui.control.Label
        ButtonGroup            matlab.ui.container.ButtonGroup
        BigendianButton        matlab.ui.control.RadioButton
        LittleendianButton     matlab.ui.control.RadioButton
        bytesEditFieldLabel_3  matlab.ui.control.Label
        memory_excess          matlab.ui.control.Label
    end

    
    properties (Access = private)
        bitsize
        type
        ordering
        filesize
        s
        current_bitdepth
        CallingApp
        bitdepth_options
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, appMain, filename)
            app.CallingApp=appMain;
            movegui(app.import_4d,'center')
            app.import_4d.Name=filename;
            app.s=dir(filename);
            app.actual_filesize.Text=sprintf('%.0f bytes',app.s.bytes);
            
            app.bitdepth_options={'1-bit','uint8';  % Medipix data are saved as a .mib file of varying bit-depth
                '6-bit','uint8';                    % as unsigned integers of the next highest class
                '8-bit Unsigned','uint8';           % (i.e. 12-bit is saved as a 16-bit unsigned integer)
                '12-bit','uint16';                  % with big-endian ordering.
                '16-bit Signed','int16';            %
                '16-bit Unsigned','uint16';         % EMPAD data are saved as a .raw file with 32-bit depth
                '24-bit','uint32';                  % with little endian ordering.
                '32-bit Real','single';             %
                '32-bit Signed','int32';            % Fiji/ImageJ tend to save their files with big-endian ordering,
                '32-bit Unsigned','uint32';         % though this may be operating system dependent.
                '64-bit Real','double'};            %
            
            app.ordering='l';
            
            if strcmp(filename(end-2:end),'mib') % Medipix data
                app.bitdepth.Items={'1-bit';'6-bit';'12-bit';'24-bit'};
                app.bitdepth.Value='12-bit';app.current_bitdepth=2;app.type={'uint16'};
                app.gap.Value=384;
                app.offset.Value=0;
                app.bitsize=[1;1;2;4];
                app.BigendianButton.Value=true;
                app.ordering='b';
            else % other data (EMPAD, ImageJ, simulation, etc.)
                app.bitdepth.Items={'8-bit Unsigned';
                    '16-bit Signed';
                    '16-bit Unsigned';
                    '32-bit Real';
                    '32-bit Signed';
                    '32-bit Unsigned';
                    '64-bit Real'};
                app.bitsize=[1;2;2;4;4;4;8];
                app.current_bitdepth=app.bitsize(strmatch(app.bitdepth.Value,app.bitdepth.Items));
            end
            
            % Check for dataset size at the end of the filename in the following style:
            %   filename='dataset_arg1xarg2xarg3xarg4.ext'
            %   where:  arg1=nopix
            %           arg2=nopiy
            %           arg3=framesx
            %           arg4=framesy
            if contains(filename,'_') && size(strfind(filename(max(strfind(filename,'_'))+1:end-4),'x'),2)==3
                input_args=strsplit(filename(max(strfind(filename,'_'))+1:end-4),'x');
                app.nopix.Value=str2double(input_args{1});
                app.nopiy.Value=str2double(input_args{2});
                app.framesx.Value=str2double(input_args{3});
                app.framesy.Value=str2double(input_args{4});
            else % Make an initial guess at the file dimensions (assume ~square field of view: framesx≈framesy)
                if rem(sqrt(app.s.bytes/(app.nopix.Value*app.nopiy.Value*app.current_bitdepth+app.gap.Value)),1)==0
                    app.framesx.Value=sqrt(app.s.bytes/(app.nopix.Value*app.nopiy.Value*app.current_bitdepth+app.gap.Value));
                    app.framesy.Value=app.framesx.Value;
                else
                    app.framesx.Value=2^(floor(log2(app.s.bytes/(app.nopix.Value*app.nopiy.Value*app.current_bitdepth+app.gap.Value)))/2);
                    app.framesy.Value=app.framesx.Value;
                end
            end
            
            boxInput(app)
        end

        % Value changed function: bitdepth, framesx, framesy, gap, 
        % nopix, nopiy, offset
        function boxInput(app, event)
            app.current_bitdepth=app.bitsize(strmatch(app.bitdepth.Value,app.bitdepth.Items));
            app.type=app.bitdepth_options(strmatch(app.bitdepth.Value,app.bitdepth_options),2);
            
            if strcmp(app.import_4d.Name(end-2:end),'mib')
                app.filesize=app.nopiy.Value*app.nopix.Value*app.framesy.Value*app.framesx.Value*app.current_bitdepth+...
                             (app.framesx.Value*app.framesy.Value-1)*app.gap.Value+app.offset.Value;
            else
                app.filesize=app.nopiy.Value*app.nopix.Value*app.framesy.Value*app.framesx.Value*app.current_bitdepth+...
                            (app.framesx.Value*app.framesy.Value-0.5)*app.gap.Value+app.offset.Value;
            end
            app.est_filesize.Text=sprintf('%.0f bytes',app.filesize);
            
            % Check usable system memory and make sure the file will fit
            if ispc
                max_mem=memory;
                max_mem=max_mem.MemAvailableAllArrays;
            elseif ismac
                [~,max_mem]=system('sysctl hw.memsize | awk ''{print $2}''');
                max_mem=str2double(max_mem);
            end
            
            if app.s.bytes>max_mem && app.filesize>max_mem
                app.actual_filesize.FontColor='r';
                app.memory_excess.Visible='on';
                app.import_data.Enable='off';
            elseif app.s.bytes>max_mem && app.filesize<max_mem
                app.actual_filesize.FontColor='r';
                app.memory_excess.Visible='off';
                app.import_data.Enable='on';
            elseif app.filesize>max_mem
                app.est_filesize.FontColor='r';
                app.memory_excess.Visible='on';
                app.import_data.Enable='off';
            else
                app.actual_filesize.FontColor='k';
                app.memory_excess.Visible='off';
                app.import_data.Enable='on';
            end
            
            % Display estimated size in green if input parameters match actual file size
            if app.filesize==app.s.bytes
                app.est_filesize.FontColor=[0 0.5 0];
            else
                app.est_filesize.FontColor='r';
            end
        end

        % Button pushed function: cancel
        function closePushed(app, event)
            import4Ddata(app.CallingApp,0,0,0,0,0,0,0,{'0'});
            delete(app)
        end

        % Button pushed function: import_data
        function import_dataPushed(app, event)
            app.offset.Value=app.offset.Value/app.current_bitdepth;
            app.gap.Value=app.gap.Value/app.current_bitdepth;
            if app.LittleendianButton.Value==1
                app.ordering='l';
            elseif app.BigendianButton.Value==1
                app.ordering='b';
            end
            import4Ddata(app.CallingApp,app.nopiy.Value,app.nopix.Value,app.framesy.Value,app.framesx.Value,app.offset.Value,app.gap.Value,app.ordering,app.type);
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.import_4d = uifigure;
            app.import_4d.Name = 'Import Data';
            app.Panel = uipanel(app.import_4d);
            app.frames_label = uilabel(app.Panel);
            app.frames_label.Text = 'Pixels in the diffraction pattern:';
            app.probe_positions_label = uilabel(app.Panel);
            app.probe_positions_label.Text = 'Number of probe positions:';
            app.gap_label = uilabel(app.Panel);
            app.gap_label.Text = 'Gap between images:';
            app.actual_filesize_label = uilabel(app.Panel);
            app.actual_filesize_label.Text = 'Actual file size:';
            app.est_filesize_label = uilabel(app.Panel);
            app.est_filesize_label.Text = 'Estimated file size:';
            app.est_filesize = uilabel(app.Panel);
            app.est_filesize.Text = '';
            app.actual_filesize = uilabel(app.Panel);
            app.actual_filesize.Text = '';
            app.import_data = uibutton(app.Panel, 'push');
            app.import_data.ButtonPushedFcn = createCallbackFcn(app, @import_dataPushed, true);
            app.import_data.Text = 'Import Data';
            app.cancel = uibutton(app.Panel, 'push');
            app.cancel.ButtonPushedFcn = createCallbackFcn(app, @closePushed, true);
            app.cancel.Text = 'Close';
            app.xEditFieldLabel = uilabel(app.Panel);
            app.xEditFieldLabel.Text = 'x:';
            app.nopix = uieditfield(app.Panel, 'numeric');
            app.nopix.Limits = [0 Inf];
            app.nopix.RoundFractionalValues = 'on';
            app.nopix.ValueChangedFcn = createCallbackFcn(app, @boxInput, true);
            app.nopix.Value = 128;
            app.yEditFieldLabel = uilabel(app.Panel);
            app.yEditFieldLabel.Text = 'y:';
            app.nopiy = uieditfield(app.Panel, 'numeric');
            app.nopiy.Limits = [0 Inf];
            app.nopiy.RoundFractionalValues = 'on';
            app.nopiy.ValueChangedFcn = createCallbackFcn(app, @boxInput, true);
            app.nopiy.Value = 128;
            app.xEditField_3Label = uilabel(app.Panel);
            app.xEditField_3Label.Text = 'x:';
            app.framesx = uieditfield(app.Panel, 'numeric');
            app.framesx.Limits = [0 Inf];
            app.framesx.RoundFractionalValues = 'on';
            app.framesx.ValueChangedFcn = createCallbackFcn(app, @boxInput, true);
            app.framesx.Value = 128;
            app.yEditField_2Label = uilabel(app.Panel);
            app.yEditField_2Label.Text = 'y:';
            app.framesy = uieditfield(app.Panel, 'numeric');
            app.framesy.Limits = [0 Inf];
            app.framesy.RoundFractionalValues = 'on';
            app.framesy.ValueChangedFcn = createCallbackFcn(app, @boxInput, true);
            app.framesy.Value = 128;
            app.gap = uieditfield(app.Panel, 'numeric');
            app.gap.Limits = [0 Inf];
            app.gap.RoundFractionalValues = 'on';
            app.gap.ValueChangedFcn = createCallbackFcn(app, @boxInput, true);
            app.gap.Value = 1024;
            app.bitdepth = uidropdown(app.Panel);
            app.bitdepth.Items = {'8-bit Unsigned', '16-bit Signed', '16-bit Unsigned', '32-bit Real', '32-bit Signed', '32-bit Unsigned', '64-bit Real'};
            app.bitdepth.ValueChangedFcn = createCallbackFcn(app, @boxInput, true);
            app.bitdepth.Value = '32-bit Real';
            app.offset_label = uilabel(app.Panel);
            app.offset_label.Text = 'Offset to first image:';
            app.offset = uieditfield(app.Panel, 'numeric');
            app.offset.Limits = [0 Inf];
            app.offset.RoundFractionalValues = 'on';
            app.offset.ValueChangedFcn = createCallbackFcn(app, @boxInput, true);
            app.offset.Value = 512;
            app.bytesEditFieldLabel_2 = uilabel(app.Panel);
            app.bytesEditFieldLabel_2.Text = 'bytes';
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.BigendianButton = uiradiobutton(app.ButtonGroup);
            app.BigendianButton.Text = 'Big endian';
            app.LittleendianButton = uiradiobutton(app.ButtonGroup);
            app.LittleendianButton.Text = 'Little endian';
            app.LittleendianButton.Value = true;
            app.bytesEditFieldLabel_3 = uilabel(app.Panel);
            app.bytesEditFieldLabel_3.Text = 'bytes';
            app.memory_excess = uilabel(app.Panel);
            app.memory_excess.Visible = 'off';
            app.memory_excess.Text = 'File will not fit in memory!!';
        end
    end

    methods (Access = public)

        % Construct app
        function app = import4Dstem(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.import_4d)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.import_4d)
        end
    end
end