classdef Quant4D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Quant4D_Fig                     matlab.ui.Figure
        diffraction_dropdown            matlab.ui.control.DropDown
        Quant4D_FigGrid                 matlab.ui.container.GridLayout
        RealPanelGrid                   matlab.ui.container.GridLayout
        RealPanel                       matlab.ui.container.Panel
        RealGrid                        matlab.ui.container.GridLayout
        RealROIGrid                     matlab.ui.container.GridLayout
        RealROIFrameY                   matlab.ui.control.Spinner
        RealYLabel                      matlab.ui.control.Label
        RealROIFrameX                   matlab.ui.control.Spinner
        RealXLabel                      matlab.ui.control.Label
        RealROIFrameLabel               matlab.ui.control.Label
        ShowRealMaskWindow              matlab.ui.control.Button
        RealROIInvert                   matlab.ui.control.StateButton
        RealROIShape                    matlab.ui.control.DropDown
        ROIShapeLabel                   matlab.ui.control.Label
        ModePanel                       matlab.ui.container.Panel
        ModeGrid                        matlab.ui.container.GridLayout
        Mode                            matlab.ui.control.DropDown
        ShortcutButtonGrid              matlab.ui.container.GridLayout
        ShowSettingsWindow              matlab.ui.control.Button
        UpdateImages                    matlab.ui.control.Button
        AutoSaveImage                   matlab.ui.control.Button
        ShowSaveWindow                  matlab.ui.control.Button
        ShowImportWindow                matlab.ui.control.Button
        ModeTabGroup                    matlab.ui.container.TabGroup
        PreviewTab                      matlab.ui.container.Tab
        PreviewGrid                     matlab.ui.container.GridLayout
        PreviewFramePanel               matlab.ui.container.Panel
        PreviewButtonGrid               matlab.ui.container.GridLayout
        PreviewFrameGrid                matlab.ui.container.GridLayout
        PreviewFrameY                   matlab.ui.control.Spinner
        PvFrYLabel                      matlab.ui.control.Label
        PreviewFrameX                   matlab.ui.control.Spinner
        PvFrXLabel                      matlab.ui.control.Label
        PreviewButton                   matlab.ui.control.Button
        PreviewFrame_X_Y                matlab.ui.control.Button
        PreviewFrame_X2_Y               matlab.ui.control.Button
        PreviewFrame_1_Y                matlab.ui.control.Button
        PreviewFrame_X_Y2               matlab.ui.control.Button
        PreviewFrame_X2_Y2              matlab.ui.control.Button
        PreviewFrame_1_Y2               matlab.ui.control.Button
        PreviewFrame_X_1                matlab.ui.control.Button
        PreviewFrame_X2_1               matlab.ui.control.Button
        PreviewFrame_1_1                matlab.ui.control.Button
        AlignmentTab                    matlab.ui.container.Tab
        AlignmentGrid                   matlab.ui.container.GridLayout
        AlignmentHelpIcon               matlab.ui.control.Image
        TransBeamAlignPanel             matlab.ui.container.Panel
        TransBeamAlignGrid              matlab.ui.container.GridLayout
        TransBeamY                      matlab.ui.control.Slider
        TBY_NFLabel                     matlab.ui.control.Label
        TransBeamYSpinner               matlab.ui.control.Spinner
        TBYLabel                        matlab.ui.control.Label
        TransBeamX                      matlab.ui.control.Slider
        TBX_NFLabel                     matlab.ui.control.Label
        TransBeamXSpinner               matlab.ui.control.Spinner
        TBXLabel                        matlab.ui.control.Label
        TransBeamR                      matlab.ui.control.Slider
        TBR_NFLabel                     matlab.ui.control.Label
        TransBeamRSpinner               matlab.ui.control.Spinner
        TBRLabel                        matlab.ui.control.Label
        TBCrossAlign                    matlab.ui.control.Button
        TBAutoAlign                     matlab.ui.control.Button
        DiffractionCalibrationPanel     matlab.ui.container.Panel
        DiffractionCalibrationGrid      matlab.ui.container.GridLayout
        mradPxLabel                     matlab.ui.control.Label
        mradPx                          matlab.ui.control.NumericEditField
        DiffractionScaleLabel           matlab.ui.control.Label
        AlphaLabel                      matlab.ui.control.Label
        Alpha                           matlab.ui.control.NumericEditField
        ConvergenceAngleLabel           matlab.ui.control.Label
        AnnularDetectorTab              matlab.ui.container.Tab
        AnnularDetectorGrid             matlab.ui.container.GridLayout
        AnnularStepGrid                 matlab.ui.container.GridLayout
        AnnularStep                     matlab.ui.control.NumericEditField
        AnnularIntegrationStepEditFieldLabel  matlab.ui.control.Label
        SegmentedDetectorPanel          matlab.ui.container.Panel
        SegmentedDetectorGrid           matlab.ui.container.GridLayout
        DetectorRotationSlider          matlab.ui.control.Slider
        DetRot_NFLabel                  matlab.ui.control.Label
        DetectorRotationSpinner         matlab.ui.control.Spinner
        DetRotLabel                     matlab.ui.control.Label
        NRung                           matlab.ui.control.Spinner
        RungsLabel                      matlab.ui.control.Label
        NSeg                            matlab.ui.control.Spinner
        SegmentsLabel                   matlab.ui.control.Label
        ScanDirectionPanel              matlab.ui.container.Panel
        ScanDirectionGrid               matlab.ui.container.GridLayout
        ScanDirectionSlider             matlab.ui.control.Slider
        FlipScanDirectionY              matlab.ui.control.StateButton
        ScanDir_NFLabel                 matlab.ui.control.Label
        ScanDirectionSpinner            matlab.ui.control.Spinner
        ScanDirLabel                    matlab.ui.control.Label
        AutoCurl                        matlab.ui.control.Button
        ScanDirectionLock               matlab.ui.control.StateButton
        AnnularRadiiPanel               matlab.ui.container.Panel
        AnnularRadiiGrid                matlab.ui.container.GridLayout
        OuterAnnularRadius              matlab.ui.control.Slider
        AnnRo_NFLabel                   matlab.ui.control.Label
        OuterAnnularRadiusSpinner       matlab.ui.control.Spinner
        AnnRoLabel                      matlab.ui.control.Label
        AnnularRadiusLink               matlab.ui.control.StateButton
        InnerAnnularRadius              matlab.ui.control.Slider
        AnnRi_NFLabel                   matlab.ui.control.Label
        InnerAnnularRadiusSpinner       matlab.ui.control.Spinner
        AnnRiLabel                      matlab.ui.control.Label
        VirtualApertureTab              matlab.ui.container.Tab
        VirtualApertureGrid             matlab.ui.container.GridLayout
        VirtualApertureSymmetryPanel    matlab.ui.container.Panel
        VirtualApertureSymmetryGrid     matlab.ui.container.GridLayout
        VirtualApertureMirrorGrid       matlab.ui.container.GridLayout
        VirtualApertureMirrorRotation   matlab.ui.control.Slider
        VrApMirrRot_NFLabel             matlab.ui.control.Label
        VirtualApertureMirrorRotationSpinner  matlab.ui.control.Spinner
        VrApMirrRotLabel                matlab.ui.control.Label
        VirtualApertureMirror           matlab.ui.control.StateButton
        VirtualApertureSymmetry         matlab.ui.control.DropDown
        VrApSymmLabel                   matlab.ui.control.Label
        VirtualApertureCoordinatesPanel  matlab.ui.container.Panel
        VirtualApertureCoordinatesGrid  matlab.ui.container.GridLayout
        VirtualApertureRotationGrid     matlab.ui.container.GridLayout
        AnnularStepGrid_2               matlab.ui.container.GridLayout
        AnnularStep_2                   matlab.ui.control.NumericEditField
        AnnularIntegrationStepEditFieldLabel_2  matlab.ui.control.Label
        VirtualApertureInvert           matlab.ui.control.StateButton
        VirtualApertureRotateCW         matlab.ui.control.Button
        VirtualApertureRotationStep     matlab.ui.control.DropDown
        VirtualApertureRotateCCW        matlab.ui.control.Button
        VirtualApertureReset            matlab.ui.control.Button
        VirtualApertureY                matlab.ui.control.Slider
        VrApY_NFLabel                   matlab.ui.control.Label
        VirtualApertureYSpinner         matlab.ui.control.Spinner
        VrApYLabel                      matlab.ui.control.Label
        VirtualApertureX                matlab.ui.control.Slider
        VrApX_NFLabel                   matlab.ui.control.Label
        VirtualApertureXSpinner         matlab.ui.control.Spinner
        VrApXLabel                      matlab.ui.control.Label
        VirtualApertureR                matlab.ui.control.Slider
        VrApR_NFLabel                   matlab.ui.control.Label
        VirtualApertureRSpinner         matlab.ui.control.Spinner
        VrApRLabel                      matlab.ui.control.Label
        CustomDetectorTab               matlab.ui.container.Tab
        CustomDetectorGrid              matlab.ui.container.GridLayout
        CustomDetectorDetailsPanel      matlab.ui.container.Panel
        CustomDetectorDetailsGrid       matlab.ui.container.GridLayout
        CustomDetectorTranspose         matlab.ui.control.StateButton
        CustomDetectorFlipVertical      matlab.ui.control.StateButton
        CustomDetectorFlipHorizontal    matlab.ui.control.StateButton
        CustomDetectorColor             matlab.ui.control.Button
        CustomDetectorInvert            matlab.ui.control.StateButton
        CustomDetectorDetailsTable      matlab.ui.control.Table
        CustomDetectorMirror            matlab.ui.control.StateButton
        CustomDetectorRotationGrid      matlab.ui.container.GridLayout
        CustomDetectorRotateCW          matlab.ui.control.Button
        CustomDetectorRotateCCW         matlab.ui.control.Button
        CustomDetectorRotationStep      matlab.ui.control.DropDown
        CustomDetectorIntraMask         matlab.ui.control.DropDown
        CDIntraCombLabel                matlab.ui.control.Label
        CustomDetectorTable             matlab.ui.control.Table
        CustomDetectorInterMaskGrid     matlab.ui.container.GridLayout
        CustomDetectorDeleteMask        matlab.ui.control.Button
        CustomDetectorInterMask         matlab.ui.control.DropDown
        IntermaskLabel                  matlab.ui.control.Label
        ShowDiffractionMaskWindow       matlab.ui.control.Button
        CustomDetectorNewMaskGrid       matlab.ui.container.GridLayout
        CustomDetectorNewGridNoCenter   matlab.ui.control.Button
        CustomDetectorNewMath           matlab.ui.control.Button
        CustomDetectorNewFromFile       matlab.ui.control.Button
        CustomDetectorNewPolygon        matlab.ui.control.Button
        CustomDetectorNewWedge          matlab.ui.control.Button
        CustomDetectorNewBandpass       matlab.ui.control.Button
        CustomDetectorNewGrid           matlab.ui.control.Button
        CustomDetectorNewCircle         matlab.ui.control.Button
        SettingsPanel                   matlab.ui.container.Panel
        SettingsGrid                    matlab.ui.container.GridLayout
        SettingsTabGroup                matlab.ui.container.TabGroup
        DisplayTab                      matlab.ui.container.Tab
        DisplayGrid                     matlab.ui.container.GridLayout
        DispContrastsGrid               matlab.ui.container.GridLayout
        DisplayMaskOpacitySpinner       matlab.ui.control.Spinner
        DisplayMaskOpacitySlider        matlab.ui.control.Slider
        ShowMaskWindow                  matlab.ui.control.Button
        DisplayGammaSpinner             matlab.ui.control.Spinner
        DisplayGamma                    matlab.ui.control.Slider
        DisplayGammaReset               matlab.ui.control.Button
        DisplayContrastSpinner          matlab.ui.control.Spinner
        DisplayContrast                 matlab.ui.control.Slider
        DisplayContrastReset            matlab.ui.control.Button
        DisplayBrightnessSpinner        matlab.ui.control.Spinner
        DisplayBrightness               matlab.ui.control.Slider
        DisplayBrightnessReset          matlab.ui.control.Button
        HistogramGrid                   matlab.ui.container.GridLayout
        HistogramLog                    matlab.ui.control.StateButton
        HistogramAxes                   matlab.ui.control.UIAxes
        ImageStatistics                 matlab.ui.control.TextArea
        DisplayDropDownGrid             matlab.ui.container.GridLayout
        DispColorMapInvert              matlab.ui.control.StateButton
        DispColorMap                    matlab.ui.control.DropDown
        ColormapLabel                   matlab.ui.control.Label
        DisplayLock                     matlab.ui.control.StateButton
        DisplayImage                    matlab.ui.control.DropDown
        ImageLabel                      matlab.ui.control.Label
        ShowImageWindow                 matlab.ui.control.Button
        WindowsTab                      matlab.ui.container.Tab
        WindowsTabGrid                  matlab.ui.container.GridLayout
        WindowResizePanel               matlab.ui.container.Panel
        WindowResizeGrid                matlab.ui.container.GridLayout
        WindowResizeTabGroup            matlab.ui.container.TabGroup
        DiffractionTab                  matlab.ui.container.Tab
        DiffractionTabGrid              matlab.ui.container.GridLayout
        DiffractionWindowPanel          matlab.ui.container.Panel
        DiffractionWindowGrid           matlab.ui.container.GridLayout
        SetAllDiffractionWindows        matlab.ui.control.Button
        SetDiffractionWindow            matlab.ui.control.Button
        SetDiffractionWindowHeight      matlab.ui.control.NumericEditField
        HEditFieldLabel                 matlab.ui.control.Label
        SetDiffractionWindowWidth       matlab.ui.control.NumericEditField
        WEditFieldLabel                 matlab.ui.control.Label
        DiffractionAxesGrid             matlab.ui.container.GridLayout
        RotateDiffraction               matlab.ui.control.DropDown
        RotateDiffractionIcon           matlab.ui.control.Image
        ReverseDiffractionY             matlab.ui.control.StateButton
        ReverseDiffractionX             matlab.ui.control.StateButton
        ShowDiffractionAxes             matlab.ui.control.StateButton
        RealspaceTab                    matlab.ui.container.Tab
        RealspaceTabGrid                matlab.ui.container.GridLayout
        RealWindowPanel                 matlab.ui.container.Panel
        RealWindowGrid                  matlab.ui.container.GridLayout
        SetRealWindowWidth              matlab.ui.control.NumericEditField
        WEditField_2Label               matlab.ui.control.Label
        SetAllRealWindows               matlab.ui.control.Button
        SetRealWindow                   matlab.ui.control.Button
        SetRealWindowHeight             matlab.ui.control.NumericEditField
        HEditField_2Label               matlab.ui.control.Label
        RealAxesGrid                    matlab.ui.container.GridLayout
        RotateReal                      matlab.ui.control.DropDown
        RotateRealIcon                  matlab.ui.control.Image
        ReverseRealY                    matlab.ui.control.StateButton
        ReverseRealX                    matlab.ui.control.StateButton
        ShowRealAxes                    matlab.ui.control.StateButton
        ShowImageWindowsPanel           matlab.ui.container.Panel
        ShowImageWindowsGrid            matlab.ui.container.GridLayout
        VectorSamplingGrid              matlab.ui.container.GridLayout
        ShowColorWheelWindow            matlab.ui.control.Button
        DisplayVectorColor              matlab.ui.control.Button
        DisplayVectorField              matlab.ui.control.DropDown
        DispVecFieldLabel               matlab.ui.control.Label
        ShowiCoMWindow                  matlab.ui.control.Button
        ShowdCoMWindow                  matlab.ui.control.Button
        ShowCoMYWindow                  matlab.ui.control.Button
        ShowCoMXWindow                  matlab.ui.control.Button
        ShowCoMMagnitudeWindow          matlab.ui.control.Button
        ShowCoMPhaseWindow              matlab.ui.control.Button
        ShowCoMPhaseMagnitudeWindow     matlab.ui.control.Button
        ShowColorWheelWindow2           matlab.ui.control.Button
        ShowRealMaskWindow2             matlab.ui.control.Button
        ShowRealWindow                  matlab.ui.control.Button
        ShowDiffractionMaskWindow2      matlab.ui.control.Button
        ShowDiffractionWindow           matlab.ui.control.Button
        BandpassFilterPanel             matlab.ui.container.Panel
        BandpassFilterGrid              matlab.ui.container.GridLayout
        BandpassFilterLow               matlab.ui.control.Spinner
        BandpassFilterHigh              matlab.ui.control.Spinner
        BandpassFilter                  matlab.ui.control.RangeSlider
        MiscTab                         matlab.ui.container.Tab
        MiscGrid                        matlab.ui.container.GridLayout
        DatasetOptionPanel              matlab.ui.container.Panel
        DatasetOptionGrid               matlab.ui.container.GridLayout
        SwapRealXY                      matlab.ui.control.Button
        SwapDiffractionXY               matlab.ui.control.Button
        SwapByteOrder                   matlab.ui.control.Button
        DetectorCoordinatePanel         matlab.ui.container.Panel
        DetectorCoordinateGrid          matlab.ui.container.GridLayout
        DetectorCoordinateUnit          matlab.ui.control.DropDown
        DetectorCoordinatePosition      matlab.ui.control.DropDown
        DetectorCoordinateSystem        matlab.ui.control.DropDown
        LiveUpdateImagesGrid            matlab.ui.container.GridLayout
        CalculationPolicy               matlab.ui.control.DropDown
        CalculationPolicyDropDownLabel  matlab.ui.control.Label
        CalculationPolicyIcon           matlab.ui.control.Image
        InfoTab                         matlab.ui.container.Tab
        InfoGrid                        matlab.ui.container.GridLayout
        ShowVariables                   matlab.ui.control.StateButton
        DatasetInfo                     matlab.ui.control.TextArea
        DatasetInfoTextAreaLabel        matlab.ui.control.Label
        MathTab                         matlab.ui.container.Tab
        MathGrid                        matlab.ui.container.GridLayout
        EvaluateGrid                    matlab.ui.container.GridLayout
        Evaluate                        matlab.ui.control.Button
        MathFormula                     matlab.ui.control.TextArea
        FormulaLabel                    matlab.ui.control.Label
        MathExampleGrid                 matlab.ui.container.GridLayout
        MathHelpIcon                    matlab.ui.control.Image
        MathExample                     matlab.ui.control.Label
        VariablesTab                    matlab.ui.container.Tab
        VariablesGrid                   matlab.ui.container.GridLayout
        VariablesTree                   matlab.ui.container.Tree
        VariablesTable                  matlab.ui.control.Table
        ImportPanel                     matlab.ui.container.Panel
        ImportGrid                      matlab.ui.container.GridLayout
        ImportButtonGrid                matlab.ui.container.GridLayout
        CancelImport                    matlab.ui.control.Button
        SwapDataset                     matlab.ui.control.CheckBox
        ImportData                      matlab.ui.control.Button
        ImportOptionsPanel              matlab.ui.container.Panel
        ImportOptionsGrid               matlab.ui.container.GridLayout
        ImportSummary                   matlab.ui.control.TextArea
        ImportSummaryLabel              matlab.ui.control.Label
        RealPartialImportGrid           matlab.ui.container.GridLayout
        RealPartialImportY              matlab.ui.control.Spinner
        INFrYl                          matlab.ui.control.Label
        RealPartialImportX              matlab.ui.control.Spinner
        INFrXl                          matlab.ui.control.Label
        RealPartialImportYStart         matlab.ui.control.Spinner
        ysub1Label_6                    matlab.ui.control.Label
        RealPartialImportXStart         matlab.ui.control.Spinner
        xsub1Label_6                    matlab.ui.control.Label
        RealPartialImportFramesDist     matlab.ui.control.Spinner
        SamplingIntervalLabel_2         matlab.ui.control.Label
        DiffractionPartialImportGrid    matlab.ui.container.GridLayout
        DiffractionPartialImportY       matlab.ui.control.Spinner
        YLabel_3                        matlab.ui.control.Label
        DiffractionPartialImportX       matlab.ui.control.Spinner
        XLabel_3                        matlab.ui.control.Label
        DiffractionPartialImportYStart  matlab.ui.control.Spinner
        ysub1Label_5                    matlab.ui.control.Label
        DiffractionPartialImportXStart  matlab.ui.control.Spinner
        xsub1Label_5                    matlab.ui.control.Label
        DiffractionPartialImportPixelsDist  matlab.ui.control.Spinner
        BinningDistanceLabel            matlab.ui.control.Label
        ImportPartialFrames             matlab.ui.control.CheckBox
        ImportPartialPixels             matlab.ui.control.CheckBox
        MemoryGrid                      matlab.ui.container.GridLayout
        GPU                             matlab.ui.control.DropDown
        MemoryType                      matlab.ui.control.DropDown
        ImportDatasetInfoPanel          matlab.ui.container.Panel
        ImportDatasetInfoGrid           matlab.ui.container.GridLayout
        ImportFileMetadata              matlab.ui.control.TextArea
        DatasetInfofromMetadataLabel    matlab.ui.control.Label
        ImportFileSizeGrid              matlab.ui.container.GridLayout
        ImportEstimatedFilesize         matlab.ui.control.Label
        ImportEstimatedFilesizeLabel    matlab.ui.control.Label
        ImportActualFilesize            matlab.ui.control.Label
        ImportActualFilesizeLabel       matlab.ui.control.Label
        ImportDimensionGrid             matlab.ui.container.GridLayout
        ImportFramesY                   matlab.ui.control.NumericEditField
        YLabel                          matlab.ui.control.Label
        ImportFramesX                   matlab.ui.control.NumericEditField
        XLabel                          matlab.ui.control.Label
        ImportFramesLabel               matlab.ui.control.Label
        ImportPixelsY                   matlab.ui.control.NumericEditField
        YLabel_2                        matlab.ui.control.Label
        ImportPixelsX                   matlab.ui.control.NumericEditField
        XLabel_2                        matlab.ui.control.Label
        ImportPixelsLabel               matlab.ui.control.Label
        ImportDatasetStructureGrid      matlab.ui.container.GridLayout
        ImportByteOrder                 matlab.ui.control.DropDown
        ByteOrderDropDownLabel          matlab.ui.control.Label
        ImportDataType                  matlab.ui.control.DropDown
        DataTypeLabel                   matlab.ui.control.Label
        FrameFooter                     matlab.ui.control.NumericEditField
        FrameFooterLabel                matlab.ui.control.Label
        FrameHeader                     matlab.ui.control.NumericEditField
        FrameHeaderLabel                matlab.ui.control.Label
        DataOffset                      matlab.ui.control.NumericEditField
        DataOffsetLabel                 matlab.ui.control.Label
        SubDataset                      matlab.ui.control.DropDown
        SubdatasetDropDownLabel         matlab.ui.control.Label
        FileTypeButtonGroup             matlab.ui.container.ButtonGroup
        Custom                          matlab.ui.control.ToggleButton
        HDF5                            matlab.ui.control.ToggleButton
        DM34                            matlab.ui.control.ToggleButton
        MRC                             matlab.ui.control.ToggleButton
        Medipix                         matlab.ui.control.ToggleButton
        EMPAD                           matlab.ui.control.ToggleButton
        ImportFileGrid                  matlab.ui.container.GridLayout
        ImportFilePath                  matlab.ui.control.EditField
        ImportFileSelect                matlab.ui.control.Button
        SavePanel                       matlab.ui.container.Panel
        SaveGrid                        matlab.ui.container.GridLayout
        SaveExportButtonGrid            matlab.ui.container.GridLayout
        SaveCloseButton                 matlab.ui.control.Button
        SaveExport                      matlab.ui.control.Button
        SaveTabGroup                    matlab.ui.container.TabGroup
        SaveImagesTab                   matlab.ui.container.Tab
        SaveImagesGrid                  matlab.ui.container.GridLayout
        SaveImageFormatGrid             matlab.ui.container.GridLayout
        SaveImagePNGGrid                matlab.ui.container.GridLayout
        SaveImageDPI                    matlab.ui.control.NumericEditField
        SaveImgAnnotDPILabel            matlab.ui.control.Label
        SaveImageAnnotations            matlab.ui.control.CheckBox
        SaveImagePNG                    matlab.ui.control.CheckBox
        SaveImageTIFF                   matlab.ui.control.CheckBox
        SaveImageListGrid               matlab.ui.container.GridLayout
        SaveImageList                   matlab.ui.control.ListBox
        SaveImageSelectAll              matlab.ui.control.CheckBox
        ImagestoSaveMultiselectwithCtrlShiftLabel  matlab.ui.control.Label
        SavePrefixAngleGrid             matlab.ui.container.GridLayout
        SavePrefixAngleOuter            matlab.ui.control.CheckBox
        SavePrefixAngleInner            matlab.ui.control.CheckBox
        SavePrefixAngleLabel            matlab.ui.control.Label
        SaveImagePrefixGrid             matlab.ui.container.GridLayout
        SaveImagePrefix                 matlab.ui.control.EditField
        FilenamePrefixLabel             matlab.ui.control.Label
        ExportDatasetTab                matlab.ui.container.Tab
        ExportGrid                      matlab.ui.container.GridLayout
        ExportPartialFrames             matlab.ui.control.CheckBox
        ExportNotes                     matlab.ui.control.TextArea
        SummaryLabel                    matlab.ui.control.Label
        RealPartialExportGrid           matlab.ui.container.GridLayout
        RealPartialExportFramesDist     matlab.ui.control.Spinner
        SamplingIntervalLabel           matlab.ui.control.Label
        RealPartialExportY              matlab.ui.control.Spinner
        ENFrYl                          matlab.ui.control.Label
        RealPartialExportX              matlab.ui.control.Spinner
        ENFrXl                          matlab.ui.control.Label
        RealPartialExportYStart         matlab.ui.control.Spinner
        ysub1Label_4                    matlab.ui.control.Label
        RealPartialExportXStart         matlab.ui.control.Spinner
        xsub1Label_4                    matlab.ui.control.Label
        DiffractionPartialExportGrid    matlab.ui.container.GridLayout
        DiffractionPartialExportY       matlab.ui.control.Spinner
        ENPxYl                          matlab.ui.control.Label
        DiffractionPartialExportX       matlab.ui.control.Spinner
        ENPxXl                          matlab.ui.control.Label
        DiffractionPartialExportYStart  matlab.ui.control.Spinner
        ysub1Label_3                    matlab.ui.control.Label
        DiffractionPartialExportXStart  matlab.ui.control.Spinner
        xsub1Label_3                    matlab.ui.control.Label
        DiffractionPartialExportPixelsDist  matlab.ui.control.Spinner
        BinningDistanceLabel_2          matlab.ui.control.Label
        ExportPartialPixels             matlab.ui.control.CheckBox
        ExportByteOrder                 matlab.ui.control.DropDown
        ExportDataType                  matlab.ui.control.DropDown
        ExportFileGrid                  matlab.ui.container.GridLayout
        ExportDimensionSuffix           matlab.ui.control.CheckBox
        ExportFilename                  matlab.ui.control.EditField
        FilenameLabel                   matlab.ui.control.Label
        SaveDirectoryGrid               matlab.ui.container.GridLayout
        SaveDirectoryPath               matlab.ui.control.EditField
        SaveDirectoryButton             matlab.ui.control.Button
        DebugContextMenu                matlab.ui.container.ContextMenu
        Test1Menu                       matlab.ui.container.Menu
        Test2Menu                       matlab.ui.container.Menu
        SaveVecMenu                     matlab.ui.container.Menu
        ReimportMenu                    matlab.ui.container.Menu
        ResetQuant4DMenu                matlab.ui.container.Menu
        EnableallUIsMenu                matlab.ui.container.Menu
        CDDelContext                    matlab.ui.container.ContextMenu
        CDDelResetMenu                  matlab.ui.container.Menu
    end

    properties (Access = public)
        debug                                                           % Debug mode: `0` to disable; Not `0` enables timers/tracers
        data                                                            % For imported dataset, may be moved to GPU
        memfile                                                         % Virtual mapping of the imported dataset
        sys_constants                                                   % Constants for system info that set at app startup
        tmp_variables                                                   % Temporary global variables, e.g. for progress bar and import etc., should never be cleared
        dataset_parameters                                              % Parameters of the current imported dataset; should be constants (except for data type conversion)
        common_parameters                                               % Common parameters, should be cleared when a dataset is unloaded
        previous_values                                                 % Previous values, should be cleared when a dataset is unloaded
        figures                                                         % Other figure windows
        image_axes                                                      % Image axes
        images                                                          % Arrays of raw data for the images/masks to be plotted/saved
        image_displays                                                  % `Image` Objects with modified contrast for display, e.g. by `app.image_displays.(id) = imagesc()`
        annotations                                                     % Image annotations on `axes` `uiaxes`, excluding annotations on `figure` `uifigure`
        masks                                                           % For masking `app.data`
        ui_groups                                                       % Different groups of figures/axes/images; assigned just once at startup in `create_other_windows()`
        center = [0 0];                                                 % Central beam absolute pixel coordinates
        center_rel = [0 0];                                             % Relative position of `app.center`: [0 0] (when "Absolute" position) or `app.center` (when "Relative")
        diff_scale = 1;                                                 % Diffraction pixel scale: 1 (when in pixel) or mrad/px (when in mrad)
        real_scale = 1;                                                 % Real-space pixel scale: 1 (when in pixel) or nm/px (when in nm). Not implemented yet
        variable_nodes                                                  % uitree nodes to display variables for users
        byte_size = struct("uint8", 1, ...                              % size of each supported data type in bytes
                           "int8", 1, ...
                           "uint16", 2, ...
                           "int16", 2, ...
                           "single", 4, ...
                           "int32", 4, ...
                           "uint32", 4, ...
                           "double", 8, ...
                           "int64", 8, ...
                           "uint64", 8);
    end

    methods (Access = public)

        % Wrapper for `OnOffSwitchState()`
        function output = switch_on_off(~, status)
            output = string(matlab.lang.OnOffSwitchState(status));
        end

        % Set properties of objects that are not `source` if it is a changing event
        function set_external_source(app, event, object, varargin)
            % Get source
            source = event.Source;

            % If not a changing event, set source as well
            if is_static_event(app, event) || isempty(source)
                source = {[]};
            end

            % Convert source to string if it is a character string
            if ischar(source)
                source = string(source);
            end

            % get index of objects that are NOT source
            object = object(:);
            source_index = object ~= source;

            % set properties and values for each object
            for i = 1:numel(varargin)/2
                prop = varargin{2*i-1};
                val = varargin{2*i};
                
                if iscell(prop) && all(numel(object) == numel(prop))
                    prop = prop(source_index);
                end
                
                if iscell(val) && all(numel(object) == numel(val))
                    val = val(source_index);
                end

                set(object(source_index),prop,val);
            end
        end

        % Rotation matrix, for degree input
        function R = rotation_matrix(~, degrees)
            R = [cosd(degrees), sind(degrees);
                -sind(degrees), cosd(degrees)];
        end

        % Function to test whether an event is NOT "MovingROI" or
        % "ValueChanging"; return `true` for `[]` etc. This helps to reduce
        % computational load especially on image refresh
        function status = is_static_event(~, event)
            status = isempty(event) || ...
                     ~(isfield(event,"EventName") || ...
                     isprop(event,"EventName")) || ...
                     isempty(event.EventName) || ...
                     ~ismember(event.EventName, ["MovingROI" "ValueChanging"]);
        end

        % Return all  DataSets with some key info
        function h = h5_datasets(~,file)

            h5_info = h5info(file);
            h = struct("Name", {}, ...
                       "Dataspace", {}, ...
                       "Datatype", {}, ...
                       "offset", {}, ...
                       "size", {}, ...
                       "byte_order", {}, ...
                       "type", {});

            get_groups(h5_info);
            type_details();

            % End of main function
            function get_groups(g)
                for i = 1:length(g)
                    datasets(g(i).Name, g(i).Datasets);
                    get_groups(g(i).Groups);
                end
            end

            function datasets(p, d)
                if p(end) ~= '/'
                    p(end + 1) = '/';
                end

                for i = 1:length(d)
                    h(end + 1).Name = strcat(p, d(i).Name); %#ok<*AGROW>
                    h(end).Dataspace = d(i).Dataspace;
                    h(end).Datatype = d(i).Datatype;
                end
            end

            function type_details()
                fid = H5F.open(file);
                for j = 1:length(h)
                    dset_id = H5D.open(fid, h(j).Name);
                    h(j).size = H5D.get_storage_size(dset_id);
                    try
                        h(j).offset = H5D.get_offset(dset_id);
                    catch
                        h(j).offset = -1;
                    end

                    type_id = H5D.get_type(dset_id);

                    byte_order = H5T.get_order(type_id);
                    % Byte order
                    switch byte_order
                        case 0
                            h(j).byte_order = 'H5T_ORDER_LE';
                        case 1
                            h(j).byte_order = 'H5T_ORDER_BE';
                        case 2
                            h(j).byte_order = 'H5T_ORDER_VAX';
                        case 3
                            % Not yet exists in MatLab
                            h(j).byte_order = 'H5T_ORDER_MIXED';
                        case 4
                            h(j).byte_order = 'H5T_ORDER_NONE';
                        case (-1)
                            h(j).byte_order = 'H5T_ORDER_ERROR';
                    end

                    data_type = h(j).Datatype;
                    switch data_type.Class
                        case 'H5T_FLOAT'
                            if data_type.Size == 4
                                h(j).type = "single";
                            elseif data_type.Size == 8
                                h(j).type = "double";
                            end

                        case 'H5T_INTEGER'
                            h(j).type = sprintf('int%d', data_type.Size * 8);
                            if ~H5T.get_sign(type_id)
                                h(j).type = strcat('u', h(j).type);
                            end
                    end
                    H5T.close(type_id);
                    H5D.close(dset_id);
                end
                h(end + 1).Name = '/';
                h(end).offset = 0;
                h(end).size = H5F.get_filesize(fid);
                H5F.close(fid);
            end
        end

        % Function to debug and time functions
        function debug_toc(app, event, notes, tic_start)
            if app.debug
                source_name = "";
                event_name = "";

                if ~isempty(event)
                    % Find name of the source field_name` by comparing the source with fieldnames of `app`
                    if (isfield(event, "Source") || isprop(event, "Source")) && ~isempty(event.Source)
                        field_name = fieldnames(app);
                        for i = 1:length(field_name)
                            if ~isa(app.(field_name{i}), 'gpuArray') && isequal(app.(field_name{i}), event.Source)
                                source_name = field_name{i};
                                break;
                            end
                        end
                    end

                    % Get EventName
                    if (isfield(event, "EventName") || ...
                        isprop(event, "EventName")) && ...
                        ~isempty(event.EventName)
                        event_name = event.EventName;
                    end
                end

                % caller(2).name`: caller of the function on dbstack
                caller = dbstack;

                % print debug notes to Command Window
                fprintf('%s, %s, %s, %s: %.3f s\n', caller(2).name, source_name, event_name, notes, toc(tic_start));
            end
        end

        % Function to flash the background of UIs, which have `BackgroundColor`
        % This process will pause the whole program during flashing
        function flash_background(~, target)
            % Input `target` can be arrays of multiple UIs;
            % which will flash simultaneously 
            
            % Get original color
            original_color = get(target, "BackgroundColor");

            % Make `original_color` a cell if not already
            if ~iscell(original_color)
                original_color = {original_color};
            end

            % commence flashing between green and `orignal_color` twice
            for i = 1:2
                pause(0.2);
                set(target, "BackgroundColor",'g');
                pause(0.2);
                set(target,{"BackgroundColor"},original_color)
            end
        end

        % state button background color change to highlight when it
        % is/isn't enabled
        function change_icon_background(app, source)
            if source.Value
                source.BackgroundColor = app.sys_constants.highlight_color;
            else
                source.BackgroundColor = app.sys_constants.background_color + 0.02;
            end
        end

        % Function to get all available GPUs
        function get_GPU(app)
            % initialize items
            app.GPU.Items = {''};
            app.GPU.ItemsData = 0;
            app.sys_constants.nGPU = -1;

            % Check if parallel computing toolbox is installed for GPU acceleration
            if any(strcmpi("Parallel Computing Toolbox", {ver().Name}))
                try app.sys_constants.nGPU = gpuDeviceCount(); catch; end
            end

            % Find GPU devices and turn on/off GPU support;
            % `app.GPU.Value` == `0` always means "GPU off"
            switch app.sys_constants.nGPU
                case -1
                    app.GPU.Items = {'Parallel Computing Toolbox missing. GPU acceleration disabled.'};
                    app.GPU.Value = 0;
                
                case 0 % no GPU devices
                    app.GPU.Items = {'No GPU found.'};
                    app.GPU.ItemsData = 0;
                    app.GPU.Value = 0;
                
                otherwise % 1 or more devices found
                    maxmem = 0;
                    for i = 1:app.sys_constants.nGPU
                        try
                            tmp = parallel.gpu.GPUDevice.getDevice(i);
                            app.sys_constants.gpu(i).memory = tmp.TotalMemory;
                            app.sys_constants.gpu(i).name = tmp.Name;
                            app.GPU.Items{i} = sprintf('%d. %s %.1f GiB', i, app.sys_constants.gpu(i).name, app.sys_constants.gpu(i).memory/1024^3);
                            app.GPU.ItemsData(i) = i;
                            
                            % Default to GPU with the most memory
                            if app.sys_constants.gpu(i).memory > maxmem
                                maxmem = app.sys_constants.gpu(i).memory;
                                app.GPU.Value = i;
                            end
                        catch
                        end
                    end
                    app.GPU.Items{end + 1} = 'GPU off';
                    app.GPU.ItemsData(end + 1) = 0;
            end
        end

        % Function to Check usable system memory and make sure the file will fit
        function get_memory(app)
            if ispc
                [~,tmp] = memory;
                app.sys_constants.total_memory = tmp.PhysicalMemory.Total;
                app.sys_constants.free_memory = tmp.PhysicalMemory.Available;

            elseif ismac
                [~,app.sys_constants.total_memory] = system('sysctl -n hw.memsize');
                app.sys_constants.total_memory = str2double(app.sys_constants.total_memory);
                [~,page_memory_size] = system("vm_stat | grep 'page size' | awk '{print $8}'");
                page_memory_size = str2double(page_memory_size);
                [~,wired_memory] = system("vm_stat | grep 'wired' | awk '{print $4}'");
                wired_memory = str2double(wired_memory)*page_memory_size;
                app.sys_constants.free_memory = app.sys_constants.total_memory - wired_memory;
            
            elseif isunix % memory output is in kB => multiply by 1024 for consistency 
                [~,app.sys_constants.total_memory] = system("cat /proc/meminfo | grep MemTotal: | awk '{print $2}'");
                app.sys_constants.total_memory = str2double(app.sys_constants.total_memory)*1024; 
                [~,app.sys_constants.free_memory] = system("cat /proc/meminfo | grep MemAvailable: | awk '{print $2}'");
                app.sys_constants.free_memory = str2double(app.sys_constants.free_memory)*1024;
            end
        end

        % ************************** UI components ************************       
        % Import/export binning/sampling distances:
        %   app.DiffractionPartialImportPixelsDist
        %   app.RealPartialImportFramesDist
        %   app.DiffractionPartialExportPixelsDist
        %   app.RealPartialExportFramesDist
        %
        % DATASET DIMENSIONS IN FILE ON DISK:
        %   app.ImportPixelsX
        %   app.ImportPixelsY
        %   app.ImportFramesX
        %   app.ImportFramesY
        %
        % IMPORT/EXPORT DIMENSIONS (AFTER BINNING/SAMPLING/CROPPING):
        %   app.DiffractionPartialImportX
        %   app.DiffractionPartialImportY
        %   app.RealPartialImportX
        %   app.RealPartialImportY
        %   app.DiffractionPartialExportX
        %   app.DiffractionPartialExportY
        %   app.RealPartialExportX
        %   app.RealPartialExportY
        %
        % IMPORT/EXPORT START POSITIONS (CROPPING):
        %   app.DiffractionPartialImportXStart
        %   app.DiffractionPartialImportYStart
        %   app.RealPartialImportXStart
        %   app.RealPartialImportYStart
        %   app.DiffractionPartialExportXStart
        %   app.DiffractionPartialExportYStart
        %   app.RealPartialExportXStart
        %   app.RealPartialExportYStart
        %
        %
        % ******************** app.dataset parameters *********************
        %
        % DATASET DIMENSIONS IN FILE ON DISK:
        %   app.dataset_parameters.n_pixels_file
        %   app.dataset_parameters.n_frames_file
        %
        % IMPORT DIMENSIONS (AFTER BINNING/SAMPLING/CROPPING):
        %   app.dataset_parameters.n_pixels
        %   app.dataset_parameters.n_frames
        %
        % IMPORT START POSITIONS (CROPPING):
        %   app.dataset_parameters.pixels_start
        %   app.dataset_parameters.frames_start
       
        % Function to calculate importing/exporting ranges
        function [notes, binning, xy_start, n_points, xy_end] = range_import_export(app, event)
            source = event.Source;
            
            % `uispinner` upper/lower limits must be different, so now set lower limit `0` but force minimum to `1`
            if isfield(event, "Value") || isprop(event, "Value")
                value = max(event.Value, 1);
            end
           
            % Round positions from ROIs
            if isprop(event,'CurrentPosition')
                position = round(event.CurrentPosition);
            end

            % `space`/`unit`: "Diffraction"/"Pixels" OR "Real"/"Frames"
            if contains(source.Tag, "Diffraction")
                space = "Diffraction";
                unit = "Pixels";
            else
                space = "Real";
                unit = "Frames";
            end

            % `action`: "Import" or "Export"
            if contains(source.Tag, "Import")
                action = "Import";
                % Raw dataset dimensions, e.g. app.ImportPixelsX.Value
                data_size = [app.(action+unit+"X").Value, app.(action+unit+"Y").Value];
            else
                action = "Export";
                % Loaded dataset dimensions
                data_size = app.dataset_parameters.("n_"+lower(unit));
            end

            % Binning/Sampling (app.DiffractionPartialImportPixelsDist, app.DiffractionPartialExportPixelsDist, app.RealPartialImportFramesDist or app.RealPartialExportFramesDist)
            binning = app.(space+"Partial"+action+unit+"Dist").Value;
            
            % Get values from UI
            switch source.Tag
                case "Export Diffraction ROI" % From Export Diffraction ROIs
                    xy_start = position(1:2);
                    n_points = max(floor((position(3:4)+1)/binning), 1);
                
                case "Export Real ROI" % From Export Real-space ROIs
                    xy_start = position(1:2);
                    n_points = floor(position(3:4)/binning)+1;
                
                otherwise % From other sources
                    % app.DiffractionPartialImportXStart, app.RealPartialExportXStart etc, (X/Y)Start UIs
                    xy_start = [app.(space+"Partial"+action+"XStart").Value app.(space+"Partial"+action+"YStart").Value];
                    
                    % app.DiffractionPartialImportX, app.RealPartialExportX etc, n_points X & Y UIs
                    n_points = [app.(space+"Partial"+action+"X").Value app.(space+"Partial"+action+"Y").Value];
            end
            
            % Replace value with current event value
            switch source
                case app.(space+"Partial"+action+unit+"Dist")
                    % Binning/Sampling UI
                    binning = value;
                
                case app.(space+"Partial"+action+"XStart")
                    % XStart UI
                    xy_start(1) = value;
                
                case app.(space+"Partial"+action+"YStart")
                    % YStart UI
                    xy_start(2) = value;
                
                case app.(space+"Partial"+action+"X")
                    % n_points X UI
                    n_points(1) = value;
                
                case app.(space+"Partial"+action+"Y")
                    % n_points Y UI
                    n_points(2) = value;
                
                case {app.("Import"+unit+"X") app.("Import"+unit+"Y") app.(action+"Partial"+unit)}
                    % Reset range options if dataset raw dimension changes OR partial selection enables/disables
                    % Update binning/sampling UI limits
                    app.(space+"Partial"+action+unit+"Dist").Limits(2) = min(data_size);
                    
                    % Update preview frame limits
                    if action == "Import" && unit == "Frames"
                        set([app.PreviewFrameX, app.PreviewFrameY], ...
                            {"Limits"}, ...
                            {[0 data_size(1)];[0 data_size(2)]}, ...
                            {"Value"}, ...
                            {data_size(1);data_size(2)});
                    end
                    binning = 1;
                    xy_start = [1 1];
                    n_points = data_size;
            end

            % In general, n_points are determined by (X/Y)Start and Binning/Sampling, unless n_points are the source
            % Whether n_points X or Y are not the source
            source_index = [app.(space+"Partial"+action+"X") app.(space+"Partial"+action+"Y")] ~= source;
            if unit == "Pixels"
                % Determine n_points X/Y if not source
                n_points(source_index) = max(1, min(n_points(source_index), ...
                                             floor((data_size(source_index)-xy_start(source_index)+1)/binning)));
                xy_start = min(xy_start, data_size-n_points*binning+1);
                
                % Endpoints of Pixel ranges
                xy_end = xy_start + n_points*binning - 1;

                % Upper limits of Pixel (X/Y)Start and n_points X & Y
                xy_start_limit = data_size - binning + 1;
                n_points_limit = floor(data_size/binning);
                
                % Positions for Export ROI
                position = [xy_start, n_points*binning-1];

                notes = "Diffraction Range (in pixels [px])";
                unit_abbreviation = " px";
                binning_text = "binning to ";

            else % "Frames"
                % Frame sampling has slightly different logic to pixel binning
                n_points(source_index) = min(n_points(source_index), ...
                                             floor((data_size(source_index) - xy_start(source_index))/binning) + 1);
                
                % Endpoints of Frame ranges
                xy_start = min(xy_start, data_size - (n_points-1)*binning);
                xy_end = xy_start + (n_points - 1)*binning;
                
                % Upper limits of Frame (X/Y)Start and n_points X & Y
                xy_start_limit = data_size;
                n_points_limit = floor((data_size-1)/binning)+1;
                
                % Positions for Export ROI
                position = [xy_start (n_points-1)*binning];
                
                notes = "Real-space Range (in frames [fr])";
                unit_abbreviation = " fr";
                binning_text = "sampling ";
            end

            % Set (X/Y)Start, n_points X & Y and Binning/Sampling UIs
            set([app.(space+"Partial"+action+"XStart"); ...
                 app.(space+"Partial"+action+"YStart"); ...
                 app.(space+"Partial"+action+"X"); ...
                 app.(space+"Partial"+action+"Y"); ...
                 app.(space+"Partial"+action+unit+"Dist")], ...
                {"Limits"}, ...
                num2cell([xy_start_limit n_points_limit min(data_size)]'.*[0 1],2), ...
                {"Value"}, ...
                num2cell([xy_start n_points binning]'))

            % Try to update Export ROIs
            if action == "Export"
                set(findobj(app.ui_groups.(lower(space)+"_axes"), "Tag", "Export "+space+" ROI"), "Position", position)
            end
            
            points_text = n_points + unit_abbreviation;
            
            % If any binning/sampling add text explaining the change in
            % number of points
            if binning > 1
                points_text = binning_text + points_text + " from " + ...
                     (xy_end - xy_start + 1) + unit_abbreviation;
            end
            
            % If not from 1st to last pixel/frame
            source_index = xy_start > 1 | xy_end < data_size;
            points_text(source_index) = n_points(source_index) + unit_abbreviation + ...
                               ", " + (xy_start(source_index)-1) + "/" + ...
                               (data_size(source_index)-xy_end(source_index)) + ...
                               unit_abbreviation + " offset from start/end";

            % Range notes
            notes = sprintf("%s:\n  X = %s–%s (%s)\n  Y = %s–%s (%s)\n", notes, ["" + xy_start; "" + xy_end; points_text]);
        end

        % Function for progress bar of data import/export, with a `timer()` to update its progress per second
        function [wait_bar, tmr] = import_export_progress(app, process, n_frames, frame_size)
            % `process` will be 'Import'/'Export', and used for texts like "Import/Export" + "ing/ed"
            wait_bar = progress_dialog(app, sprintf("\n%sing ...\n",process), "Data "+process, true);
            
            % Total time and global variable for processed frames of
            % [this second, last second]
            total_time = tic;
            app.tmp_variables.frame_number = [0 0];
            
            % `timer()` to run the sub function per second
            tmr = timer("TimerFcn", @(~,~)update_waitbar(app, wait_bar), ...
                        "ExecutionMode", "fixedDelay", ...
                        "Name", process);
            
            % Start the timer now
            tmr.start();
            
            % Kill the timer when progress windows closes
            wait_bar.DeleteFcn = {@(~,~,t) kill_timer(app,t), tmr};

            % Update progress per second
            function update_waitbar(app, wait_bar)
                % Current  and previous frame number
                current_frame = app.tmp_variables.frame_number(1);
                previous_frame = app.tmp_variables.frame_number(2);

                % Estimate remaining time based on fraction of frames
                % processed since timer executes @ 1 Hz
                if current_frame == previous_frame
                    remaining_time = 0;
                else
                    remaining_time = (n_frames - current_frame) / ...
                                     (current_frame - previous_frame);
                end

                % update waitbar
                waitbar(current_frame/n_frames, ...
                        wait_bar, ...
                        sprintf("%sed frames: %d/%d\nSpeed: %.2f MiB/s\nElapsed: %s \t|\t Remaining: %s", ...
                                process, current_frame, n_frames, (current_frame - previous_frame) * frame_size/1024^2, ...
                                duration(seconds(floor(toc(total_time))),'Format','mm:ss'), ...
                                duration(floor(seconds(remaining_time)),'Format','mm:ss') ...
                                )...
                         );
                
                % move current frame number to previous frame number slot
                app.tmp_variables.frame_number(2) = current_frame;
            end

            % Function to kill the timer and keep windows disabled when
            % progress windows is closed
            function kill_timer(app, tmr)
                stop(tmr);
                delete(tmr);
                app.enable_windows(false)
            end
        end

        % Function to import mask for given (diffraction/real) space
        function mask = import_mask(app, space)
            % get `data_size` from `space`: "n_pixels" or "n_frames"
            if space == "Real"
                space_name = "Real-space";
                data_size = app.dataset_parameters.n_frames;
            else % "Diffraction"
                space_name = "Diffraction";
                data_size = app.dataset_parameters.n_pixels;
            end

            mask = [];
            message = "";
            file_types = '*.tif;*.tiff;*.png;*.jpg;*.jpeg;*.bmp;*.gif';
            
            % open file selection UI
            [file,fpath] = uigetfile({file_types, ['Supported Types (' file_types ')']; ...
                                      '*.*','All Files (*.*)'}, ...
                                      sprintf("Select a mask image with shape [%d,%d]",data_size), ...
                                      "MultiSelect", "off");
            
            % get file extension
            try
                [~,~,extension] = fileparts(file);
            catch
                extension = "";
            end
            
            % force lowercase
            switch lower(extension)
                case {'.tif';'.tiff';'.png';'.jpg';'.jpeg';'.bmp';'.gif'}
                    % permute mask, `imread()` transposes images implicitly
                    mask = permute(imread([fpath,file]), [2 1 3]);
                    
                    % Check size/orientation
                    mask_size = size(mask, [1 2]);
                    
                    % If dimension mismatch
                    if ~isequal(mask_size, data_size)
                        % If dimension transposed
                        if isequal(mask_size, flip(data_size))
                            mask = permute(mask, [2 1 3]);
                            message = message + ...
                                sprintf("Image size [%d,%d] transposed to fit data's %s size [%d,%d].\n", mask_size, space_name, data_size);
                        else
                            notification_dialog(app, "warn", sprintf("Image size [%d,%d] does not fit data's %s size [%d,%d]!", ...
                                mask_size, space_name, data_size),"Dimension Mismatch");
                            return
                        end
                    end
                    % Convert to double
                    mask = double(mask);
                
                case ""
                    % Canceled, etc. -> return now
                    return
                
                otherwise
                    notification_dialog(app, "warn", "File type '*"+extension+"' currently not supported!", "Import Failed");
                    return
            end

            % check for NaNs and Infs, set any to 0
            if any(isnan(mask(:)) | isinf(mask(:)))
                mask(isnan(mask) | isinf(mask)) = 0;
                message = message + "NaN/Inf found in image, and set to 0.\n";
            end

            % Convert RGB to gray scale
            if size(mask,3) > 1
                mask = rgb2gray(mask);
                message = message + "Converted from RGB to grayscale.\n";
            end

            % Try to scale the maximum to 1
            mask_max = max(mask(:));
            if mask_max ~= 0 && mask_max ~= 1
                mask = mask/mask_max;
                message = message+"The maximum was "+mask_max+"; now scaled to 1, by `IMG = IMG/"+mask_max+"`.\n";
            end

            % Notification
            if message ~= ""
                message = "Please note:\n"+message+"\n";
            end
            notification_dialog(app, "help", sprintf(message+"Please check the "+space_name+" Mask image."),'Mask Imported');
            figure(app.figures.(space+"Mask"))
        end

        % Wrapper function for (re)plotting all images
        function plot_all_patterns(app, event)
            debug_time = tic;

            % Update images if visible OR not in changing event;
            % include CoMs if in the modes
            for id = app.ui_groups.image_id(contains(app.ui_groups.image_type, ["Primary";"Mask"]) | ...
                                            (ismember(app.Mode.Value, ["CoM" "DPC"]) & ...
                                             app.ui_groups.image_type == "CoM" & ...
                                             app.ui_groups.image_id ~= "ColorWheel"))'
                
                if is_static_event(app, event) || app.figures.(id).Visible
                    plot_image(app, event, id)
                end

            end

            debug_toc(app, event, "", debug_time)
        end

        % Generic function for plotting an image on a given axis with brightness/contrast/gamma values
        function plot_image(app, event, id)
            % Special case for plotting the color wheel
            if id == "ColorWheel"
                plot_colorwheel();
            end

            % get axis and user_data
            ax = app.image_axes.(id);
            user_data = ax.UserData;

            % clear old `image` on `ax`
            delete(findobj(ax, "Tag", "Image "+user_data.space+" "+user_data.type));
            
            % Abort if image not yet ready
            if ~isfield(app.images, id) || isempty(app.images.(id)) %|| isempty(app.masks)
                if isequal(id, app.DisplayImage.Value)
                    app.ImageStatistics.Value = ax.UserData.name;
                end
                return;
            end

            % Raw array is column-major; transposed to row-major `im`
            im = app.images.(id)';

            % special case for color mixing custom masks
            if strcmp(app.Mode.Value, "Custom") && strcmp(app.CustomDetectorInterMask.Value, "Color Mix")
                % otherwise take over the image with a color mix
                switch id
                    case "Real"
                        % Zeros if no masks yet
                        if ~isfield(app.masks,'CustomColorMix')
                            im = zeros(app.dataset_parameters.n_frames, app.dataset_parameters.data_type);
                        else
                            im = app.images.RealColorMix;
                        end
                    case "DiffractionMask"
                        % Zeros if no masks yet
                        if ~isfield(app.masks,'CustomColorMix')
                            im = zeros(app.dataset_parameters.n_pixels, app.dataset_parameters.data_type);
                        else
                            im = app.masks.CustomColorMix;
                        end
                end
            end

            % Put usable values in `image_stats` for statistics
            image_stats = im(~(isnan(im) | isinf(im)));
            
            % Make `image_stats` NaN if no usable values
            if isempty(image_stats)
                image_stats = NaN();
            end

            % Find maximum/minimum/range
            [image_min, image_max] = bounds(image_stats(:));
            image_range = image_max - image_min;
            
            % Brightness between -0.5 and 0.5; Contrast between -Inf and 0.5
            image_brightness = (user_data.brightness-50)/100;
            image_contrast = log2(max(realmin(), user_data.contrast/50))/2; % Avoid log2(0)
            
            % Display limits from brightness/contrast; have the same magnitude to the raw image `im`
            image_low = image_min - (image_brightness - image_contrast)*image_range;
            image_high = image_max - (image_brightness + image_contrast)*image_range;
            
            % Normalize image, and apply display thresholds; `im_norm` is always between 0 and 1
            if image_high == image_low && (image_high <= 1 || image_high >= 0)
                % If display limits are both 1 or 0, then no rescale (e.g. `im` is all 1 or 0)
                im_norm = im;

            elseif image_high > image_low || (image_high == image_low && (image_high > 1 || image_high < 0))
                % Normal case for upper limit > lower limit, just apply the limits than rescale
                im_norm = rescale(im, "InputMin", image_low, "InputMax", image_high);
            
            else
                % Display limits are too close or identical, then make
                % `im_norm` binary; If limits are NaN, make them 0.5
                im_norm = (image_high + image_low)/2;
                im_norm(isnan(im_norm)) = 0.5;
                image_high = im_norm;
                image_low = im_norm;
                im_norm = double(im > im_norm);
            end
            
            % Plot the row-major array as row-major `image` and apply gamma
            im_display = imagesc(ax, ...
                                 im_norm.^user_data.gamma, ...
                                 "Tag", "Image "+user_data.space+" "+user_data.type);
            
            % Plot the background, which zooms with the image, for images that rely on `AlphaData` shading
            background = fill(ax, ...
                              [0.5 ax.XLim(2) ax.XLim(2) 0.5], ...
                              [0.5 0.5 ax.YLim(2) ax.YLim(2)], ...
                              "k", ...
                              "Tag", "Image "+user_data.space+" "+user_data.type);

            % Plot mask opacity on non-mask/non-preview image
            if ~ismember(user_data.type, ["Mask" "Preview"])
                mask = app.images.(user_data.space+"Mask")';
                im_display.AlphaData = (1-user_data.mask_opacity/100)*(1-logical(mask))+logical(mask);
            end
            
            % Shade CoM/DPC Phase with Magnitude
            if id == "CoMPhMag"
                im_display.AlphaData = im_display.AlphaData.*app.images.CoMMagNorm';
            end
            
            % Move the `image` to the bottom level of the axis children
            ax.Children = [setdiff(ax.Children,[im_display; background],'stable'); im_display; background];
            
            % Assign the `im_display` `background` to global handles
            app.image_displays.(id) = im_display;
            app.image_displays.(id+"Background") = background;

            % Plot vector field, if selected
            plot_vector_field();
            
            % Only update statistics if not in changing Event AND it is the "selected" image
            if isequal(id, app.DisplayImage.Value) && is_static_event(app, event)
                update_display_image_stats();
            end

            %%% Sub functions
            % Function to update image statistics
            function update_display_image_stats()
                debug_time = tic;
                
                % Get Histogram axis
                hist_ax = app.HistogramAxes;

                % Update histogram; skip if image unchanged
                if is_different_to_previous(app, 'image_stats', image_stats)
                    
                    % Warn if NaNs/Infs are present
                    if numel(im) ~= numel(image_stats)
                        nanwarn = "Contains NaN/Inf";
                    else
                        nanwarn = "";
                    end

                    % Update ImageStatistics display panel text
                    app.ImageStatistics.Value = ...
                        sprintf('min:% -14.7g std:% -14.7g\nmax:% -14.7g sum:% -14.7g\navg:% -14.7g %s', ...
                        image_min, std(image_stats,0, "all"), image_max, sum(image_stats, "all",'omitnan'), mean(image_stats, "all"), nanwarn);

                    % Delete any previous histogram
                    delete(findobj(hist_ax, "Tag", "HistBars"))

                    % Generate an intensity histogram for the selected image
                    h = histogram(hist_ax, ...
                                  image_stats, ...
                                  128, ...
                                  "FaceColor", "r", ...
                                  "Tag", "HistBars");

                    % Set histogram plot limits
                    if image_max > image_min
                        hist_ax.XLim = [image_min image_max] + [-1 1]*0.02*image_range;
                    else
                        hist_ax.XLim = [-Inf Inf];
                    end
                    
                    % Make sure upper limit is at least 1
                    hist_ax.YLim = [0.5 max([h.BinCounts*1.1, 1])];
                end

                % Update histogram annotations; skip if values unchanged
                if is_different_to_previous(app, 'hist', {ax image_max image_min image_range image_high image_low user_data.gamma app.HistogramLog.Value})
                    % Update ROIs
                    % Draw ROI of range if not yet
                    if isempty(findobj(hist_ax, "Tag", "HistROI"))
                        app.annotations.hist.Box = drawrectangle(hist_ax, ...
                                                                 "Position", [1 1 1 1], ...
                                                                 "LineWidth", 1, ...
                                                                 "MarkerSize", eps(), ...
                                                                 "FaceAlpha", 0.1, ...
                                                                 "InteractionsAllowed", "translate");

                        app.annotations.hist.Hi = drawpoint(hist_ax, "Position",[1 1]);
                        app.annotations.hist.Lo = drawpoint(hist_ax, "Position",[1 1]);
                        
                        % add listeners
                        addlistener([app.annotations.hist.Box, ...
                                     app.annotations.hist.Lo, ...
                                     app.annotations.hist.Hi], ...
                                    "MovingROI", @move_histogram_high_low);
                        user_data.Move = addlistener([app.annotations.hist.Box, ...
                                                      app.annotations.hist.Lo, ...
                                                      app.annotations.hist.Hi], ...
                                                     "ROIMoved", @move_histogram_high_low);
                        
                        % set parameters
                        set([app.annotations.hist.Box, ...
                             app.annotations.hist.Lo, ...
                             app.annotations.hist.Hi], ...
                            "Deletable", false, ...
                            "UserData", user_data, ...
                            "Tag", "HistROI")
                    end

                    % Save current display limits to ROIs
                    app.annotations.hist.Box.UserData = {ax image_max image_min image_range image_high image_low};

                    % Update ROIs if not called from them
                    if ~ismember(event.Source, [app.annotations.hist.Box app.annotations.hist.Lo app.annotations.hist.Hi])
                        move_histogram_high_low(event.Source, event);
                    end

                    % Update other annotations
                    delete(findobj(hist_ax, "Tag", "HistAnnot"))
                    
                    % Gamma line
                    x = linspace(image_low,image_high,100);
                    y = ((x-image_low)/(image_high-image_low)).^user_data.gamma.*range(hist_ax.YLim) + hist_ax.YLim(1);
                    app.annotations.hist.gamCrv = plot(hist_ax, ...
                                                       x, y, ...
                                                       "Color", "k", ...
                                                       "Tag", "HistAnnot");
                    
                    % Print display limits on the histogram
                    app.annotations.hist.note = text(hist_ax, ...
                                                     0.02, 0.96, ...
                                                     sprintf('Hi:% -.3g\nLo:% -.3g', image_high, image_low), ...
                                                     "VerticalAlignment", "top", ...
                                                     "BackgroundColor", "w", ...
                                                     "Units", "normalized", ...
                                                     "FontName", "FixedWidth", ...
                                                     "FontWeight", "bold", ...
                                                     "FontSize", 11, ...
                                                     "Tag", "HistAnnot");
                    app.annotations.hist.note.BackgroundColor(4) = 0.7;
                end

                debug_toc(app, event, "", debug_time)

                % Sub function for ROI update
                function move_histogram_high_low(source, event)
                    if isprop(event,'CurrentPosition')
                        position = event.CurrentPosition;
                    end
                    
                    % Get preset values
                    hist_annotation = app.annotations.hist;
                    hist_ax = app.HistogramAxes;
                    [i_ax, i_max, i_min, i_range, i_hi, i_lo] = deal(hist_annotation.Box.UserData{:});
                    y_lim = hist_ax.YLim;

                    switch source
                        case hist_annotation.Box
                            i_lo = position(1);
                            i_hi = position(1) + position(3);

                        case hist_annotation.Lo
                            i_lo = position(1);

                        case hist_annotation.Hi
                            i_hi = position(1);
                    end
                    
                    % Sort out upper/lower limits
                    i_hi = max(i_lo,i_hi);
                    i_lo = min(i_lo,i_hi);
                    
                    % Find mid-height of the histogram, log- or linear-scale
                    if app.HistogramLog.Value
                        ymid = sqrt(prod(y_lim));
                    else
                        ymid = mean(y_lim);
                    end
                    
                    % Update ROI
                    set([hist_annotation.Box, ...
                         hist_annotation.Hi, ...
                         hist_annotation.Lo], ...
                        {"Position"}, ...
                        {[i_lo, y_lim(1), i_hi-i_lo, y_lim(2)-y_lim(1)]; ...
                         [i_hi ymid]; ...
                         [i_lo ymid]})
                    
                    % Limit ROI translating area
                    drawing_area = [hist_ax.XLim(1), ymid, range(hist_ax.XLim), 0];
                    
                    if any(isinf(drawing_area))
                        drawing_area = "auto";
                    end

                    set([hist_annotation.Box, ...
                         hist_annotation.Hi, ...
                         hist_annotation.Lo], ...
                        "DrawingArea", drawing_area, ...
                        "Visible", app.DisplayContrast.Enable)
                    
                    % Update UI and image if called from ROIs
                    if ismember(source,[hist_annotation.Box, hist_annotation.Hi, hist_annotation.Lo])
                        i_ax.UserData.brightness = clip(double(50+(i_max+i_min-i_hi-i_lo)/2/i_range*100),0,100);
                        i_ax.UserData.contrast = clip(double(2^((i_max-i_min-i_hi+i_lo)/2/i_range*2)*50),0,100);
                        display_callbacks(app, event);
                    end
                end
            end

            % Function to plot a vector field image over top of the DPC or CoM images (either magnitude, angle, or combination)
            function plot_vector_field()
                delete(findobj(ax, "Tag", "VectorField"))
                vec = user_data.vector_field;
                
                if ~vec || ~ismember(app.Mode.Value, ["CoM", "DPC"])
                    return;
                end
                
                % Plot vector arrows over the selected image
                app.annotations.vector_field.(id) = quiver(ax, ...
                                                           1:vec:app.dataset_parameters.n_frames(1), ...    % x location
                                                           1:vec:app.dataset_parameters.n_frames(2), ...    % y location
                                                           app.images.CoMX(1:vec:end, 1:vec:end)'./vec, ... % u component
                                                           app.images.CoMY(1:vec:end, 1:vec:end)'./vec, ... % v component
                                                           1, ...                                           % scale
                                                           "Color", user_data.vector_color, ...
                                                           "LineWidth", 1.5, ...
                                                           "Tag", "VectorField");
            end

            % Function to plot color wheel
            function plot_colorwheel()
                % clear the axis
                cla(app.image_axes.ColorWheel)
                
                % Make ColorWheel for DPC/CoM imaging - this color
                % scheme will stay constant for all DPC/CoM images
                % regardless of their individual parameters like scan
                % or detector rotation for consistency
                width = 512; 
                r = width/2;
                
                [ygrid, xgrid] = meshgrid(1 - r : r);
                
                % Get atan() from -180 to 180
                colorwheel = atan2d(ygrid, xgrid);
                
                % shift the negative values of atan() up by 360
                app.images.ColorWheel = (colorwheel) .* (colorwheel >= 0) + ...
                                        (colorwheel + 360) .* (colorwheel < 0);

                % mask the color wheel to make it a circle
                app.images.ColorWheelMask = xgrid.^2 + ygrid.^2 < r^2;
                axis(app.image_axes.ColorWheel,[0, width+1, 0, width+1]);
                app.image_axes.ColorWheel.UserData.mask_opacity = 100;
            end
        end

        % Function to check whether the named given value is changed
        % compared to the previous value, and store the new value
        function status = is_different_to_previous(app, name, value)
            status = ~isfield(app.previous_values, name) || ...
                     ~isequal(app.previous_values.(name), value);
            if status
                app.previous_values.(name) = value;
            end
        end

        % Function to determine whether to update images
        %   Return true if any: 1) `app.CalculationPolicy.Value` is 2 (active update)
        %                    OR 2) `app.CalculationPolicy.Value` is 1 (reduced) and not when changing
        %                    OR 3) is a manual update (from update buttons or detector mode change)
        function to_update = to_update_image(app, event)
            calc = app.CalculationPolicy.Value;
            to_update = calc == 2 || ...
                        (calc && is_static_event(app, event)) || ...
                        event.Source == app.UpdateImages || ...
                        event.Source == app.Mode;
        end

        % Function to mock UI callbacks
        function mock_UI_callbacks(app, source, value)
            arguments
                app
                source
                value = [];
            end
            
            % Set value if passed in
            if ~isempty(value)
                source.Value = value;
            end
            
            switch source
                case app.UpdateImages
                    % Update all images
                    update_images(app, struct("Source", source, "EventName", "ButtonPushed"))

                case app.DisplayImage
                    % Update Display UI or Select current image
                    display_callbacks(app, struct("Source", source, "EventName", "ValueChanged"))

                case app.SaveImagePrefix
                    % Update image saving prefix
                    save_callbacks(app, struct("Source", source, "EventName", "ValueChanged"))

                case {app.ShowDiffractionAxes app.ShowRealAxes}
                    % Update axes annotations
                    axis_direction_callbacks(app, struct("Source", source,"EventName", "ButtonPushed"))

                case app.RealROIShape
                    % Update real-space controls
                    realspace_ROI_callbacks(app, struct("Source", source, "EventName", "ValueChanged"));

                case app.CustomDetectorTable
                    % Update Custom Detectors for Main Table selection change
                    custom_detector_callbacks(app, struct("Source", source,"EventName", "SelectionChanged"))

                case app.CustomDetectorDetailsTable
                    % Mock Custom Detector Detail Table edit to update ROI/images
                    custom_detector_callbacks(app,struct("Source", source, "EventName", "CellEdit"))

                case app.SaveTabGroup
                    % Mock changing Save/Export tabs in Save window
                    export_callbacks(app, struct("Source", source, "EventName", "SelectionChanged"))

                case app.ImportFileSelect
                    % Mock import file select button
                    import_select_file(app,struct("Source", source, "EventName", "ButtonPushed"));

                case app.ShowImportWindow
                    % Mock user clicking "Import" button on main UI
                    import_callbacks(app,struct("Source", source, "EventName", "ButtonPushed"));

                case app.Mode
                    % Mock detector mode changed
                    detector_mode_callbacks(app, struct("Source", source, "EventName", "ValueChanged"));

                case app.PreviewButton
                    % Mock "Import Preview" button
                    preview_callbacks(app, struct("Source", source,"EventName", "ButtonPushed"))

                case app.ShowSaveWindow
                    % Mock user clicking "Save" button on main UI
                    save_callbacks(app, struct("Source", source, "EventName", "ButtonPushed"));
            end
        end

        % Wrapper for notification dialog; use `sprintf()` before calling this function to process "\n" in `message`
        function selection = notification_dialog(app, icon, message, title_name, option)
            arguments
                app; 
                icon; 
                message; 
                title_name = ""; 
                option = ["OK" "Cancel"];
            end

            selection = "";
            
            % Set global default UI font size temporarily
            font_size = 9.5;
            default_font_size = get(0, 'DefaultUIControlFontSize');
            set(0, 'DefaultUIControlFontSize', font_size)
            beep

            switch icon
                case 'quest'
                    % `questdlg()` will block all windows and background process
                    if numel(option) > 2
                        selection = questdlg(message, ...
                                             title_name, ...
                                             option(1), ...
                                             option(2), ...
                                             option(3), ...
                                             option(1));
                    else
                        selection = questdlg(message, ...
                                             title_name, ...
                                             option(1), ...
                                             option(2), ...
                                             option(1));
                    end
                
                case 'list'
                    % `listdlg()` will block all windows and background process
                    selection = listdlg("ListString", option, ...
                                        "SelectionMode", "single", ...
                                        "PromptString", cellstr(split(message,newline)), ...
                                        "Name", title_name, ...
                                        "ListSize", [300 150]);
                
                case {"help", "warn", "error", "none",""}
                    % The only way to set font size here is Tex; escape
                    % special characters in Tex; Tex is only used for font size
                    message = "\fontsize{" + font_size + "}" + regexprep(message, "([\\^_{}])", "\\$1");

                    % Disable windows because `msgbox()` cannot block `uifigure()` windows
                    enable_windows(app,false)
                    f = msgbox(message, ...
                               title_name, ...
                               icon, ...
                               struct('WindowStyle','modal','Interpreter','tex'));

                    % Enable windows when the notification is gone
                    f.DeleteFcn = {@(~,~) enable_windows(app,true)};

                    % ensure the message is brought to the front
                    figure(f)

                    % Block background process until `msgbox()` is gone
                    uiwait(f)
            end
            
            % Reset global default UI font size
            set(0, "DefaultUIControlFontSize", default_font_size)
        end

        % Wrapper for `waitbar`
        function wait_bar = progress_dialog(app, message, title_name, interruptible)
            arguments
                app;
                message;
                title_name = ""; 
                interruptible = false;
            end

            % Disable all windows
            enable_windows(app,false)

            % Set mouse cursor to "busy"
            set(app.ui_groups.all_figures, "Pointer", "watch")

            if ~interruptible
                title_name = "(Uninterruptible) "+title_name;
            end

            wait_bar = waitbar(0, ...
                               message, ...
                               "Name", title_name, ...
                               "WindowStyle", "modal");
            
            % For uninterruptible process:
            if ~interruptible
                % Indeterminate progress bar
                findall(wait_bar,"type","hgjavacomponent").JavaPeer.setIndeterminate(1);
                
                % Disable the close function
                wait_bar.CloseRequestFcn = [];
                
                % Enable windows and reset mouse pointer when the progress windows is gone
                wait_bar.DeleteFcn = @(~,~) enable_windows(app,true);
            end
        end

        % Function to unload data and clean all images; swap_data keeps
        % all previous parameters and simply swaps out app.data
        function unload_data(app, swap_data)
            arguments
                app
                swap_data (1,:) {mustBeNumericOrLogical} = false;
            end

            % Disable windows for the data unload process
            enable_windows(app,false)

            % reset main UI title
            app.Quant4D_Fig.Name = 'Quant4D';

            % Whether or not to swap dataset only, without changing
            % any existing parameters
            if ~swap_data
                % Clear all Axes
                arrayfun(@(x) cla(x), app.ui_groups.image_axes);
                cla(app.HistogramAxes);
                
                % Clear all parameters, data structures, etc. 
                app.common_parameters = []; 
                app.annotations = [];
                app.images = [];
                app.masks = [];
                app.image_displays = [];
                app.CustomDetectorTable.Data(:,:) = [];
            end

            % Remove all data from GPU if in use
            try
                if app.dataset_parameters.gpu > 0
                    reset(parallel.gpu.GPUDevice.getDevice(app.dataset_parameters.gpu));
                    gpuDevice([]);
                end
            catch
            end

            % Clear data, parameters and Axes
            app.data = [];
            app.memfile = [];
            app.dataset_parameters = [];
            app.previous_values = [];
        end

        % Function to enable/disable windows/UI; disable is only for notification/progress bar/busy background etc
        function enable_windows(app, state)
            arguments
                app
                state (1,:) {mustBeNumericOrLogical} = true;
            end

            % Preview mode or not
            preview_mode = app.Mode.Value == "Preview";

            % Detector Mode UI, keep disabled in Import Preview Mode unless a dataset is loaded
            set(app.ModeGrid.Children, ...
                "Enable", ...
                switch_on_off(app, state && (~preview_mode || ~isempty(app.dataset_parameters))))

            % Real-space ROI controls, keep disabled in Import Preview Mode
            set(app.RealPanel, "Enable",switch_on_off(app, state && ~preview_mode))

            % Other UIs can be enabled in Import Preview Mode
            set([app.ShortcutButtonGrid.Children; ...
                 app.ModePanel; ...
                 app.SettingsPanel; ...
                 app.SavePanel; ...
                 app.ImportPanel], ...
                "Enable", ...
                switch_on_off(app, state))

            % Disable all images when not "on"; visible means disable
            set(app.ui_groups.image_grayout, "Visible", ~state)
            
            % Also reset all windows' mouse pointer when enabling UIs
            if state
                set(app.ui_groups.all_figures,'Pointer','arrow');
            end
        end

        % Function to create other UI windows and Image figures
        function create_other_windows(app)
            debug_time = tic;

            % Set window position without using `movegui()`, to avoid its implicit `drawnow`
            title_height = app.sys_constants.title_height;
            decor_width = app.sys_constants.decor_width;
            
            % Available sizes of width/height/left/bottom/right/top
            screen_width = app.sys_constants.screen_avail(3);
            screen_height = app.sys_constants.screen_avail(4);
            screen_left = app.sys_constants.screen_avail(1)+decor_width/2;
            screen_bottom = app.sys_constants.screen_avail(2);
            screen_right = screen_left+screen_width-decor_width;
            screen_top = screen_bottom+screen_height-title_height; % top excluding title bar
            horizontal_center = (screen_left+screen_right)/2;
            vertical_center = (screen_bottom+screen_top)/2;
            image_size = app.Quant4D_Fig.Position(4);

            % Create other UI Windows
            app.figures.Settings = move_UI_to_new_figure('Settings', app.SettingsPanel);
            app.figures.Import = move_UI_to_new_figure('Import Data', app.ImportPanel);
            app.figures.Import.Position(1:2) = ([horizontal_center vertical_center]-app.figures.Import.Position(3:4)/2); % Move to center
            
            % Set `app.Fig.Import` window close as "pushing `app.CancelImport"
            app.figures.Import.CloseRequestFcn = @(~,~)import_callbacks(app, struct("Source", app.CancelImport, "EventName",'ButtonPushed'));
            
            % Move to Save UI to the center
            app.figures.Save = move_UI_to_new_figure('Save/Export', app.SavePanel);
            app.figures.Save.Position(1:2) = ([horizontal_center vertical_center] - app.figures.Save.Position(3:4))/2;
            
            % Set `app.Fig.Save` window close as "pushing `app.SaveCloseButton`"
            app.figures.Save.CloseRequestFcn = @(~,~)export_callbacks(app, struct("Source", app.SaveCloseButton, "EventName",'ButtonPushed'));
            
            % Set main windows `app.Quant4D_Fig` other properties and move to top left
            app.Quant4D_Fig.Position(1:2) = [1, app.sys_constants.screen_size(4) - app.Quant4D_Fig.Position(4) - app.sys_constants.title_height];
            app.Quant4D_Fig.SizeChangedFcn = [];
            app.Quant4D_Fig.KeyPressFcn = @key_press;

            % When delete the main window `app.Quant4D_Fig` (e.g. closing), also delete other windows
            app.Quant4D_Fig.DeleteFcn = {@(~,~,figs) delete(figs), structfun(@(x) x, app.figures)};

            % Move Settings UI next to main window
            app.figures.Settings.Position(1:2) = [sum(app.Quant4D_Fig.Position([1,3]))+1, app.Quant4D_Fig.Position(2)];

            % Set limits/values for image window resize options in settings window
            set([app.SetDiffractionWindowWidth, ...
                 app.SetDiffractionWindowHeight, ...
                 app.SetRealWindowWidth, ...
                 app.SetRealWindowHeight], ...
                {"Limits"}, ...
                {[1 screen_width]; ...
                 [1 screen_height]; ...
                 [1 screen_width]; ...
                 [1 screen_height]}, ...
                "Value", ...
                image_size)

            % Create Image Windows with inputs:
            %   id
            %   title
            %   space (Diffraction/Real/ColorWheel/Preview)
            %   type (Primary/Mask/CoM/Test/Preview)
            %   colormap
            %   position/size

            create_axes_figure("ColorWheel",...
                               "Color Wheel for Phase Direction", ...
                               "ColorWheel", ...
                               "CoM", ...
                               "hsv", ...
                               [horizontal_center 1 200 200]);
            axtoolbar(app.image_axes.ColorWheel, {"datacursor"});

            create_axes_figure("Diffraction", ...
                               "Diffraction", ...
                               "Diffraction", ...
                               "Primary", ...
                               "gray", ...
                               [sum(app.figures.Settings.Position([1,3]))+1 app.Quant4D_Fig.Position(2) image_size image_size]); % next to Settings UI
                              %[horizontal_center-image_size/2 screen_top-image_size image_size image_size]); % Top-center
                              
             % add dropdown to diffraction figure to control how
             % patterns are combined (sum, mean, max, std)
             app.diffraction_dropdown.Parent = app.figures.Diffraction;
             app.diffraction_dropdown.ValueChangedFcn = @app.move_real_ROI;
             app.diffraction_dropdown.Position = [0, app.figures.Diffraction.Position(4) - app.diffraction_dropdown.Position(4), 65, 22];

            create_axes_figure("DiffractionMask", ...
                               "Diffraction Mask", ...
                               "Diffraction", ...
                               "Mask", ...
                               "gray", ...
                               [sum(app.figures.Settings.Position([1,3]))+1 1 image_size image_size]); % Below Diffraction
                               %[horizontal_center-image_size/2 1 image_size image_size]); % Bottom-center

            % Diffraction preview image for import
            create_axes_figure("Preview", ...
                               "Preview", ...
                               "Preview", ...
                               "Preview", ...
                               "gray", ...
                               app.figures.Diffraction.Position);
            
            % Place Real-space images on the right, from top to bottom
            image_left = sum(app.figures.Diffraction.Position([1,3]))+1;
            image_bottom = app.Quant4D_Fig.Position(2);
            image_bottom_offset = image_bottom/8;
            
            ids = ["Real"; "CoMPhMag"; "CoMPh"; "CoMMag"; "CoMX"; "CoMY"; "dCoM"; "iCoM"; "RealMask"];
            titles = ["Real-space"; "CoM Phase (Magnitude-shaded)"; "CoM Phase"; "CoM Magnitude"; "CoM X"; "CoM Y"; "dCoM"; "iCoM"; "Real-space Mask"];
            space = "Real";
            types = ["Primary"; "CoM"; "CoM"; "CoM"; "CoM"; "CoM"; "CoM"; "CoM"; "Mask"];
            cmaps = ["gray"; "hsv"; "hsv"; "gray"; "gray"; "gray"; "gray"; "gray"; "gray"];
            position = [image_left, image_bottom, image_size, image_size];
            
            % Real-space image figures
            for i_figure = 1:numel(ids)
                create_axes_figure(ids(i_figure), ...
                                   titles(i_figure), ...
                                   space, ...
                                   types(i_figure), ...
                                   cmaps(i_figure), ...
                                   position);
                position = position - [0 image_bottom_offset 0 0];
            end

            % Debugging figures
            if app.debug
                create_axes_figure("tDiffraction",...
                                   "Test Diffraction", ...
                                   "Diffraction", ...
                                   "Test", ...
                                   "gray", ...
                                   [horizontal_center - image_size/2, 1, image_size, image_size]); % Bottom-center
                
                create_axes_figure("tReal", ...
                                   "Test BF/DF", ...
                                   "Real", ...
                                   "Test", ...
                                   "gray", ...
                                   [image_left, image_bottom, image_size, image_size]);
            end

            % Variable names, image names and image types (tag) for all patterns'/images' figures/axes/images
            app.ui_groups.image_id = string(fieldnames(app.image_axes));                            % ID/fieldnames under `app.image_axes` `app.figures` `app.images` `app.image_dispays` etc
            app.ui_groups.image_space = string(structfun(@(x) x.UserData.space, app.image_axes));   % Spaces ("Real"/"Diffraction")
            app.ui_groups.image_type = string(structfun(@(x) x.UserData.type, app.image_axes));     % Types ("Primary", "CoM" related, "Mask", "Test")
            app.ui_groups.image_name = string(structfun(@(x) x.UserData.name, app.image_axes));     % Names/Titles
            
            % AND their Axes/Figure objects, as well as the gray overlay to disable them
            app.ui_groups.image_axes = arrayfun(@(x) app.image_axes.(x), app.ui_groups.image_id);
            app.ui_groups.image_figures = arrayfun(@(x) app.figures.(x), app.ui_groups.image_id);
            app.ui_groups.image_grayout = arrayfun(@(x) app.figures.(x).UserData.grayout, app.ui_groups.image_id); % Set them visible to disable the axes
            
            % Variable names for all diffraction-sized figures/axes/images AND THEN their Axes objects
            app.ui_groups.diffraction_id = app.ui_groups.image_id(app.ui_groups.image_space == "Diffraction");
            app.ui_groups.diffraction_axes = arrayfun(@(x) app.image_axes.(x), app.ui_groups.diffraction_id);
            
            % Variable names for all real-space-sized figures/axes/images AND THEN their Axes objects
            app.ui_groups.real_id = app.ui_groups.image_id(app.ui_groups.image_space == "Real");
            app.ui_groups.real_axes = arrayfun(@(x) app.image_axes.(x), app.ui_groups.real_id);
            
            % Group all "Show ... Window" buttons related to CoM/DPC
            app.ui_groups.CoM_buttons = [findobj(app.ShowImageWindowsGrid,'-regexp', "Tag",'CoM'); ...
                                         app.ShowColorWheelWindow; ...
                                         app.ShowColorWheelWindow2];
            
            % Group all diffraction detector panels
            app.ui_groups.detector_ui = [app.PreviewGrid; ...
                                         app.AlignmentGrid; ...
                                         app.AnnularDetectorGrid; ...
                                         app.VirtualApertureGrid; ...
                                         app.CustomDetectorGrid];

            % Transfer all detector panels to main window, then delete their old container
            set(flip(app.ui_groups.detector_ui), 'Parent', app.ModePanel);
            delete(app.ModeTabGroup)
            
            % Group all window `figure` `uifigure`
            app.ui_groups.all_figures = [app.Quant4D_Fig; structfun(@(f) f, app.figures)];

            debug_toc(app, [], "", debug_time)


            %%% Sub functions of `otherFigures()`
            % Keyboard shortcuts
            function key_press(~, event)
                debug_time = tic;
                switch event.Key
                    case 'f5'
                        % update with the F5 key
                        if app.UpdateImages.Enable
                            mock_UI_callbacks(app,app.UpdateImages);
                        end
                    case {'o', 'O'}
                        if ismember('control',event.Modifier)
                            mock_UI_callbacks(app, app.ShowImportWindow)
                        end
                    case {'s', 'S'}
                        if ismember('control',event.Modifier)
                            mock_UI_callbacks(app, app.ShowSaveWindow)
                        end
                end
                debug_toc(app, event, "", debug_time)
            end

            % Hide window instead of closing it
            function hide_window(source, ~)
                if isvalid(app)
                    source.Visible = "off";
                else
                    delete(source);
                end
            end

            % Transplant `UI` from its parent to a new separated Figure `ui_figure`
            function ui_figure = move_UI_to_new_figure(name, ui)
                ui_figure = uifigure("Name", name, ...
                                     "Visible", "off", ...
                                     "Position", [1 1 ui.Position(3:4)+2], ...
                                     "AutoResizeChildren", "off", ...
                                     "CloseRequestFcn", @hide_window, ...
                                     "Resize", "off", ...
                                     "KeyPressFcn", @key_press);

                grid_layout = uigridlayout(ui_figure,[1 1],'Padding',[0 0 0 0]);
                ui.Parent = grid_layout;
            end

            % Create Image Window/Axes
            function create_axes_figure(id, name, space, ax_type, color_map, position)
                fig = uifigure("Visible", "off", ...
                               "Name", name, ...
                               "Position", position, ...
                               "MenuBar", "none", ...
                               "NumberTitle", "off", ...
                               "IntegerHandle", "off", ...
                               "Color", app.sys_constants.background_color, ...
                               "WindowButtonDownFcn", @clicked_window, ...
                               "WindowScrollWheelFcn", @scroll_figure, ...
                               "CloseRequestFcn", @hide_window, ...
                               "KeyPressFcn", @key_press, ...
                               "AutoResizeChildren", 'off', ...
                               "SizeChangedFcn", @resize_figure, ...
                               "Tag", space + " " + ax_type);

                % allow for custom axes toolbar
                enableLegacyExplorationModes(fig)

                % An overlay on figure to cover axes; disables the axes when visible
                grayout = annotation(fig, ...
                                     "rectangle", [0 0 1 1], ...
                                     "Visible", "off", ...
                                     "FaceColor", [0.96 0.96 0.96], ...
                                     "FaceAlpha", 0.7);

                % Not `Clipping` axes to let images expand beyond the axes when zooming in
                ax = axes(fig, ...
                          "Color", "k", ...
                          "Colormap", feval(color_map), ...
                          "CLimMode", "manual", ...
                          "Units", "normalized", ...
                          "Position", [0 0 1 1], ...
                          "DataAspectRatio", [1 1 1], ...
                          "XTick", [], ...
                          "YTick", [], ...
                          "XColor", "k", ...
                          "YColor", "k", ...
                          "Box", 1, ...
                          "Clipping", 0, ...
                          "NextPlot", "add", ...
                          "Interactions", [], ...
                          "Tag", space + " " + ax_type);
                
                % Toolbar & its `restoreview()`
                tool_bar = axtoolbar(ax, {"export", "datacursor", "pan", "zoomin", "zoomout", "restoreview"});
                tool_bar.Children(end).ButtonPushedFcn = @restore_view;
                
                % Data Tips
                cursor_mode = datacursormode(fig);
                cursor_mode.UpdateFcn = @show_value;
                set(cursor_mode.UIContextMenu.Children,"Visible", "off")
                
                % Image properties
                if color_map == "gray"
                    vector_color = "r";
                else
                    vector_color = "w";
                end

                ax.UserData = struct("id", id, ...
                                     "name", name, ...
                                     "space", space, ...
                                     "type", ax_type, ...
                                     "colormap", color_map, ...
                                     "invert", false, ...
                                     "mask_opacity", 0, ...
                                     "brightness", 50,...
                                     "contrast", 50,...
                                     "gamma", 1,...
                                     "vector_field", false,...
                                     "vector_color", vector_color);

                fig.UserData = struct("id", id, ...
                                      "name", name, ...
                                      "space", space, ...
                                      "type", ax_type, ...
                                      "grayout", grayout);

                % add references to fig and ax to their respective lists
                app.figures.(id) = fig;
                app.image_axes.(id) = ax;

                %% Sub sub functions
                % "restoreview" goes back to the limits of the actual image, nothing more/less.
                function restore_view(~, event)
                    axis(event.Axes, app.common_parameters.(lower(event.Axes.UserData.space)+"_axes_limit"))
                    event.Axes.Position = [0 0 1 1];
                    app.diffraction_dropdown.Position = [0, app.figures.Diffraction.Position(4) - app.diffraction_dropdown.Position(4), 65, 22];
                end

                % Modify Data Tips to show the raw value from `app.images.(...)`
                function data_tip = show_value(~, event)
                    x = event.Position(1);
                    y = event.Position(2);
                    value = strtrim(sprintf('%14.7g',app.images.(event.Target.Parent.UserData.id)(x,y)));
                    data_tip = sprintf('Value: \\bf%s\n\\rmX: \\bf%g\\rm, Y: \\bf%g', value, x, y);
                end

                % Select image in Display/contrast panel unless DisplayLock
                % is selected, which leaves the selected window fixed,
                % which is especially useful for watching image statistics
                % change as a detector or ROI moves
                function clicked_window(source, ~)
                    id = string(source.UserData.id);
                    if ~app.DisplayLock.Value && app.DisplayImage.Value ~= id && ismember(id, app.DisplayImage.ItemsData)
                        mock_UI_callbacks(app, app.DisplayImage, id)
                    end
                end

                % ensure that the diffraction dropdown stays pinned to the
                % upper left corner of the diffraction figure/axis during
                % resizing
                function resize_figure(source, ~)
                    if source == app.figures.Diffraction
                        app.diffraction_dropdown.Position = [0, app.figures.Diffraction.Position(4) - app.diffraction_dropdown.Position(4), 65, 22];
                    end
                end

                % function to allow the user to zoom via the mouse scroll
                % wheel without having to click the zoom in/out icons on
                % the axes toolbar; all axes in a given space are linked,
                % so this will carry across to all axes in that space
                function scroll_figure(source,event)
                    zoom_ax = source.CurrentAxes;
                    % zoom in/out by 10% (5% on either side) with each
                    % mouse wheel scroll; VerticalScorrlCount == -1 for an
                    % upward scroll and == 1 for a downward scroll
                    zoom_amount = 0.05*[-1 1]*event.VerticalScrollCount;
                    zoom_ax.XLim = zoom_ax.XLim + range(zoom_ax.XLim)*zoom_amount;
                    zoom_ax.YLim = zoom_ax.YLim + range(zoom_ax.YLim)*zoom_amount;

                    % get axis limits for the given space
                    if contains(source.Tag, "Real")
                        lim = app.common_parameters.real_axes_limit;
                    elseif contains(source.Tag, "Diffraction")
                        lim = app.common_parameters.diffraction_axes_limit;
                    end

                    % make sure we don't zoom out too far
                    zoom_ax.XLim = clip(zoom_ax.XLim, lim(1), lim(2));
                    zoom_ax.YLim = clip(zoom_ax.YLim, lim(3), lim(4));
                end
            end
        end

        % Function to check NaN/Inf in data/image
        function selection = check_NaN(app, image, preview)
            selection="";
            if any(isnan(image), "all") || any(isinf(image), "all")
                title_text = 'NaN/Inf in Data!';
                message = "There are NaN/Inf values in the data! Which may " + ...
                          "indicate the data structure is not correctly set " + ...
                          "(e.g. wrong Data Type, Byte Order, Data Offset, " + ...
                          "Frame Header/Footer etc).\n\nIf any data are " + ...
                          "corrupt, NaNs can be set to 0.";

                % For preview only
                if preview
                    selection = notification_dialog(app, ...
                                                    'quest', ...
                                                    sprintf(message), ...
                                                    title_text, ...
                                                    ["Continue", "Set NaNs to 0"]);

                elseif app.dataset_parameters.pixel_binning> 1 || app.dataset_parameters.frame_footer + app.dataset_parameters.frame_header > 0
                    selection = notification_dialog(app, ...
                                                    'quest', ...
                                                    sprintf(message+"\n\nRe-importing with corrected configuration is suggested."), ...
                                                    title_text, ...
                                                    ["Continue", "Set NaNs to 0"]);
                
                else
                    selection = notification_dialog(app, ...
                                                    'quest', ...
                                                    sprintf(message+"\n\nDo you want to swap the byte-order?"), ...
                                                    title_text, ...
                                                    ["Yes, try to swap the byte-order", "No"]);
                    
                    % Select the Data Info tab and bring Settings to the front
                    app.SettingsTabGroup.SelectedTab = app.InfoTab;
                    figure(app.Quant4D_Fig)
                    figure(app.figures.Settings) 
                    
                    % Execute the swap byte order callbacks
                    if startsWith(selection, "Yes")
                        dataset_options_callbacks(app,struct("Source",app.SwapByteOrder,"EventName", "ButtonPushed"))
                    end
                end
            end
        end

        % Function to check the size of various screen attributes
        function get_screen_info(app)
            % Get main screen size (MATLAB "pixels")
            app.sys_constants.screen_size = get(0, 'ScreenSize');

            % Get all monitor position/size
            app.sys_constants.monitor_positions = get(0,'MonitorPositions');
            
            % Scale for MATLAB "pixels" vs actual pixels, for high DPI
            app.sys_constants.screen_pixel_scale = app.sys_constants.screen_size(4)/java.awt.Toolkit.getDefaultToolkit.getScreenSize.height;
            
            % A Java window to measure window decorations like "title bar"/"border width"
            jf = javax.swing.JFrame('Test');
            jf.setDefaultCloseOperation(javax.swing.JFrame.DISPOSE_ON_CLOSE);
            jf.pack();
            insets = jf.getInsets();   % Not accurate for Windows OS, due to the transparent shadow
            app.sys_constants.title_height = insets.top*app.sys_constants.screen_pixel_scale;
            app.sys_constants.decor_height = app.sys_constants.title_height + insets.bottom*app.sys_constants.screen_pixel_scale;
            app.sys_constants.decor_width = (insets.left + insets.right)*app.sys_constants.screen_pixel_scale;
            jf.dispose()
            
            % Actual available main screen size excluding taskbar etc
            insets = java.awt.Toolkit.getDefaultToolkit().getScreenInsets(getGraphicsConfiguration(javax.swing.JFrame));
            insets = [insets.left, insets.bottom, insets.right+insets.left, insets.top+insets.bottom] * app.sys_constants.screen_pixel_scale;
            app.sys_constants.screen_avail = [insets(1:2)+1, app.sys_constants.screen_size(3:4)-insets(3:4)];
        end

        % Function to get/set system constants 
        function get_sys_constants(app)
            % Default app background color
            app.sys_constants.background_color = app.Quant4D_Fig.Color;

            % highlight state buttons when enabled
            app.sys_constants.highlight_color = [0.702, 1.00, 0.702];
            
            % Warning color
            app.sys_constants.warning_color = [1.00, 0.65, 0.00];
            
            % Get native endianness
            [~,~,endian] = computer();
            app.sys_constants.system_endianness = lower(endian);
            app.sys_constants.endian_text = struct("l",'Little Endian',"b",'Big Endian');
        end

        % Function to save vector field images
        function save_vector_field(app)
                % Create temporary figure with axes
                fig = figure("Visible", "on");
                ax = axes(fig);
                ax.NextPlot = "replace";
                imagesc(ax, app.images.CoMPh, "AlphaData", app.images.CoMMagNorm);
                set(ax, ...
                    "DataAspectRatio", [1 1 1], ...
                    "XTick", [], ...
                    "YTick", [], ...
                    "YDir", "reverse", ...
                    "Color", "k");
                axis(ax, app.common_parameters.real_axes_limit);
                colormap(ax, hsv);

                % get the vector field sampling (1:1, 1:2, etc)
                vec = app.image_axes.CoMPhMag.UserData.vector_field;

                set(ax, "NextPlot", "add");
                quiver(ax, ...
                       1:vec:app.dataset_parameters.n_frames(1),...
                       1:vec:app.dataset_parameters.n_frames(2),...
                       -app.images.CoMX(1:vec:end,1:vec:end)./vec,...
                       -app.images.CoMY(1:vec:end,1:vec:end)./vec,...
                       1/vec,"w");
                set(ax, ...
                    "DataAspectRatio", [1 1 1], ...
                    "XTick", [], ...
                    "YTick", [], ...
                    "YDir", "reverse", ...
                    "NextPlot", "replace");
                axis(ax, app.common_parameters.real_axes_limit);

                % Save as a .png, .fig, and .pdf (vector image, not pixelated)
                %fig.InvertHardcopy = "off";
                exportgraphics(fig,strcat(app.SaveImagePrefix.Value, "VectField.png"), "Resolution", 1000, "BackgroundColor", "k");
                saveas(fig,strcat(app.SaveImagePrefix.Value, "VectField.fig"));
                exportgraphics(fig,"VectField_vector.pdf", 'ContentType', 'vector', "BackgroundColor","K")
                fig.Visible = "off";

                % write vector field to a comma-separated text file
                writematrix([-app.images.CoMX(1:vec:end,1:vec:end)./vec ...
                             -app.images.CoMY(1:vec:end,1:vec:end)./vec], ...
                             strcat(app.SaveImagePrefix.Value, "vector_field.txt"));
                
                close(fig);
        end

        % Function to delete plotted detectors if they exist
        function clear_detectors(app)
            % Transmitted Beam alignment
            delete(findobj(app.image_axes.Diffraction, "Tag","TransBeamAnnot"))

            % Annular/Round detectors and Segmented detectors
            delete(findobj(app.image_axes.Diffraction, '-regexp', "Tag", "AnnDetrAnnot"))
            delete(findobj(app.image_axes.Diffraction, "Tag", "SegDetrAnnot"))

            % Virtual apertures
            delete(findobj(app.image_axes.Diffraction, "Tag", "VrApertAnnot"))

            % Custom detectors - hide Custom ROIs only
            set(findobj(app.image_axes.Diffraction.Children, '-regexp', "Tag", "Custom "), "Visible", false);

            % Scan direction alignment
            delete(findobj(app.image_axes.Diffraction, "Tag", "ScanDirAnnot"))
        end

        % Function to draw Real-space ROI of various shapes on real space image(s)
        function draw_real_ROI(app, event, roi_type)
            % Delete all previous ROIs
            delete(findobj(app.ui_groups.real_axes, "Tag",'Real-space ROI'))

            % set roi polygon color
            color = [1 0.5 0.5];

            % Special case for drawing polygon ROI
            if roi_type == "poly"
                % List for available real-space images
                real_sace_images = app.ui_groups.image_space == "Real" & ...
                                   app.ui_groups.image_type ~= "Mask" & ...
                                   ismember(app.ui_groups.image_id,app.DisplayImage.ItemsData);

                sel = notification_dialog(app, ...
                                          'list', ...
                                          sprintf("Select a Real-space Image to draw a polygon ROI.\n\n" + ...
                                            "Press 'Esc' to cancel during drawing. Please refer to MATLAB's 'drawpolygon' for more tips.\n"), ...
                                          "Draw Polygon ROI", ...
                                          app.ui_groups.image_name(real_sace_images));
                
                % Revert to "full" is canceled
                if isempty(sel)
                    mock_UI_callbacks(app, app.RealROIShape, "full");
                    return;
                end
                
                % Bring selected image window to front
                options = app.ui_groups.image_id(real_sace_images);
                selection_index = options(sel);
                ax = app.image_axes.(selection_index);
                figure(app.figures.(selection_index))
                
                % Disable UIs until drawing finishes
                enable_windows(app, false)
                
                % Enable the selected image only
                app.figures.(selection_index).UserData.grayout.Visible = "off";
                
                % Draw temporary polygon for positions
                tmp_polygon = drawpolygon(ax, "Color", color);
                polygon_position = tmp_polygon.Position;
                delete(tmp_polygon)

                % Re-enable all windows
                enable_windows(app,true)
                
                % Revert to "full" is canceled
                if isempty(polygon_position)
                    mock_UI_callbacks(app, app.RealROIShape, "full");
                    return;
                end
            end
            
            % Draw ROIs on Real-space images, except the mask image
            real_space_limits = range([app.image_axes.Real.XLim; app.image_axes.Real.YLim]'); 
            pos = [app.RealROIFrameX.Value app.RealROIFrameY.Value];

            for selection_index = app.ui_groups.image_id(app.ui_groups.image_space == "Real" & app.ui_groups.image_type ~= "Mask")'
                switch roi_type
                    case "full"
                        roi = drawrectangle(app.image_axes.(selection_index), ...
                                            "Position", [1 1 real_space_limits], ...
                                            "Visible", 0, ...
                                            "EdgeAlpha", 0, ...
                                            "FaceAlpha", 0);
                    
                    case 'point'
                        roi = drawpoint(app.image_axes.(selection_index), ...
                                        "Position",pos);
                    
                    case 'ellipse'
                        roi = drawellipse(app.image_axes.(selection_index), ...
                                          "Center", pos, ...
                                          "SemiAxes", min(0.1*real_space_limits)*[1 1], ...
                                          "FaceAlpha", 0);
                    
                    case 'rectangle'
                        roi = drawrectangle(app.image_axes.(selection_index), ...
                                            "Position", [pos-0.1*real_space_limits, 0.2*real_space_limits], ...
                                            "Rotatable", true, ...
                                            "FaceAlpha",0);
                    
                    case 'poly'
                        roi = drawpolygon(app.image_axes.(selection_index), ...
                                          "Position", polygon_position, ...
                                          "FaceAlpha", 0);
                    
                    case "file"
                        roi = drawrectangle(app.image_axes.(selection_index), ...
                                            "Position", [1 1 real_space_limits], ...
                                            "Visible", 0, ...
                                            "EdgeAlpha", 0, ...
                                            "FaceAlpha",0);
                        bw = visboundaries(app.image_axes.(selection_index), ...
                                           bwboundaries(app.images.RealROIFile'), ...
                                           "Color",color, ...
                                           "EnhanceVisibility",0);
                        bw.Tag = 'Real-space ROI';
                end

                set(roi, ...
                    "Deletable", false, ...
                    "Color", color, ...
                    "Tag", "Real-space ROI");

                roi.UserData.Move = addlistener(roi, "MovingROI", @app.move_real_ROI);
                addlistener(roi, "ROIMoved", @app.move_real_ROI);
                app.annotations.RealROI.(selection_index) = roi;
            end

            move_real_ROI(app, app.annotations.RealROI.Real, event)
        end

        % Function to move Real-space ROIs on real space image(s)
        function move_real_ROI(app, source, event)
            debug_time = tic;
            
            % Update panel values for individual probe positions
            roi = app.annotations.RealROI.Real;
            x = app.RealROIFrameX.Value;
            y = app.RealROIFrameY.Value;
            
            switch source
                case app.RealROIFrameX
                    % Force X >= 1
                    x = max(1, event.Value);
                
                case app.RealROIFrameY
                    % Force Y >= 1
                    y = max(1, event.Value);

                case app.diffraction_dropdown
                
                otherwise % E.g. ROIs
                    roi = source;
                    switch app.RealROIShape.Value
                        case {'rectangle', 'full', 'file'}
                            position = source.Position(1:2) + source.Position(3:4)/2;
                            x = position(1);
                            y = position(2);
                        case 'point'
                            x = source.Position(1);
                            y = source.Position(2);
                        case 'ellipse'
                            x = source.Center(1);
                            y = source.Center(2);
                        case 'poly'
                            % avoid any warnings that arise when:
                            % 'Polyshape has duplicate vertices,
                            % intersections, or other inconsistencies
                            % that may produce inaccurate or unexpected
                            % results. Input data has been modified to
                            % create a well-defined polyshape.'
                            warning('off','MATLAB:polyshape:repairedBySimplify');
                            [x, y] = centroid(polyshape(source.Position));
                    end
            end

            x = round(x);
            y = round(y);

            % Set current ROI position if called from `app.RealROIFrameX` or `app.RealROIFrameY`, and it's a point ROI
            if ismember(source, [app.RealROIFrameX, app.RealROIFrameY]) && app.RealROIShape.Value == "point"
                roi.Position = [x y];
            end
            
            % Update UI
            set_external_source(app, event,[app.RealROIFrameX, app.RealROIFrameY],{"Value"},{x;y})
            
            % Only update ROIs on other visible figures; update all when not in "changing" Event
            roi_struct = structfun(@(i) i, app.annotations.RealROI);
            roi_struct = roi_struct(arrayfun(@(i) ancestor(i,'figure').Visible, roi_struct) | is_static_event(app, event));
            
            % Sync existing properties of positions etc.
            for prop = ["Position" "Center" "SemiAxes" "RotationAngle"]
                if isprop(roi, prop)
                    set_external_source(app, event, roi_struct, prop, roi.(prop));
                end
            end

            % Update images
            if to_update_image(app, event)
                % generate a mask for displaying diffraction pattern
                switch app.RealROIShape.Value
                    case "full"
                        mask_real = true(app.dataset_parameters.n_frames);
                    
                    case 'point'
                        mask_real = false(app.dataset_parameters.n_frames);
                        mask_real(x, y) = true;
                    
                    case 'file'
                        mask_real = app.images.RealROIFile;
                    
                    otherwise
                        mask_real = createMask(app.annotations.RealROI.Real, app.image_displays.Real)';
                end

                % Convert to single/double
                mask_real = cast(mask_real,app.dataset_parameters.data_type);

                % Invert mask?
                if app.RealROIInvert.Value
                    mask_real = max(mask_real,[], "all") - mask_real;
                end

                % Store the mask for plotting/saving
                app.images.RealMask = mask_real;
                app.masks.real = mask_real;

                % Update diffraction pattern for the specified ROI; special
                % case to speed up `point` as it does not require the
                % entire dataset to be multiplied
                if strcmp(app.RealROIShape.Value, "point")
                    app.images.Diffraction = gather(reshape(app.data(:,mask_real==1), app.dataset_parameters.n_pixels));
                else
                    switch app.diffraction_dropdown.Value
                        case 'max'
                            app.images.Diffraction = gather(reshape(max(app.data.*reshape(mask_real, 1, []), [],2), app.dataset_parameters.n_pixels));

                        case 'std'
                            app.images.Diffraction = gather(reshape(std(app.data.*reshape(mask_real, 1, []), [],2), app.dataset_parameters.n_pixels));

                        otherwise % sum, mean, sqrt, ln, log10
                            app.images.Diffraction = gather(reshape(app.data*reshape(mask_real, [], 1), app.dataset_parameters.n_pixels));
                    end
                end
                
                % add operation on top of summed data
                switch app.diffraction_dropdown.Value
                    case 'mean'
                        app.images.Diffraction = app.images.Diffraction / sum(mask_real, "all");

                    case 'sqrt'
                        app.images.Diffraction = real(sqrt(app.images.Diffraction));
                    
                    case 'ln'
                        app.images.Diffraction = clip(real(log(app.images.Diffraction)),0,Inf);
                    
                    case 'log10'
                        app.images.Diffraction = clip(real(log10(app.images.Diffraction)),0,Inf);
                end

                plot_all_patterns(app, event)
            end

            debug_toc(app, event, "", debug_time)
        end

        function get_fields(app, tree_node, variable_struct)
            if ~isstruct(variable_struct)
                return
            end
            
            fnames = fieldnames(variable_struct);

            for i_field = 1:numel(fnames)
                field_name = fnames{i_field};

                item = variable_struct.(field_name);

                % recursively interrogate struct
                if isa(item,'struct')
                    % only add uitreenode if it doesn't already exists
                    parent = findobj(tree_node,'Text',field_name,'Type','uitreenode');
                    if isempty(parent) || ~isvalid(parent)
                        parent = uitreenode(tree_node, "Text", field_name);
                    end
                    get_fields(app, parent, item)
                end

                % only add uitreenode if it doesn't already exists
                h = findobj(tree_node,'Text',field_name,'Type','uitreenode');
                if isempty(h) || ~isvalid(h)
                    uitreenode(tree_node, "Text", field_name);
                end
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup_function(app, debug)
            arguments
                app;
                debug (1,1) {mustBeNumericOrLogical} = false;
            end

            debug_time = tic;

            % Non-0 values if debugging
            app.debug = debug;

            % Hide until startup process is ready
            app.Quant4D_Fig.Visible = "off"; 

            % get/set system info
            get_screen_info(app)
            get_sys_constants(app)
            get_memory(app);
            get_GPU(app);

            % Disable histogram interactions
            app.HistogramAxes.Interactions = [];
            
            % Setup "Custom Detector" tables
            set(app.CustomDetectorTable, ...
                'SelectionType','row', ...
                'Multiselect',0, ...
                'Data',table('Size',[0 6], ...
                             'VariableTypes',{'string','string','string','logical','logical','double'}, ...
                             'VariableNames',{'ID','Type','Label', 'Enable','Show','Weight'}));
            app.CustomDetectorDetailsTable.ColumnFormat = {'bank','bank','bank'};
            
            % Create Other Panel and Image Windows
            create_other_windows(app)

            % Keep UIs disabled
            enable_windows(app,false)

            % Reset app UI and data, which enters Import Preview Mode
            reset_Quant4D(app, struct("Source", "startup_function", "EventName",[]))

            % make main app UI visible
            app.Quant4D_Fig.Visible = "on";

            % Plot axes display directions
            mock_UI_callbacks(app,app.ShowDiffractionAxes);
            mock_UI_callbacks(app,app.ShowRealAxes)
            
            % Link Axes limits for zooming, `drawnow` implied
            linkaxes(app.ui_groups.real_axes);
            linkaxes(app.ui_groups.diffraction_axes);

            % populate variable viewer 
            variable_viewer(app, struct())

            debug_toc(app, [], "`startup_function` finished", debug_time)
        end

        % Callback function: CancelImport, ImportData, ReimportMenu, 
        % ...and 1 other component
        function import_callbacks(app, event)
            switch event.Source
                case app.ShowImportWindow
                    figure(app.figures.Import)
                    
                    % Enter Import Preview Mode
                    if app.Mode.Value ~= "Preview"
                        mock_UI_callbacks(app,app.Mode, "Preview");
                    end

                case app.CancelImport
                    % Hide window when closing
                    app.figures.Import.Visible = "off";
                    
                    % Try to re-enter previous detector mode from Import Preview Mode
                    if app.Mode.Value == "Preview" && isfield(app.common_parameters,'detector_mode')
                        mock_UI_callbacks(app,app.Mode, app.common_parameters.detector_mode);
                    end

                case app.ReimportMenu
                    % Re-import the current dataset, mostly for debugging
                    if ~isfield(app.tmp_variables,'prev_D')
                        return;
                    end
                    
                    unload_data(app)
                    app.dataset_parameters = app.tmp_variables.prev_D;
                    import_data(event)

                case {app.ImportData app.PreviewButton}
                    % When the "Import Data" or "Preview" button is pressed
                    app.tmp_variables.Preview = [];

                    if ~isfile(app.ImportFilePath.Value)
                        notification_dialog(app, ...
                                            "warn", ...
                                            'The entered file path is invalid!', ...
                                            'Invalid File!');
                    
                    elseif app.ImportDataType.Value == "unknown"
                        notification_dialog(app, ...
                                            "warn", ...
                                            'Select the correct data type!', ...
                                            'Select Data Type!');
                    
                    else
                        if event.Source == app.ImportData
                            % Import data
                            app.figures.Import.Visible = "off";
                            
                            % unload data unless `SwapDataset` is ticked
                            unload_data(app, app.SwapDataset.Value)
                            
                            % gather all options from the Import UI, preview mode = false
                            app.dataset_parameters = gather_import_options(false);
                            
                            % Backup current `app.D`
                            app.tmp_variables.prev_D = app.dataset_parameters;
                            import_data(event)
                        else
                            % gather all options from the Import UI, preview mode = true
                            app.tmp_variables.Preview = gather_import_options(true);
                        end
                    end

                case {app.SwapDiffractionXY app.SwapRealXY}
                    % Refresh axes settings etc when dimensions swapped
                    prepare_data(event)

            end


            %%% Sub functions
            % Function to read Import Panel options into `app.D`
            function d = gather_import_options(preview)
                % get file location
                d.file_path = app.ImportFilePath.Value;
                [d.file_directory, d.file_name, d.file_extension] = fileparts(d.file_path);
                d.file_directory = strcat(d.file_directory, filesep);
                d.subimage = app.SubDataset.Value;
                
                % BYTES offset of: 1) the dataset from the beginning of file, 2) before and 3) after each frame
                d.offset = app.DataOffset.Value;
                d.frame_header = app.FrameHeader.Value;
                d.frame_footer = app.FrameFooter.Value;
                
                % Endianness (big "b" or little "l"), Data type of the dataset and Size in bytes of the data type
                d.byte_orer = app.ImportByteOrder.Value;
                d.data_type = app.ImportDataType.Value;
                d.byte_size = app.byte_size.(d.data_type);
                
                % Physical or virtual memory loading
                d.memory_type= app.MemoryType.Value;
                
                % Select GPU if acceleration is enabled AND not in Import Preview Mode
                d.gpu = app.GPU.Value;
                if ~preview && d.gpu
                    gpuDevice(d.gpu);
                end
                
                % Dimensions of pixels/frames in file and import binning/sampling distance
                d.n_pixels_file = [app.ImportPixelsX.Value, app.ImportPixelsY.Value];
                d.n_frames_file = [app.ImportFramesX.Value, app.ImportFramesY.Value];
                d.pixels_binning= app.DiffractionPartialImportPixelsDist.Value;
                d.frames_sampling = app.RealPartialImportFramesDist.Value;
                
                % Starting points and Number of pixels/frames after binning/sampling to import
                d.pixels_start = [app.DiffractionPartialImportXStart.Value, app.DiffractionPartialImportYStart.Value];
                d.frames_start = [app.RealPartialImportXStart.Value, app.RealPartialImportYStart.Value]; 
                d.n_pixels = [app.DiffractionPartialImportX.Value, app.DiffractionPartialImportY.Value];
                d.n_frames = [app.RealPartialImportX.Value, app.RealPartialImportY.Value];
                
                % Estimated memory usage for current dataset
                d.estimated_memory = app.tmp_variables.estimated_memory;
                
                % Swap dataset option+app.ImportSummary.Value;
                d.keep_parameters = app.SwapDataset.Value;
                
                % Notes at import
                d.file_metadata = app.ImportFileMetadata.Value;
                d.import_options = app.ImportSummary.Value;
                
                % H5 dataset path within the H5 file; if not set empty, the H5 library API will be called when importing
                d.h5ds = [];
                if app.HDF5.Value && d.memory_type== "Physical Memory"
                    d.h5ds = d.subimage;
                end
            end

            % Initialize GUI/Image/app.P for the data
            function prepare_data(event)
                app.tmp_variables.progress_dialog = progress_dialog(app, 'Preparing data ...', "Data Preparation");
                
                % Detector controls
                
                % Reset alignments
                app.common_parameters.scan_direction_initialized = false;
                 
                % Prepare to enter alignment mode
                app.Mode.Value = "Alignment";
                app.AnnularRadiusLink.Value = false;

                set([app.NSeg, ...
                     app.NRung, ...
                     app.DetectorRotationSlider, ...
                     app.DetectorRotationSpinner, ...
                     app.ScanDirectionSlider, ...
                     app.ScanDirectionSpinner], ...
                    {"Value"}, ...
                    {4; ...
                     1; ...
                     0; ...
                     0; ...
                     0; ...
                     0})

                set([app.VirtualApertureSymmetry, ...
                     app.VirtualApertureInvert, ...
                     app.VirtualApertureMirror, ...
                     app.VirtualApertureMirrorRotation], ...
                    {"Value"}, ...
                    {1; ...
                     0; ...
                     0; ...
                     0})
                
                % Alignments
                
                % Exact center of the Diffraction image
                app.common_parameters.diffraction_center = app.dataset_parameters.n_pixels/2+.5;
                
                % Exact center of the Real-space image
                app.common_parameters.real_space_center = app.dataset_parameters.n_frames/2+.5;
                
                % Convergence angle default = 1
                app.Alpha.Value = 1;

                % Set virtual aperture (x,y) default location to diffraction center
                app.common_parameters.virtual_aperture_center = app.common_parameters.diffraction_center;
                
                % Axes limits, restrict to avoid rounding to pixels outside the image
                app.common_parameters.diffraction_axes_limit = [0.51, app.dataset_parameters.n_pixels(1) + .49, 0.51, app.dataset_parameters.n_pixels(2) + .49];
                app.common_parameters.real_axes_limit = [0.51, app.dataset_parameters.n_frames(1) + .49, 0.51, app.dataset_parameters.n_frames(2) + .49];
                set(app.ui_groups.diffraction_axes, ...
                    "XLim" , app.common_parameters.diffraction_axes_limit(1:2), ...
                    "YLim",app.common_parameters.diffraction_axes_limit(3:4));
                set(app.ui_groups.real_axes, ...
                    "XLim", app.common_parameters.real_axes_limit(1:2), ...
                    "YLim",app.common_parameters.real_axes_limit(3:4));
                
                % Transmitted Beam controls
                if event.Source ~= app.SwapRealXY
                    % Keep transmitted beam alignment if swapping Real-space X/Y
                    app.common_parameters.transmitted_beam_init = false;
                    
                    % set transmitted beam radius, x, y limits and values
                    set([app.TransBeamR; ...
                         app.TransBeamX; ...
                         app.TransBeamXSpinner; ...
                         app.TransBeamY; ...
                         app.TransBeamYSpinner], ...
                        {"Limits"}, ...
                        {[eps(), max(app.dataset_parameters.n_pixels)]; ...
                         [0, app.dataset_parameters.n_pixels(1)]; ...
                         [0, app.dataset_parameters.n_pixels(1)]; ...
                         [0, app.dataset_parameters.n_pixels(2)]; ...
                         [0, app.dataset_parameters.n_pixels(2)]}, ...
                        {"Value"}, ...
                        {min(app.dataset_parameters.n_pixels)/10; ...
                         app.common_parameters.diffraction_center(1); ...
                         app.common_parameters.diffraction_center(1); ...
                         app.common_parameters.diffraction_center(2); ...
                         app.common_parameters.diffraction_center(2)});

                    % Update Transmitted Beam dependent variables
                    transmitted_beam_callbacks(app, event)
                end
                
                % Real-space ROI controls
                set([app.RealROIFrameX; ...
                     app.RealROIFrameY], ...
                    {"Limits"}, ...
                    {[0 app.dataset_parameters.n_frames(1)]; ...
                     [0 app.dataset_parameters.n_frames(2)]}, ...
                    {"Value"}, ...
                    {app.common_parameters.real_space_center(1); ...
                     app.common_parameters.real_space_center(2)})
                app.RealROIInvert.Value = false;
                app.RealROIShape.Value = "full";
                
                % Export controls
                export_callbacks(app, struct("Source", app.ExportPartialPixels, "EventName", [], "Value", 0))
                export_callbacks(app, struct("Source", app.ExportPartialFrames, "EventName", [], "Value", 0))
                
                % Image display contrasts and vector field
                for ax = app.ui_groups.image_axes'
                    ax.UserData.brightness = 50;
                    ax.UserData.contrast = 50;
                    ax.UserData.gamma = 1;
                    ax.UserData.mask_opacity = 0;
                    ax.UserData.vector_field = false;
                end

                % Update all images
                mock_UI_callbacks(app,app.UpdateImages)
                
                % Close progress bar and Enable panel objects
                delete(app.tmp_variables.progress_dialog);
                
                % Enter initializing alignment
                mock_UI_callbacks(app,app.Mode)
                app.SettingsTabGroup.SelectedTab = app.DisplayTab;
                
                % Show the brightness/contrast/gamma controls
                figure(app.Quant4D_Fig)
                figure(app.figures.Settings)

                % alert user to the need to calibrate the transmitted beam
                flash_background(app, [app.DiffractionCalibrationGrid app.TransBeamAlignGrid])
            end

            % Function to import data
            function import_data(event)
                debug_time = tic;

                if ~isfile(app.dataset_parameters.file_path)
                    notification_dialog(app, "warn", "File: '"+app.dataset_parameters.file_path+"' is not found!",'File Unfound!');
                    return
                end

                % Set save/export filenames etc.
                app.Quant4D_Fig.Name = "Quant4D: " + app.dataset_parameters.file_name + app.dataset_parameters.file_extension;
                app.SaveDirectoryPath.Value = app.dataset_parameters.file_directory;
                app.SaveImagePrefix.Value = app.dataset_parameters.file_name + "_";
                app.ExportFilename.Value = app.dataset_parameters.file_name + "_export";

                % Start file reading here
                try
                    err = read_file();
                catch ME
                    % Kill the reading progress update timer
                    try stop(app.tmp_variables.tmr), delete(app.tmp_variables.tmr); catch; end
                    try delete(app.tmp_variables.progress_dialog); catch; end
                    try unload_data(app); catch; end
                    stk = "";
                    for i = 1:numel(ME.stack)
                        stk = sprintf("%s\n%s, Line: %d", stk, ME.stack(i).name, ME.stack(i).line);
                    end
                    notification_dialog(app, ...
                                        "error", ...
                                        sprintf('%s\n%s\n%s%s', ME.identifier, ME.message, ME.Correction, stk), ...
                                        "Error during Import!");
                    enable_windows(app,true)
                    rethrow(ME)
                end
                % If aborted or errors occurred during file reading, reset app like just opened without import
                if err == -1
                    reset_Quant4D(app, event); 
                    return;
                end

                debug_toc(app, event, "", debug_time)

                % Prepare imported data
                if app.dataset_parameters.keep_parameters
                    % Enter alignment mode
                    app.Mode.Value = "Alignment";

                    % Not re-initializing and just to update all images if "swap data"
                    mock_UI_callbacks(app,app.UpdateImages)
                    mock_UI_callbacks(app,app.Mode)
                    
                    % Swap byte check
                    dataset_options_callbacks(app, event)
                
                else
                    prepare_data(event)
                
                end


                %%%% Sub sub functions
                % Sub sub function to read file into memory
                function err = read_file()
                    debug_time = tic;
                    err = 0;

                    % Use local variables, which is faster
                    offs = app.dataset_parameters.offset;
                    hdr = app.dataset_parameters.frame_header;
                    ftr = app.dataset_parameters.frame_footer;
                    order = app.dataset_parameters.byte_orer;
                    dtype = app.dataset_parameters.data_type;
                    bsize = app.dataset_parameters.byte_size;
                    dnpx = app.dataset_parameters.n_pixels_file(1);
                    dnpy = app.dataset_parameters.n_pixels_file(1);
                    pd = app.dataset_parameters.pixels_binning;
                    dnfx = app.dataset_parameters.n_frames_file(1);
                    dnfy = app.dataset_parameters.n_frames_file(2);
                    fd = app.dataset_parameters.frames_sampling;
                    px1 = app.dataset_parameters.pixels_start(1);
                    py1 = app.dataset_parameters.pixels_start(2);
                    npx = app.dataset_parameters.n_pixels(1);
                    npy = app.dataset_parameters.n_pixels(2);
                    fx1 = app.dataset_parameters.frames_start(1);
                    fy1 = app.dataset_parameters.frames_start(2);
                    nfx = app.dataset_parameters.n_frames(1);
                    nfy = app.dataset_parameters.n_frames(2);
                    frame_size = npx*npy*pd^2*bsize;

                    % convert to single/double if int/uint
                    dtype1 = dtype;
                    if contains(dtype,'int')
                        if bsize > 4
                            dtype1 = "double";
                            bsize1 = 8;
                        else
                            dtype1 = "single";
                            bsize1 = 4;
                        end
                        app.dataset_parameters.data_type = dtype1;
                        app.dataset_parameters.byte_size = bsize1;
                    end

                    [app.tmp_variables.progress_dialog, app.tmp_variables.tmr] = import_export_progress(app, "Import", prod(app.dataset_parameters.n_frames), frame_size);

                    if ~isempty(app.dataset_parameters.h5ds) % HDF5 import
                        % Preallocate array
                        data_tmp = zeros(npx, npy, nfx, nfy, dtype1);
                        fid = H5F.open(app.dataset_parameters.file_path);
                        dset_id = H5D.open(fid, app.dataset_parameters.h5ds);
                        file_space_id = H5S.create_simple(4, [dnfy dnfx dnpy dnpx], []);
                        mem_space_id = H5S.create_simple(2, [npy*pd npx*pd], []);
                        
                        % Read whole
                        % data = H5D.read(dset_id,'H5ML_DEFAULT','H5S_ALL','H5S_ALL','H5P_DEFAULT');

                        % Read frame by frame
                        for ify = 1:nfy
                            for ifx = 1:nfx
                                % Cancel if progress window closed
                                if ~isvalid(app.tmp_variables.progress_dialog)
                                    err = -1;
                                    break;
                                end 

                                % Update processed Frame Number for progress update
                                app.tmp_variables.frame_number(1) = (ify-1)*nfx + ifx;
                                
                                % Read 1 frame at a time; C-like array indices for H5 low-level APIs
                                H5S.select_hyperslab(file_space_id, 'H5S_SELECT_SET', [fy1+(ify-1)*fd, fx1+(ifx-1)*fd, py1, px1]-1, [],[], [1 1 npy*pd npx*pd]);
                                
                                frame = H5D.read(dset_id, 'H5ML_DEFAULT', mem_space_id, file_space_id, 'H5P_DEFAULT');
                                
                                % Pixel binning
                                if pd > 1
                                    frame = squeeze(sum(reshape(frame, [pd npx pd npy]), [1 3]),'omitnan');
                                end
                                
                                % Store frame
                                data_tmp(:, :, ifx, ify) = frame;
                            end
                            
                            % break on error
                            if err == -1
                                break;
                            end
                        end

                        % Close opened handles
                        H5S.close(mem_space_id);
                        H5S.close(file_space_id);
                        H5D.close(dset_id);
                        H5F.close(fid);
                        
                        % Make dataset a 2D array
                        app.data = reshape(data_tmp, prod(app.dataset_parameters.n_pixels), prod(app.dataset_parameters.n_frames));
                    
                    elseif app.dataset_parameters.memory_type== "Physical Memory"
                        % General reading method; read data into physical memory
                        
                        % Pre-allocate array
                        data_tmp = zeros(npx, npy, nfx, nfy, dtype1);
                        
                        % frame size including header/footer
                        dfrSize = hdr+dnpx*dnpy*bsize+ftr;

                        % file id
                        fid = fopen(app.dataset_parameters.file_path,"r");
                        
                        for ify = 1:nfy
                            for ifx = 1:nfx
                                % Cancel if progress window closed
                                if ~isvalid(app.tmp_variables.progress_dialog)
                                    err = -1;
                                    break;
                                end 

                                % Update processed Frame Number for progress update
                                app.tmp_variables.frame_number(1) = (ify-1)*nfx + ifx;

                                % Read 1 frame at a time; move cursor to "frame offset" + "pixel Y offset" in file dataset
                                % Frame indices in dataset file
                                dfx = fx1+(ifx-1)*fd;
                                dfy = fy1+(ify-1)*fd;

                                fseek(fid, offs + (dfx-1 + (dfy-1)*dnfx) * dfrSize + hdr + ((px1-1) + (py1-1)*dnpx) * bsize, 'bof');
                                % Read including "pixel X offset"
                                frame = fread(fid, [dnpx, npy*pd], dtype, 0, order);

                                % Crop "pixel X offset" off from array memory
                                if npx*pd ~= dnpx
                                    frame = frame(px1:px1+npx*pd-1, :);
                                end

                                % Pixel binning
                                if pd > 1
                                    frame = squeeze(sum(reshape(frame, [pd npx pd npy]), [1 3],'omitnan'));
                                end
                                
                                % Store frame
                                data_tmp(:, :, ifx, ify) = frame;
                            end
                            
                            % break on error
                            if err == -1
                                break;
                            end
                        end

                        % close file id
                        fclose(fid);

                        % Make dataset a 2D array
                        app.data = reshape(data_tmp,prod(app.dataset_parameters.n_pixels),prod(app.dataset_parameters.n_frames));

                    elseif app.dataset_parameters.memory_type== "Virtual Mapping"
                        % Virtual memory mapping

                        % Create a memory mapped file, rather than reading the file directly into memory
                        if ~strcmp(app.sys_constants.system_endianness, order)
                            error("Dataset's endianness is different to that of the system!")
                        end

                        if hdr == 0 && ftr == 0
                            app.memfile = memmapfile(app.dataset_parameters.file_path, ...
                                                     'Offset',offs, ...
                                                     'Format',{dtype1,[prod(app.dataset_parameters.n_pixels), prod(app.dataset_parameters.n_frames)],'data'}, ...
                                                     'Repeat',1);
                            app.data = app.memfile.Data.data;
                        else
                            error("Virtual memory with header/footer > 0 not supported yet.")
                        end
                    end
                    
                    % remove temporary data reference
                    clear data_tmp

                    % Delete the interruptible progress window
                    delete(app.tmp_variables.progress_dialog)
                    
                    % Move to GPU if enabled
                    if app.dataset_parameters.gpu > 0 && err == 0
                        app.tmp_variables.progress_dialog = progress_dialog(app, "Moving dataset to GPU memory ...", "GPU Transfer");
                        app.data = gpuArray(app.data);
                        delete(app.tmp_variables.progress_dialog)
                    end

                    % Check data NaN/Inf, without asking to sway byte-order
                    sel = check_NaN(app,app.data(:),1); 
                    if strcmp(sel,'Set NaNs to 0')
                        app.data(isnan(app.data(:)))=0;
                    end

                    debug_toc(app, event, "", debug_time)
                end

            end
        end

        % Callback function: ImportFilePath, ImportFileSelect
        function import_select_file(app, event)
            debug_time = tic;

            if event.Source == app.ImportFileSelect
                % Get user to select a file
                [fileName, fileDir] = uigetfile({'*.raw;*.mib;*.mrc;*.dm3;*.dm4;*.npy;*.h5;*.hdf5','Supported Formats'; ...
                                                 '*.*','All Files'}, ...
                                                 'Select 4DSTEM Dataset');
                
                % Force to show
                set(app.figures.Import,"Visible", "off","Visible", "on");
                
                if fileName
                    app.ImportFilePath.Value = fullfile(fileDir, fileName);
                end
            end

            if isfile(app.ImportFilePath.Value)
                % get file directory and extension
                [fileDir, ~, fileExt] = fileparts(app.ImportFilePath.Value);

                % change to directory containing dataset for future saving
                cd(fileDir)

                % get file size in bytes
                app.tmp_variables.FileSize = dir(app.ImportFilePath.Value).bytes;
                
                % Display actual file size in bytes and GiB
                app.ImportActualFilesize.Text = sprintf('%d bytes, %.1f GiB',app.tmp_variables.FileSize,app.tmp_variables.FileSize/1024^3);

                switch lower(fileExt)
                    case ".mib"
                        % Medipix data
                        app.Medipix.Value = 1;
                    
                    case ".mrc"
                        % MRC data
                        app.MRC.Value = 1;
                    
                    case {".dm3"; ".dm4"}
                        % Gatan K2/K3 data
                        app.DM34.Value = 1;
                    
                    case {".h5"; ".hdf5"}
                        app.HDF5.Value = 1;
                    
                    case {".raw"}
                        app.EMPAD.Value = 1;
                    
                    otherwise
                        app.Custom.Value = 1;
                end
            
            else
                % if the file doesn't exist, revert to starting point
                app.ImportActualFilesize.Text = "";
                app.tmp_variables.FileSize = 0;
                
            end

            debug_toc(app, event, "", debug_time)

            % Further file info
            import_file_type(app, event)
        end

        % Callback function: FileTypeButtonGroup, SubDataset
        function import_file_type(app, event)
            % Make an initial guess at the file dimensions (assume ~square field of view: NFrX≈NFrY)
            function guessFramesFromSize()
                if app.ImportPixelsX.Value > 1 && app.ImportPixelsY.Value > 1
                    x = sqrt((app.tmp_variables.FileSize-app.DataOffset.Value-app.tmp_variables.fileTail) ...
                        /(app.ImportPixelsX.Value*app.ImportPixelsY.Value*app.byte_size.(app.ImportDataType.Value)+app.FrameHeader.Value+app.FrameFooter.Value));
                    
                    set([app.ImportFramesX, ...
                         app.ImportFramesY], ...
                         "Value",max(floor(x),1))
                end
            end

            % Guess numbers of Frame X and Y from 3D dataset (with only Frame X)
            function guessFramesFromDimZ(dz)
                app.ImportFramesX.Value = round(sqrt(dz));
                app.ImportFramesY.Value = round(dz/app.ImportFramesX.Value);
            end

            % Guess dataset size from fileName in the following style (only the first match):
            %   fileName = 'prefixAxBxCxDbar' or 'scan_xC_yD.raw'
            % 
            % where A,B,C,D are integers:
            %   (A,B) = # pixels in Diffraction space
            %   (C,D) = # probe positions in Real space

            function getDimFromName(name)
                s = regexp(name, ["scan_x(\d*)_y(\d*).raw", "(\d*)x(\d*)x(\d*)x(\d*)"], "tokens");
                
                if ~isempty(s{1})
                    % "scan_xC_yD.raw" file name, assume EMPAD file format
                    set([app.ImportPixelsX app.ImportPixelsY app.ImportFramesX app.ImportFramesY],{"Value"},num2cell([128 128 str2double(s{1}{1})])')
                
                elseif ~isempty(s{2})
                    % "AxBxCxD" found in file name, assume (kx, ky, rx, ry)
                    set([app.ImportPixelsX app.ImportPixelsY app.ImportFramesX app.ImportFramesY],{"Value"},num2cell(str2double(s{2}{1}))')
                end


            end

            debug_time = tic;

            % Medipix data are saved as a .mib file of varying bit-depth as unsigned integers of the next highest class
            %   (i.e. 12-bit is saved as a 16-bit unsigned integer) with big-endian ordering.
            % EMPAD data are saved as a .raw file with 32-bit depth with little endian ordering.
            % Fiji/ImageJ tend to save their files with big-endian ordering, though this is also system- and user-dependent.

            notes = "";
            err = 0;
            app.ImportDataType.Items = {'Select Data Type';
                                        '8-bit Signed';
                                        '8-bit Unsigned';
                                        '16-bit Signed';
                                        '16-bit Unsigned';
                                        '32-bit Real';
                                        '32-bit Signed';
                                        '32-bit Unsigned';
                                        '64-bit Real';
                                        '64-bit Signed';
                                        '64-bit Unsigned'};

            app.ImportDataType.ItemsData = {'unknown';
                                            'int8';
                                            'uint8';
                                            'int16';
                                            'uint16';
                                            'single';
                                            'int32';
                                            'uint32';
                                            'double';
                                            'int64';
                                            'uint64'};

            app.tmp_variables.dm = [];
            app.tmp_variables.h5 = [];
            set([app.DataOffset app.FrameHeader app.FrameFooter], "Value",0)
            app.tmp_variables.fileTail = 0;
            app.ImportByteOrder.Value = "l";
            app.ImportDataType.Value = "unknown";
            
            if event.Source == app.ImportFileSelect
                set(app.SubDataset, "Items",{}, "Enable", "off")
            end
            
            if isfile(app.ImportFilePath.Value)
                switch app.FileTypeButtonGroup.SelectedObject
                    case app.EMPAD
                        app.FrameFooter.Value = 1024;
                        app.ImportDataType.Value = "single";
                        app.ImportPixelsX.Value = 128;
                        app.ImportPixelsY.Value = 128;
                        notes = sprintf('Pixels:\t128x128\n32-bit Real\n** EMPAD data have 1024-byte frame footers');
                        guessFramesFromSize();
                        getDimFromName(app.ImportFilePath.Value)

                        % check if file size matches (i.e. likely an EMPAD
                        % file), or if it suits better to set FrameHeader,
                        % FrameFoot, and DataOffset to 0 (i.e. a .raw
                        % binary file from simulation or other)
                        file_size = app.DataOffset.Value ...
                                    + (app.FrameHeader.Value...
                                       + app.ImportPixelsX.Value ...
                                       * app.ImportPixelsY.Value ...
                                       * app.byte_size.(app.ImportDataType.Value) ...
                                       + app.FrameFooter.Value ...
                                       ) ...
                                    * (app.ImportFramesX.Value* app.ImportFramesX.Value);
                        if file_size > dir(app.ImportFilePath.Value).bytes
                            test_size = app.ImportPixelsX.Value * ...
                                        app.ImportPixelsY.Value * ...
                                        app.ImportFramesX.Value * ...
                                        app.ImportFramesX.Value * ...
                                        app.byte_size.(app.ImportDataType.Value);
                            if test_size == dir(app.ImportFilePath.Value).bytes
                                app.Custom.Value = 1;
                                app.FrameFooter.Value = 0;
                                app.FrameHeader.Value = 0;
                                app.DataOffset.Value = 0;
                            end
                        end

                    case app.Medipix
                        app.ImportDataType.Items = ["Select Data Type"; ["1 or 6";"12";"24"]+"-bit Padded Unsigned"];
                        app.ImportDataType.ItemsData = {"unknown";"uint8";"uint16";"uint32"};
                        fid = fopen(app.ImportFilePath.Value,"r");
                        fmeta = strsplit(fread(fid, 128, 'uint8=>char')', ',');
                        
                        % Signature character in header
                        if numel(fmeta) >= 7 && ismember(lower(fmeta{7}(1)), ["r", "u"])
                            % Re-read with known header size
                            frewind(fid)
                            fmeta = strsplit(fread(fid, str2double(fmeta{3}), 'uint8=>char')', ',');
                            
                            dtype = lower(fmeta{7});
                            bitsize = str2double(fmeta(end-1));
                            
                            if dtype(1) == 'u'
                                switch bitsize
                                    case {1; 6}, app.ImportDataType.Value = "uint8";
                                    case 12, app.ImportDataType.Value = "uint16";
                                    case 24, app.ImportDataType.Value = "uint32";
                                end
                            end
                            
                            app.FrameHeader.Value = str2double(fmeta{3});
                            app.ImportByteOrder.Value = "b";
                            app.ImportPixelsX.Value = str2double(fmeta{5});
                            app.ImportPixelsY.Value = str2double(fmeta{6});
                            notes = sprintf(['Dim: X = %s, Y = %s\nData type: %s\nPixel depth: %d bit\n' ...
                                             'Frame header: %s bytes\nBig endian'], fmeta{5}, fmeta{6}, fmeta{7}, bitsize, fmeta{3});
                            guessFramesFromSize();
                            getDimFromName(app.ImportFilePath.Value)
                        
                        else
                            notes = 'Unrecognizable as a Medipix/.mib file.';
                            err = -1;
                        end
                        fclose(fid);

                    case app.MRC
                        % Read in MRC header - first 1024 bytes
                        fid = fopen(app.ImportFilePath.Value,"r");
                        fseek(fid,208,'bof');
                        map = fread(fid, [1, 4], 'uint8=>char');
                        
                        % Signature characters
                        if map == "MAP "
                            % Bytes for Endianness
                            e = fread(fid, 2, "uint8");
                            
                            if e(1) == 68 && ismember(e(2), [65, 68])
                                app.ImportByteOrder.Value = "l";
                                bo = 'Little Endian';
                            
                            elseif e(1) == 17 && e(2) == 17
                                app.ImportByteOrder.Value = "b";
                                bo = 'Big Endian';
                            
                            else
                                bo = 'Unknown Endianness';
                            
                            end

                            frewind(fid);
                            
                            % X, Y and Z, only 3D
                            dim = fread(fid,3,"uint32");
                            
                            app.ImportPixelsX.Value = dim(1);
                            app.ImportPixelsY.Value = dim(2);
                            app.ImportFramesX.Value = dim(3);
                            
                            guessFramesFromDimZ(dim(3))
                            getDimFromName(app.ImportFilePath.Value)

                            dtype = fread(fid,1,"uint32");
                            
                            switch dtype
                                case 0
                                    app.ImportDataType.Value = "int8";
                                    tnote = "8-bit Signed Integer";
                                
                                case 1
                                    app.ImportDataType.Value = "int16";
                                    tnote = "16-bit Signed Integer";
                                
                                case 2
                                    app.ImportDataType.Value = "single";
                                    tnote = "32-bit Real Float";
                                
                                case 3
                                    tnote = "16-bit Complex Signed Integer, unsupported";
                                
                                case 4
                                    tnote = "32-bit Complex Float, unsupported";
                                
                                case 6
                                    app.ImportDataType.Value = "uint16";
                                    tnote = "16-bit Unsigned Integer";
                                
                                otherwise
                                    tnote = "Uknown data type";
                            end

                            app.DataOffset.Value = 1024;
                            notes = sprintf('Dim: X = %d, Y = %d, Z = %d\n%s, %s\n** MRC files have a 1024-byte data offset', ...
                                dim, tnote, bo);
                        else
                            notes = sprintf('Unrecognizable as MRC.\n\n"MAP " word missed.');
                            err = -1;
                        end
                        fclose(fid);

                    case app.DM34
                        % Read .dm3/4 tag values
                        try app.tmp_variables.dm = dmInfo(app.ImportFilePath.Value);catch;end
                        
                        if iscell(app.tmp_variables.dm) && numel(app.tmp_variables.dm) > 1
                            % List all images' names in `app.SubDataset`
                            pdim = zeros(numel(app.tmp_variables.dm)-1, 1);
                            app.SubDataset.Enable = "on";
                            for i = 1:numel(pdim)
                                app.SubDataset.Items{i} = char(app.tmp_variables.dm{i}.Name);
                                pdim(i) = prod(cell2mat(app.tmp_variables.dm{i}.Dimensions));
                            end
                            
                            % Select sub-image with the largest dimension; unless manual selected
                            if event.Source == app.SubDataset
                                app.SubDataset.Value = event.Value;
                            else
                                [~, i] = max(pdim);
                                app.SubDataset.Value = app.SubDataset.Items{i};
                            end
                            
                            % Info of selected sub-image
                            dm = app.tmp_variables.dm{[strcmp(app.SubDataset.Items, app.SubDataset.Value), false]};
                            finfo = app.tmp_variables.dm{end};
                            
                            % Read dimensions
                            dims = dm.Dimensions;
                            
                            % Fill missing dimensions as 1
                            for i = 1:4
                                if i > numel(dims)
                                    dims{i} = 1;
                                end
                            end

                            set([app.ImportPixelsX, ...
                                 app.ImportPixelsY, ...
                                 app.ImportFramesX, ...
                                 app.ImportFramesY], ...
                                {"Value"}, dims)
                            
                            % Find offset, file tail, data type, byte-order of dataset
                            app.DataOffset.Value = dm.Data.offset;
                            try app.ImportDataType.Value = dm.Data.dataType; catch;end
                            app.tmp_variables.fileTail = finfo.fileSize - dm.Data.offset - dm.Data.size;
                            
                            % If pixels are not scalars (e.g. complex image)
                            if dm.ImageDataType.pixel ~= "scalar"
                                app.ImportDataType.Value = "unknown";
                            end

                            % Is it little endian?
                            if finfo.liEndian
                                app.ImportByteOrder.Value = "l";
                                bo = 'Little Endian';
                            else
                                app.ImportByteOrder.Value = "b";
                                bo = 'Big Endian';
                            end

                            notes = sprintf("Dim: X = %d, Y = %d, Z = %d, W = %d\nDM%d, %s\nData-type: %s\n" ...
                                +"Selected sub-image's offset is %d bytes.", ...
                                dims{:}, finfo.version, bo, dm.ImageDataType.full, dm.Data.offset);

                            if dims{4} == 1
                                guessFramesFromDimZ(dims{3});
                            end

                        else
                            notes = "Read DM file error: " + app.tmp_variables.dm;
                            err = -1;
                        end

                    case app.HDF5
                        try
                            app.tmp_variables.h5 = h5_datasets(app,app.ImportFilePath.Value);
                        catch
                            notes = 'Read HDF5 file error';
                            err = -1;
                        end

                        if ~err
                            app.SubDataset.Enable = "on";
                            % Select sub-image with the 1st largest dimension; unless manual selected
                            if event.Source ~= app.SubDataset
                                % List all images' names in `app.SubDataset`
                                pdim = zeros(numel(app.tmp_variables.h5)-1, 1);
                                for i = 1:numel(pdim)
                                    app.SubDataset.Items{i} = char(app.tmp_variables.h5(i).Name);
                                    pdim(i) = prod(app.tmp_variables.h5(i).Dataspace.Size);
                                end
                                [~, i] = max(pdim);
                                app.SubDataset.Value = app.SubDataset.Items{i};
                            end
                            
                            % Info of selected sub-image
                            h5i = app.tmp_variables.h5([strcmp(app.SubDataset.Items, app.SubDataset.Value), false]);
                            
                            % Read dimensions
                            dims = h5i.Dataspace.Size;
                            
                            % Fill missing dimensions as 1
                            for i = 1:4
                                if i > numel(dims)
                                    dims(i) = 1;
                                end
                            end

                            % set Import UI values
                            set([app.ImportPixelsX; ...
                                 app.ImportPixelsY; ...
                                 app.ImportFramesX; ...
                                 app.ImportFramesY], ...
                                {"Value"},num2cell(dims)')
                            
                            % Find offset, file tail, data type, byte-order of dataset
                            if h5i.offset == -1
                                app.DataOffset.Value = 0;
                                noteoffs = 'This dataset is not "contiguous", thus data offset is unknown.';
                            else
                                app.DataOffset.Value = h5i.offset;
                                noteoffs = "Selected dataset's offset is "+h5i.offset+" bytes.";
                            end

                            app.tmp_variables.fileTail = app.tmp_variables.h5(end).size - app.DataOffset.Value - h5i.size;
                            
                            % If data type is unknown
                            if ~isempty(h5i.type)
                                app.ImportDataType.Value = h5i.type;
                            end

                            if endsWith(h5i.byte_order, "_BE")
                                app.ImportByteOrder.Value = "b";
                                bo = 'Big Endian';
                            else
                                app.ImportByteOrder.Value = "l";
                                bo = 'Little Endian';
                            end

                            notes = sprintf("Dim: X = %d, Y = %d, Z = %d, W = %d\n%s, %s\n%s", dims, bo, h5i.Datatype.Type, noteoffs);
                            
                            if dims(4) == 1
                                guessFramesFromDimZ(dims(3));
                            end
                        end

                    otherwise %case app.Custom
                        getDimFromName(app.ImportFilePath.Value)
                        app.ImportDataType.Value = "single";
                        notes = "Custom import";

                end
            else
                notes = "File path is invalid";
                err = -1;
            end

            app.ImportFileMetadata.Value = notes;

            if app.ImportDataType.Value == "unknown" &&  err ~= -1
                notification_dialog(app, ...
                                    "warn", ...
                                    "The data type indicated in the file's metadata is not supported/recognized.", ...
                                    "Invalid Data Type!");
            end

            debug_toc(app, event, "", debug_time)

            % Reset import range selections
            range_import_export(app, struct("Source", app.ImportPixelsX, "EventName", []));
            range_import_export(app, struct("Source", app.ImportFramesX, "EventName", []));
            import_box_input(app, event)
        end

        % Value changed function: DataOffset, 
        % ...and 22 other components
        function import_box_input(app, event)
            debug_time = tic;
            
            % initialize parameters
            app.ImportEstimatedFilesize.Text = "";
            notes = "";
            diffraction_event = event;
            real_event = event;

            if isfile(app.ImportFilePath.Value)
                % Update Diffraction and Real-space import ranges; use dummy event if source is not a range UI
                if ~contains(event.Source.Tag, "Diffraction")
                    diffraction_event = struct("Source",app.DiffractionPartialImportPixelsDist, "Value",app.DiffractionPartialImportPixelsDist.Value);
                end
                if ~contains(event.Source.Tag, "Real")
                    real_event = struct("Source",app.RealPartialImportFramesDist, "Value",app.RealPartialImportFramesDist.Value);
                end
                
                [dnote,~,~,np,~] = range_import_export(app, diffraction_event);
                [rnote,~,~,nf,~] = range_import_export(app, real_event);
                notes = dnote+rnote;
                
                % Enable/Disable Range UIs
                set(app.DiffractionPartialImportGrid.Children, "Enable", app.ImportPartialPixels.Value)
                set(app.RealPartialImportGrid.Children, "Enable", app.ImportPartialFrames.Value)
                
                % Current dataset dimensions
                irng = [np nf];
                
                % Allow for virtual memory mapping in some situations
                if app.FrameHeader.Value == 0 && app.FrameFooter.Value == 0 ...
                        && (~app.HDF5.Value || app.DataOffset.Value) ...
                        && app.ImportByteOrder.Value == app.sys_constants.system_endianness && ~contains(app.ImportDataType.Value,'int') ...
                        && isequal([app.ImportPixelsX.Value app.ImportPixelsY.Value app.ImportFramesX.Value app.ImportFramesY.Value], irng)
                    app.MemoryType.Enable = "on";
                else
                    app.MemoryType.Enable = "off";
                    app.MemoryType.Value = "Physical Memory";
                end

                % Calculate dataset size and memory usage
                if app.ImportDataType.Value ~= "unknown"
                    bytesize = app.byte_size.(app.ImportDataType.Value);
                    app.tmp_variables.EstSize = app.DataOffset.Value + app.tmp_variables.fileTail ...
                        + (app.FrameHeader.Value+app.ImportPixelsX.Value*app.ImportPixelsY.Value*bytesize+app.FrameFooter.Value) ...
                        * app.ImportFramesX.Value*app.ImportFramesY.Value;
                    app.ImportEstimatedFilesize.Text = sprintf('%d bytes, %.1f GiB',app.tmp_variables.EstSize,app.tmp_variables.EstSize/1024^3);

                    if contains(app.ImportDataType.Value,'int')
                        if bytesize > 4
                            f = "double";
                        else
                            f = "single";
                        end
                        bytesize = app.byte_size.(f);
                        notes = notes+"\nDataset will be converted into "+bytesize*8+"-bit Real.";
                    end
                    notes = notes+"\nImport by "+app.MemoryType.Value+"\n"+checkMem(np,nf,bytesize);
                    notes = notes+"\n\n"+checkGPUMEM(np,nf);
                end
                
                % Allow to swap dataset/keep parameters if the dimensions are identical
                app.SwapDataset.Enable = ~isempty(app.dataset_parameters) && isequal(irng, [app.dataset_parameters.n_pixels(1) app.dataset_parameters.n_pixels(2) app.dataset_parameters.n_frames(1) app.dataset_parameters.n_frames(2)]);
                if ~app.SwapDataset.Enable
                    app.SwapDataset.Value = false;
                end
                
                if app.SwapDataset.Value
                    notes = notes+"\n\nSwapping dataset - keep current setups/alignments etc.";
                end
            else
                % default to GPU off when no file path is present
                app.GPU.Value = 0;
            end

            app.ImportSummary.Value = sprintf(notes);

            debug_toc(app, event, "", debug_time)

            %%% Sub functions
            function notes = checkMem(np,nf,byte_size)
                % Display estimated size in green if matches actual size, brown if smaller, red if larger
                if app.tmp_variables.EstSize == app.tmp_variables.FileSize
                    app.ImportEstimatedFilesize.FontColor = [0 0.5 0];
                elseif app.tmp_variables.EstSize < app.tmp_variables.FileSize
                    app.ImportEstimatedFilesize.FontColor = [0.5 0.5 0];
                else
                    app.ImportEstimatedFilesize.FontColor = "r";
                end

                % Estimate the amount of memory needed to hold the largest arrays in memory
                % Estimate of the distance to the far corner of the diffraction pattern
                est_mem = prod([np nf]) ...                                         % app.data
                          + prod(nf)*size(0:0.5:floor(sqrt(sum((np/2).^2))),2) ...  % app.images.annular_images
                          + prod(nf)*10 + prod(np)*2  ...                           % app.images
                          + prod(np)*10;                                            % app.masks

                app.tmp_variables.estimated_memory = est_mem*byte_size;

                % check if there is an appropriate GPU to use
                if event.Source ~= app.GPU
                    if any([app.sys_constants.gpu.memory]>=app.tmp_variables.estimated_memory)
                        [~,gpu_index] = max([app.sys_constants.gpu.memory]);
                        app.GPU.Value = gpu_index;
                    else
                        app.GPU.Value = 0;
                    end
                end

                notes = sprintf("Estimated memory usage: %.3f GiB.",app.tmp_variables.estimated_memory/1024^3);
                if app.tmp_variables.estimated_memory > app.sys_constants.total_memory
                    msg = sprintf("WARNING: Size of dataset is larger than the system memory!");
                    notification_dialog(app, "error", msg, 'Size Exceeds System Memory!');
                    notes = notes+"\n"+msg;
                end
                notes = notes+"\nSystem byte order: "+app.sys_constants.endian_text.(app.sys_constants.system_endianness)+".";
            end

            function gpu_note = checkGPUMEM(np,nf)
                igpu = app.GPU.Value;
                if ~igpu
                    gpu_note = "GPU disabled.";
                elseif prod([np nf]) > intmax("int32")
                    gpu_note = "GPU disabled, because dataset has more elements than MATLAB CUDA's maximum (2^31-1).";
                    app.GPU.Value = 0;
                else
                    tgpu = sprintf('GPU %d. %s %.1f GiB', igpu, app.sys_constants.gpu(igpu).name, app.sys_constants.gpu(igpu).memory/1024^3);
                    try
                        if ~parallel.gpu.GPUDevice.getDevice(igpu).DeviceSelected
                            gpuDevice(igpu);
                        end
                        
                        available_memory = parallel.gpu.GPUDevice.getDevice(igpu).AvailableMemory;
                        
                        % If GPU is already in use, taking the current data size into account
                        if isfield(app.dataset_parameters, "GPU") && app.dataset_parameters.gpu == app.GPU.Value
                            try
                                available_memory = available_memory + app.dataset_parameters.estimated_memory;
                            catch
                            end
                        end
                        
                        amem_t = sprintf('%.3f GiB', available_memory/1024^3);
                        
                        warning_text = [];
                        
                        if app.tmp_variables.estimated_memory > available_memory - 128*1024^2 % Has 128 MiB extra?
                            gpu_note = tgpu+" does NOT have enough free memory ("+amem_t+"). 128 MiB extra free memory required.";
                            warning_text = 'GPU Not Enough Free Memory!';
                            app.GPU.Value = 0;
                        
                        elseif app.tmp_variables.estimated_memory > available_memory - 512*1024^2 % Has 512 MiB extra?
                            gpu_note = tgpu+" has LIMITED free memory ("+amem_t+") and may cause errors.";
                            warning_text = 'GPU Limited Free Memory!';
                        
                        else
                            gpu_note = tgpu+" has enough free memory ("+amem_t+") for the selected dataset.";
                        
                        end
                        
                        % Notify warnings if any
                        if ~isempty(warning_text)
                            notification_dialog(app, "warn", gpu_note, warning_text);
                        end
                    
                    catch ME
                        gpu_note = sprintf('Failed to select %s!\n%s\n%s\n%s',tgpu,ME.identifier,ME.message,ME.Correction);
                        notification_dialog(app, "error", gpu_note, 'Error Selecting GPU!');
                        app.GPU.Value = 0;
                    end
                end
            end
        end

        % Callback function: AutoSaveImage, SaveDirectoryButton, 
        % ...and 12 other components
        function save_callbacks(app, event)
            debug_time = tic;
            
            % Try to update image name prefix
            updatePrefix()

            switch event.Source
                case app.ShowSaveWindow
                    figure(app.figures.Save)
                    % Trigger tab change callbacks defined in `@app.export_callbacks()`
                    mock_UI_callbacks(app,app.SaveTabGroup);
                
                case app.SaveDirectoryButton
                    dir = uigetdir(app.SaveDirectoryPath.Value, 'Select Directory to Save Images');
                    drawnow
                    figure(app.figures.Save)
                    if dir
                        app.SaveDirectoryPath.Value = dir;
                    end

                case app.SaveDirectoryPath
                
                case {app.SaveImagePrefix app.SavePrefixAngleInner app.SavePrefixAngleOuter}
                
                case app.SaveImageList % Save image list selection change
                    % If user selects all of the images in the list, tick the "Save All" checkbox
                    app.SaveImageSelectAll.Value = numel(app.SaveImageList.Value) == numel(app.SaveImageList.Items);
                
                case {app.AutoSaveImage app.SaveExport} % Save images/Auto-save images or Export data
                    if app.SaveDirectoryPath.Value == "" || isempty(app.SaveDirectoryPath.Value)
                        notification_dialog(app, "warn",sprintf("Please specify the directory!"),'Directory Missing');
                    else
                        % Create directory if not exists
                        [s, msg] = mkdir(app.SaveDirectoryPath.Value);
                        if ~s
                            notification_dialog(app, "warn",sprintf("Failed to create directory!\n\n"+msg),'Directory Error');
                        else
                            % Save images if called from `app.AutoSaveImage` or currently the `app.SaveImagesTab` is selected
                            if app.SaveTabGroup.SelectedTab == app.SaveImagesTab || event.Source == app.AutoSaveImage
                                saveImages(); % Save images
                            else
                                export_callbacks(app, event) % Export data
                            end
                        end
                    end
                
                case app.SaveImageSelectAll % Select all/none
                    if event.Value
                        app.SaveImageList.Value = app.SaveImageList.ItemsData;
                    else
                        app.SaveImageList.Value = {};
                    end
                
                case {app.SaveImagePNG app.SaveImageAnnotations} % Enable/Disable PNG options
                    app.SaveImageAnnotations.Enable = app.SaveImagePNG.Value;
                    set([app.SaveImageDPI app.SaveImgAnnotDPILabel], "Enable",app.SaveImageAnnotations.Value && app.SaveImagePNG.Value)
            end

            debug_toc(app, event, "", debug_time)


            %%% Sub functions
            % Update Image name prefix
            function updatePrefix()
                if is_static_event(app, event)
                    % If not in any annular mode, disable the inner/outer radii prefix
                    set([app.SavePrefixAngleInner; ...
                         app.SavePrefixAngleOuter], ...
                        "Enable", ismember(app.Mode.Value,["Annular" "CoM" "DPC"]))

                    if ~app.SavePrefixAngleInner.Enable
                        set([app.SavePrefixAngleInner app.SavePrefixAngleOuter], "Value",0);
                    end

                    % Include inner and outer annular radii
                    ang = "";
                    inner_radius = "";
                    outer_radius = "";
                    if app.SavePrefixAngleInner.Value
                        inner_radius = "" + round(app.InnerAnnularRadiusSpinner.Value,2);
                    end
                    
                    if app.SavePrefixAngleOuter.Value
                        outer_radius = ""+round(app.OuterAnnularRadiusSpinner.Value,2);
                    end
                    
                    if all([inner_radius outer_radius] ~= "")
                        ang = "-";
                    end
                    
                    ang = inner_radius + ang + outer_radius;
                    
                    if ang ~= ""
                        ang = ang + app.DetectorCoordinateUnit.Value + "_";
                    end

                    app.SaveImageList.Items = app.SaveImagePrefix.Value + ang + app.SaveImageList.ItemsData;
                    if app.Mode.Value == "DPC"
                        app.SaveImageList.Items = replace(app.SaveImageList.Items, "CoM", "DPC");
                    end
                end
            end

            function saveImages()
                % Give the user a progress bar in case it takes some time to save
                app.tmp_variables.progress_dialog = progress_dialog(app, 'Saving image(s) ...', "Image Saving");

                % Save as 32-bit unscaled, uncompressed Tiff images
                tagstruct = struct('PlanarConfiguration',Tiff.PlanarConfiguration.Chunky,'Compression',Tiff.Compression.None, ...
                    'SampleFormat',Tiff.SampleFormat.IEEEFP,'Photometric',Tiff.Photometric.MinIsBlack,'BitsPerSample',32);

                for id = reshape(string(app.SaveImageList.Value),1,[])
                    imgfile = fullfile(app.SaveDirectoryPath.Value, app.SaveImageList.Items{app.SaveImageList.ItemsData == id});

                    if app.SaveImagePNG.Value
                        if app.SaveImageAnnotations.Value
                            print(app.figures.(id), imgfile, '-dpng', "-r"+num2str(app.SaveImageDPI.Value))
                        else
                            imgd = app.image_displays.(id);
                            imwrite(ind2rgb(round(255*imgd.CData)+1,app.image_axes.(id).Colormap).*imgd.AlphaData, [imgfile '.png'])
                        end
                    end

                    if app.SaveImageTIFF.Value
                        if id == "DiffractionMask" && app.Mode.Value == "DPC"
                            % Save all mask segments into a TIFF stack
                            for j = 1:app.NRung.Value
                                for i = 1:app.NSeg.Value
                                    im = uint8(reshape(app.masks.Seg(app.NSeg.Value*(j-1)+i, :), app.dataset_parameters.n_pixels))';
                                    imwrite(im, [imgfile '.tif'], 'WriteMode','append');
                                end
                            end
                        elseif id == "DiffractionMask" && app.Mode.Value == "CoM"
                            im = uint8(app.masks.annular)';
                            imwrite(im, [imgfile '.tif']);
                        else
                            % Other images (and "DiffractionMask" from `app.images.DiffractionMask` for other Detector Modes)
                            t = Tiff([imgfile '.tif'], "w");
                            im = app.images.(id)';
                            [tagstruct.ImageLength, tagstruct.ImageWidth] = size(im);
                            tagstruct.SamplesPerPixel = size(im,3);
                            t.setTag(tagstruct);
                            t.write(single(im));
                            t.close();
                        end
                    end
                end

                delete(app.tmp_variables.progress_dialog);
            end
        end

        % Callback function: DiffractionPartialExportPixelsDist, 
        % ...and 27 other components
        function export_callbacks(app, event)
            debug_time = tic;

            switch event.Source
                case app.SaveCloseButton % Close button on Save/Export Window
                     % Try to remove Export ROIs
                    delete(findobj(app.ui_groups.image_axes,'-regexp', "Tag",'Export .... ROI'))
                    
                    % Reset button color
                    app.SaveCloseButton.BackgroundColor = app.sys_constants.background_color;

                    % hide save window
                    app.figures.Save.Visible = "off";

                case {app.SaveTabGroup app.ExportPartialPixels app.ExportPartialFrames} % Tab change or enable/disable partial export
                     % Try to delete old ROIs
                    delete(findobj(app.ui_groups.image_axes,'-regexp', "Tag",'Export .... ROI'))

                    % Try to update export range
                    if ~isempty(app.dataset_parameters)
                        update_export_range();
                    end
                    
                    if app.SaveTabGroup.SelectedTab == app.ExportDatasetTab && app.figures.Save.Visible
                        % To start Export Data Mode
                        app.SaveExport.Text = "Export Data";

                        % Draw ROIs & add listening functions for live updates on all Real and Diffraction space images
                        for id = app.ui_groups.image_id(app.ui_groups.image_space ~= "ColorWheel" & app.ui_groups.image_type ~= "Mask")
                            app.annotations.Export.(id) = drawrectangle(app.image_axes.(id), ...
                                                                        "Position", [1 1 0 0], ...
                                                                        "Color", "y", ...
                                                                        "Deletable", 0, ...
                                                                        'FaceAlpha', 0, ...
                                                                        'FaceSelectable', 0);
                            app.annotations.Export.(id).Tag = "Export " + app.image_axes.(id).UserData.space + " ROI";
                            
                            % add listeners for ROI annotation movement
                            app.annotations.Export.(id).UserData.Move = addlistener(app.annotations.Export.(id), "MovingROI", @move_export_annotation);
                            addlistener(app.annotations.Export.(id), "ROIMoved", @move_export_annotation);
                        end

                        % Force ROIs to update
                        export_callbacks(app, struct("Source", app.DiffractionPartialExportXStart, ...
                                                     "EventName", "ValueChanged", ...
                                                     "Value", app.DiffractionPartialExportXStart.Value))

                        export_callbacks(app, struct("Source", app.RealPartialExportXStart, ...
                                                     "EventName", "ValueChanged", ...
                                                     "Value", app.RealPartialExportXStart.Value))
                        
                        % Show/Hide ROIs and enable/disable UIs if (not) selecting whole ranges
                        set(findobj(app.ui_groups.diffraction_axes, "Tag",'Export Diffraction ROI'), "Visible", app.ExportPartialPixels.Value)
                        set(findobj(app.ui_groups.real_axes, "Tag",'Export Real ROI'), "Visible", app.ExportPartialFrames.Value)
                        set(app.RealPartialExportGrid.Children, "Enable", app.ExportPartialFrames.Value)
                        set(app.DiffractionPartialExportGrid.Children, "Enable", app.ExportPartialPixels.Value)
                        
                        % Set close button color to hint/link with the ROIs
                        if app.ExportPartialPixels.Value || app.ExportPartialFrames.Value
                            c = 'y';
                        else
                            c = app.sys_constants.background_color;
                        end
                    
                    else % For "Save Images"
                        app.SaveExport.Text = "Save Images";
                        c = app.sys_constants.background_color;
                    end

                    % Color the close button to hint/link with the ROIs
                    app.SaveCloseButton.BackgroundColor = c;

                case {app.ExportDimensionSuffix app.ExportDataType app.ExportByteOrder}
                    update_export_range();
                
                case app.SaveExport % Export Button
                    [pd,px1,py1,npx,npy,px2,py2,fd,fx1,fy1,nfx,nfy] = update_export_range();
                    try
                        export_data();
                    catch ME
                        % delete the progress dialog and export file
                        try delete(app.tmp_variables.progress_dialog); catch; end
                        try delete(app.tmp_variables.efile); catch; end
                        
                        % Reshape dataset to 2D
                        try app.data = reshape(app.data, prod(app.dataset_parameters.n_pixels), []);  catch; end
                        enable_windows(app,true)

                        % pass along error message
                        rethrow(ME)
                    end
            end

            debug_toc(app, event, "", debug_time)


            %%% Sub functions of `export_callbacks()`
            % Update Export Data boundaries as user change the Export Data Annotations
            function move_export_annotation(~, event)
                debug_time = tic;
                export_callbacks(app, event)
                debug_toc(app, event, "", debug_time)
            end

            function [pd, px1, py1, npx, npy, px2, py2, fd, fx1, fy1, nfx, nfy] = update_export_range()
                % initialize parameters
                diffraction_event = event;
                real_event = event;
                
                % Update Diffraction and Real-space export ranges; use dummy event if source is not a range UI
                if ~contains(event.Source.Tag, "Diffraction")
                    diffraction_event = struct("Source",app.DiffractionPartialExportPixelsDist, "Value",app.DiffractionPartialExportPixelsDist.Value);
                end
                
                if ~contains(event.Source.Tag, "Real")
                    real_event = struct("Source",app.RealPartialExportFramesDist, "Value",app.RealPartialExportFramesDist.Value);
                end
                
                [dnote, pd, p1, np, p2] = range_import_export(app, diffraction_event);
                [rnote, fd, f1, nf,  ~] = range_import_export(app, real_event);
                
                % Store parameters for file export
                px1 = p1(1);
                py1 = p1(2);
                npx = np(1);
                npy = np(2);
                px2 = p2(1);
                py2 = p2(2);
                fx1 = f1(1);
                fy1 = f1(2);
                nfx = nf(1);
                nfy = nf(2);

                % Add dimension suffix and Remove old suffix
                app.ExportFilename.Value = regexprep(app.ExportFilename.Value, '_\d*x\d*x\d*x\d*$', "");
                if app.ExportDimensionSuffix.Value
                    app.ExportFilename.Value = sprintf('%s_%dx%dx%dx%d', app.ExportFilename.Value, np, nf);
                end

                % Export summary notes
                filenote = sprintf("Directory: '%s'\nFilename: '%s.%s'", app.SaveDirectoryPath.Value, app.ExportFilename.Value, app.ExportDataType.Value);
                
                if app.ExportDataType.Value == "h5"
                    filenote = filenote + sprintf("\nDataset: '/STEM4D'");
                end

                notes = dnote + rnote ...
                        + "\nData Type: " + app.dataset_parameters.data_type + " (was " + app.tmp_variables.prev_D.data_type + " in original file)" ...
                        + "\nByte Order: " + app.sys_constants.endian_text.(app.ExportByteOrder.Value) + " (was " + app.sys_constants.endian_text.(app.tmp_variables.prev_D.byte_orer) + " in original file)" ...
                        + "\nSystem Byte Order: " + app.sys_constants.endian_text.(app.sys_constants.system_endianness) ...
                        + sprintf('\nEstimated file size: %.3f GiB', prod([np nf])*app.dataset_parameters.byte_size/1024^3) ...
                        + sprintf('\nAvailable space: %.3f GiB', java.io.File(app.SaveDirectoryPath.Value).getUsableSpace/1024^3);

                app.ExportNotes.Value = filenote+sprintf("\n\n"+notes);
            end

            % Function to export data
            function err = export_data()
                err = 0;
                
                % export full file name with directory path
                app.tmp_variables.efile = fullfile(app.SaveDirectoryPath.Value, app.ExportFilename.Value+"."+app.ExportDataType.Value);
                
                % notify user and exit if the file already exists
                if isfile(app.tmp_variables.efile)
                    notification_dialog(app, "warn", "File: '"+app.tmp_variables.efile+"' already exists!",'File Exists!');
                    return
                end

                % Local variables
                data_type = app.dataset_parameters.data_type;
                frame_size = npx*npy*app.dataset_parameters.byte_size;
                byte_order = app.ExportByteOrder.Value;
                
                % Progress window with per-second-updating `timer`
                [app.tmp_variables.progress_dialog, app.tmp_variables.tmr] = import_export_progress(app, "Export", nfx*nfy, frame_size);
                
                % Make dataset 4D
                app.data = reshape(app.data, [app.dataset_parameters.n_pixels, app.dataset_parameters.n_frames]);

                if app.ExportDataType.Value == "h5"
                    % Sort out the h5 data type string; e.g. H5T_IEEE_F32LE, H5T_IEEE_F32BE, H5T_IEEE_F64LE, H5T_IEEE_F64BE
                    h5dt = "H5T_IEEE_F" + app.byte_size.(data_type)*8 + upper(byte_order) + "E";

                    % C-like array indices for H5 low-level APIs
                    fid = H5F.create(app.tmp_variables.efile);
                    type_id = H5T.copy(h5dt);
                    file_space_id = H5S.create_simple(4, [nfy nfx npy npx], []);
                    mem_space_id = H5S.create_simple(2, [npy npx], []);
                    dcpl = H5P.create('H5P_DATASET_CREATE');
                    H5P.set_chunk(dcpl, [1 1 npy npx]);
                    dset_id = H5D.create(fid, '/STEM4D', type_id, file_space_id, dcpl);
                    
                    % Whole data in one go
                    %         H5D.write(dset_id,'H5ML_DEFAULT','H5S_ALL','H5S_ALL','H5P_DEFAULT', ...
                    %           data(px1:px2, py1:py2, fx1:fx2, fy1:fy2)); 
                    
                    % One frame at a time
                    for efy = 0 : nfy-1
                        for efx = 0 : nfx-1
                            % Break when progress windows closed
                            if ~isvalid(app.tmp_variables.progress_dialog)
                                err = -1;
                                break;
                            end

                            % Update processed Frame Number for progress update
                            app.tmp_variables.frame_number(1) = efy*nfx + efx + 1;
                            H5S.select_hyperslab(file_space_id, 'H5S_SELECT_SET', [efy efx 0 0], [], [], [1 1 npy npx]);
                            
                            % Selected frame
                            frame = gather(app.data(px1:px2, py1:py2, fx1+efx*fd, fy1+efy*fd));
                            
                            % Pixel Binning
                            if pd > 1
                                frame = squeeze(sum(reshape(frame, [pd npx pd npy]), [1 3]),'omitnan');
                            end
                            
                            H5D.write(dset_id, 'H5ML_DEFAULT', mem_space_id, file_space_id, 'H5P_DEFAULT', frame);
                        end

                        if err == -1
                            break;
                        end
                    end

                    % Close created handles
                    H5D.close(dset_id);
                    H5S.close(mem_space_id);
                    H5S.close(file_space_id);
                    H5T.close(type_id);
                    H5P.close(dcpl);
                    H5F.close(fid);

                elseif app.ExportDataType.Value == "raw"
                    fid = fopen(app.tmp_variables.efile,"w");
                    % One frame at a time
                    for efy = 0:nfy-1
                        for efx = 0:nfx-1
                            % Break when progress windows closed
                            if ~isvalid(app.tmp_variables.progress_dialog)
                                err = -1;
                                break;
                            end

                            % Update processed Frame # for progress update
                            app.tmp_variables.frame_number(1) = efy*nfx + efx + 1;
                            
                            % Selected frame
                            frame = gather(app.data(px1:px2, py1:py2, fx1+efx*fd, fy1+efy*fd));
                            
                            % Pixel binning
                            if pd > 1
                                frame = squeeze(sum(reshape(frame, [pd npx pd npy]), [1 3]),'omitnan');
                            end
                            
                            fwrite(fid, frame, data_type, byte_order);
                        end
                        if err == -1
                            break;
                        end
                    end

                    fclose(fid);
                end

                % Close progress window
                delete(app.tmp_variables.progress_dialog);
                
                % Make dataset 2D again
                app.data = reshape(app.data, prod(app.dataset_parameters.n_pixels), []);

                if err
                    app.tmp_variables.progress_dialog = progress_dialog(app, "Deleting the canceled export file ...", "Export Canceled");
                    % Delete created file, if export canceled
                    delete(app.tmp_variables.efile);
                    delete(app.tmp_variables.progress_dialog)
                else
                    notification_dialog(app, "help", "Export to '"+app.tmp_variables.efile+"' finished!",'Export Finished!');
                end
                enable_windows(app,true)
            end
        end

        % Value changed function: ReverseDiffractionX, 
        % ...and 7 other components
        function axis_direction_callbacks(app, event)
            % Function to flip/rotate image axes
            debug_time = tic;

            % Space, "Real" or "Diffraction"
            space = event.Source.Tag;
            
            % For axis direction
            axis_direction = ["normal" "reverse"];
            
            % Get X/Y-axis direction and rotation options of diffraction/real-space
            reverse_x = struct('Diffraction', app.ReverseDiffractionX.Value, 'Real', app.ReverseRealX.Value);
            reverse_y = struct('Diffraction', app.ReverseDiffractionY.Value, 'Real', app.ReverseRealY.Value);
            rotate_view = struct('Diffraction', app.RotateDiffraction.Value, 'Real', app.RotateReal.Value);
            
            % Set axis directions and rotations of either diffraction or real-space images
            set(app.ui_groups.(lower(space)+"_axes"), ...
                "XDir", axis_direction(reverse_x.(space)+1), ...
                "YDir", axis_direction(reverse_y.(space)+1), ...
                'View', [rotate_view.(space), 90])

            % set state button icon color based on value
            for state_button = [app.ReverseDiffractionY, ...
                                app.ReverseDiffractionX, ...
                                app.ShowDiffractionAxes, ...
                                app.ReverseRealY, ...
                                app.ReverseRealX, ...
                                app.ShowRealAxes]
                change_icon_background(app, state_button)
            end

            % Sync ColorWheel with real-space axes direction
            if space == "Real"
                set(app.image_axes.ColorWheel, ...
                    {"XDir" "YDir" 'View'}, ...
                    get(app.image_axes.Real,{"XDir" "YDir" 'View'}));
            end
            % X/Y labels of axis annotations
            labels = struct('x', struct('Diffraction', "kx",'Real', "rx"), ...
                            'y', struct('Diffraction', "ky",'Real', "ry"));
            
            % Update axis annotations
            arrayfun(@(x) drawAxeDir(x,space), app.ui_groups.(lower(space)+"_id"));

            debug_toc(app, event, "", debug_time)


            % Sub function to draw the axis annotations
            function drawAxeDir(id,space)
                fig = app.figures.(id);
                
                % Colors of X/Y axes
                color_x = "r";
                color_y = 'g';
                
                % Draw annotations on `figure`
                % delete(findall(fig,'-depth',2, "Tag",'AxeDir')) % Always delete old and draw new annotations
                if isempty(findall(fig,'-depth',2, "Tag",'AxeDir')) || ~isfield(fig.UserData, 'AxAnnot')
                    fig.UserData.AxAnnot = [];
                    
                    % Draw scan directions on Diffraction images
                    if space == "Diffraction"
                        drawAxeDir(id, "Real");
                    end
                    
                    x_arrow = annotation(fig, "arrow");
                    x_text = annotation(fig, "textbox", "String",labels.x.(space));
                    y_arrow = annotation(fig, "arrow");
                    y_text = annotation(fig, "textbox", "String",labels.y.(space));
                    
                    set([x_arrow y_arrow], ...
                        "LineWidth", 1, ...
                        "HeadLength", 3, ...
                        "HeadWidth", 3, ...
                        "HeadStyle", "plain")

                    set([x_text y_text], ...
                        "FontWeight", "bold", ...
                        "HorizontalAlignment", "center", ...
                        "VerticalAlignment", "middle")

                    set([x_arrow; y_arrow; x_text; y_text], ...
                        'Units', "pixels", ...
                        "PickableParts", "no", ...
                        "Tag", "AxeDir", ....
                        {"Color"}, {color_x;color_y;color_x;color_y})
                    fig.UserData.AxAnnot = [x_arrow; y_arrow; x_text; y_text; fig.UserData.AxAnnot]; % Scan direction annot at 5:8 for "Diffraction"
                    uistack(fig.UserData.AxAnnot,'bottom')
                end
                
                % Set annotation positions/directions
                %  `l`/`t`: length for arrow/total;
                %  `xy`: [arrow origin, arrow X, arrow Y, label X, label Y] positions
                l = 4;
                t = 12;
                xy = [-t -t; l -t; -t l; t -t; -t t];
                
                % Offset from bottom-left
                offset = 22;
                
                % X/Y flip
                xy_flip = 1 - [reverse_x.(space) reverse_y.(space)]*2;

                % Axes rotation (clockwise)
                R = rotation_matrix(app, -rotate_view.(space));
                
                update_annoation_positions(fig.UserData.AxAnnot(1:4), offset*[1 1], xy.*xy_flip*R)
                set(fig.UserData.AxAnnot(1:4), "Visible",app.("Show"+space+"Axes").Value)

                % Set scan directions on Diffraction patterns
                if space == "Diffraction"
                    l = 1;
                    t = 9;
                    xy = [-t -t; l -t; -t l; t -t; -t t];
                    Rscan = rotation_matrix(app, app.ScanDirectionSlider.Value);
                    scan_flip_y = 1 - 2*[0 app.FlipScanDirectionY.Value];
                    update_annoation_positions(fig.UserData.AxAnnot(5:end), offset*[2.8 1], xy.*scan_flip_y*Rscan.*xy_flip*R)

                    % Warning color if scan directions not aligned
                    if ~isfield(app.common_parameters,'scan_direction_initialized') || ~app.common_parameters.scan_direction_initialized
                        color_x = app.sys_constants.warning_color;
                        color_y = color_x;
                    end
                    
                    % Only visible when:
                    % 1) show Diffraction annotation AND
                    % 2) not during scan direction alignment AND
                    % 3) in CoM/DPC modes
                    set(fig.UserData.AxAnnot(5:8), ...
                        {"Color"}, {color_x;color_y;color_x;color_y}, ...
                        "Visible", app.ShowDiffractionAxes.Value && ...
                                  ~app.ScanDirectionLock.Value && ...
                                   any(app.Mode.Value == ["CoM" "DPC"]))
                end

                % Sub sub function to update annotation positions
                function update_annoation_positions(annotation, offset, xy)
                    % Arrow position: [ Origin XY, Vector XY ];
                    arrow_position_x = [offset+xy(1,:), xy(2,:)-xy(1,:)];
                    arrow_position_y = [offset+xy(1,:), xy(3,:)-xy(1,:)];
                    
                    % Label position: [ Center XY, width, height ]
                    arrow_label_x = [offset+xy(4,:), 0, 0];
                    arrow_label_y = [offset+xy(5,:), 0, 0];
                    
                    % update annotation positions
                    set(annotation, ...
                        {"Position"}, ...
                        {arrow_position_x; ...
                         arrow_position_y; ...
                         arrow_label_x; ...
                         arrow_label_y})
                end
            end
        end

        % Callback function: DispColorMap, DispColorMapInvert, 
        % ...and 24 other components
        function display_callbacks(app, event)
            debug_time = tic;
            
            id = app.DisplayImage.Value;
            user_data = app.image_axes.(id).UserData;
            source = event.Source;

            % Initial contrast will be set as the total range of min:max
            % A change in contrast will expand or contract the width of the range
            % Initial brightness will be also set as the total range of min:max
            % A change in brightness will result in shift of the range up or down
            % Initial gamma is set to 1
            % A change in gamma will result in a power law change in contrast I' = I^gamma
            switch source
                case app.HistogramLog 
                    % set state button icon color based on value
                    change_icon_background(app, app.HistogramLog)

                    % Log-scale option of histogram
                    if app.HistogramLog.Value
                        app.HistogramAxes.YScale = 'log';
                    else
                        app.HistogramAxes.YScale = 'linear';
                    end

                case app.DisplayLock
                    % Lock "current" image
                    % set state button icon color based on value
                    change_icon_background(app, app.DisplayLock)
                    app.DisplayImage.BackgroundColor = app.DisplayTab.BackgroundColor;

                case app.DisplayVectorField
                    % Vector field
                    user_data.vector_field = event.Value;

                case app.DisplayVectorColor
                    % Vector field color
                    color = uisetcolor(user_data.vector_color);

                    % Early return if canceled
                    if isequal(color, 0)
                        return;
                    end
                    user_data.vector_color = color;

                case app.DispColorMap
                    % Colormap
                    user_data.colormap = event.Value;
                    cmap = str2func(user_data.colormap);
                    cm = cmap();
                    
                    % Whether to invert colormap
                    if user_data.invert
                        cm = flipud(cmap());
                    end
                    colormap(app.image_axes.(id), cm)
                
                case app.DispColorMapInvert
                    % set state button icon color based on value
                    change_icon_background(app, app.DispColorMapInvert)

                    % Invert colormap
                    user_data.invert = event.Value;
                    cmap = str2func(user_data.colormap);
                    cm = cmap();
                    
                    % Whether to invert colormap
                    if user_data.invert
                        cm = flipud(cmap());
                    end
                    colormap(app.image_axes.(id), cm)
                
                case app.DisplayBrightnessReset
                    % Reset brightness
                    user_data.brightness = 50;
                
                case app.DisplayContrastReset
                    % Reset contrast
                    user_data.contrast = 50;
                
                case app.DisplayGammaReset
                    % Reset gamma
                    user_data.gamma = 1;
                
                case {app.DisplayBrightnessSpinner, app.DisplayBrightness}
                    % Brightness
                    user_data.brightness = event.Value;
                
                case {app.DisplayContrastSpinner, app.DisplayContrast}
                    % Contrast
                    user_data.contrast = event.Value;
                
                case {app.DisplayGamma, app.DisplayGammaSpinner}
                    % Gamma
                    user_data.gamma = event.Value;
                
                case {app.DisplayMaskOpacitySlider, app.DisplayMaskOpacitySpinner}
                    % Mask opacity
                    user_data.mask_opacity = event.Value;
                
                otherwise   % e.g. app.DisplayImage
                    % Bring selected image to front
                    figure(app.figures.(id))
                    
                    % Update GUIs by values stored in `app.image_axes.(id).UserData` for the current selected image
                    set([app.DisplayVectorField; ...
                         app.DispColorMap; ...
                         app.DispColorMapInvert], ...
                        {"Value"}, ...
                        {user_data.vector_field; ...
                         user_data.colormap; ...
                         user_data.invert})

                    app.DisplayVectorColor.BackgroundColor = user_data.vector_color;
                    
                    % Enable vector field UIs if: 1) in CoM/DPC mode AND 2) real-space image selected
                    set([app.DisplayVectorField; ...
                         app.DispVecFieldLabel; ...
                         app.DisplayVectorColor], ...
                        "Enable", ismember(app.Mode.Value, ["CoM" "DPC"]) && user_data.space == "Real")
                    
                    % Disable brightness/contrast/colormap UIs if: (CoM/DPC) "Phase" and "ColorWheel for Phase" images
                    set([findobj(app.DispContrastsGrid.Children,'-not',"type",'uibutton'); ...
                         app.DispColorMap;app.DispColorMapInvert], ...
                        "Enable", ~contains(user_data.name, "Phase"));
                    
                    % Disable mask opacity UIs if: ColorWheel/Mask/Preview images selected
                    set([app.ShowMaskWindow; ...
                         app.DisplayMaskOpacitySpinner; ...
                         app.DisplayMaskOpacitySlider], ...
                        "Enable", user_data.space ~= "ColorWheel" && ~ismember(user_data.type, ["Mask" "Preview"]))
            end

            % Update UI
            set_external_source(app, event,[app.DisplayBrightnessSpinner, app.DisplayBrightness], "Value",user_data.brightness)
            set_external_source(app, event,[app.DisplayContrastSpinner, app.DisplayContrast], "Value",user_data.contrast)
            set_external_source(app, event,[app.DisplayGammaSpinner, app.DisplayGamma], "Value",user_data.gamma)
            set_external_source(app, event,[app.DisplayMaskOpacitySpinner, app.DisplayMaskOpacitySlider], "Value",user_data.mask_opacity)

            % Update properties stored by the `axes`
            app.image_axes.(id).UserData = user_data;
            
            % Re-plot image
            plot_image(app, event, id);

            debug_toc(app, event, "", debug_time)
        end

        % Callback function: RealROIFrameX, RealROIFrameX, RealROIFrameY, 
        % ...and 3 other components
        function realspace_ROI_callbacks(app, event)
            debug_time = tic;

            switch event.Source
                case app.UpdateImages
                    % Draw ROI if doesn't yet exist and update
                    % diffraction image
                    if isempty(findobj(app.ui_groups.real_axes, "Tag","Real-space ROI"))
                        mock_UI_callbacks(app, app.RealROIShape)
                    else
                        move_real_ROI(app, app.annotations.RealROI.Real, event)
                    end
                
                case app.RealROIShape
                    roi_type = app.RealROIShape.Value;
                    if roi_type == "file"
                        app.images.RealROIFile = import_mask(app, "Real");
                        
                        % if ROI import from file is canceled, revert 
                        if isempty(app.images.RealROIFile)
                            roi_type = "full";
                        end
                    end

                    % Update GUI if changed programmatically
                    app.RealROIShape.Value = roi_type;

                    % Panel enable/disable
                    set([app.RealROIGrid.Children; ...
                         app.RealROIFrameLabel], ...
                        "Enable", roi_type == "point")

                    % Allow for inversion of the ROI if not full field
                    app.RealROIInvert.Enable = roi_type ~= "full";
                    
                    % If disabled, then set to off
                    app.RealROIInvert.Value = app.RealROIInvert.Value && ...
                                              app.RealROIInvert.Enable;
                    
                    % Draw ROIs
                    draw_real_ROI(app, event, roi_type);
                
                case {app.RealROIFrameX, app.RealROIFrameY}
                    % move the real space ROI according to user input
                    % on frame (x,y) spinners
                    move_real_ROI(app, event.Source, event)
                
                otherwise

                    % set state button icon color based on value
                    change_icon_background(app, app.RealROIInvert)
                        
                    % run the listener to update any/all other ROIs and
                    % the diffraction pattern
                    move_real_ROI(app, app.annotations.RealROI.Real, event)
            end

            debug_toc(app, event, "", debug_time)
        end

        % Value changed function: Mode
        function detector_mode_callbacks(app, event)
            debug_time = tic;
            dmod = app.Mode.Value;

            % Enable windows
            enable_windows(app,false)
            
            % Clear ROIs on Diffraction
            clear_detectors(app);

            % Clear Real-space images if not switching to alignment or preview
            if ~ismember(dmod, ["Alignment" "Preview"])
                delete(findobj(app.ui_groups.real_axes(~contains({app.ui_groups.real_axes.Tag}, "Mask")),'Type','image'));
            end

            % Enable/Disable CoM/DPC related UI/images/buttons
            set(app.ui_groups.CoM_buttons, ...
                "Enable", any(dmod == ["CoM" "DPC"]));
            
            if ismember(dmod, ["CoM" "DPC"])
                app.DisplayImage.Items = app.ui_groups.image_name;
                app.DisplayImage.ItemsData = app.ui_groups.image_id;

                % Substitute "CoM"/"DPC" in image names, window titles and
                % button texts
                mode_prefix = dmod(1:3);
                app.DisplayImage.Items = replace(app.ui_groups.image_name, ["CoM" "DPC"], mode_prefix);
                set(app.ui_groups.image_figures, {"Name"},cellstr(app.DisplayImage.Items)')
                set(app.ui_groups.CoM_buttons, {"Text"}, cellstr(replace(get(app.ui_groups.CoM_buttons, "Text"), ["CoM" "DPC"], mode_prefix)))
            
                % show Windows tab in the Settings UI when in DPC/CoM
                app.SettingsTabGroup.SelectedTab = app.WindowsTab;
                
            else
                % Image dropdown texts
                app.DisplayImage.Items = app.ui_groups.image_name(app.ui_groups.image_type ~= "CoM");
                
                % Image dropdown values
                app.DisplayImage.ItemsData = app.ui_groups.image_id(app.ui_groups.image_type ~= "CoM");
                
                % Image windows
                set(app.ui_groups.image_figures(app.ui_groups.image_type == "CoM"), "Visible", "off")

                % show Display tab in the Settings UI when not in DPC/CoM
                app.SettingsTabGroup.SelectedTab = app.DisplayTab;
            end

            % Show primary diffraction/real-space image when mode changes unless in Preview mode
            set([app.figures.Diffraction; app.figures.Real], "Visible", dmod ~= "Preview")
            
            % Special case for Import Preview Mode
            if dmod == "Preview"
                % Keep only "Preview" image
                app.DisplayImage.Items = "Preview";
                app.DisplayImage.ItemsData = "Preview";
                
                % Hide other UIs
                set([app.MiscTab, ...
                     app.InfoTab, ...
                     app.ExportDatasetTab], ...
                    "Parent", [])

                set([app.figures.Preview, ...
                    app.figures.Settings, ...
                     app.figures.Import], ...
                    "Visible", "on")
            else
                % Note current mode
                app.common_parameters.detector_mode = app.Mode.Value;
                
                % Remove "Preview" image
                preview_index = app.DisplayImage.ItemsData == "Preview";
                app.DisplayImage.Items(preview_index) = [];
                app.DisplayImage.ItemsData(preview_index) = [];
                app.figures.Preview.Visible = "off";
                
                % Show all UIs
                set([app.MiscTab, ...
                     app.InfoTab], ...
                    "Parent", app.SettingsTabGroup)

                set(app.ExportDatasetTab, "Parent", app.SaveTabGroup)
            end

            % Define images in the saving list
            app.SaveImageList.Items = app.DisplayImage.ItemsData;
            app.SaveImageList.ItemsData = app.SaveImageList.Items;
            
            % Update Image saving prefix
            mock_UI_callbacks(app, app.SaveImagePrefix)
            
            % Update diffraction axis annotations
            mock_UI_callbacks(app, app.ShowDiffractionAxes)

            % Set detector size when transmitted beam is aligned the first time
            if isfield(app.common_parameters,'transmitted_beam_init') && ...
               ~app.common_parameters.transmitted_beam_init && ...
               dmod ~= "Alignment"
                set([app.InnerAnnularRadius; ...
                     app.OuterAnnularRadius; ...
                     app.VirtualApertureR], ...
                    {"Value"}, ...
                    {app.TransBeamR.Value; ...
                     (app.TransBeamR.Value + app.common_parameters.max_radius)/3; ...
                     app.TransBeamR.Value})
                app.common_parameters.virtual_aperture_center = app.center;
                app.common_parameters.transmitted_beam_init = true;
            end

            % Diffraction detector UI
            set(app.ui_groups.detector_ui, "Visible", "off");
            
            % Hide Math Tab
            app.MathTab.Parent = [];
            app.RealPanel.Visible = "on";
            
            switch dmod
                case "Preview"
                    app.PreviewGrid.Visible = "on";
                    app.RealPanel.Visible = "off";
                    preview_callbacks(app, event)
                
                case "Alignment"
                    app.AlignmentGrid.Visible = "on";
                    transmitted_beam_callbacks(app, event)
                
                case {"Annular", "CoM", "DPC"}
                    app.AnnularDetectorGrid.Visible = "on";
                    annular_detector_callbacks(app, event)
                    if dmod ~= "Annular" % 'CoM' OR 'DPC'
                        % show scan direction panel
                        app.ScanDirectionPanel.Visible = "on";

                        % if DPC show segmented detector panel, else hide
                        if dmod == "DPC"
                            app.SegmentedDetectorPanel.Visible = "on";
                        else
                            app.SegmentedDetectorPanel.Visible = "off";
                        end

                        % Plot ColorWheel
                        plot_image(app, event, "ColorWheel")
                        
                        % bring ColorWheel and CoM Phase Magnitude to the
                        % front
                        figure(app.figures.CoMPhMag);
                        figure(app.figures.ColorWheel)
                        
                        % Flashing to align scan direction
                        if ~app.common_parameters.scan_direction_initialized
                            flash_background(app, app.ScanDirectionGrid);
                        end
                    
                    else
                        % Hide segmented detector and scan direction panels
                        % if not in DPC or CoM
                        app.SegmentedDetectorPanel.Visible = "off";
                        app.ScanDirectionPanel.Visible = "off";
                    
                    end
                
                case "Virtual"
                    % Show Virtual aperture panel
                    app.VirtualApertureGrid.Visible = "on";
                    virtual_aperture_callbacks(app, event);
                
                case "Custom"
                    % Show custom detector panel
                    app.CustomDetectorGrid.Visible = "on";

                    % Add Math tab to the Settings UI
                    app.MathTab.Parent = app.SettingsTabGroup;

                    custom_detector_callbacks(app, event)
                    
                    % Bring the diffraction mask to the front
                    figure(app.figures.DiffractionMask)
            end

            debug_toc(app, event, "", debug_time)
        end

        % Value changed function: AnnularStep, DetectorCoordinatePosition, 
        % ...and 2 other components
        function detector_coordinates_callbacks(app, event)
            % "Polar" necessitates "Relative"
            switch event.Source
                case app.DetectorCoordinateSystem
                    if app.DetectorCoordinateSystem.Value == "Polar"
                        app.DetectorCoordinatePosition.Value = "Relative";
                    end
                
                case app.DetectorCoordinatePosition
                    if app.DetectorCoordinatePosition.Value == "Absolute"
                        app.DetectorCoordinateSystem.Value = "Cartesian";
                    end
            end

            % Set diffraction scale based on coordinates used
            if app.DetectorCoordinateUnit.Value == "mrad"
                app.diff_scale = app.mradPx.Value;
            else
                % "pixels" are 1:1
                app.diff_scale = 1;
            end
            
            % Set relative center of the diffraction pattern
            if app.DetectorCoordinatePosition.Value == "Relative"
                app.center_rel = app.center;
            else
                % "Absolute" is relative to 
                app.center_rel = [0 0];
            end
            
            if event.Source == app.AnnularStep
                annular_detector_callbacks(app, event)
                
            end

            % Update UI
            mock_event = struct("Source", app.DetectorCoordinateSystem, ...
                                "EventName", "UpdateCoord");

            switch app.Mode.Value
                case {'Annular' 'DPC' 'CoM'}
                    annular_detector_callbacks(app, mock_event)
                
                case "Virtual"
                    virtual_aperture_callbacks(app, mock_event)
                
                case "Custom"
                    custom_detector_callbacks(app, mock_event)
            end
        end

        % Button pushed function: SwapByteOrder, SwapDiffractionXY, 
        % ...and 1 other component
        function dataset_options_callbacks(app, event)
            switch event.Source
                case app.SwapByteOrder % Swap byte-order
                    message = "Swapping byte order ...";
                    app.tmp_variables.progress_dialog = progress_dialog(app, sprintf("\n%s\n",message), "Byte Order Swap");

                    try
                        app.data = swapbytes(app.data);
                    catch
                        if app.dataset_parameters.gpu
                            waitbar(0,app.tmp_variables.progress_dialog,message+sprintf("\nIt seems GPU has not enough memory.\nUsing CPU for this process ..."))
                            app.data = gather(app.data);
                            app.data = swapbytes(app.data);
                            app.data = gpuArray(app.data);
                        else
                            delete(app.tmp_variables.progress_dialog)
                            notification_dialog(app, "error", "It seems the system has not enough memory for this process! " + ...
                                "Maybe try to increase system's virtual memory.", "Out of Memory")
                            return
                        end
                    end
                    
                    % update data info at `app.dataset_parameters.byte_orer`
                    bo = struct("l", "b", "b", "l");
                    app.dataset_parameters.byte_orer = bo.(app.dataset_parameters.byte_orer);
                    
                    app.DatasetInfo.Value = get_import_info();
                    
                    mock_UI_callbacks(app,app.UpdateImages)
                    
                    delete(app.tmp_variables.progress_dialog)
                    sel = notification_dialog(app,'quest', "Is the current byte-order correct?",'Byte Order Swap',["Yes", "No. Revert"]);
                    
                    % Run the process again if selected "No ....."
                    if startsWith(sel, "No")
                        dataset_options_callbacks(app,struct("Source",app.SwapByteOrder,"EventName", ""))
                    end

                case {app.SwapDiffractionXY app.SwapRealXY}
                    % Diffraction or real-space
                    if event.Source.Tag == "Diffraction"
                        unit = "pixels";
                    else
                        % Delete all Real-space ROIs;
                        unit = "frames";
                        delete(findobj(app.ui_groups.real_axes, "Tag",'Real-space ROI'));
                        app.common_parameters.transmitted_beam_init = true;
                    end

                    % Swap X/Y dimensions
                    
                    % n_pixels_file or n_frames_file
                    app.dataset_parameters.("n_"+unit+"_file") = fliplr(app.dataset_parameters.("n_"+unit+"_file"));
                    
                    % n_pixels or n_frames
                    app.dataset_parameters.("n_"+unit) = fliplr(app.dataset_parameters.("n_"+unit));
                    
                    % pixels_start or frames_start
                    app.dataset_parameters.(unit+"_start") = fliplr(app.dataset_parameters.(unit+"_start"));
                    
                    % Re-prepare the imported data
                    import_callbacks(app, event)
                    app.DatasetInfo.Value = get_import_info();

                case app.PreviewButton
                    % Check data NaN/Inf, without asking to sway byte-order
                    sel = check_NaN(app, app.images.Preview,1);

                    if strcmp(sel,'Set NaNs to 0')
                        app.data(isnan(app.data(:)))=0;
                    end

                otherwise
                    app.DatasetInfo.Value = get_import_info();
                    
                    % Check data NaN/Inf, ask whether to sway byte-order
                    sel = check_NaN(app, app.images.Diffraction,0);
                    if strcmp(sel,'Set NaNs to 0')
                        app.data(isnan(app.data(:)))=0;
                    end

            end


            %% Sub functions
            % Function to swap values
            function [b,a] = swap(a,b); end

        % Function to update current data info
            function notes = get_import_info()
                d = app.dataset_parameters;
                d0 = app.tmp_variables.prev_D;

                % create a notes string with important info
                notes = "File: " + replace(d.file_path, "\", "\\");

                % include sub-image if applicable
                if ~isempty(d.subimage)
                    notes = notes + "\nSub-dataset: " + d.subimage;
                end

                notes = notes ...
                        + "\n\n" ...
                        + "# Current Data Info #\n" ...
                        + "Byte Order: " + app.sys_constants.endian_text.(d.byte_orer) + "\n"...
                        + "Data Type: " + d.data_type + "\n"...
                        + "Byte Size: " + app.byte_size.(d.data_type) + " bytes\n" ...
                        + sprintf("Diffraction size (X×Y): %d×%d\n",d.n_pixels) ...
                        + sprintf("Real-spaces size (X×Y): %d×%d\n\n",d.n_frames) ...
                        + "# Data Structure in File #\n" ...
                        + "Data Offset: " + d0.offset + " bytes\n" ...
                        + "Frame Header: " + d0.frame_header + "bytes\n" ...
                        + "Frame Footer: " + d0.frame_footer + " bytes\n" ...
                        + "Byte Order: " + app.sys_constants.endian_text.(d0.byte_orer) + "\n"...
                        + "Data Type: "+ d0.data_type + "\n"...
                        + "Byte Size: " + app.byte_size.(d0.data_type) + " bytes" ...
                        + "\n\n" ...
                        + "# File Meta Info Notes #\n" ...
                        + strjoin(d0.file_metadata,'\n') ...
                        + "\n\n" ...
                        + "# Import Option Notes #\n" ...
                        + strjoin(d0.import_options,'\n');

                notes = sprintf(notes);
            end
        end

        % Callback function: PreviewButton, PreviewFrameX, PreviewFrameY, 
        % ...and 9 other components
        function preview_callbacks(app, event)
            switch event.Source
                case app.Mode % Entering mode
                    cla(app.image_axes.Preview);
                    
                    % Clear previous preview
                    app.images.Preview = [];
                    
                    % Enable windows
                    enable_windows(app,true)

                case app.UpdateImages % Update image
                    % Preview
                    mock_UI_callbacks(app,app.PreviewButton)

                case {app.PreviewFrameX app.PreviewFrameY} % Preview Frame X/Y
                    % Force Frame X/Y minimum as 1
                    event.Source.Value = max(event.Value, 1);
                    
                    % Preview
                    mock_UI_callbacks(app,app.PreviewButton)

                case {app.PreviewFrame_X2_Y; app.PreviewFrame_1_Y; app.PreviewFrame_X_Y; % Preview location buttons
                      app.PreviewFrame_X2_Y2; app.PreviewFrame_1_Y2; app.PreviewFrame_X_Y2;
                      app.PreviewFrame_X2_1; app.PreviewFrame_1_1; app.PreviewFrame_X_1}
                    
                    % Get limits from Frame X/Y UIs
                    X = app.PreviewFrameX.Limits(2);
                    Y = app.PreviewFrameY.Limits(2);

                    % Evaluate the text on buttons
                    xy = min(ceil(eval(event.Source.Text)), [X Y]);
                    
                    % Set values of Frame X/Y UIs
                    set([app.PreviewFrameX app.PreviewFrameY],{"Value"},{xy(1);xy(2)});
                    
                    % Preview
                    mock_UI_callbacks(app,app.PreviewButton)

                case app.PreviewButton
                    cla(app.image_axes.Preview);
                    
                    % Get parameters
                    import_callbacks(app, event);
                    d = app.tmp_variables.Preview;
                    ifx = app.PreviewFrameX.Value-1;
                    ify = app.PreviewFrameY.Value-1;

                    if ~isempty(d)
                        dnpx = d.n_pixels_file(1);
                        dnpy = d.n_pixels_file(2);
                        dnfx = d.n_frames_file(1);
                        dnfy = d.n_frames_file(2);

                        app.common_parameters.PreviewAxLim = [0.51, dnpx + .49, 0.51, dnpy + .49];
                        set(app.image_axes.Preview, ...
                            "XLim", app.common_parameters.PreviewAxLim(1:2), ...
                            "YLim", app.common_parameters.PreviewAxLim(3:4));

                        try
                            if ~isempty(d.h5ds) % Preview HDF5
                                fid = H5F.open(d.file_path);
                                dset_id = H5D.open(fid, d.h5ds);
                                file_space_id = H5S.create_simple(4, [dnfy dnfx dnpy dnpx], []);
                                mem_space_id = H5S.create_simple(2, [dnpy dnpx], []);
                                
                                % C-like array indices for H5 low-level APIs
                                H5S.select_hyperslab(file_space_id, 'H5S_SELECT_SET', [ify ifx 0 0], [], [], [1 1 dnpy dnpx]);
                                frame = H5D.read(dset_id, 'H5ML_DEFAULT', mem_space_id, file_space_id, 'H5P_DEFAULT');

                            else % Preview others
                                fid = fopen(d.file_path,"r");
                                
                                % Move cursor to end of the to-be-read frame, for testing
                                err = fseek(fid, d.offset + (1 + ifx + ify*dnfx)*(d.frame_header + dnpx*dnpy*d.byte_size + d.frame_footer), 'bof');
                                
                                if err == -1
                                    throw(MException("",""));
                                end
                                
                                % Move cursor to start of the to-be-read frame
                                fseek(fid, -(dnpx*dnpy*d.byte_size + d.frame_footer), 'cof');
                                frame = fread(fid, [dnpx, dnpy], d.data_type, 0, d.byte_orer);
                            end

                            app.images.Preview = frame;
                            
                            % Plot image
                            plot_image(app, event, "Preview")
                            
                            % Check NaN
                            dataset_options_callbacks(app, event)
                        
                        catch
                            notification_dialog(app, ...
                                                "error", ...
                                                "Errors occurred during preview! Maybe reached end-of-file.", ...
                                                "Error during Preview!");

                        end
                        % Close opened handles
                        try
                            if ~isempty(d.h5ds)
                                H5S.close(mem_space_id);
                                H5S.close(file_space_id);
                                H5D.close(dset_id);
                                H5F.close(fid);
                            else
                                fclose(fid);
                            end
                        catch
                        end
                    end
            end

        end

        % Callback function: Alpha, TBAutoAlign, TBCrossAlign, TransBeamR, 
        % ...and 12 other components
        function transmitted_beam_callbacks(app, event)
            debug_time = tic;
            switch event.Source
                case app.Mode % Entering mode
                    % Create transmitted beam annotation, allow for disk as large as the entire diffraction pattern
                    app.annotations.TransBeam = drawcircle(app.image_axes.Diffraction, ...
                                                           "Center", app.center, ...
                                                           "Radius", app.TransBeamR.Value, ...
                                                           "Color", "b",...
                                                           "DrawingArea", "unlimited", ...
                                                           "Deletable", false, ...
                                                           "EdgeAlpha", 0.7, ...
                                                           "FaceAlpha", 0, ...
                                                           "Tag", "TransBeamAnnot");
                    app.annotations.TransBeam.UserData.Move = addlistener(app.annotations.TransBeam, "MovingROI", @move_transmitted_beam);
                    addlistener(app.annotations.TransBeam, "ROIMoved", @move_transmitted_beam);
                    
                    % Select `Diffraction` image
                    mock_UI_callbacks(app, app.DisplayImage, "Diffraction");
                    
                    % If this is the initializing alignment, then do swap byte check and auto-align
                    if ~app.common_parameters.transmitted_beam_init
                        dataset_options_callbacks(app, event);
                        autoAlign();
                    end

                    % Enable windows
                    enable_windows(app,true)
                
                case app.UpdateImages
                    % Update images
                    % Create a summed image of all diffraction patterns simply for alignment and calibration
                    app.images.DiffractionMask = ones(app.dataset_parameters.n_pixels,app.dataset_parameters.data_type);
                    app.images.Real = gather(reshape(reshape(app.images.DiffractionMask,1,[])*app.data, app.dataset_parameters.n_frames));
                
                case app.TBAutoAlign
                    % Auto-align
                    autoAlign();
                
                case app.TBCrossAlign
                    % Align transmitted beam via two crossed lines
                    crossAlign();
            end
            % Update dependent variables
            move_transmitted_beam(event.Source, event);

            debug_toc(app, event, "", debug_time)


            %% Sub functions
            % Function to sync UIs/ROIs depending on the central beam location
            function move_transmitted_beam(source, event)
                b0 = [app.TransBeamX.Value app.TransBeamY.Value];
                r = app.TransBeamR.Value;
                a = app.Alpha.Value;
                mrad_per_px = a/r;  np = app.dataset_parameters.n_pixels;
                if isempty(findobj(app.image_axes.Diffraction, "Tag",'TransBeamAnnot'))
                    app.annotations.TransBeam = []; % If ROI is not plotted
                else
                    switch event.Source
                        case {app.TransBeamX app.TransBeamXSpinner}
                            % Force X >= 1
                            b0(1) = max(1, event.Value);
                        
                        case {app.TransBeamY app.TransBeamYSpinner}
                            % Force Y >= 1
                            b0(2) = max(1, event.Value);
                        
                        case {app.TransBeamR app.TransBeamRSpinner}
                            % Radius
                            r = event.Value;
                        
                        case app.Alpha
                            % Calculate the diffraction scale
                            a = event.Value;
                            mrad_per_px = a/r;

                        case app.mradPx
                            % Manually set the diffraction scale
                            mrad_per_px = event.Value;
                            a = mrad_per_px*r;

                        case app.annotations.TransBeam
                            % ROI
                            b0 = source.Center; 
                            r = source.Radius;
                    end
                end
                
                % Restrict transmitted beam location/size
                b0(b0 > np) = np(b0 > np);
                b0(b0 < 1) = 1;
                app.center = b0;
                
                % Find the max allowed radius
                app.common_parameters.max_radius = ceil(sqrt(sum((abs(app.common_parameters.diffraction_center-b0) + app.common_parameters.diffraction_center -1).^2)));
                
                % set the annular integration step size based on max radius
                % (convert to mrad for the UI); default to stepping each
                % pixel, with a max of 250 steps, otherwise pre-calculation
                % of the annular images can get quite slow
                if event.Source ~= app.AnnularStep
                    app.AnnularStep.Value = app.mradPx.Value * max(1, app.common_parameters.max_radius/250);
                end

                % make sure r is not larger than the max radius; cannot be
                % 0 so clip to eps
                r = clip(r, eps, app.common_parameters.max_radius);
                
                % Update UI limits
                set([app.TransBeamR, ...
                     app.TransBeamRSpinner], ...
                    "Limits", ...
                    [eps(), app.common_parameters.max_radius])
                
                set([app.InnerAnnularRadius, ...
                     app.OuterAnnularRadius, ...
                     app.VirtualApertureR, ...
                     app.BandpassFilterLow, ...
                     app.BandpassFilterHigh, ...
                     app.BandpassFilter], ...
                    "Limits", ...
                    [0, app.common_parameters.max_radius])
                
                % Update UI values
                set_external_source(app, ...
                                    event, ...
                                    [app.TransBeamR; ...
                                     app.TransBeamX; ...
                                     app.TransBeamY; ...
                                     app.TransBeamRSpinner; ...
                                     app.TransBeamXSpinner; ...
                                     app.TransBeamYSpinner; ...
                                     app.BandpassFilterLow; ...
                                     app.BandpassFilterHigh; ...
                                     app.BandpassFilter; ...
                                     app.Alpha; ...
                                     app.mradPx], ...
                                    {"Value"}, ...
                                    {r; ...
                                     b0(1); ...
                                     b0(2); ...
                                     r; ...
                                     b0(1); ...
                                     b0(2); ...
                                     0;...
                                     app.common_parameters.max_radius; ...
                                     [0, app.common_parameters.max_radius];...
                                     a; ...
                                     mrad_per_px})
                
                % Update ROI
                set(app.annotations.TransBeam,"Center",b0,'Radius',r)
                
                % Update global coordinate properties
                detector_coordinates_callbacks(app, event)

            end

            % Auto-align transmitted beam
            function autoAlign()
                app.tmp_variables.progress_dialog = progress_dialog(app, 'Finding transmitted beam ...', "Auto Alignment");
                
                % Reset any zoom on the transmitted beam
                %axis(app.image_axes.Diffraction, app.common_parameters.diffraction_axes_limit)

                % Align transmitted beam by trying to find the transmitted
                % disk in the diffraction pattern (app.images.Diffraction),
                % apply slight Gaussian blur of 5 pixels to help the fit if
                % necessary
                try
                    warning("off");
                    [trans_beam_center, trans_beam_radius, ~] = imfindcircles(app.images.Diffraction*(-1)^app.DispColorMapInvert.Value, [1 round(max(app.dataset_parameters.n_pixels)/2)]);
                    if isempty(trans_beam_center)
                        [trans_beam_center, trans_beam_radius, ~] = imfindcircles(imgaussfilt(app.images.Diffraction*(-1)^app.DispColorMapInvert.Value,5), [1 round(max(app.dataset_parameters.n_pixels)/2)]);
                    end
                    warning("on");
                catch
                    trans_beam_center = [];
                    trans_beam_radius = [];
                end
                trans_beam_center = fliplr(trans_beam_center);
                
                % Close the progress bar
                delete(app.tmp_variables.progress_dialog);
                
                % Store old transmitted beam values
                transBeam_old = [app.center app.TransBeamR.Value];

                % Find circle closest to the center of the diffraction
                % pattern, plot for user confirmation
                if isempty(trans_beam_center)
                    notification_dialog(app, ...
                                        "warn", ...
                                        "No transmitted beam detected. Please align the transmitted beam manually.", ...
                                        "Auto-align Failed");
                    sel = "No. Revert";
                else
                    % Assign radius and center value(s) found above using
                    % imfindcircles based on whichever is found to be
                    % closest to the diffraction pattern center
                    % `app.P.diffraction_center`
                    [~,im] = min(sqrt(sum((trans_beam_center-app.common_parameters.diffraction_center).^2,2)));
                    app.TransBeamX.Value = trans_beam_center(im,1);
                    app.TransBeamY.Value = trans_beam_center(im,2);
                    app.TransBeamR.Value = trans_beam_radius(im);
                    
                    % To update annotation/panel values
                    transmitted_beam_callbacks(app,struct("Source", "", "EventName", ""));

                    sel = notification_dialog(app,'quest', "Is the auto-alignment correct?",'Auto-align',["Yes", "No. Revert"]);
                end

                % Revert alignment if not correctly detected
                if sel == "No. Revert"
                    app.TransBeamX.Value = transBeam_old(1);
                    app.TransBeamY.Value = transBeam_old(2);
                    app.TransBeamR.Value = transBeam_old(3);
                end
            end

            % align transmitted beam at the crossing of two lines
            function crossAlign()
                app.annotations.line1 = drawline("Parent", app.image_axes.Diffraction, ...
                                                 "Position", app.dataset_parameters.n_pixels.*[1 1;3 3]/4, ...
                                                 "Color", "r", ...
                                                 "Deletable",false);
                
                app.annotations.line2 = drawline("Parent", app.image_axes.Diffraction, ...
                                                 "Position", app.dataset_parameters.n_pixels.*[1 3;3 1]/4, ...
                                                 "Color", "r", ...
                                                 "Deletable",false);

                addlistener(app.annotations.line1, "ROIMoved", @moveTB);
                addlistener(app.annotations.line1, "MovingROI", @moveTB);
                addlistener(app.annotations.line2, "ROIMoved", @moveTB);
                addlistener(app.annotations.line2, "MovingROI", @moveTB);
                
                moveTB()

                h = helpdlg('Push OK when finished');
                uiwait(h);

                delete(app.annotations.line1)
                delete(app.annotations.line2)


                function moveTB(~,~)
                    x1 = app.annotations.line1.Position(1,1);
                    x2 = app.annotations.line1.Position(2,1);
                    x3 = app.annotations.line2.Position(1,1);
                    x4 = app.annotations.line2.Position(2,1);

                    y1 = app.annotations.line1.Position(1,2);
                    y2 = app.annotations.line1.Position(2,2);
                    y3 = app.annotations.line2.Position(1,2);
                    y4 = app.annotations.line2.Position(2,2);

                    app.center = [x1*y2-x2*y1, x3*y4-x4*y3] / ...
                                 [   y2-y1,  y4-y3;
                                  -(x2-x1),-(x4-x3)];

                    app.TransBeamX.Value = app.center(1);
                    app.TransBeamXSpinner.Value = app.center(1);
                    app.TransBeamY.Value = app.center(2);
                    app.TransBeamYSpinner.Value = app.center(2);

                    app.annotations.TransBeam.Center = app.center;

                end
            end
        end

        % Callback function: AnnularRadiusLink, AutoCurl, 
        % ...and 20 other components
        function annular_detector_callbacks(app, event)
            debug_time = tic;
            
            % Absolute pixel coordinates of central beam; only changes in
            % `transmitted_beam_callbacks()`
            transmitted_beam_center = app.center;
            
            % Detector mode; when changed this callback will be called
            dmod = app.Mode.Value;

            % set state button icon color based on value
            change_icon_background(app, app.FlipScanDirectionY)
            change_icon_background(app, app.AnnularRadiusLink)

            switch event.Source
                case app.DetectorCoordinateSystem % Change coordinate options
                    % set UI limits
                    limitAnnUI()

                case {app.Mode, app.AnnularStep} % Entering current mode
                    % set UI limits
                    limitAnnUI()

                    % Lock scan directions first upon entering annular modes
                    app.ScanDirectionLock.Value = false;
                    
                    % UI enable/disable
                    enableAnnUI()
                    
                    % Update annular integral mask
                    integrate_annular()
                    
                    % Draw detectors
                    draw_annular_detector();

                    % Draw segmented detectors if in DPC mode
                    if dmod == "DPC"
                        draw_segmented_detector();
                    end
                    
                    % Update UI/ROIs
                    move_annular_detector(event.Source, event)
                    
                    % Enable windows
                    enable_windows(app,true)

                case {app.InnerAnnularRadius app.InnerAnnularRadiusSpinner ...
                      app.OuterAnnularRadius app.OuterAnnularRadiusSpinner ...
                      app.DetectorRotationSlider app.DetectorRotationSpinner ...
                      app.ScanDirectionSlider app.ScanDirectionSpinner ...
                      app.AnnularRadiusLink app.NSeg app.NRung ...
                      app.FlipScanDirectionY app.UpdateImages}
                    % Update UI/ROIs
                    move_annular_detector(event.Source, event)

                case app.ScanDirectionLock
                    if app.ScanDirectionLock.Value
                        app.ScanDirectionLock.Icon = 'unlock.png'; 
                    else
                        app.ScanDirectionLock.Icon = 'lock.png';
                    end

                    % Flag scan directions as aligned
                    app.common_parameters.scan_direction_initialized = true;
                    
                    % Enable/Disable GUI elements
                    enableAnnUI()
                    
                    % Try to remove existing scan direction ROIs
                    delete(findobj(app.image_axes.Diffraction, "Tag",'ScanDirAnnot'));
                    
                    % Draw annotations
                    if app.ScanDirectionLock.Value
                        draw_scan_direction();
                    end

                    % Update diffraction axes annotations
                    mock_UI_callbacks(app,app.ShowDiffractionAxes)

                case app.AutoCurl
                    % display a progress dialog for user
                    app.tmp_variables.progress_dialog = progress_dialog(app, sprintf('Automatically estimating scan \norientation my minimizing curl...'), "Auto scan orientation");
                    
                    % minimize curl and update UI/ROIs twice for precision
                    for ii=1:2
                        minimize_curl()
                        move_annular_detector(event.Source, event)
                    end
                    
                    % automatically close out the scan direction routine
                    app.ScanDirectionLock.Value = false;
                    annular_detector_callbacks(app, struct("Source",app.ScanDirectionLock, "EventName", "ValueChanged", "Value", false, "PreviousValue", true))

                    % Close progress bar and Enable panel objects
                    delete(app.tmp_variables.progress_dialog);
            end

            debug_toc(app, event, "", debug_time)


            %% Sub functions
            % Function to try to automatically find angle between
            % diffraction space and real space by minimizing center of mass
            % curl
            function minimize_curl()
                % find the curl for all angles
                CoM = zeros([2,fliplr(app.dataset_parameters.n_frames)]);
                CoM(1,:,:) = app.images.CoMX';
                CoM(2,:,:) = app.images.CoMY';

                if all(isnan(CoM(:)))
                    notification_dialog(app, ...
                                        "warn", ...
                                        "CoM is all NaNs! This caused " + ...
                                        "minimize_curl() to fail. Try " + ...
                                        "changing the CoM detector and " + ...
                                        "repeating.", ...
                                        "Automatic Curl Minimization Failed");
                    return
                end

                % set up angles
                theta = reshape(0:359,[1,1,360]) + app.ScanDirectionSlider.Value;
                R_applied = [cosd(-app.ScanDirectionSpinner.Value) -sind(-app.ScanDirectionSpinner.Value);
                             sind(-app.ScanDirectionSpinner.Value)  cosd(-app.ScanDirectionSpinner.Value)];
                R = permute([cosd(theta) -sind(theta); ...
                             sind(theta)  cosd(theta)],[3,1,2]);
                
                % undo rotation by ScanDir angle via R_applied
                rotCoM = tensorprod(R,tensorprod(R_applied,CoM,2,1),3,1);
                
                %rotCoM = tensorprod(R,CoM,3,1);
                [dFx_dx,dFx_dy] = gradient(permute(squeeze(rotCoM(:,1,:,:)),[2,3,1]));
                [dFy_dx,dFy_dy] = gradient(permute(squeeze(rotCoM(:,2,:,:)),[2,3,1]));
                cav = squeeze(mean(abs(0.5*(dFy_dx-dFx_dy)),[1,2]));
                div = squeeze(sum(dFx_dx + dFy_dy,[1,2]));
                
                % try transpose
                [dFx_dx,dFx_dy] = gradient(permute(squeeze(rotCoM(:,1,:,:)),[3,2,1]));
                [dFy_dx,dFy_dy] = gradient(permute(squeeze(rotCoM(:,2,:,:)),[3,2,1]));
                cav(:,2) = squeeze(mean(abs(0.5*(dFy_dx-dFx_dy)),[1,2]));
                div(:,2) = squeeze(sum(dFx_dx + dFy_dy,[1,2]));

                % refine theta value
                thetaIdx = find(cav==min(cav(:)));
                if numel(thetaIdx)>1
                    [~,idx] = max(div(thetaIdx));
                    thetaIdx = thetaIdx(idx);
                end

                [thetaIdx,transVal] = ind2sub(size(cav),thetaIdx);
                transVal = transVal - 1;
                thetaCoarse = theta(thetaIdx)-app.ScanDirectionSlider.Value;
                theta = reshape(thetaCoarse-1:0.1:thetaCoarse+1,[1,1,21]);
                R = permute([cosd(theta) -sind(theta);sind(theta) cosd(theta)],[3,1,2]);
                rotCoM = tensorprod(R,CoM,3,1);

                if transVal==1
                    [~,dFx_dy] = gradient(permute(squeeze(rotCoM(:,1,:,:)),[3,2,1]));
                    [dFy_dx,~] = gradient(permute(squeeze(rotCoM(:,2,:,:)),[3,2,1]));
                
                else
                    [~,dFx_dy] = gradient(permute(squeeze(rotCoM(:,1,:,:)),[2,3,1]));
                    [dFy_dx,~] = gradient(permute(squeeze(rotCoM(:,2,:,:)),[2,3,1]));
                end
                
                cav = squeeze(mean(abs(0.5*(dFy_dx-dFx_dy)),[1,2]));
                thetaVal = theta(cav==min(cav)) + app.ScanDirectionSlider.Value;
                
                app.ScanDirectionSlider.Value = wrapTo180(thetaVal);
                app.ScanDirectionSpinner.Value = wrapTo180(thetaVal);
                app.FlipScanDirectionY.Value = xor(app.FlipScanDirectionY.Value,transVal);
            end

            % Function to update Annular detector UI limits/labels when coordinate options change
            function limitAnnUI()
                set([app.AnnRi_NFLabel app.AnnRo_NFLabel], ...
                    'Text', ...
                    app.DetectorCoordinateUnit.Value)
                set([app.InnerAnnularRadiusSpinner; ...
                     app.OuterAnnularRadiusSpinner], ...
                    "Limits", ...
                    [0 app.common_parameters.max_radius*app.diff_scale], ...
                    'Step', ...
                    app.AnnularStep.Value)
                inner_radius = clip(round(app.InnerAnnularRadius.Value*app.mradPx.Value/app.AnnularStep.Value)*app.AnnularStep.Value, 0, app.InnerAnnularRadius.Limits(2));
                outer_radius = clip(round(app.OuterAnnularRadius.Value*app.mradPx.Value/app.AnnularStep.Value)*app.AnnularStep.Value, 0, app.OuterAnnularRadius.Limits(2));
                set_external_source(app, event, ...
                                    [app.InnerAnnularRadiusSpinner; ...
                                     app.OuterAnnularRadiusSpinner; ...
                                     app.InnerAnnularRadius; ...
                                     app.OuterAnnularRadius], ...
                                    {"Value"}, ...
                                    {inner_radius; ...
                                     outer_radius; ...
                                     min(inner_radius/app.mradPx.Value, app.common_parameters.max_radius); ...
                                     min(outer_radius/app.mradPx.Value, app.common_parameters.max_radius)}); 
            end

            % Function to enable/disable UI
            function enableAnnUI()
                % Enable segmented panel
                set(app.SegmentedDetectorGrid.Children, "Enable",switch_on_off(app, dmod == "DPC"))

                % Change Enable state of scan direction UIs, except `app.ScanDirectionLock`
                set(setdiff(app.ScanDirectionGrid.Children,app.ScanDirectionLock), ...
                    "Enable", ...
                    switch_on_off(app, app.ScanDirectionLock.Value))

                % enable ScanDirectionLock if in CoM/DPC mode, else disable
                app.ScanDirectionLock.Enable = ismember(dmod, ["CoM" "DPC"]);
                
                % Highlight to align Scanning Direction
                if app.ScanDirectionLock.Enable && ~app.common_parameters.scan_direction_initialized
                    background_color = app.sys_constants.warning_color;
                else
                    background_color = app.sys_constants.background_color;
                end
                
                % Set scan direction panel color
                app.ScanDirectionGrid.BackgroundColor = background_color;
            end

            % Function to live update annular/round/segmented detectors
            function move_annular_detector(source, event)
                inner_radius = app.InnerAnnularRadius.Value;
                outer_radius = app.OuterAnnularRadius.Value;
                detector_rotation = app.DetectorRotationSlider.Value;
                scan_direction = app.ScanDirectionSlider.Value;
                flip_scan_y = 1-2*app.FlipScanDirectionY.Value;

                % Cases for different detectors
                if contains(source.Tag,'AnnDetr')
                    moveAnnR()
                elseif contains(source.Tag,'SegDetr')
                    move_segmented_detector()
                elseif contains(source.Tag,'ScanDir')
                    move_scan()
                else % app.Mode app.UpdateImages
                    moveAnnR();
                    
                    % Update scan directions as well
                    if app.ScanDirectionLock.Value
                        move_scan();
                    end
                end

                % Whether to update images
                if to_update_image(app, event)
                    % Skip calculations of new BF/DF `app.images.Real` if
                    % annular radii are not changed
                    if is_different_to_previous(app,'AnnR',[inner_radius outer_radius]) || is_static_event(app, event)
                        % 2D matrix of distances from central beam, X/Y on the 1st/2nd dimensions
                        mask_ann = sqrt( ((1:app.dataset_parameters.n_pixels(1))-transmitted_beam_center(1))'.^2 + ((1:app.dataset_parameters.n_pixels(2))-transmitted_beam_center(2)).^2 );
                        mask_ann = mask_ann >= inner_radius & mask_ann <= outer_radius;
                        app.images.DiffractionMask = mask_ann;
                        app.masks.annular = mask_ann;
                        
                        % The plus 1 is because the array begins at 0, but is 1-indexed.
                        adf_inner = clip(round(inner_radius*app.mradPx.Value/app.AnnularStep.Value) + 1, 1, app.common_parameters.max_radius);
                        adf_outer = clip(round(outer_radius*app.mradPx.Value/app.AnnularStep.Value) + 1, 1, app.common_parameters.max_radius);
                        app.images.Real = gather(sum(app.images.annular_images(:, :,adf_inner:adf_outer), 3,'omitnan'));
                    end

                    % CoM/DPC calculations; skip if parameters unchanged
                    if ismember(dmod, ["CoM", "DPC"])
                        if is_different_to_previous(app,'CoM_P',[inner_radius outer_radius scan_direction flip_scan_y detector_rotation]) || is_static_event(app, event)
                            calculate_CoM();
                        end
                    end
                    % Update images
                    plot_all_patterns(app, event)
                end

                % Sub sub functions
                % Sub sub function for annular radii
                function moveAnnR()
                    scale = app.diff_scale;
                    
                    % given the AnnularStep (in mrad), round detector step
                    % size (in pixels)
                    annular_pixel_step = app.AnnularStep.Value/app.mradPx.Value;

                    switch source
                        case app.annotations.inner_annulus
                            inner_radius = round(event.CurrentRadius/annular_pixel_step) * annular_pixel_step;
                        case app.annotations.outer_annulus
                            outer_radius = round(event.CurrentRadius/annular_pixel_step) * annular_pixel_step;
                        case app.InnerAnnularRadius
                            inner_radius = round(event.Value/annular_pixel_step) * annular_pixel_step;
                        case app.OuterAnnularRadius
                            outer_radius = round(event.Value/annular_pixel_step) * annular_pixel_step;
                        case app.InnerAnnularRadiusSpinner
                            inner_radius = round(event.Value/app.AnnularStep.Value)*app.AnnularStep.Value/app.diff_scale;
                        case app.OuterAnnularRadiusSpinner
                            outer_radius = round(event.Value/app.AnnularStep.Value)*app.AnnularStep.Value/app.diff_scale;
                        otherwise % {app.AnnularRadiusLink, app.Mode app.UpdateImages}
                            app.common_parameters.detector_width = outer_radius - inner_radius;
                    end

                    % If inner/outer detector radii are linked
                    if app.AnnularRadiusLink.Value
                        detector_width = app.common_parameters.detector_width;
                        if contains(source.Tag,'RI')
                            inner_radius = min(inner_radius, app.common_parameters.max_radius-detector_width);
                            outer_radius = inner_radius + detector_width;
                        elseif contains(source.Tag,'RO')
                            outer_radius = max(outer_radius, detector_width);
                            inner_radius = outer_radius - detector_width;
                        end
                    end
                    
                    % Restrict Inner/Outer radii
                    if contains(source.Tag,'RI')
                        inner_radius = clip(inner_radius, 0, outer_radius);
                    elseif contains(source.Tag,'RO')
                        outer_radius = clip(outer_radius, inner_radius, app.common_parameters.max_radius);
                    end
                    
                    % Update ROI
                    set([app.annotations.inner_annulus;app.annotations.outer_annulus], ...
                        {'Radius'}, {inner_radius;outer_radius})
                    
                    % Update UI with Limits check
                    set_external_source(app, event, ...
                                        [app.InnerAnnularRadius; ...
                                         app.OuterAnnularRadius; ...
                                         app.InnerAnnularRadiusSpinner; ...
                                         app.OuterAnnularRadiusSpinner], ...
                                        {"Value"}, ...
                                        {inner_radius; ...
                                         outer_radius; ...
                                         min(inner_radius*scale, app.common_parameters.max_radius*scale); ...
                                         min(outer_radius*scale, app.common_parameters.max_radius*scale)});
                    
                    % Update saving prefix
                    if is_static_event(app, event)
                        mock_UI_callbacks(app,app.SaveImagePrefix)
                    end

                    % Whether to update segments as well
                    if dmod == "DPC"
                        move_segmented_detector();
                    end
                end

                % Sub sub function for segmented detector
                function move_segmented_detector()
                    switch source
                        case {app.DetectorRotationSlider app.DetectorRotationSpinner}
                            detector_rotation = event.Value;
                        case app.annotations.SegDetrP0
                            rxy = (event.CurrentPosition-transmitted_beam_center);
                            detector_rotation = atan2d(rxy(2), rxy(1));
                        case {app.NSeg app.NRung}
                            draw_segmented_detector();
                    end

                    % Update UI
                    set_external_source(app, event, [app.DetectorRotationSlider app.DetectorRotationSpinner], "Value",detector_rotation)
                    
                    % Update ROI
                    pos0 = [inner_radius, 0; outer_radius, 0; (inner_radius+outer_radius)/2, 0]*rotation_matrix(app, detector_rotation)+transmitted_beam_center;
                    set([app.annotations.SegDetrL0 app.annotations.SegDetrP0],{"Position"},{pos0(1:2,:);pos0(3,:)});
                    set(app.annotations.segmented_detector_circle,{'Radius'}, num2cell((1:app.NRung.Value-1)'/app.NRung.Value*(outer_radius - inner_radius) + inner_radius))
                    
                    % Use 3D matrix for positions, then put the 1st/2nd dimensions (2x2 array) in each cell for each segment
                    t = detector_rotation+360*(reshape(1:app.NSeg.Value,1,1,[])-.5)/app.NSeg.Value;
                    set(app.annotations.segmented_detector_line,{"Position"},squeeze(num2cell([inner_radius;outer_radius].*[cosd(t) sind(t)]+transmitted_beam_center, 1:2)))
                end

                % Sub sub function for scan directions
                function move_scan()
                    % Use polar relative pixel coordinates for calculation
                    svx = app.annotations.ScanDir.xc.Center - transmitted_beam_center;  svy = app.annotations.ScanDir.yc.Center - transmitted_beam_center;
                    switch source
                        case app.annotations.ScanDir.xc
                            svx = event.CurrentCenter - transmitted_beam_center;
                            scan_direction = atan2d(svx(2), svx(1));
                        case app.annotations.ScanDir.yc
                            svy = (event.CurrentCenter - transmitted_beam_center)*flip_scan_y;
                            scan_direction = -atan2d(svy(1), svy(2));
                        case {app.ScanDirectionSlider app.ScanDirectionSpinner}
                            scan_direction = event.Value;
                        case app.FlipScanDirectionY
                    end

                    % Convert back to Cartesian absolute pixel coordinates for annotations
                    [x1,y1] = pol2cart(deg2rad(scan_direction), sqrt(sum(svx.^2)));
                    sp1 = [x1 y1] + transmitted_beam_center;
                    [x2,y2] = pol2cart(deg2rad(scan_direction+90*flip_scan_y), sqrt(sum(svy.^2)));
                    sp2 = [x2 y2] + transmitted_beam_center;
                    
                    % Not limiting annotation positions
                    set([app.annotations.ScanDir.xl app.annotations.ScanDir.yl],{"Position"},{[transmitted_beam_center;sp1];[transmitted_beam_center;sp2]})
                    set_external_source(app, event, [app.annotations.ScanDir.xc app.annotations.ScanDir.yc],{"Center"},{sp1;sp2})
                    set_external_source(app, event, [app.ScanDirectionSlider app.ScanDirectionSpinner], "Value",scan_direction)
                end
            end

            % Function to draw annular/round/segmented detectors
            function draw_annular_detector()
                % delete any annular detector annotations
                delete(findobj(app.image_axes.Diffraction,'-regexp', "Tag",'AnnDetrAnnot'))
                
                % draw inner and outer circles
                app.annotations.inner_annulus = drawcircle(app.image_axes.Diffraction, ...
                                                           "Center", transmitted_beam_center, ...
                                                           "Radius", 1, ...
                                                           "Color", "r", ...
                                                           "Tag", "AnnDetrAnnot RI");
                app.annotations.outer_annulus = drawcircle(app.image_axes.Diffraction, ...
                                                           "Center", transmitted_beam_center, ...
                                                           "Radius", 2, ...
                                                           "Color", "m", ...
                                                           "Tag", "AnnDetrAnnot RO");
                
                % add listener for live update during user interaction
                addlistener([app.annotations.inner_annulus, ...
                             app.annotations.outer_annulus], ...
                            "MovingROI", @move_annular_detector);
                
                u.Move = addlistener([app.annotations.inner_annulus, ...
                                      app.annotations.outer_annulus], ...
                                     "ROIMoved", @move_annular_detector);

                % set properties for inner and out annulus annotations
                set([app.annotations.inner_annulus app.annotations.outer_annulus], ...
                    "FaceAlpha", 0, ...
                    "FaceSelectable", 0, ...
                    "LineWidth", 4, ...
                    "DrawingArea", "unlimited", ...
                    "Deletable", 0, ...
                    "InteractionsAllowed", "reshape", ...
                    "UserData", u);
            end

            % Function to draw segmented annotations
            function draw_segmented_detector()
                % delete any old segmented detector annotations
                delete(findobj(app.image_axes.Diffraction, "Tag",'SegDetrAnnot'))
                
                app.annotations.segmented_detector_circle = gobjects(app.NRung.Value-1,1);
                app.annotations.segmented_detector_line = gobjects(app.NSeg.Value,1);

                % plot the lines between segments
                for i = 1:app.NSeg.Value
                    app.annotations.segmented_detector_line(i) = drawline(app.image_axes.Diffraction, ...
                                                                          "Position", [0 1; 0 1] + transmitted_beam_center, ...
                                                                          "Color", "b", ...
                                                                          "LineWidth", 2);
                end
                % Zero-degree line
                app.annotations.SegDetrL0 = drawline(app.image_axes.Diffraction, ...
                                                     "Position", [transmitted_beam_center;transmitted_beam_center], ...
                                                     "Color", "w", ...
                                                     "StripeColor", "k", ...
                                                     "LineWidth", 1.5, ...
                                                     "EdgeAlpha", 0.7);

                % plot the circles between rungs
                for i = 1:app.NRung.Value - 1
                    app.annotations.segmented_detector_circle(i) = drawcircle(app.image_axes.Diffraction, ...
                                                                              "Center", transmitted_beam_center, ...
                                                                              "Radius", 1, ...
                                                                              "Color", "y", ...
                                                                              "LineWidth", 2, ...
                                                                              "FaceAlpha", 0, ...
                                                                              "FaceSelectable", false);
                end
                
                % Zero-degree point
                app.annotations.SegDetrP0 = drawpoint(app.image_axes.Diffraction, ...
                                                      "Position", transmitted_beam_center, ...
                                                      "Color", "w", ...
                                                      "LabelTextColor", "w", ...
                                                      "LabelAlpha", 0, ...
                                                      "Label", "0", ...
                                                      "Deletable", 0, ...
                                                      "Tag", "SegDetrAnnot");
                
                % add listeners to segment annotations
                addlistener(app.annotations.SegDetrP0, "MovingROI", @move_annular_detector);
                app.annotations.SegDetrP0.UserData.Move = addlistener(app.annotations.SegDetrP0, "ROIMoved", @move_annular_detector);
                
                % set segment annotation parameters
                set([app.annotations.SegDetrL0; ...
                     app.annotations.segmented_detector_circle; ...
                     app.annotations.segmented_detector_line], ...
                    "InteractionsAllowed", "none", ...
                    "Tag", "SegDetrAnnot");
            end

            % Function to draw Scan Direction alignment annotations
            function draw_scan_direction()
                p1 = transmitted_beam_center+[app.common_parameters.max_radius/2 0];
                p2 = transmitted_beam_center+[0 app.common_parameters.max_radius/2];
                
                app.annotations.ScanDir.xl = drawline(app.image_axes.Diffraction, ...
                                                      "Position", [transmitted_beam_center;p1], ...
                                                      "Color","r", ...
                                                      'LabelTextColor',"r", ...
                                                      'Label','Scan X');

                app.annotations.ScanDir.yl = drawline(app.image_axes.Diffraction, ...
                                                      "Position", [transmitted_beam_center;p2], ...
                                                      "Color", 'g', ...
                                                      'LabelTextColor', 'g', ...
                                                      'Label', 'Scan Y');

                app.annotations.ScanDir.xc = drawcircle(app.image_axes.Diffraction, ...
                                                        "Center", p1, ...
                                                        'Radius', app.TransBeamR.Value);

                app.annotations.ScanDir.yc = drawcircle(app.image_axes.Diffraction, ...
                                                        "Center", p2, ...
                                                        'Radius', app.TransBeamR.Value);
                
                % add listeners to scan direction annotations
                addlistener([app.annotations.ScanDir.xc; ...
                             app.annotations.ScanDir.yc], ...
                            "MovingROI", @move_annular_detector);
                u.Move = addlistener([app.annotations.ScanDir.xc; ...
                                      app.annotations.ScanDir.yc], ...
                                     "ROIMoved", @move_annular_detector);

                % set scan direction annotation parameters
                set([app.annotations.ScanDir.xc; ...
                     app.annotations.ScanDir.yc], ...
                    "Color", [0 0.5 1], ...
                    "FaceAlpha", 0, ...
                    "UserData",u)
                set([app.annotations.ScanDir.xl; ...
                     app.annotations.ScanDir.yl], ...
                    "MarkerSize", 1, ...
                    "LineWidth",1, ...
                    "LabelAlpha",0, ...
                    "InteractionsAllowed", "none")
                set([app.annotations.ScanDir.xc; ...
                     app.annotations.ScanDir.yc; ...
                     app.annotations.ScanDir.xl; ...
                     app.annotations.ScanDir.yl], ...
                    "Deletable", false, ...
                    "DrawingArea", "unlimited", ...
                    "Tag", "ScanDirAnnot")

                % update annular detector
                move_annular_detector(event.Source, event)
            end

            % Integrate the annular detector for the primary real-space
            % pattern `app.Axes.Real` with step size app.AnnularStep.Value
            function integrate_annular()
                debug_time = tic;
                
                % Rerun the annular integration based on the newly aligned location of the transmitted disk
                message = "Generating radial masks (takes a while) ...";
                
                if is_different_to_previous(app,'B0',app.center) || (event.Source == app.AnnularStep && ~strcmp(app.Mode.Value,'Alignment'))
                    app.tmp_variables.progress_dialog = progress_dialog(app, sprintf("\n%s\n",message), "Annular Integration");
                    
                    % Make pixel array for mask
                    [ygrid, xgrid] = meshgrid(1:app.dataset_parameters.n_pixels(2), 1:app.dataset_parameters.n_pixels(1));
                    
                    % Integrate in app.AnnularStep (mrad) increments all the way out to the furthest corner; put these on the 3rd dimension
                    dist = reshape(0:app.AnnularStep.Value/app.mradPx.Value:app.common_parameters.max_radius, 1, 1, []);
                    mask_ann = sqrt((xgrid-app.center(1)).^2 + (ygrid-app.center(2)).^2);
                    mask_ann = reshape(mask_ann >= dist & mask_ann < dist+app.AnnularStep.Value/app.mradPx.Value, [], numel(dist))';
                    mask_ann = cast(mask_ann,app.dataset_parameters.data_type);
                    
                    % Multiply data by the annular masks
                    try
                        app.images.annular_images = reshape((mask_ann*app.data)', app.dataset_parameters.n_frames(1), app.dataset_parameters.n_frames(2), []);
                    catch ME
                        if app.dataset_parameters.gpu
                            waitbar(0,app.tmp_variables.progress_dialog,message+sprintf("\nIt seems the GPU does not have enough memory.\nUsing CPU for this process ..."))
                            app.images.annular_images = reshape((mask_ann*gather(app.data))', app.dataset_parameters.n_frames(1), app.dataset_parameters.n_frames(2), []);
                            try 
                                % Move the integrated images to GPU
                                app.images.annular_images = gpuArray(app.images.annular_images);
                            catch
                            end
                        else
                            delete(app.tmp_variables.progress_dialog)
                            notification_dialog(app, ...
                                                "error", ...
                                                "It seems the system has not enough memory for this process! " ...
                                                 + "Maybe try to increase system's virtual memory.", ...
                                                "Out of Memory")
                            rethrow(ME)
                        end
                    end

                    % Close progress window
                    delete(app.tmp_variables.progress_dialog);
                end

                debug_toc(app, [], "", debug_time)
            end

            % Calculate the CoM images
            function calculate_CoM()
                debug_time = tic;
                if ~isfield(app.masks, "annular")
                    return;
                end

                inner_radius = app.InnerAnnularRadius.Value;
                outer_radius = app.OuterAnnularRadius.Value;
                scan_direction = app.ScanDirectionSlider.Value;
                flip_scan_y = 1-2*app.FlipScanDirectionY.Value;
                detector_rotation = app.DetectorRotationSlider.Value;

                [CoMX, CoMY] = integrate_CoM();
                app.images.CoMX = CoMX;
                app.images.CoMY = CoMY;

                % CoM magnitude is the length of the vector -- in mrad
                app.images.CoMMag = sqrt(CoMX.^2 + CoMY.^2);
                app.images.CoMMagNorm = rescale(app.images.CoMMag);
                
                % CoM angle was calculated from the arctangent of the difference images (CoMX and CoMY)
                app.images.CoMPh = atan2(CoMY,CoMX);
                
                % Adjust atan2 range from [-pi/2:pi/2) to [0:2pi)
                app.images.CoMPh(app.images.CoMPh < 0) = app.images.CoMPh(app.images.CoMPh < 0) + 2*pi;
                app.images.CoMPhMag = app.images.CoMPh;

                % Make iCoM/iDPC and dCoM/dDPC images - based on maths from Lazic et al.,
                % (https://doi.org/10.1016/j.ultramic.2015.10.011)
                %         [ky, kx] = meshgrid(floor(-app.dataset_parameters.n_framesY/2):floor(-app.dataset_parameters.n_framesY/2)+app.dataset_parameters.n_framesY-1, ...
                %           floor(-app.dataset_parameters.n_framesX/2):floor(-app.dataset_parameters.n_framesX/2)+app.dataset_parameters.n_framesX-1);
                [ky, kx] = meshgrid((1:app.dataset_parameters.n_frames(2)) - app.common_parameters.real_space_center(2), ...
                                    (1:app.dataset_parameters.n_frames(1)) - app.common_parameters.real_space_center(1));

                kr = sqrt(kx.^2+ky.^2);
                
                % a zero pixel will mess up the maths here
                kr(kr == 0) = 1;
                
                % prepare for FFTs
                kx = fftshift(kx);
                ky = fftshift(ky);
                kr = fftshift(kr);

                % generate band-pass filter 
                bandpass_filter = kr<=app.BandpassFilterHigh.Value & kr>=app.BandpassFilterLow.Value;

                % integrated center of mass
                app.images.iCoM = real(ifft2(kx.*fft2(CoMX)./(2*pi*1j*kr.^2).*bandpass_filter) + ...
                                       ifft2(ky.*fft2(CoMY)./(2*pi*1j*kr.^2).*bandpass_filter));
                
                % differential center of mass
                app.images.dCoM = real(ifft2(2*pi*1i*kx.*fft2(CoMX).*bandpass_filter +...
                                             2*pi*1i*ky.*fft2(CoMY).*bandpass_filter));

                debug_toc(app, [], "", debug_time)

                %% Sub sub function
                function [CoMX,CoMY] = integrate_CoM()
                    R_scan = rotation_matrix(app, scan_direction);
                    R_detector = rotation_matrix(app, detector_rotation);
                    
                    % Make pixel array for masks
                    [ygrid, xgrid] = meshgrid(((1:app.dataset_parameters.n_pixels(2))-transmitted_beam_center(2))*flip_scan_y, ...
                                               (1:app.dataset_parameters.n_pixels(1))-transmitted_beam_center(1));
                    
                    im_real = cast(reshape(app.masks.annular,1,[]),app.dataset_parameters.data_type)*app.data;

                    % switch between detector types (except annular/round)
                    if dmod == "CoM"
                        % Mask off diffraction pattern: less than outer angle & greater than inner angle
                        CoM = zeros(prod(app.dataset_parameters.n_frames), 2, 'like',app.data);
                        
                        % Integrated mass of each diffraction pattern within the mask:
                        % Center of Mass adjusted to the center of the diffraction pattern
                        CoM(:,1) = cast(reshape(xgrid.*app.masks.annular,1,[]), app.dataset_parameters.data_type) * app.data./im_real;
                        CoM(:,2) = cast(reshape(ygrid.*app.masks.annular,1,[]), app.dataset_parameters.data_type) * app.data./im_real;
                        
                        % Adjust for scan rotation
                        CoM = CoM*R_scan;
                    
                    elseif dmod == "DPC"
                        nrung = app.NRung.Value;
                        nseg = app.NSeg.Value;
                        rdist = ygrid.^2 + xgrid.^2;

                        % Calculate the atan of all pixel positions within the image
                        
                        % Get atan (-180, 180], then
                        % subtract off any detector rotation applied, then
                        % shift atan by half of a segment span
                        a = atan2d(ygrid,xgrid) - detector_rotation + 180/nseg;
                        
                        % shift the negative values of atan up by 360
                        a(a < 0) = a(a < 0) + 360;
                        
                        % Get total range of atan from [0,360)
                        a(a >= 360) = a(a >= 360)-360;
                        
                        % Preallocate a DPC/CoM vector image that will be
                        % the sum of all segments (i.e. DPC_A, DPC_B, ...)
                        CoM_length = zeros(nrung*nseg,2,app.dataset_parameters.data_type);
                        app.masks.Seg = zeros(nrung*nseg,prod(app.dataset_parameters.n_pixels),'logical');
                        
                        for j = 1:nrung
                            for i = 1:nseg
                                % Define vectors k1 and k2 that correspond
                                % to the inner and outer angles of the
                                % detector for a given number of rungs
                                k1 = (j-1)*(outer_radius-inner_radius)/nrung+inner_radius;
                                k2 = (j)*(outer_radius-inner_radius)/nrung+inner_radius;
                                
                                % less than outer angle & greater than/equal
                                % to inner angle & greater than the start
                                % of the segment angle & less than the end
                                % of the segment angle
                                app.masks.Seg(nseg*(j-1)+i,:) = reshape( ...
                                    rdist < k2^2 & ...
                                    rdist >= k1^2 & ...
                                    a >= (i-1)*360/nseg & ...
                                    a < (i)*360/nseg, [], 1);

                                % Find center of mass of each segment in each rung, and adjust for scan and detector rotation
                                t = (i-1)*360/nseg + detector_rotation;
                                CoM_length(nseg*(j-1)+i,:) = 2*sind(360/nseg/2) / (3*360/nseg/2) ...
                                    * (k1^3-k2^3)/(k1^2-k2^2) * [cosd(t) sind(t)] * R_scan * R_detector;
                            end
                        end

                        % integrated mass of each diffraction pattern within the mask:
                        CoM = (app.masks.Seg*app.data./im_real)'*CoM_length;
                    end

                    % Convert to mrad and pull out x and y components of the CoM
                    CoM = gather(reshape(CoM, [app.dataset_parameters.n_frames 2])*app.mradPx.Value);
                    CoMX = CoM(:,:,1);  CoMY = CoM(:,:,2);
                end

            end
        end

        % Callback function: VirtualApertureInvert, VirtualApertureMirror, 
        % ...and 21 other components
        function virtual_aperture_callbacks(app, event)
            debug_time = tic;
            transmitted_beam_center = app.center;

            switch event.Source
                case app.Mode % Entering mode
                    % Update UI limits
                    limit_virtual_aperture_UI()
                    
                    % Enable/Disable UIs
                    enable_virtual_aperture_UI()
                    
                    % Draw ROIs
                    draw_virtual_aperture_annotation()
                    
                    % Update ROI/UI
                    move_virtual_aperture(event.Source, event)
                    
                    % Enable windows
                    enable_windows(app,true)
                
                case app.VirtualApertureMirror % Mirror enable/disable
                    
                    % set state button icon color based on value
                    change_icon_background(app, app.VirtualApertureMirror)

                    % Enable/Disable UIs
                    enable_virtual_aperture_UI()
                    
                    % Draw ROIs
                    draw_virtual_aperture_annotation()
                    
                    % Update ROI/UI
                    move_virtual_aperture(event.Source, event)
                
                case app.VirtualApertureSymmetry % Symmetric option
                    % Draw ROIs
                    draw_virtual_aperture_annotation()
                    
                    % Update ROI/UI
                    move_virtual_aperture(event.Source, event)
                
                case app.DetectorCoordinateSystem % Coordinate options
                    % Update UI limits
                    limit_virtual_aperture_UI()
                    
                    % Update UI coordinate values
                    move_virtual_aperture(event.Source, event)
                
                case {app.VirtualApertureReset; app.VirtualApertureInvert; ...
                      app.VirtualApertureR; app.VirtualApertureRSpinner; ...
                      app.VirtualApertureX; app.VirtualApertureXSpinner; ...
                      app.VirtualApertureY; app.VirtualApertureYSpinner; ...
                      app.VirtualApertureMirrorRotation; app.VirtualApertureMirrorRotationSpinner; ...
                      app.VirtualApertureRotateCCW; app.VirtualApertureRotateCW; app.UpdateImages}
                    
                    % set state button icon color based on value
                    change_icon_background(app, app.VirtualApertureInvert)
                    
                    % Update ROI/UI
                    move_virtual_aperture(event.Source, event)
            end

            debug_toc(app, event, "", debug_time)


            %% Sub functions
            % Function to update UI enable/disable states
            function enable_virtual_aperture_UI()
                % Enable/Disable mirror UIs, except `app.VirtualApertureMirror`
                set(setdiff(app.VirtualApertureMirrorGrid.Children,app.VirtualApertureMirror), ...
                    "Enable", switch_on_off(app, app.VirtualApertureMirror.Value))
            end
            % Function to update UIs Limits/Labels for coordinate options
            function limit_virtual_aperture_UI()
                step_size = 0.25*app.diff_scale; 
                unit = app.DetectorCoordinateUnit.Value;

                if app.DetectorCoordinateSystem.Value == "Polar"
                    x_limits = [0, min([transmitted_beam_center app.dataset_parameters.n_pixels-transmitted_beam_center]*2^.5)*app.diff_scale];
                    y_limits = [-1 1]*180;
                    x_text = 'Distance';
                    y_text = 'Rotation';
                    y_unit = '°'; 
                    y_step = 4;
                else
                    x_limits = (app.TransBeamX.Limits-app.center_rel(1))*app.diff_scale;
                    y_limits = (app.TransBeamY.Limits-app.center_rel(2))*app.diff_scale;
                    x_text = 'X'; 
                    y_text = 'Y';
                    y_unit = unit;
                    y_step = step_size;
                end
                
                set([app.VirtualApertureX; ...
                     app.VirtualApertureXSpinner; ...
                     app.VirtualApertureY; ...
                     app.VirtualApertureYSpinner; ...
                     app.VirtualApertureRSpinner], ...
                    {"Limits"}, ...
                    {x_limits; ...
                     x_limits; ...
                     y_limits; ...
                     y_limits; ...
                     app.TransBeamR.Limits*app.diff_scale})

                set([app.VrApXLabel; ...
                     app.VrApYLabel; ...
                     app.VrApX_NFLabel; ...
                     app.VrApY_NFLabel; ...
                     app.VrApR_NFLabel], ...
                    {'Text'}, ...
                    {x_text; ...
                     y_text; ...
                     unit; ...
                     y_unit; ...
                     unit})

                set([app.VirtualApertureXSpinner; ...
                     app.VirtualApertureYSpinner; ...
                     app.VirtualApertureRSpinner], ...
                    {'Step'}, ...
                    {step_size; ...
                     y_step; ...
                     step_size})
            end

            % Function to draw virtual apertures
            function draw_virtual_aperture_annotation()
                delete(findobj(app.image_axes.Diffraction, "Tag","VrApertAnnot"))

                % plot the apertures, and only allow the first one to be
                % interactive
                app.annotations.VrAp = gobjects(app.VirtualApertureSymmetry.Value*(1+app.VirtualApertureMirror.Value), 1);

                for i = 1:numel(app.annotations.VrAp)
                    app.annotations.VrAp(i) = drawcircle(app.image_axes.Diffraction, ...
                                                         "Center", app.common_parameters.virtual_aperture_center, ...
                                                         "Radius", app.VirtualApertureR.Value, ...
                                                         "Color","r", ...
                                                         "FaceAlpha", 0, ...
                                                         "Deletable", 0, ...
                                                         "InteractionsAllowed", "none", ...
                                                         "Tag","VrApertAnnot");
                end
                
                % Make sure the draggable annotations are at the front
                bringToFront(app.annotations.VrAp(1));

                set(app.annotations.VrAp(1), ...
                    "InteractionsAllowed", "all", ...
                    "DrawingArea", "unlimited")
                
                app.annotations.VrAp(1).UserData.Move = addlistener(app.annotations.VrAp(1), "ROIMoved", @move_virtual_aperture);
                addlistener(app.annotations.VrAp(1), "MovingROI", @move_virtual_aperture);

                % Mirror line/point
                app.annotations.VrApMirL = drawline(app.image_axes.Diffraction, ...
                                                    "Position", [0 0; app.dataset_parameters.n_pixels], ...
                                                    "Color", [0 0.7 0], ...
                                                    "StripeColor", "k", ...
                                                    "LineWidth", 1.5, ...
                                                    "EdgeAlpha", 0.7, ...
                                                    "InteractionsAllowed", "none", ...
                                                    "Visible", app.VirtualApertureMirror.Value, ...
                                                    "Deletable", false, ...
                                                    "Tag","VrApertAnnot");

                app.annotations.VrApMirP = drawpoint(app.image_axes.Diffraction, ...
                                                     "Position", [min(app.dataset_parameters.n_pixels)/4 0] + transmitted_beam_center, ...
                                                     "Color", "g", ...
                                                     "MarkerSize", 8, ...
                                                     "Visible", app.VirtualApertureMirror.Value, ...
                                                     "Deletable", 0, ...
                                                     "Tag", "VrApertAnnot");

                app.annotations.VrApMirP.UserData.Move = addlistener(app.annotations.VrApMirP, "ROIMoved", @move_virtual_aperture);
                addlistener(app.annotations.VrApMirP, "MovingROI", @move_virtual_aperture);
            end

            % live update function for virtual aperture
            function move_virtual_aperture(source, event)
                % To determine real-time values
                transmitted_beam_center = app.center_rel;
                scale = app.diff_scale;
                num_pix = app.dataset_parameters.n_pixels;
                mirror_angle = app.VirtualApertureMirrorRotation.Value;
                radius = app.VirtualApertureR.Value;
                virtual_aperture_center = app.common_parameters.virtual_aperture_center;
                
                switch source
                    case app.annotations.VrAp(1)
                        radius = source.Radius;
                        virtual_aperture_center = source.Center;

                    case {app.VirtualApertureX app.VirtualApertureXSpinner app.VirtualApertureY app.VirtualApertureYSpinner}
                        % Potentially converting from polar/relative/mrad to Cartesian absolute pixel coordinates
                        x = app.VirtualApertureX.Value;
                        y = app.VirtualApertureY.Value;
                        
                        if ismember(source, [app.VirtualApertureX app.VirtualApertureXSpinner])
                            x = event.Value;
                        else
                            y = event.Value;
                        end

                        if app.DetectorCoordinateSystem.Value == "Cartesian"
                            virtual_aperture_center = [x y]/scale + transmitted_beam_center;
                        else
                            [x,y] = pol2cart(deg2rad(y), x/scale);
                            virtual_aperture_center = app.center+[x y];
                        end

                    case app.VirtualApertureR
                        radius = event.Value;
                    
                    case app.VirtualApertureRSpinner
                        radius = event.Value/scale;
                    
                    case app.VirtualApertureReset
                        radius = app.TransBeamR.Value;
                        virtual_aperture_center = transmitted_beam_center;
                    
                    case {app.VirtualApertureRotateCCW app.VirtualApertureRotateCW} % Rotation buttons
                        % Clockwise if odd numbers of "Reverse X"
                        % "Reverse Y" "Rotate Clockwise" are true
                        rot = app.VirtualApertureRotationStep.Value * (1-2*rem(app.ReverseDiffractionX.Value+app.ReverseDiffractionY.Value+(source == app.VirtualApertureRotateCW), 2));
                        virtual_aperture_center = (virtual_aperture_center-transmitted_beam_center)*rotation_matrix(app, rot)+transmitted_beam_center;
                    
                    case {app.VirtualApertureMirrorRotation app.VirtualApertureMirrorRotationSpinner}
                        % update the mirror line position
                        mirror_angle = event.Value;
                    
                    case app.annotations.VrApMirP
                        mirror_position = source.Position - transmitted_beam_center;
                        mirror_angle = atan2d(mirror_position(2), mirror_position(1));
                end
                
                % Restrict aperture location/size
                radius = min(radius, app.common_parameters.max_radius);
                virtual_aperture_center(virtual_aperture_center > num_pix) = num_pix(virtual_aperture_center > num_pix);
                virtual_aperture_center(virtual_aperture_center < 1) = 1;
                app.common_parameters.virtual_aperture_center = virtual_aperture_center;

                % Update GUI with Limits check
                xy_limits = [app.VirtualApertureX.Limits; app.VirtualApertureY.Limits]';
                
                if app.DetectorCoordinateSystem.Value == "Cartesian"
                    xyui = (virtual_aperture_center - transmitted_beam_center)*scale;
                else % "Polar"
                    xyui = virtual_aperture_center - transmitted_beam_center;
                    [theta, rho] = cart2pol(xyui(1), xyui(2));
                    xyui = [rho*scale, rad2deg(theta)];
                end
                
                % Limit check
                xyui = clip(xyui, xy_limits(1,:), xy_limits(2,:));

                set_external_source(app, event, ...
                                    [app.VirtualApertureR; ...
                                     app.VirtualApertureRSpinner; ...
                                     app.VirtualApertureX; ...
                                     app.VirtualApertureXSpinner; ...
                                     app.VirtualApertureY; ...
                                     app.VirtualApertureYSpinner], ...
                                    {"Value"}, ...
                                    {radius; ...
                                     min(radius*scale, app.common_parameters.max_radius*scale); ...
                                     xyui(1); ...
                                     xyui(1); ...
                                     xyui(2); ...
                                     xyui(2)})

                set_external_source(app, event, ...
                                    [app.VirtualApertureMirrorRotation; ...
                                     app.VirtualApertureMirrorRotationSpinner], ...
                                    "Value", mirror_angle)

                % Early return if only updating for coordinate options
                if source == app.DetectorCoordinateSystem
                    return;
                end

                % Update ROIs
                [theta, rho] = cart2pol(virtual_aperture_center(1) - transmitted_beam_center(1), ...
                                        virtual_aperture_center(2) - transmitted_beam_center(2));

                angles = rad2deg(theta) + (0 : 360/app.VirtualApertureSymmetry.Value : 360*(1-1/app.VirtualApertureSymmetry.Value))';
                virtual_aperture_center_new = rho*[cosd(angles) sind(angles)];

                % If mirrored, double the amount of center positions
                if app.VirtualApertureMirror.Value
                    R_mirror = rotation_matrix(app, 90 - mirror_angle);
                    virtual_aperture_center_new = [virtual_aperture_center_new; virtual_aperture_center_new*R_mirror.*[-1 1]*(R_mirror')];
                end

                % Relative -> Absolute
                virtual_aperture_center_new = virtual_aperture_center_new + transmitted_beam_center;
                set(app.annotations.VrAp, ...
                    {"Center"}, num2cell(virtual_aperture_center_new,2), ...
                    'Radius', radius)
                
                % Update mirror line ROIs, whether visible or not, deal
                % with any Infs by clipping to [-1e9, 1e9]
                pos = [[1;app.dataset_parameters.n_pixels(1)], tand(mirror_angle)*([1;app.dataset_parameters.n_pixels(1)] - transmitted_beam_center(1)) + transmitted_beam_center(2)];
                pos = clip(pos,-1e9,1e9);
                app.annotations.VrApMirL.Position = pos;

                [x,y] = pol2cart(deg2rad(mirror_angle), sqrt(sum((app.annotations.VrApMirP.Position - transmitted_beam_center).^2)));
                
                % Restrict point within plot
                xy_point = [x y] + transmitted_beam_center;
                xy_point(xy_point > num_pix) = num_pix(xy_point > num_pix);
                xy_point(xy_point < 1) = 1;
                set_external_source(app, event, app.annotations.VrApMirP, "Position", xy_point)

                % Whether to update images
                if to_update_image(app, event)
                    % Generate mask
                    mask_vr = false(app.dataset_parameters.n_pixels);
                    for i = 1:numel(app.annotations.VrAp)
                        mask_vr = mask_vr | createMask(app.annotations.VrAp(i), app.image_displays.Diffraction)';
                    end

                    % Invert mask?
                    if app.VirtualApertureInvert.Value
                        mask_vr = 1 - mask_vr;
                    end

                    app.images.DiffractionMask = mask_vr;
                    app.images.Real = gather(reshape(reshape(mask_vr,1,[])*app.data, app.dataset_parameters.n_frames));
                    plot_all_patterns(app, event)
                end
            end
        end

        % Callback function: CDDelResetMenu, CustomDetectorColor, 
        % ...and 22 other components
        function custom_detector_callbacks(app, event)
            % Central beam location
            b0 = app.center;

            % Current selected row in Main Table `sel`, and the ROI group `ROI`
            if app.CustomDetectorTable.Selection > height(app.CustomDetectorTable.Data)
                app.CustomDetectorTable.Selection = [];
            end
            
            % set state button icon color based on value
            for state_button = [app.CustomDetectorInvert, ...
                                app.CustomDetectorMirror, ...
                                app.CustomDetectorFlipHorizontal, ...
                                app.CustomDetectorFlipVertical, ...
                                app.CustomDetectorTranspose]
                change_icon_background(app, state_button)
            end

            sel = app.CustomDetectorTable.Data(app.CustomDetectorTable.Selection, :); 
            ROI = {[]};
            
            if ~isempty(sel)
                ROI = app.annotations.Custom.(sel.ID);
            end

            switch event.Source
                case app.DetectorCoordinateSystem % Coordinate options
                    % Update UI coordinate systems
                    limit_custom_detector_UI()
                    
                    % Update ROI/mask/images
                    move_custom_ROI(ROI{1}, event);

                case {app.UpdateImages app.CustomDetectorInterMask app.Evaluate}
                    % Update ROI/mask/images
                    move_custom_ROI(ROI{1}, event);

                case app.Mode % Entering current mode
                    if ~isfield(app.annotations,'Custom')
                        app.annotations.Custom = [];
                    end

                    % Update UI coordinate systems
                    limit_custom_detector_UI()
                    
                    % Update Enable/Show states of all ROI groups
                    update_enable_show_ROI()
                    
                    % Update for selection change
                    if ~isempty(ROI)
                        mock_UI_callbacks(app,app.CustomDetectorTable)
                    end
                    
                    % Enable windows
                    enable_windows(app,true)

                case app.CustomDetectorTable % Main table
                    % Update Enable/Show states of all ROI groups
                    if event.EventName == "CellEdit"
                        update_enable_show_ROI()
                    end
                    
                    % Update UI and images
                    update_custom_detector_UI();
                    %if ~isempty(ROI{1})
                    move_custom_ROI(ROI{1}, event);
                    %end

                case app.CustomDetectorDeleteMask % Delete selected mask
                    % Early return if no mask selected
                    if isempty(sel)
                        return;
                    end

                    % Delete Annotations, remove field from app.A.Custom and any mask associated with the detector
                    delete(findobj(app.image_axes.Diffraction.Children, "Tag", "Custom " + sel.ID));
                    app.annotations.Custom.(sel.ID) = [];
                    app.annotations.Custom = rmfield(app.annotations.Custom,sel.ID);
                    app.masks.Custom.(sel.ID) = [];
                    app.masks.Custom = rmfield(app.masks.Custom,sel.ID);
                    app.images.Custom.(sel.ID) = [];
                    app.images.Custom = rmfield(app.images.Custom,sel.ID);
                    
                    % Remove item from main table
                    app.CustomDetectorTable.Data(app.CustomDetectorTable.Selection,:) = [];
                    
                    % Update selection
                    if isempty(app.CustomDetectorTable.Data)
                        % No selection if empty
                        app.CustomDetectorTable.Selection = [];
                        ROI = {[]};

                    elseif isempty(app.CustomDetectorTable.Selection)
                        app.CustomDetectorTable.Selection = height(app.CustomDetectorTable.Data);
                        ROI = app.annotations.Custom.(app.CustomDetectorTable.Data.ID(app.CustomDetectorTable.Selection));
                    
                    else
                        % Select last if exceeds table
                        app.CustomDetectorTable.Selection = min(app.CustomDetectorTable.Selection,height(app.CustomDetectorTable.Data));
                        ROI = app.annotations.Custom.(app.CustomDetectorTable.Data.ID(app.CustomDetectorTable.Selection));
                    end

                    % Update for selection change
                    mock_UI_callbacks(app,app.CustomDetectorTable)

                case app.CDDelResetMenu
                    % Remove all masks; Reset
                    delete(findobj(app.image_axes.Diffraction.Children,'-regexp', "Tag", "Custom "));
                    app.CustomDetectorTable.Data(:,:) = [];
                    app.annotations.Custom = [];
                    app.masks.Custom = [];
                    app.images.Custom = [];
                    ROI = {[]};
                    app.CustomDetectorTable.Selection = [];
                    
                    % Update for selection change
                    mock_UI_callbacks(app,app.CustomDetectorTable)

                case {app.CustomDetectorNewCircle; % Create new mask
                      app.CustomDetectorNewGrid;
                      app.CustomDetectorNewGridNoCenter;
                      app.CustomDetectorNewBandpass;
                      app.CustomDetectorNewWedge;
                      app.CustomDetectorNewPolygon;
                      app.CustomDetectorNewFromFile;
                      app.CustomDetectorNewMath}
                    % Character before 'A'
                    idl = "@";

                    % Last ID characters
                    if ~isempty(app.CustomDetectorTable.Data)
                        idl = app.CustomDetectorTable.Data(end,:).ID;
                    end 
                    
                    % warn on maximum number of custom detectors
                    if idl == "ZZ"
                        notification_dialog(app, "warn", "Mask ID can not go beyond 'ZZ'!", "Maximum Custom Detectors Capacity Reached");
                        return
                    end

                    % Serial numbers of Last ID characters, e.g. 3 for "C" or [1 2] for "AB"
                    nidl = double(char(idl))-64;
                    
                    % Convert to one single serial number for Last ID
                    nidl = (numel(nidl)-1)*nidl(1)*26 + nidl(end);
                    
                    % New ID, e.g. 'AA' for 26, "b" for 1
                    id = replace(char([floor(nidl/26), rem(nidl, 26)+1]+64),'@',"");
                    
                    % Preset values
                    app.CustomDetectorMirror.Value = 0;
                    app.CustomDetectorInvert.Value = 0;
                    app.CustomDetectorIntraMask.Value = "Union";

                    switch event.Source
                        case app.CustomDetectorNewCircle
                            roi_type = "Circle";

                        case app.CustomDetectorNewGrid
                            roi_type = "Grid";

                        case app.CustomDetectorNewGridNoCenter
                            roi_type = "GridNoCenter";
                        
                        case app.CustomDetectorNewBandpass
                            roi_type = "Bandpass";
                        
                        case app.CustomDetectorNewWedge
                            roi_type = "Wedge";
                            app.CustomDetectorMirror.Value = 1;
                        
                        case app.CustomDetectorNewPolygon
                            roi_type = "Polygon";
                        
                        case app.CustomDetectorNewFromFile, roi_type = "File";
                            mask = import_mask(app, "Diffraction");
                            % Early return if `[]` (failed/canceled)
                            if isempty(mask)
                                return
                            end
                            app.masks.Custom.(id) = mask;
                        
                        case app.CustomDetectorNewMath
                            roi_type = "Math";
                    end

                    ROI = drawCDROI(id, roi_type);
                    
                    % Early return if draw ROI failed, e.g. polygon canceled.
                    if isempty(ROI)
                        return;
                    end
                    
                    % Store the ROI group
                    app.annotations.Custom.(id) = ROI;
                    
                    % Add row to Main Table
                    app.CustomDetectorTable.Data(end + 1,:) = {id, roi_type, id, true, true, 1};
                    
                    % Select the last, just added row
                    app.CustomDetectorTable.Selection = height(app.CustomDetectorTable.Data);
                    
                    % Color the Label in Main Table
                    color_custom_detector_table_label(app.CustomDetectorTable.Selection,app.annotations.Custom.(id){1}.UserData.Color);
                    
                    % Update for selection change
                    mock_UI_callbacks(app,app.CustomDetectorTable)

                case {app.CustomDetectorIntraMask, app.CustomDetectorInvert, app.CustomDetectorMirror} % Intra-mask, Invert Mask, Mirror Mask
                    % Update values in `UserData` for current mask
                    ROI{1}.UserData.IntraMask = app.CustomDetectorIntraMask.Value;
                    ROI{1}.UserData.Inverted = app.CustomDetectorInvert.Value;
                    ROI{1}.UserData.Mirrored = app.CustomDetectorMirror.Value;
                    ROI = drawCDROI(sel.ID, sel.Type);
                    
                    % Re-drawn ROI
                    app.annotations.Custom.(sel.ID) = ROI;
                    
                    % Update for selection change
                    mock_UI_callbacks(app,app.CustomDetectorTable)

                case app.CustomDetectorColor  % Change the color of mask annotations
                    % Open color selection palette
                    color = uisetcolor(ROI{1}.UserData.Color);
                    
                    % If color unchanged
                    if isequal(color, ROI{1}.UserData.Color)
                        return;
                    end
                    ROI{1}.UserData.Color = color;

                    % make sure all overlays change color with main overlay
                    cellfun(@(r) set(findobj(r,"-property", "Color"), "Color", color),ROI);
                    color_custom_detector_table_label(app.CustomDetectorTable.Selection, color);
                    
                    % Update UI
                    update_custom_detector_UI()
                    
                    % changing mask color in "Color Mix" mode requires images to be re-plotted to match
                    if strcmp(app.CustomDetectorInterMask.Value, "Color Mix")
                        plot_custom_mask(event, "")
                    end

                case {app.CustomDetectorFlipHorizontal app.CustomDetectorFlipVertical app.CustomDetectorTranspose} % Flip and Transpose
                    % Whether diffraction is rotated by +/-90°
                    r90 = mod(app.RotateDiffraction.Value,180) == 90;

                    if sel.Type == "File"
                        % Transform the imported image array, then update/create mask from the transformed image
                        switch event.Source
                            case {app.CustomDetectorFlipHorizontal app.CustomDetectorFlipVertical}
                                % flip custom mask across horizontal/vertical axis
                                if (r90 && event.Source == app.CustomDetectorFlipHorizontal) || (~r90 && event.Source == app.CustomDetectorFlipVertical)
                                    % Visually Flip X
                                    app.masks.Custom.(sel.ID) = flipud(app.masks.Custom.(sel.ID));
                                else
                                    % Visually Flip Y
                                    app.masks.Custom.(sel.ID) = fliplr(app.masks.Custom.(sel.ID));
                                end

                            case app.CustomDetectorTranspose
                                % transpose custom mask (only available if diffraction pattern is square)
                                app.masks.Custom.(sel.ID) = app.masks.Custom.(sel.ID)';
                        end

                        % Update Images
                        move_custom_ROI(ROI{1}, event);

                    else
                        % Convert Detail Table positions to Cartesian relative (pixel or mrad)
                        details_table = app.CustomDetectorDetailsTable.Data(:, 1:2);
                        if app.DetectorCoordinateSystem.Value == "Cartesian"
                            xycr = details_table - (app.center-app.center_rel)*app.diff_scale;
                        else
                            xycr = details_table(:, 1).*[cosd(details_table(:, 2)) sind(details_table(:, 2))];
                        end

                        switch event.Source
                            case {app.CustomDetectorFlipHorizontal app.CustomDetectorFlipVertical}
                                % flip custom mask across horizontal/vertical axis
                                if (r90 && event.Source == app.CustomDetectorFlipHorizontal) || (~r90 && event.Source == app.CustomDetectorFlipVertical)
                                    % Flip X
                                    xycr(:,1) = -xycr(:,1);
                                else
                                    % Flip Y
                                    xycr(:,2) = -xycr(:,2);
                                end
                                
                            case app.CustomDetectorTranspose % transpose custom mask
                                % Swap X/Y
                                xycr = fliplr(xycr);
                        end

                        % Convert from Cartesian relative (pixel or mrad) back to whatever it should be
                        if app.DetectorCoordinateSystem.Value == "Cartesian"
                            app.CustomDetectorDetailsTable.Data(:, 1:2) = xycr + (app.center-app.center_rel)*app.diff_scale;
                        else
                            app.CustomDetectorDetailsTable.Data(:, 1:2) = [sqrt(sum(xycr.^2,2)), atan2d(xycr(:,2), xycr(:,1))];
                        end
                        
                        % Trigger Detail Table editing callbacks
                        mock_UI_callbacks(app, app.CustomDetectorDetailsTable)
                    end

                case {app.CustomDetectorRotateCCW, app.CustomDetectorRotateCW} % Rotate detector
                    % Clockwise if odd numbers of "Reverse X" "Reverse Y" "Rotate Clockwise" are true
                    rot = app.CustomDetectorRotationStep.Value*(1-2*rem(app.ReverseDiffractionX.Value+app.ReverseDiffractionY.Value+(event.Source == app.CustomDetectorRotateCW),2));
                    
                    if sel.Type == "File"
                        % Transform the imported image array, then update/create mask from the transformed image
                        app.masks.Custom.(sel.ID) = rot90(app.masks.Custom.(sel.ID), rot/90);
                        
                        % Update Images
                        move_custom_ROI(ROI{1}, event);
                    
                    else
                        % Edit coordinates in `app.CustomDetectorDetailsTable` and trigger its callbacks to rotate ROIs
                        if app.DetectorCoordinateSystem.Value == "Cartesian"
                            % If absolute then convert to relative then convert back, before/after rotation
                            R = rotation_matrix(app, rot);
                            rrb0 = (app.center-app.center_rel)*app.diff_scale;
                            app.CustomDetectorDetailsTable.Data(:, 1:2) = ((app.CustomDetectorDetailsTable.Data(:, 1:2)-rrb0)*R + rrb0);
                        else
                            app.CustomDetectorDetailsTable.Data(:, 2) = mod(app.CustomDetectorDetailsTable.Data(:, 2)+rot, 360);
                        end
                        
                        % Trigger Detail Table editing callbacks
                        mock_UI_callbacks(app, app.CustomDetectorDetailsTable)
                    end

                case app.CustomDetectorDetailsTable % Detail Table
                    % Determine Cartesian absolute pixel position from
                    % Detail Table (may be polar/relative/mrad)
                    details_table = app.CustomDetectorDetailsTable.Data;

                    if app.DetectorCoordinateSystem.Value == "Cartesian"
                        p = details_table(:, 1:2)/app.diff_scale + app.center_rel;
                    else
                        p = details_table(:, 1)/app.diff_scale.*[cosd(details_table(:, 2)) sind(details_table(:, 2))] + app.center_rel;
                    end

                    % Sync ROIs positions with the edited Detail Table
                    switch sel.Type
                        case {"Circle", "Grid", "GridNoCenter"}
                            r = details_table(1, 3);
                            % If edited the 2nd R ([2 3] in table) then use
                            % that, instead of the default 1st R
                            if isprop(event, "Indices") && isequal(event.Indices, [2 3])
                                r = str2double(event.EditData);
                            end
                            
                            ROI{1}.Center = p(1, :);
                            ROI{1}.Radius = r/app.diff_scale;

                            if height(p) > 1
                                ROI{2}.Center = p(2, :);
                                ROI{2}.Radius = r/app.diff_scale;
                            end

                        case "Bandpass"
                            color = p(1, :);
                            % If edited the 2nd X or Y ([2 1] or [2 2] in
                            % table), then use that value, instead of the
                            % default 1st X/Y
                            if isprop(event, "Indices") && event.Indices(1) == 2 && any(event.Indices(2) == [1 2])
                                color = p(2, :);
                            end

                            ROI{1}.Center = color; 
                            ROI{1}.Radius = details_table(1, 3)/app.diff_scale;
                            ROI{2}.Center = color;
                            ROI{2}.Radius = details_table(2, 3)/app.diff_scale;

                        case "Wedge"
                            ROI{1}.Position = p(1, :);
                            ROI{2}.Position = p(2, :);

                        case "Polygon"
                            ROI{1}.Position = p;
                    end

                    % Update Images
                    move_custom_ROI(ROI{1}, event);
                
                otherwise
                    error("Undefined")

            end


            %%% Sub functions
            % Function to return font color (black or white) based on input color
            function color = font_color(c)
                % Dark color criterion
                color = [0 0 0] + (c.^2*[0.299;0.587;0.114] < 0.25);
            end

            % Function to color Main Table
            function color_custom_detector_table_label(selection, color)
                addStyle(app.CustomDetectorTable, ...
                         uistyle("BackgroundColor",color,'FontColor',font_color(color)), ...
                         'cell',[selection, 3])
            end

            % Function to update UI labels; actually Custom Detector UIs have no limits
            function limit_custom_detector_UI()
                unit = " " + app.DetectorCoordinateUnit.Value;
                
                if app.DetectorCoordinateSystem.Value == "Polar"
                    app.CustomDetectorDetailsTable.ColumnName(1:3) = {"ρ"+unit, "θ °", "R"+unit};
                else
                    app.CustomDetectorDetailsTable.ColumnName(1:3) = {"X"+unit, "Y"+unit, "R"+unit};
                end
            end

            % Function to update enable/show state of ROIs based on Main Table
            function update_enable_show_ROI()
                for i = 1:height(app.CustomDetectorTable.Data)
                    ri = app.CustomDetectorTable.Data(i, :);
                    cellfun(@(r) set(findobj(r,'-property',"Visible"),"Visible", ri.Show && ri.Enable), ...
                        app.annotations.Custom.(ri.ID));
                end
            end

            % Function to update UI
            function update_custom_detector_UI()
                % Try to resize columns
                app.CustomDetectorDetailsTable.ColumnWidth = {"1x","1x","1x"};
                app.CustomDetectorTable.ColumnWidth = {"fit", "fit", "1x", "fit", "fit", "fit"};

                if ~isempty(app.CustomDetectorTable.Data)
                    app.CustomDetectorTable.Data(end + 1,:) = app.CustomDetectorTable.Data(end,:);
                    app.CustomDetectorTable.Data(end,:)=[];
                end
                
                % Disable Detail UI if no ROI selected
                app.CustomDetectorDetailsPanel.Enable = switch_on_off(app, ~isempty(app.CustomDetectorTable.Selection));

                if isempty(app.CustomDetectorTable.Selection)
                    app.CustomDetectorDetailsTable.Data = [];
                    set([app.CustomDetectorDetailsGrid app.CustomDetectorRotationGrid], "BackgroundColor","w");
                else
                    % Set component options based on current mask item
                    user_data = app.annotations.Custom.(app.CustomDetectorTable.Data.ID(app.CustomDetectorTable.Selection)){1}.UserData;
                    set([app.CustomDetectorInvert; ...
                         app.CustomDetectorIntraMask; ...
                         app.CustomDetectorMirror], ...
                        {"Value"}, ...
                        {user_data.Inverted; ...
                         user_data.IntraMask; ...
                         user_data.Mirrored})
                    
                    % Set Detail UI color; white font if darker background color
                    set([app.CustomDetectorDetailsGrid; ...
                         app.CustomDetectorRotationGrid; ...
                         app.CustomDetectorDetailsTable], ...
                        "BackgroundColor",user_data.Color)

                    app.CDIntraCombLabel.FontColor = font_color(user_data.Color);
                    app.CustomDetectorDetailsTable.ForegroundColor = font_color(user_data.Color);
                    
                    % Enable/Disable UI
                    set([app.CustomDetectorMirror; ...
                         app.CustomDetectorIntraMask; ...
                         app.CustomDetectorRotationStep; ...
                         app.CustomDetectorTranspose], ...
                        {"Enable"},{0;0;1;1})
                    
                    equ = "";
                    
                    switch user_data.Type
                        case "Circle"
                            app.CustomDetectorMirror.Enable = "on";
                            app.CustomDetectorIntraMask.Enable = app.CustomDetectorMirror.Value;
                        
                        case "Wedge"
                            app.CustomDetectorMirror.Enable = "on";
                        
                        case {"Grid","GridNoCenter"}
                            app.CustomDetectorIntraMask.Enable = "on";
                        
                        case "Bandpass"
                        
                        case "Polygon"
                        
                        case "File"
                            % Allow transpose/rotate 90° if diffraction
                            % image is square
                            app.CustomDetectorTranspose.Enable = ~range(app.dataset_parameters.n_pixels);
                            set(app.CustomDetectorRotationStep, ...
                                "Value", 180 - ~range(app.dataset_parameters.n_pixels)*90, ...
                                "Enable", "off");
                        
                        case "Math"
                            equ = user_data.Math;
                    end
                    
                    % Put formula on Math UI
                    app.MathFormula.Value = equ;
                end
            end

            % Function to draw annotations
            function ROI = drawCDROI(id, roi_type)
                % parameters
                r0 = app.TransBeamR.Value;
                xmin = 0;
                xmax = app.dataset_parameters.n_pixels(1)+1;
                ymin = 0;
                ymax = app.dataset_parameters.n_pixels(2)+1;

                % Initial values
                p1 = [];
                r1 = [];
                p2 = [];
                r2 = [];
                user_data = struct("ID", id, ...
                                   "Type", roi_type, ...
                                   "Color", rand(1,3), ...
                                   "Mirrored", app.CustomDetectorMirror.Value, ...
                                   "IntraMask", app.CustomDetectorIntraMask.Value, ...
                                   "Inverted", app.CustomDetectorInvert.Value);

                % If the ROIs already exist, get their current color/position/radius & UserData
                if isfield(app.annotations.Custom, id)
                    ROI = app.annotations.Custom.(id);
                    user_data = ROI{1}.UserData;

                    if isprop(ROI{1}, "Position")
                        p1 = ROI{1}.Position;
                    end
                    
                    if isprop(ROI{1}, "Center")
                        p1 = ROI{1}.Center;
                        r1 = ROI{1}.Radius;
                    end

                    if isprop(ROI{2}, "Position")
                        p2 = ROI{2}.Position;
                    end
                    
                    if isprop(ROI{2}, "Center")
                        p2 = ROI{2}.Center;
                        r2 = ROI{2}.Radius;
                    end
                    
                    % Remove old ROIs
                    delete(ROI{1});
                    delete(ROI{2});
                    delete(ROI{3});
                end

                % Draw ROIs
                ROI = cell(3,1);
                switch roi_type
                    case "Circle"
                        % Initial disk identical to the central beam
                        if isempty(p1)
                            p1 = b0;
                            r1 = r0;
                        end
                        
                        % draw main circle
                        ROI{1} = drawcircle(app.image_axes.Diffraction, ...
                                            "Center", p1, ...
                                            "Radius", r1);

                        % draw mirrored circle if enabled by user
                        if user_data.Mirrored
                            ROI{2} = drawcircle(app.image_axes.Diffraction, ...
                                                "Center", -p1+2*b0, ...
                                                "Radius", r1, ...
                                                "InteractionsAllowed", "none");
                        end

                        % set parameters
                        set([ROI{1} ROI{2}], ...
                            "FaceAlpha", 0, ...
                            "FaceSelectable", 0, ...
                            "DrawingArea", "unlimited");
                    
                    case {"Grid","GridNoCenter"}
                        % draw pair of apertures with starting size of transmitted disk
                        if isempty(p2)
                            p1 = b0 + [xmax-xmin 0]/4;
                            p2 = b0 + [0 ymax-ymin]/4;
                            r1 = r0;
                        end

                        % draw two circles for basis vectors
                        ROI{1} = drawcircle(app.image_axes.Diffraction, ...
                                            "Center", p1, ...
                                            "Radius", r1);

                        ROI{2} = drawcircle(app.image_axes.Diffraction, ...
                                            "Center", p2, ...
                                            "Radius", r1);

                        % set parameters
                        set([ROI{1} ROI{2}], ...
                            "FaceAlpha", 0, ...
                            "FaceSelectable", 0, ...
                            "DrawingArea", "unlimited")
                    
                    case "Bandpass"
                        % draw two circles for the user to resize, centered on the optic axis
                        if isempty(r2)
                            r1 = r0;
                            r2 = (xmax-xmin)/4 + r0/2;
                        end

                        % draw inner and outer circles of the band-pass
                        ROI{1} = drawcircle(app.image_axes.Diffraction, ...
                                            "Center", b0, ...
                                            "Radius",r1);

                        ROI{2} = drawcircle(app.image_axes.Diffraction, ...
                                            "Center", b0, ...
                                            "Radius", r2);

                        % set parameters
                        set([ROI{1} ROI{2}], ...
                            "FaceAlpha", 0, ...
                            "DrawingArea", "unlimited", ...
                            "FaceSelectable", false);

                        % draw a filled polygon between the two circles
                        % that the user cannot interact with, just for
                        % reference
                        ROI{3} = drawpolygon(app.image_axes.Diffraction, ...
                                             "Position", [ROI{1}.Vertices;ROI{2}.Vertices], ...
                                             "InteractionsAllowed", "none", ...
                                             "EdgeAlpha", 0, ...
                                             "FaceSelectable", false);
                    
                    case "Wedge"
                        % give polygon temporary set of points, keep
                        % invisible. Points will be sorted in move_wedge()
                        if isempty(p2)
                            l = min(app.dataset_parameters.n_pixels)/3;
                            p1 = [l l];
                            p2 = [l 2*l];
                        end

                        % draw polygon wedge and a pair of points for user
                        % interaction
                        ROI{3} = drawpolygon(app.image_axes.Diffraction, ...
                                             "Position", [p1;p2;b0], ...
                                             "InteractionsAllowed", "none");
                        ROI{1} = drawpoint(app.image_axes.Diffraction, "Position",p1);
                        ROI{2} = drawpoint(app.image_axes.Diffraction, "Position",p2);
                    
                    case "Polygon"
                        if isempty(p1)
                            notification_dialog(app, ...
                                                "help", ...
                                                sprintf("Please draw a Polygon ROI on the Diffraction image!\n\n" ...
                                                        + "Press 'Esc' to cancel during drawing. Please refer to MATLAB's 'drawpolygon' for more tips."), ...
                                                "Draw Polygon ROI");
                            
                            % Disable UIs until polygon is drawn
                            enable_windows(app,false);
                            figure(app.figures.Diffraction);
                            app.figures.Diffraction.UserData.grayout.Visible = "off";
                            
                            % draw polygon and re-enable UIs
                            ROI{1} = drawpolygon(app.image_axes.Diffraction, ...
                                                 "Color", user_data.Color);
                            p1 = ROI{1}.Position;
                            enable_windows(app,true)
                            
                            % Early return if polygon is not drawn
                            if isempty(p1)
                                delete(ROI{1});
                                ROI = [];
                                return;
                            end

                        else
                            ROI{1} = drawpolygon(app.image_axes.Diffraction, ...
                                                 "Position",p1);
                        end

                        ROI{1}.FaceSelectable = false;
                        ROI{2} = drawpolygon(app.image_axes.Diffraction, ...
                                             "Position", p1, ...
                                             "EdgeAlpha", 0, ...
                                             "InteractionsAllowed", "none");
                    
                    case "File"
                        % Invisible ROI as placeholder
                        ROI{1} = drawrectangle(app.image_axes.Diffraction, ...
                                               "Position", [0 0 xmax+1 ymax+1], ...
                                               "InteractionsAllowed", "none", ...
                                               "EdgeAlpha", 0, ...
                                               "FaceAlpha",0);
                    
                    case "Math"
                        % Invisible ROI as placeholder
                        ROI{1} = drawrectangle(app.image_axes.Diffraction, ...
                                               "Position", [0 0 xmax+1 ymax+1], ...
                                               "InteractionsAllowed", "none", ...
                                               "EdgeAlpha", 0, ...
                                               "FaceAlpha",0);

                        % set defaults
                        app.MathFormula.Value = "1";
                        user_data.Math = "1";
                        
                        % Show and select Math Tab
                        figure(app.figures.Settings);
                        app.SettingsTabGroup.SelectedTab = app.MathTab;
                end
                
                % Try to set common properties for all ROI objects
                for i = 1:numel(ROI)
                    if ~any(ishandle(ROI{i}))
                        continue;
                    end

                    % set parameters
                    set(ROI{i}, ...
                        "Color", user_data.Color, ...
                        "Deletable", false, ...
                        "Tag", "Custom "+id, ...
                        "UserData", user_data)
                    
                    % add listeners
                    ROI{i}.UserData.Move = addlistener(ROI{i}, "ROIMoved", @move_custom_ROI);
                    addlistener(ROI{i}, "MovingROI", @move_custom_ROI);
                    addlistener(ROI{i},'ROIClicked', @move_custom_ROI);
                end
            end

            % Function for UI/ROI changes
            function move_custom_ROI(source, event)
                debug_time = tic;
                
                % Clear diffraction mask and real images if empty source; early return
                if isempty(source)
                    plot_custom_mask(event, []);
                    return;
                end

                % Retrieve ROIs of the current interacted-with
                ROI = app.annotations.Custom.(replace(source.Tag, "Custom ",""));
                user_data = ROI{1}.UserData;

                % Change selected mask based on the one the user is interacting with
                if app.CustomDetectorTable.Data.ID(app.CustomDetectorTable.Selection) ~= user_data.ID
                    app.CustomDetectorTable.Selection = find(app.CustomDetectorTable.Data.ID == user_data.ID);
                    update_custom_detector_UI();
                end

                % Get position etc from ROI
                p1 = [];
                r1 = [];
                p2 = [];
                r2 = [];
                p1t = [];
                p2t = [];

                if isprop(ROI{1}, "Position")
                    p1 = ROI{1}.Position;
                end

                if isprop(ROI{1}, "Radius")
                    p1 = ROI{1}.Center;
                    r1 = ROI{1}.Radius;
                end

                % Types with significant 2nd ROI
                if ismember(user_data.Type, ["Grid"; "GridNoCenter"; "Bandpass"; "Wedge"])
                    if isprop(ROI{2}, "Position")
                        p2 = ROI{2}.Position;
                    end

                    if isprop(ROI{2}, "Radius")
                        p2 = ROI{2}.Center;
                        r2 = ROI{2}.Radius;
                    end
                end

                % Update Detail Table with coordinate options, with maybe reduced calculation policy
                if is_static_event(app, event) || app.CalculationPolicy.Value == 2
                    if ~isempty(p1)
                        p1t = p1(:, 1:2);
                    end

                    if ~isempty(p2)
                        p2t = p2(:, 1:2);
                    end

                    xyt = ([p1t; p2t] - app.center_rel)*app.diff_scale;

                    if app.DetectorCoordinateSystem.Value == "Polar"
                        xyt = [sqrt(sum(xyt.^2, 2)), mod(atan2d(xyt(:, 2), xyt(:, 1)), 360)];
                    end

                    app.CustomDetectorDetailsTable.Data = [xyt, [r1;r2]*app.diff_scale];
                end

                % Diffraction image size limits
                xmin = 0;
                xmax = app.dataset_parameters.n_pixels(1)+1;
                ymin = 0;
                ymax = app.dataset_parameters.n_pixels(2)+1;

                switch user_data.Type
                    case "Circle"
                        % Make mask from current detector objects
                        mask = createMask(ROI{1}, app.image_displays.Diffraction)';

                        if user_data.Mirrored
                            % update circle{2} location
                            ROI{2}.Center = 2*b0 - p1;
                            ROI{2}.Radius = r1;

                            % Mask of mirrored circle
                            mask2 = createMask(ROI{2}, app.image_displays.Diffraction)';

                            switch user_data.IntraMask
                                case "Additive"
                                    mask = mask + mask2;

                                case "Union"
                                    mask = mask | mask2;

                                case "Intersection"
                                    mask = mask & mask2;
                            end
                        end

                    case {"Grid", "GridNoCenter"}
                        % Either from ROI{1} or ROI{2}
                        if isprop(event, "CurrentRadius")
                            r1 = event.CurrentRadius;
                        end

                        % Sync radii of both ROIs
                        ROI{1}.Radius = r1;
                        ROI{2}.Radius = r1;

                        
                        % max # of repeats (anything larger gets laggy)
                        nRep = 100;
                        
                        % Find position of all circles in the grid
                        [repsy, repsx] = meshgrid(-nRep:nRep);
                        reps = [reshape(repsx,[],1), reshape(repsy,[],1)];

                        % if "GridNoCenter" remove all even repeats to
                        % ignore transmitted beam and equivalents
                        if strcmp(user_data.Type, "GridNoCenter")
                            reps(all(mod(reps,2) == 0,2) | all(mod(reps,2) == 1,2),:) = [];
                        end
                        
                        % apply basis vectors to each circle
                        reps = reps*([p1; p2]-b0) + b0;
                        
                        % Remove duplicates within single precision
                        reps = uniquetol(reps,eps("single"),"ByRows",true);

                        % filter out any grid points outside the diffraction pattern
                        reps(reps(:,1) < -r1 | ...
                             reps(:,2) < -r1 | ...
                             reps(:,1) > xmax+r1 | ...
                             reps(:,2) > ymax+r1, :) = [];
                        
                        % Draw circles for grid
                        delete(ROI{3})
                        ROI{3} = viscircles(app.image_axes.Diffraction, ...
                                            reps, r1+reps(:,1)*0, ...
                                            "EnhanceVisibility", false, ...
                                            "Color", user_data.Color);

                        % Copy properties
                        ROI{3}.Tag = ROI{1}.Tag;
                        ROI{3}.Visible = ROI{1}.Visible;
                        
                        % Make mask from current detector grid
                        [ygrid,xgrid] = meshgrid(1:app.dataset_parameters.n_pixels(2), 1:app.dataset_parameters.n_pixels(1));
                        mask = reshape(sum(sqrt((xgrid(:)-reps(:,1)').^2+(ygrid(:)-reps(:,2)').^2) <= r1, 2), app.dataset_parameters.n_pixels);

                        switch user_data.IntraMask
                            case "Additive"

                            case "Union"
                                mask = logical(mask);

                            case "Intersection"
                                mask = mask > 1;
                        end

                    case "Bandpass"
                        % Sync centers if moved
                        if isprop(event, "CurrentCenter")
                            p1 = event.CurrentCenter;
                            set_external_source(app, event,[ROI{1} ROI{2}],"Center",p1)
                        end

                        % Generate mask
                        pos = [ROI{2}.Vertices; ROI{1}.Vertices];
                        ROI{3}.Position = pos;
                        mask = createMask(ROI{3}, app.image_displays.Diffraction)';
                        
                        % Invert only ROI appearance here
                        if user_data.Inverted
                            ROI{3}.Position = [pos;
                                               [xmax, pos(end,2);
                                                xmax, ymax;
                                                xmin, ymax;
                                                xmin, ymin;
                                                xmax, ymin;
                                                xmax, pos(end,2)] ...
                                               ];
                        end

                    case "Wedge"
                        % Get polar degrees of the two Point ROIs in `dp`, and sort as `dp(1)` < `dp(2)`
                        dp = [p1;p2] - b0;
                        dp = sort(mod(atan2d(dp(:,2), dp(:,1)), 360));

                        % Make sure the in-between angle < 180
                        if dp(2)-dp(1) > 180
                            dp = [dp(2) dp(1)+360];
                        end

                        % `d1` & `d2` are for degrees of vertices of the two symmetric sectors, `dp` as initials
                        d1 = dp;
                        d2 = dp + 180;

                        % Extra logics for inverted/mirrored mask
                        if user_data.Inverted
                            d1 = [dp(2) dp(1)+360];
                        end

                        if user_data.Inverted && user_data.Mirrored
                            d1 = [dp(2) dp(1)+180];
                            d2 = [dp(2)+180 dp(1)+360];
                        end

                        % Insert diagonal degrees to cover the corners
                        cnr = 45:90:720;
                        d1 = [d1(1), cnr(cnr >= d1(1) & cnr <= d1(2)), d1(2)];
                        d2 = [d2(1), cnr(cnr >= d2(1) & cnr <= d2(2)), d2(2)];
                        
                        % Ignore `d2` for single-sided mask, then convert to
                        % X/Y and insert the center [0 0] as starting point
                        if user_data.Mirrored
                            d = [d1 fliplr(d2)]';
                        else
                            d = d1';
                        end

                        ROI{3}.Position = [0 0; 2*max(ymax,xmax).*[cosd(d) sind(d)]] + b0;
                        
                        % Generate mask
                        mask = createMask(ROI{3}, app.image_displays.Diffraction)';
                        
                        % Extra inversion, because it'll be inverted universally at the end
                        if user_data.Inverted
                            mask = 1 - mask;
                        end

                    case "Polygon"
                        % Invert only ROI appearance here
                        if user_data.Inverted
                            endPt = p1(end,:);
                            endPtAng = atan2d(endPt(2) - b0(2), endPt(1) - b0(1));
                            
                            if endPtAng < 135 && endPtAng >= 45
                                % top edge
                                corners = [endPt;endPt(1),ymax; xmax,ymax; xmax,ymin; xmin,ymin; xmin,ymax; endPt(1),ymax;endPt];
                            
                            elseif endPtAng < 45 && endPtAng >= -45
                                % right edge
                                corners = [endPt;xmax,endPt(2); xmax,ymax; xmin,ymax; xmin,ymin; xmax,ymin; xmax,endPt(2);endPt];
                            
                            elseif endPtAng < -45 && endPtAng >= -135
                                % bottom edge
                                corners = [endPt;endPt(1),ymin; xmin,ymin; xmin,ymax; xmax,ymax; xmax,ymin; endPt(1),ymin;endPt];
                            
                            else
                                % left edge
                                corners = [endPt;xmin,endPt(2); xmin,ymax; xmax,ymax; xmax,ymin; xmin,ymin; xmin,endPt(2);endPt];
                            end
                            pos2 = [corners;p1];
                            faceAlpha = 0;
                        else
                            pos2 = p1;
                            faceAlpha = 0.2;
                        end

                        % update poly2 points and faceAlpha for both. set max alpha to 0.2
                        ROI{1}.FaceAlpha = faceAlpha;
                        ROI{2}.FaceAlpha = 0.2 - faceAlpha;
                        ROI{2}.Position = pos2;
                        
                        % generate mask
                        mask = createMask(ROI{1}, app.image_displays.Diffraction)';

                    case "File"
                        % Only deal with visual here
                        mask = app.masks.Custom.(user_data.ID);
                        
                        % Invert only ROI appearance here
                        if user_data.Inverted
                            mask = max(mask,[], "all") - mask;
                        end
                        
                        delete(ROI{2})
                        
                        % Draw boundaries of imported file mask
                        try
                            visb = visboundaries(app.image_axes.Diffraction, ...
                                                 bwboundaries(mask'), ...
                                                 "Color", user_data.Color, ...
                                                 "EnhanceVisibility", 0);

                            % If some boundary is drawn, copy properties
                            if ~isempty(visb)
                                visb.Tag = ROI{1}.Tag;
                                visb.Visible = ROI{1}.Visible;
                                ROI{2} = visb;
                            end
                        catch
                        end

                        % Actual mask is already updated before
                        app.masks.Custom.(user_data.ID) = mask;

                    case "Math"
                        message = strings(0);
                        
                        % Get/Store formula
                        equ0 = string(strjoin(app.MathFormula.Value));
                        ROI{1}.UserData.Math = equ0;
                        
                        % Extract "A" "AB" etc
                        ms = unique(string(regexp(equ0, "\<[A-Z]+\>", "match")));

                        ids = string(fieldnames(app.annotations.Custom));
                        
                        % Existing IDs and Unknown IDs
                        unk = setdiff(ms,ids);
                        
                        % Check IDs
                        if numel(unk)
                            message(end + 1) = sprintf("Unknown IDs: %s !", strjoin(unk, ", "));
                        end
                        
                        if ismember(user_data.ID, ms)
                            message(end + 1) = sprintf("Self ID (%s) encountered!", user_data.ID);
                        end
                        
                        % Try to evaluate
                        if isempty(message)
                            equ = regexprep(equ0, "\<" + ms + "\>", "app.masks.Custom." + ms); 
                            mask_t = [];
                            try
                                mask_t = eval(equ);
                                mask = zeros(app.dataset_parameters.n_pixels) + mask_t;
                            catch ME
                                message(end + 1) = sprintf('%s\n%s\n%s', ME.identifier, ME.message, ME.Correction);
                                % Check result size
                                if ~isempty(mask_t)
                                    message(end + 1) = sprintf("Resulted mask size is %d×%d (X×Y).", size(mask_t));
                                end
                            end
                        end

                        % Check real/complex
                        if isempty(message) && ~isreal(mask)
                            message(end + 1) = sprintf("Resulted mask is a complex array!");
                        end
                        
                        % Early return if any error
                        if ~isempty(message)
                            notification_dialog(app, ...
                                                "warn", ...
                                                "Calculation failed!" + sprintf("\n%s",message), ...
                                                "Evaluation Failed");
                            return
                        end

                        delete(ROI{2})
                        
                        % Draw boundaries of calculated mask
                        try
                            visb = visboundaries(app.image_axes.Diffraction, ...
                                                 bwboundaries(mask'), ...
                                                 "Color", user_data.Color, ...
                                                 "EnhanceVisibility",0);

                            % If some boundary is drawn, copy properties
                            if ~isempty(visb)
                                visb.Tag = ROI{1}.Tag;
                                visb.Visible = ROI{1}.Visible;
                                ROI{2} = visb;
                            end
                        catch
                        end
                end

                % bring ROIs to the front
                for i_ROI = 3:-1:1
                    if ismethod(ROI{i_ROI},'bringToFront')
                        bringToFront(ROI{i_ROI});
                    end
                end
                
                % Assign the created ROIs back to the global handle
                app.annotations.Custom.(user_data.ID) = ROI;
                
                % Invert mask
                if user_data.Inverted
                    mask = max(mask,[], "all") - mask;
                end
                
                % Store mask
                app.masks.Custom.(user_data.ID) = mask;

                % Store color mix mask
                if strcmp(app.CustomDetectorInterMask.Value, "Color Mix")
                    app.masks.CustomColor.(user_data.ID) = mask.*reshape(app.annotations.Custom.(user_data.ID){1}.Color,[1 1 3]);
                end

                plot_custom_mask(event, user_data.ID)
                debug_toc(app, event, "", debug_time)
            end

            function plot_custom_mask(event, id)
                debug_time = tic;
                % Whether to update images
                if to_update_image(app, event)
                    % start with a clear mask
                    custom_mask = zeros(app.dataset_parameters.n_pixels, app.dataset_parameters.data_type);
                    
                    % Go through all enabled AND non-zero-weighting masks; add/intersect them accordingly
                    cds = app.CustomDetectorTable.Data(app.CustomDetectorTable.Data.Enable & app.CustomDetectorTable.Data.Weight, :);

                    % exit if there are no custom masks
                    if isempty(cds)
                        app.images.DiffractionMask = custom_mask;
                        app.images.Real = zeros(app.dataset_parameters.n_frames, app.dataset_parameters.data_type);
                        plot_all_patterns(app, event)
                        return
                    end

                    % split masks by color if in "Color Mix" mode
                    if strcmp(app.CustomDetectorInterMask.Value, "Color Mix")
                        color_masks = zeros([height(cds),prod(app.dataset_parameters.n_pixels)],app.dataset_parameters.data_type);
                        app.masks.CustomColorMix = zeros([prod(app.dataset_parameters.n_pixels) 3],app.dataset_parameters.data_type);
                        color_mix_list = zeros([3 height(cds)]);
                    end

                    for i = 1:height(cds)
                        selection = cds(i, :);
                        maski = app.masks.Custom.(selection.ID)*selection.Weight;
                        switch app.CustomDetectorInterMask.Value
                            case "Additive"
                                custom_mask = custom_mask + maski;

                            case "Current-only"
                                if selection.ID == id
                                    custom_mask = maski;
                                end

                            case "Intersection"
                                if i == 1
                                    custom_mask = maski;
                                else
                                    custom_mask = custom_mask & maski;
                                end

                            case "Union"
                                custom_mask = custom_mask | maski;

                            case "Color Mix"
                                color_masks(i,:) = maski(:);
                                color_mix_list(:,i) = app.annotations.Custom.(selection.ID){1}.UserData.Color;
                                custom_mask = custom_mask | maski;
                        end
                    end

                    % Update images if the mask actually changed OR not a changing event
                    if ~isequal(app.images.DiffractionMask, custom_mask) || is_static_event(app, event)
                        app.images.DiffractionMask = custom_mask;
                        app.images.Real = gather(reshape(reshape(custom_mask,1,[])*app.data, app.dataset_parameters.n_frames));
                        
                        if strcmp(app.CustomDetectorInterMask.Value, "Color Mix")
                            app.masks.CustomColorMix = permute(reshape((color_mix_list*color_masks)',[app.dataset_parameters.n_pixels 3]),[2 1 3]);
                            app.images.RealColorMix = gather(rescale(reshape((color_mix_list*color_masks*app.data)',[app.dataset_parameters.n_frames 3])));
                        end

                    end

                    plot_all_patterns(app, event)
                end
                debug_toc(app, event, "", debug_time)
            end
        end

        % Callback function: BandpassFilter, BandpassFilter, 
        % ...and 5 other components
        function first_moment(app, event)
            switch event.Source
                case app.SaveVecMenu
                    % save vector field as image and figure
                    save_vector_field(app);
                    
                case {app.BandpassFilterLow, app.BandpassFilterHigh}
                    % update app.BandpassFilter RangeSlider
                    app.BandpassFilter.Value = sort([app.BandpassFilterLow.Value,app.BandpassFilterHigh.Value]);
                    app.BandpassFilterLow.Value = min(app.BandpassFilter.Value);
                    app.BandpassFilterHigh.Value = max(app.BandpassFilter.Value);

                case app.BandpassFilter
                    % update app.BandpassFilterLow and
                    % app.BandpassFilterLow spinners
                    app.BandpassFilterLow.Value = event.Value(1);
                    app.BandpassFilterHigh.Value = event.Value(2);
            end

            % update images
            mock_UI_callbacks(app,app.UpdateImages)
        end

        % Button pushed function: ShowCoMMagnitudeWindow, 
        % ...and 17 other components
        function show_window(app, event)
            debug_time = tic;

            id = app.DisplayImage.Value;
            switch event.Source
                case app.ShowImageWindow
                    % Show selected image
                    figure(app.figures.(id));
                    %movegui(app.figures.(id))
                
                case app.ShowMaskWindow
                    % Show diffraction or Real `space` mask
                    % for the selected image
                    id = app.image_axes.(id).UserData.space + "Mask";
                    figure(app.figures.(id));
                    %movegui(app.figures.(id))
                
                otherwise
                    % All other `app.Show***Window`
                    id = event.Source.Tag;
                    figure(app.figures.(id))
                    %movegui(app.figures.(id))

                    % If `id` is one of the available images, select it
                    if ~app.DisplayLock.Value && ...
                       ~strcmp(app.DisplayImage.Value, id) && ...
                       ismember(id, app.DisplayImage.ItemsData)
                        mock_UI_callbacks(app, app.DisplayImage, id)
                    end
            end

            debug_toc(app, event, "", debug_time)
        end

        % Callback function: SetAllDiffractionWindows, SetAllRealWindows, 
        % ...and 6 other components
        function resize_window(app, event)
            % `Diffraction` or `Real` space from source Tag
            space = event.Source.Tag;
            
            % user-defined window size from Settings UI window
            window_size = [app.("Set" + space + "WindowWidth").Value ...
                           app.("Set" + space + "WindowHeight").Value];

            % ensure window size is in range [1, screen_avail]
            window_size = clip(window_size, 1, app.sys_constants.screen_avail(3:4));

            switch event.Source
                case {app.SetDiffractionWindow, app.SetRealWindow}
                    % Only resize currently selected image window
                    id = app.DisplayImage.Value;

                    % If the selected image is not of that space (i.e.
                    % app.DisplayLock.Value = true), then resize the
                    % primary image window for that space instead
                    if app.image_axes.(id).UserData.space ~= space
                        id = space;
                    end

                    % get image axes position
                    pos = app.image_axes.(id).Position;

                    % set window size
                    app.figures.(id).Position(3:4) = window_size;
                    
                    % make sure figure window stays on the screen
                    off_screen = app.figures.(id).Position(1:2) + window_size > app.sys_constants.screen_avail(3:4);
                    if any(off_screen)
                        shift = (app.sys_constants.screen_size(3:4) - (app.figures.(id).Position(1:2) + window_size + [0, app.sys_constants.title_height])).*(off_screen);
                        app.figures.(id).Position(1:2) = app.figures.(id).Position(1:2) + shift;
                    end

                    % make sure image axes position remains unchanged after
                    % window size change
                    app.image_axes.(id).Position = pos;
                
                case {app.SetAllDiffractionWindows, app.SetAllRealWindows}
                    % Resize all image windows of the given space
                    for fig = app.ui_groups.image_figures(app.ui_groups.image_space == space)'
                        % get image axes and position
                        ax = fig.CurrentAxes;
                        pos = ax.Position;

                        % set window size
                        fig.Position(3:4) = window_size;
                        
                        % make sure figure window stays on the screen
                        off_screen = fig.Position(1:2) + window_size > app.sys_constants.screen_size(3:4);
                        if off_screen
                            shift = (app.sys_constants.screen_size(3:4) - (fig.Position(1:2) + window_size + [0, app.sys_constants.title_height])).*(off_screen);
                            fig.Position(1:2) = fig.Position(1:2) + shift;
                        end

                        % make sure image axes position remains unchanged after
                        % window size change
                        ax.Position = pos;
                    end
            end

            % always keep diffraction_dropdown in the upper right hand
            % corner of the diffraction figure
            app.diffraction_dropdown.Position = [0, app.figures.Diffraction.Position(4) - app.diffraction_dropdown.Position(4), 65, 22];
        end

        % Callback function: CalculationPolicy, UpdateImages
        function update_images(app, event)
            switch event.Source
                case app.CalculationPolicy
                    % Update once if not passive
                    if event.Value
                        mock_UI_callbacks(app, app.UpdateImages);
                    end

                case app.UpdateImages
                    % Calculate and update all images by triggering
                    % detector callbacks
                    switch app.Mode.Value
                        case "Preview"
                            preview_callbacks(app, event)

                        case "Alignment"
                            transmitted_beam_callbacks(app, event)
                        
                        case {'Annular' 'CoM' 'DPC'}
                            annular_detector_callbacks(app, event)
                        
                        case "Virtual"
                            virtual_aperture_callbacks(app, event)
                        
                        case "Custom"
                            custom_detector_callbacks(app, event)
                    end

                    % Trigger real-space ROI callbacks
                    if app.Mode.Value ~= "Preview"
                        realspace_ROI_callbacks(app, event)
                    end
            end
        end

        % Menu selected function: ResetQuant4DMenu
        function reset_Quant4D(app, event)
            % A state like just start-up without import
            unload_data(app)
            mock_UI_callbacks(app,app.Mode, "Preview")
        end

        % Menu selected function: EnableallUIsMenu
        function enable_all_UI(app, event)
            % Try to delete any progress window
            try delete(app.tmp_variables.progress_dialog); catch; end
            
            % Enable all windows
            enable_windows(app,true)
        end

        % Menu selected function: Test1Menu
        function test1(app, event)
            % places main window at top left of main screen, with settings, diffraction and real space windows immediately adjacent
            app.Quant4D_Fig.Position(1:2) = [1,app.sys_constants.screen_size(4) - app.Quant4D_Fig.Position(4)-app.sys_constants.title_height];
            for ii=1:2 % running it twice makes it work better for some reason
                app.figures.Settings.Position(1) = sum(app.Quant4D_Fig.Position([1,3])) + 1;
                app.figures.Diffraction.Position(1) = sum(app.figures.Settings.Position([1,3])) + 1;
                app.figures.Preview.Position(1) = sum(app.figures.Settings.Position([1,3])) + 1;
                app.figures.Real.Position(1) = sum(app.figures.Diffraction.Position([1,3])) + 1;
                app.figures.Import.Position(1) = sum(app.figures.Diffraction.Position([1,3])) + 1;
                app.figures.Settings.Position(2:4) = app.Quant4D_Fig.Position(2:4);
                app.figures.Diffraction.Position(2:4) = app.Quant4D_Fig.Position([2,4,4]);
                app.figures.Preview.Position(2:4) = app.Quant4D_Fig.Position([2,4,4]);
                app.figures.Real.Position(2:4) = app.Quant4D_Fig.Position([2,4,4]);
                app.figures.Import.Position(2) = app.figures.Preview.Position(2)+app.figures.Preview.Position(4)-app.figures.Import.Position(4);
            end
        end

        % Menu selected function: Test2Menu
        function test2(app, event)
            fprintf(""); % Can be used for debugging or interfacing with internal data.
            % Just right click on the "Import" button and select Test2
        end

        % Close request function: Quant4D_Fig
        function close_function(app, event)
            % close the progress bar if its open
            try delete(app.tmp_variables.progress_dialog);catch;end

            % make sure all figures are closed after the app is closed

            try figs = app.ui_groups.image_figures; catch; figs =[]; end
            delete(app);
            
            for ii = 1:numel(figs)
                try close(figs(ii)); catch; end
            end
            clear figs;
        end

        % Callback function: ShowVariables, VariablesTree
        function variable_viewer(app, event)
            switch app.ShowVariables.Value
                case true
                    app.VariablesTab.Parent = app.SettingsTabGroup;
                    app.ShowVariables.Text = "Hide all variables";
                    app.SettingsTabGroup.SelectedTab = app.VariablesTab;
                case false
                    app.VariablesTab.Parent = [];
                    app.ShowVariables.Text = "Show all variables";
            end
            % Get currently selected node
            selectedNode = app.VariablesTree.SelectedNodes;

            % Only show these variables/structures 
            group_names = {"dataset_parameters";
                           "common_parameters";
                           "sys_constants";
                           "figures";
                           "images";
                           "masks";
                           "image_axes";
                           "annotations";
                           "ui_groups"};

            % Refresh TreeNode as needed
            for i_group = 1:numel(group_names)
                group_name = group_names{i_group};
                
                % Add TreeNode if it doesn't already exist or is invalid
                if ~isfield(app.variable_nodes,group_name) || ...
                   ~isa(app.variable_nodes.(group_name),'matlab.ui.container.TreeNode') || ...
                    ~isvalid(app.variable_nodes.(group_name))

                    app.variable_nodes.(group_name) = uitreenode(app.VariablesTree, "Text", group_name);
                end
                
                % Populate the branches on the TreeNode
                get_fields(app, app.variable_nodes.(group_name), app.(group_name))
            end

            % If node is selected, show variable data in app.VariablesTable
            if ~isempty(selectedNode)
                % get parent
                parent = selectedNode.Parent;
                tree_path = selectedNode.Text;

                % Get all TreeNode parents above it
                while isa(parent,'matlab.ui.container.TreeNode')
                    tree_path = [parent.Text,'.',tree_path];
                    parent = parent.Parent;
                end

                % Absolute path 
                tree_path = ['app.', tree_path];

                % Get variable in a suitable way for displaying
                app.VariablesTable.Data = get_variable_display(tree_path);
            end            

            function value = get_variable_display(variable_path)
                value = [];

                % get the variable from the text string
                try
                    item = eval(variable_path);
                catch 
                    return
                end

                item_class = class(item);

                switch item_class
                    case 'char'
                        value = string(item);

                    case {'string' 'cell'}
                        if isscalar(item)
                            value = item;
                        elseif isrow(item)
                            value = item';
                        else
                            value = item;
                        end
                    
                    case {'images.roi.Circle', ...
                          'images.roi.Rectangle', ...
                          'images.roi.Line', ...
                          'images.roi.Point', ...
                          'images.roi.Polygon', ...
                          'matlab.graphics.shape.Rectangle', ...
                          'matlab.ui.control.Button'}
                        try
                            item_class = arrayfun(@(r) string(class(r)), item, 'UniformOutput', false);

                            if all(isprop(item,'Name'))
                                value = reshape({item.Name},[],1);
                            elseif all(isprop(item,'Tag'))
                                value = reshape({item.Tag},[],1);
                            else
                                error('');
                            end

                            if numel(item_class) == numel(value) && isequal(size(item_class),size(value))
                                value = table([value,item_class]);
                            end

                        catch
                            try
                                value = arrayfun(@(r) class(r), item, 'UniformOutput', false);
                            catch
                                value = item_class;
                            end
                        end

                    case 'struct'
                        try
                            value = [fieldnames(item), struct2cell(item)];
                        catch
                            value = fieldnames(item);
                        end

                        if ~iscolumn(value)
                            value = cell2table(value);
                        end

                    otherwise
                        if isnumeric(item)
                            if ~iscolumn(item) && ~isrow(item) && ndims(item)>=2
                                % print shape and class of array instead of
                                % the array itself
                                value = sprintf("[%s %s]",string(size(item)).join("×"),class(item));
                            else
                                % return the item itself
                                value = item;
                            end
                        
                        elseif islogical(item)
                            if isscalar(item)
                                value = string(item);
                            else
                                value = sprintf("[%s %s]",string(size(item)).join("×"),class(item));
                            end

                        elseif isgraphics(item)
                            if isscalar(item)
                                value = string(item_class);
                            else
                                try
                                    item_class = arrayfun(@(r) string(class(r)), item, 'UniformOutput', false);
                                    
                                    if all(isprop(item,'Name'))
                                        value = reshape({item.Name},[],1);
                                    elseif all(isprop(item,'Tag'))
                                        value = reshape({item.Tag},[],1);
                                    elseif all(isprop(item,'Text'))
                                        value = reshape({item.Text},[],1);
                                    else
                                        error('')
                                    end

                                    if numel(item_class) == numel(value) && isequal(size(item_class),size(value))
                                        value = table([value,item_class]);
                                    end
                                catch 
                                    try
                                        value = arrayfun(@(r) class(r), item, 'UniformOutput', false);
                                    catch
                                        value = item_class;
                                    end
                                end
                            end
                        else
                            value = "Unknown";
                        end
                end

                if ~isscalar(item) && isrow(item) || (isrow(value) && ~isstring(value)) && ~istable(value)
                    value = value';
                end

                if ischar(value)
                    value = string(value);
                end

                switch size(value,2)
                    case 2
                        app.VariablesTable.ColumnName = {"Name", "Value"};
                        app.VariablesTable.ColumnWidth = 'auto';
                    otherwise
                        app.VariablesTable.ColumnName = "Value";
                        app.VariablesTable.ColumnWidth = '1x';
                end
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Quant4D_Fig = uifigure('Visible', 'off');
            app.Quant4D_Fig.Name = 'Quant4D';
            app.Quant4D_Fig.CloseRequestFcn = createCallbackFcn(app, @close_function, true);
            app.SavePanel = uipanel(app.Quant4D_Fig);
            app.SaveGrid = uigridlayout(app.SavePanel);
            app.SaveDirectoryGrid = uigridlayout(app.SaveGrid);
            app.SaveDirectoryButton = uibutton(app.SaveDirectoryGrid, 'push');
            app.SaveDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @save_callbacks, true);
            app.SaveDirectoryButton.Tooltip = {'Select saving/export directory'};
            app.SaveDirectoryButton.Text = 'Directory';
            app.SaveDirectoryPath = uieditfield(app.SaveDirectoryGrid, 'text');
            app.SaveDirectoryPath.ValueChangedFcn = createCallbackFcn(app, @save_callbacks, true);
            app.SaveDirectoryPath.Tooltip = {'Saving/Export directory'};
            app.SaveTabGroup = uitabgroup(app.SaveGrid);
            app.SaveTabGroup.SelectionChangedFcn = createCallbackFcn(app, @export_callbacks, true);
            app.SaveImagesTab = uitab(app.SaveTabGroup);
            app.SaveImagesTab.Title = 'Save Images';
            app.SaveImagesGrid = uigridlayout(app.SaveImagesTab);
            app.SaveImagePrefixGrid = uigridlayout(app.SaveImagesGrid);
            app.FilenamePrefixLabel = uilabel(app.SaveImagePrefixGrid);
            app.FilenamePrefixLabel.Text = 'Filename Prefix';
            app.SaveImagePrefix = uieditfield(app.SaveImagePrefixGrid, 'text');
            app.SaveImagePrefix.ValueChangedFcn = createCallbackFcn(app, @save_callbacks, true);
            app.SavePrefixAngleGrid = uigridlayout(app.SaveImagesGrid);
            app.SavePrefixAngleLabel = uilabel(app.SavePrefixAngleGrid);
            app.SavePrefixAngleLabel.Text = 'Include Annular Detector Angles in Filename';
            app.SavePrefixAngleInner = uicheckbox(app.SavePrefixAngleGrid);
            app.SavePrefixAngleInner.ValueChangedFcn = createCallbackFcn(app, @save_callbacks, true);
            app.SavePrefixAngleInner.Text = 'Inner';
            app.SavePrefixAngleOuter = uicheckbox(app.SavePrefixAngleGrid);
            app.SavePrefixAngleOuter.ValueChangedFcn = createCallbackFcn(app, @save_callbacks, true);
            app.SavePrefixAngleOuter.Text = 'Outer';
            app.SaveImageListGrid = uigridlayout(app.SaveImagesGrid);
            app.ImagestoSaveMultiselectwithCtrlShiftLabel = uilabel(app.SaveImageListGrid);
            app.ImagestoSaveMultiselectwithCtrlShiftLabel.Text = 'Images to Save (Multi-select with Ctrl/Shift)';
            app.SaveImageSelectAll = uicheckbox(app.SaveImageListGrid);
            app.SaveImageSelectAll.ValueChangedFcn = createCallbackFcn(app, @save_callbacks, true);
            app.SaveImageSelectAll.Text = 'Select All';
            app.SaveImageList = uilistbox(app.SaveImageListGrid);
            app.SaveImageList.Items = {};
            app.SaveImageList.Multiselect = 'on';
            app.SaveImageList.ValueChangedFcn = createCallbackFcn(app, @save_callbacks, true);
            app.SaveImageList.Value = {};
            app.SaveImageFormatGrid = uigridlayout(app.SaveImagesGrid);
            app.SaveImageTIFF = uicheckbox(app.SaveImageFormatGrid);
            app.SaveImageTIFF.ValueChangedFcn = createCallbackFcn(app, @save_callbacks, true);
            app.SaveImageTIFF.Text = 'Raw data (single-precision), TIFF';
            app.SaveImageTIFF.Value = true;
            app.SaveImagePNGGrid = uigridlayout(app.SaveImageFormatGrid);
            app.SaveImagePNG = uicheckbox(app.SaveImagePNGGrid);
            app.SaveImagePNG.ValueChangedFcn = createCallbackFcn(app, @save_callbacks, true);
            app.SaveImagePNG.Text = 'As-displayed, PNG; ';
            app.SaveImagePNG.Value = true;
            app.SaveImageAnnotations = uicheckbox(app.SaveImagePNGGrid);
            app.SaveImageAnnotations.ValueChangedFcn = createCallbackFcn(app, @save_callbacks, true);
            app.SaveImageAnnotations.Text = 'with annotations, e.g. ROIs,';
            app.SaveImageAnnotations.Value = true;
            app.SaveImgAnnotDPILabel = uilabel(app.SaveImagePNGGrid);
            app.SaveImgAnnotDPILabel.Text = 'DPI';
            app.SaveImageDPI = uieditfield(app.SaveImagePNGGrid, 'numeric');
            app.SaveImageDPI.Limits = [1 Inf];
            app.SaveImageDPI.RoundFractionalValues = 'on';
            app.SaveImageDPI.ValueDisplayFormat = '%d';
            app.SaveImageDPI.ValueChangedFcn = createCallbackFcn(app, @save_callbacks, true);
            app.SaveImageDPI.Value = 72;
            app.ExportDatasetTab = uitab(app.SaveTabGroup);
            app.ExportDatasetTab.Title = 'Export Dataset';
            app.ExportGrid = uigridlayout(app.ExportDatasetTab);
            app.ExportFileGrid = uigridlayout(app.ExportGrid);
            app.FilenameLabel = uilabel(app.ExportFileGrid);
            app.FilenameLabel.Text = 'Filename';
            app.ExportFilename = uieditfield(app.ExportFileGrid, 'text');
            app.ExportFilename.ValueChangedFcn = createCallbackFcn(app, @export_callbacks, true);
            app.ExportDimensionSuffix = uicheckbox(app.ExportFileGrid);
            app.ExportDimensionSuffix.ValueChangedFcn = createCallbackFcn(app, @export_callbacks, true);
            app.ExportDimensionSuffix.Tooltip = {'To suffix the filename with image stack''s dimensions'};
            app.ExportDimensionSuffix.Text = 'Dimensions suffix';
            app.ExportDataType = uidropdown(app.ExportGrid);
            app.ExportDataType.Items = {'HDF5 (*.h5)', 'Raw binary (*.raw)'};
            app.ExportDataType.ItemsData = {'h5', 'raw'};
            app.ExportDataType.ValueChangedFcn = createCallbackFcn(app, @export_callbacks, true);
            app.ExportDataType.Value = 'h5';
            app.ExportByteOrder = uidropdown(app.ExportGrid);
            app.ExportByteOrder.Items = {'Little Endian', 'Big Endian'};
            app.ExportByteOrder.ItemsData = {'l', 'b'};
            app.ExportByteOrder.ValueChangedFcn = createCallbackFcn(app, @export_callbacks, true);
            app.ExportByteOrder.Value = 'l';
            app.ExportPartialPixels = uicheckbox(app.ExportGrid);
            app.ExportPartialPixels.ValueChangedFcn = createCallbackFcn(app, @export_callbacks, true);
            app.ExportPartialPixels.Tag = 'Export Diffraction';
            app.ExportPartialPixels.Text = '    Diffraction Partial Export';
            app.DiffractionPartialExportGrid = uigridlayout(app.ExportGrid);
            app.BinningDistanceLabel_2 = uilabel(app.DiffractionPartialExportGrid);
            app.BinningDistanceLabel_2.Text = 'Binning Distance';
            app.DiffractionPartialExportPixelsDist = uispinner(app.DiffractionPartialExportGrid);
            app.DiffractionPartialExportPixelsDist.ValueChangingFcn = createCallbackFcn(app, @export_callbacks, true);
            app.DiffractionPartialExportPixelsDist.RoundFractionalValues = 'on';
            app.DiffractionPartialExportPixelsDist.ValueDisplayFormat = '%.0f';
            app.DiffractionPartialExportPixelsDist.ValueChangedFcn = createCallbackFcn(app, @export_callbacks, true);
            app.DiffractionPartialExportPixelsDist.Tag = 'Export Diffraction';
            app.DiffractionPartialExportPixelsDist.Tooltip = {'To bin n×n pixels into one (by averaging)'};
            app.DiffractionPartialExportPixelsDist.Value = 1;
            app.xsub1Label_3 = uilabel(app.DiffractionPartialExportGrid);
            app.xsub1Label_3.Interpreter = 'html';
            app.xsub1Label_3.Text = 'x<sub>1';
            app.DiffractionPartialExportXStart = uispinner(app.DiffractionPartialExportGrid);
            app.DiffractionPartialExportXStart.ValueChangingFcn = createCallbackFcn(app, @export_callbacks, true);
            app.DiffractionPartialExportXStart.RoundFractionalValues = 'on';
            app.DiffractionPartialExportXStart.ValueDisplayFormat = '%.0f';
            app.DiffractionPartialExportXStart.ValueChangedFcn = createCallbackFcn(app, @export_callbacks, true);
            app.DiffractionPartialExportXStart.Tag = 'Export Diffraction';
            app.DiffractionPartialExportXStart.Tooltip = {'Starting pixel on X (1st dimension of image stack) to import, in each frame'};
            app.DiffractionPartialExportXStart.Value = 1;
            app.ysub1Label_3 = uilabel(app.DiffractionPartialExportGrid);
            app.ysub1Label_3.Interpreter = 'html';
            app.ysub1Label_3.Text = 'y<sub>1';
            app.DiffractionPartialExportYStart = uispinner(app.DiffractionPartialExportGrid);
            app.DiffractionPartialExportYStart.ValueChangingFcn = createCallbackFcn(app, @export_callbacks, true);
            app.DiffractionPartialExportYStart.RoundFractionalValues = 'on';
            app.DiffractionPartialExportYStart.ValueDisplayFormat = '%.0f';
            app.DiffractionPartialExportYStart.ValueChangedFcn = createCallbackFcn(app, @export_callbacks, true);
            app.DiffractionPartialExportYStart.Tag = 'Export Diffraction';
            app.DiffractionPartialExportYStart.Tooltip = {'Starting pixel on Y (2nd dimension of image stack) to import, in each frame'};
            app.DiffractionPartialExportYStart.Value = 1;
            app.ENPxXl = uilabel(app.DiffractionPartialExportGrid);
            app.ENPxXl.Text = 'X';
            app.DiffractionPartialExportX = uispinner(app.DiffractionPartialExportGrid);
            app.DiffractionPartialExportX.ValueChangingFcn = createCallbackFcn(app, @export_callbacks, true);
            app.DiffractionPartialExportX.RoundFractionalValues = 'on';
            app.DiffractionPartialExportX.ValueDisplayFormat = '%.0f';
            app.DiffractionPartialExportX.ValueChangedFcn = createCallbackFcn(app, @export_callbacks, true);
            app.DiffractionPartialExportX.Tag = 'Export Diffraction';
            app.DiffractionPartialExportX.Tooltip = {'Total pixels after binning on X (1st dimension of image stack), in each frame'};
            app.DiffractionPartialExportX.Value = 1;
            app.ENPxYl = uilabel(app.DiffractionPartialExportGrid);
            app.ENPxYl.Text = 'Y';
            app.DiffractionPartialExportY = uispinner(app.DiffractionPartialExportGrid);
            app.DiffractionPartialExportY.ValueChangingFcn = createCallbackFcn(app, @export_callbacks, true);
            app.DiffractionPartialExportY.RoundFractionalValues = 'on';
            app.DiffractionPartialExportY.ValueDisplayFormat = '%.0f';
            app.DiffractionPartialExportY.ValueChangedFcn = createCallbackFcn(app, @export_callbacks, true);
            app.DiffractionPartialExportY.Tag = 'Export Diffraction';
            app.DiffractionPartialExportY.Tooltip = {'Total pixels after binning on Y (2nd dimension of image stack), in each frame'};
            app.DiffractionPartialExportY.Value = 1;
            app.RealPartialExportGrid = uigridlayout(app.ExportGrid);
            app.xsub1Label_4 = uilabel(app.RealPartialExportGrid);
            app.xsub1Label_4.Interpreter = 'html';
            app.xsub1Label_4.Text = 'x<sub>1';
            app.RealPartialExportXStart = uispinner(app.RealPartialExportGrid);
            app.RealPartialExportXStart.ValueChangingFcn = createCallbackFcn(app, @export_callbacks, true);
            app.RealPartialExportXStart.RoundFractionalValues = 'on';
            app.RealPartialExportXStart.ValueDisplayFormat = '%.0f';
            app.RealPartialExportXStart.ValueChangedFcn = createCallbackFcn(app, @export_callbacks, true);
            app.RealPartialExportXStart.Tag = 'Export Real';
            app.RealPartialExportXStart.Tooltip = {'Starting frame on X (3rd dimension of image stack) to import'};
            app.RealPartialExportXStart.Value = 1;
            app.ysub1Label_4 = uilabel(app.RealPartialExportGrid);
            app.ysub1Label_4.Interpreter = 'html';
            app.ysub1Label_4.Text = 'y<sub>1';
            app.RealPartialExportYStart = uispinner(app.RealPartialExportGrid);
            app.RealPartialExportYStart.ValueChangingFcn = createCallbackFcn(app, @export_callbacks, true);
            app.RealPartialExportYStart.RoundFractionalValues = 'on';
            app.RealPartialExportYStart.ValueDisplayFormat = '%.0f';
            app.RealPartialExportYStart.ValueChangedFcn = createCallbackFcn(app, @export_callbacks, true);
            app.RealPartialExportYStart.Tag = 'Export Real';
            app.RealPartialExportYStart.Tooltip = {'Starting frame on Y (4th dimension of image stack) to import'};
            app.RealPartialExportYStart.Value = 1;
            app.ENFrXl = uilabel(app.RealPartialExportGrid);
            app.ENFrXl.Text = 'X';
            app.RealPartialExportX = uispinner(app.RealPartialExportGrid);
            app.RealPartialExportX.ValueChangingFcn = createCallbackFcn(app, @export_callbacks, true);
            app.RealPartialExportX.RoundFractionalValues = 'on';
            app.RealPartialExportX.ValueDisplayFormat = '%.0f';
            app.RealPartialExportX.ValueChangedFcn = createCallbackFcn(app, @export_callbacks, true);
            app.RealPartialExportX.Tag = 'Export Real';
            app.RealPartialExportX.Tooltip = {'Total frames after sampling on X (3rd dimension of image stack)'};
            app.RealPartialExportX.Value = 1;
            app.ENFrYl = uilabel(app.RealPartialExportGrid);
            app.ENFrYl.Text = 'Y';
            app.RealPartialExportY = uispinner(app.RealPartialExportGrid);
            app.RealPartialExportY.ValueChangingFcn = createCallbackFcn(app, @export_callbacks, true);
            app.RealPartialExportY.RoundFractionalValues = 'on';
            app.RealPartialExportY.ValueDisplayFormat = '%.0f';
            app.RealPartialExportY.ValueChangedFcn = createCallbackFcn(app, @export_callbacks, true);
            app.RealPartialExportY.Tag = 'Export Real';
            app.RealPartialExportY.Tooltip = {'Total frames after sampling on Y (4th dimension of image stack)'};
            app.RealPartialExportY.Value = 1;
            app.SamplingIntervalLabel = uilabel(app.RealPartialExportGrid);
            app.SamplingIntervalLabel.Text = 'Sampling Interval';
            app.RealPartialExportFramesDist = uispinner(app.RealPartialExportGrid);
            app.RealPartialExportFramesDist.ValueChangingFcn = createCallbackFcn(app, @export_callbacks, true);
            app.RealPartialExportFramesDist.RoundFractionalValues = 'on';
            app.RealPartialExportFramesDist.ValueDisplayFormat = '%.0f';
            app.RealPartialExportFramesDist.ValueChangedFcn = createCallbackFcn(app, @export_callbacks, true);
            app.RealPartialExportFramesDist.Tag = 'Export Real';
            app.RealPartialExportFramesDist.Tooltip = {'Distance between sampling frames in both X & Y directions (neighboring frames have distance of 1)'};
            app.RealPartialExportFramesDist.Value = 1;
            app.SummaryLabel = uilabel(app.ExportGrid);
            app.SummaryLabel.Text = 'Summary';
            app.ExportNotes = uitextarea(app.ExportGrid);
            app.ExportNotes.Editable = 'off';
            app.ExportPartialFrames = uicheckbox(app.ExportGrid);
            app.ExportPartialFrames.ValueChangedFcn = createCallbackFcn(app, @export_callbacks, true);
            app.ExportPartialFrames.Tag = 'Export Real';
            app.ExportPartialFrames.Text = '    Real-space Partial Export';
            app.SaveExportButtonGrid = uigridlayout(app.SaveGrid);
            app.SaveExport = uibutton(app.SaveExportButtonGrid, 'push');
            app.SaveExport.ButtonPushedFcn = createCallbackFcn(app, @save_callbacks, true);
            app.SaveExport.Text = 'Save Images';
            app.SaveCloseButton = uibutton(app.SaveExportButtonGrid, 'push');
            app.SaveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @export_callbacks, true);
            app.SaveCloseButton.Text = 'Close';
            app.ImportPanel = uipanel(app.Quant4D_Fig);
            app.ImportGrid = uigridlayout(app.ImportPanel);
            app.ImportFileGrid = uigridlayout(app.ImportGrid);
            app.ImportFileSelect = uibutton(app.ImportFileGrid, 'push');
            app.ImportFileSelect.ButtonPushedFcn = createCallbackFcn(app, @import_select_file, true);
            app.ImportFileSelect.Text = 'Select';
            app.ImportFilePath = uieditfield(app.ImportFileGrid, 'text');
            app.ImportFilePath.ValueChangedFcn = createCallbackFcn(app, @import_select_file, true);
            app.ImportDatasetInfoPanel = uipanel(app.ImportGrid);
            app.ImportDatasetInfoPanel.Title = 'Dataset Info';
            app.ImportDatasetInfoGrid = uigridlayout(app.ImportDatasetInfoPanel);
            app.FileTypeButtonGroup = uibuttongroup(app.ImportDatasetInfoGrid);
            app.FileTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @import_file_type, true);
            app.FileTypeButtonGroup.Tooltip = {'EMPAD'; '128x128 pixels'; 'Header: 0 bytes'; 'Footer: 1024 bytes'; '32-bit Real, Little endian'; ''; 'MEDIPIX'; '256x256 pixels'; 'Header: 384 bytes'; 'Footer: 0 bytes'; 'Unsigned integer, Big endian'; ''; 'MRC'; 'variable # of pixels'; 'Header: 1024 bytes'; 'Footer: 0 bytes'; 'Signed integer, Little endian'};
            app.EMPAD = uitogglebutton(app.FileTypeButtonGroup);
            app.EMPAD.Tooltip = {'*.raw'; '128x128 pixels'; 'Data Offset: 0 bytes'; 'Frame Header: 0 bytes'; 'Frame Footer: 1024 bytes'; '32-bit Real, Little Endian'};
            app.EMPAD.Text = 'EMPAD';
            app.EMPAD.Value = true;
            app.Medipix = uitogglebutton(app.FileTypeButtonGroup);
            app.Medipix.Tooltip = {'*.mib'; '256x256 pixels'; 'Data Offset: 0 bytes'; 'Frame Header: 384 bytes'; 'Frame Footer: 0 bytes'; 'Unsigned integer, Big Endian'};
            app.Medipix.Text = 'Medipix';
            app.MRC = uitogglebutton(app.FileTypeButtonGroup);
            app.MRC.Tooltip = {'*.mrc'; 'Data Offset: 1024 bytes'; 'Frame Header: 0 bytes'; 'Frame Footer: 0 bytes'; 'Signed integer, Little Endian'};
            app.MRC.Text = 'MRC';
            app.DM34 = uitogglebutton(app.FileTypeButtonGroup);
            app.DM34.Tooltip = {'Gatan *.dm3/*.dm4'};
            app.DM34.Text = 'DM3/4';
            app.HDF5 = uitogglebutton(app.FileTypeButtonGroup);
            app.HDF5.Tooltip = {'*.h5/*.hdf5'};
            app.HDF5.Text = 'HDF5';
            app.Custom = uitogglebutton(app.FileTypeButtonGroup);
            app.Custom.Tooltip = {'E.g. simulated/raw data'};
            app.Custom.Text = 'Custom';
            app.ImportDatasetStructureGrid = uigridlayout(app.ImportDatasetInfoGrid);
            app.SubdatasetDropDownLabel = uilabel(app.ImportDatasetStructureGrid);
            app.SubdatasetDropDownLabel.Text = 'Sub-dataset';
            app.SubDataset = uidropdown(app.ImportDatasetStructureGrid);
            app.SubDataset.Items = {};
            app.SubDataset.ValueChangedFcn = createCallbackFcn(app, @import_file_type, true);
            app.SubDataset.Tooltip = {'Some file formats (e.g. DM3, DM4 or HDF5) can contain multiple image-stacks in one file'};
            app.SubDataset.Value = {};
            app.DataOffsetLabel = uilabel(app.ImportDatasetStructureGrid);
            app.DataOffsetLabel.Text = 'Data Offset';
            app.DataOffset = uieditfield(app.ImportDatasetStructureGrid, 'numeric');
            app.DataOffset.Limits = [0 Inf];
            app.DataOffset.ValueDisplayFormat = '%d bytes';
            app.DataOffset.ValueChangedFcn = createCallbackFcn(app, @import_box_input, true);
            app.DataOffset.Tooltip = {'Number of bytes before the image stack in file (e.g. for metadata)'};
            app.FrameHeaderLabel = uilabel(app.ImportDatasetStructureGrid);
            app.FrameHeaderLabel.Text = 'Frame Header';
            app.FrameHeader = uieditfield(app.ImportDatasetStructureGrid, 'numeric');
            app.FrameHeader.Limits = [0 Inf];
            app.FrameHeader.ValueDisplayFormat = '%d bytes';
            app.FrameHeader.ValueChangedFcn = createCallbackFcn(app, @import_box_input, true);
            app.FrameHeader.Tooltip = {'Number of bytes before the actual pattern in each frame (e.g. for metadata)'};
            app.FrameFooterLabel = uilabel(app.ImportDatasetStructureGrid);
            app.FrameFooterLabel.Text = 'Frame Footer';
            app.FrameFooter = uieditfield(app.ImportDatasetStructureGrid, 'numeric');
            app.FrameFooter.Limits = [0 Inf];
            app.FrameFooter.ValueDisplayFormat = '%d bytes';
            app.FrameFooter.ValueChangedFcn = createCallbackFcn(app, @import_box_input, true);
            app.FrameFooter.Tooltip = {'Number of bytes after the actual pattern in each frame (e.g. for metadata)'};
            app.DataTypeLabel = uilabel(app.ImportDatasetStructureGrid);
            app.DataTypeLabel.Text = 'Data Type';
            app.ImportDataType = uidropdown(app.ImportDatasetStructureGrid);
            app.ImportDataType.Items = {};
            app.ImportDataType.ValueChangedFcn = createCallbackFcn(app, @import_box_input, true);
            app.ImportDataType.Value = {};
            app.ByteOrderDropDownLabel = uilabel(app.ImportDatasetStructureGrid);
            app.ByteOrderDropDownLabel.Text = 'Byte Order';
            app.ImportByteOrder = uidropdown(app.ImportDatasetStructureGrid);
            app.ImportByteOrder.Items = {'Little Endian', 'Big Endian'};
            app.ImportByteOrder.ItemsData = {'l', 'b'};
            app.ImportByteOrder.ValueChangedFcn = createCallbackFcn(app, @import_box_input, true);
            app.ImportByteOrder.Value = 'l';
            app.ImportDimensionGrid = uigridlayout(app.ImportDatasetInfoGrid);
            app.ImportPixelsLabel = uilabel(app.ImportDimensionGrid);
            app.ImportPixelsLabel.Text = 'Pixels';
            app.XLabel_2 = uilabel(app.ImportDimensionGrid);
            app.XLabel_2.Text = 'X';
            app.ImportPixelsX = uieditfield(app.ImportDimensionGrid, 'numeric');
            app.ImportPixelsX.Limits = [1 Inf];
            app.ImportPixelsX.RoundFractionalValues = 'on';
            app.ImportPixelsX.ValueDisplayFormat = '%d';
            app.ImportPixelsX.ValueChangedFcn = createCallbackFcn(app, @import_box_input, true);
            app.ImportPixelsX.Tag = 'Import Diffraction';
            app.ImportPixelsX.Tooltip = {'Number of pixels on X axis in each frame (1st dimension of image stack)'};
            app.ImportPixelsX.Value = 1;
            app.YLabel_2 = uilabel(app.ImportDimensionGrid);
            app.YLabel_2.Text = ' Y';
            app.ImportPixelsY = uieditfield(app.ImportDimensionGrid, 'numeric');
            app.ImportPixelsY.Limits = [1 Inf];
            app.ImportPixelsY.RoundFractionalValues = 'on';
            app.ImportPixelsY.ValueDisplayFormat = '%d';
            app.ImportPixelsY.ValueChangedFcn = createCallbackFcn(app, @import_box_input, true);
            app.ImportPixelsY.Tag = 'Import Diffraction';
            app.ImportPixelsY.Tooltip = {'Number of pixels on Y axis in each frame (2nd dimension of image stack)'};
            app.ImportPixelsY.Value = 1;
            app.ImportFramesLabel = uilabel(app.ImportDimensionGrid);
            app.ImportFramesLabel.Text = 'Frames';
            app.XLabel = uilabel(app.ImportDimensionGrid);
            app.XLabel.Text = 'X';
            app.ImportFramesX = uieditfield(app.ImportDimensionGrid, 'numeric');
            app.ImportFramesX.Limits = [1 Inf];
            app.ImportFramesX.RoundFractionalValues = 'on';
            app.ImportFramesX.ValueDisplayFormat = '%d';
            app.ImportFramesX.ValueChangedFcn = createCallbackFcn(app, @import_box_input, true);
            app.ImportFramesX.Tag = 'Import Real';
            app.ImportFramesX.Tooltip = {'Number of frames on X axis (3rd dimension of image stack)'};
            app.ImportFramesX.Value = 1;
            app.YLabel = uilabel(app.ImportDimensionGrid);
            app.YLabel.Text = ' Y';
            app.ImportFramesY = uieditfield(app.ImportDimensionGrid, 'numeric');
            app.ImportFramesY.Limits = [1 Inf];
            app.ImportFramesY.RoundFractionalValues = 'on';
            app.ImportFramesY.ValueDisplayFormat = '%d';
            app.ImportFramesY.ValueChangedFcn = createCallbackFcn(app, @import_box_input, true);
            app.ImportFramesY.Tag = 'Import Real';
            app.ImportFramesY.Tooltip = {'Number of frames on Y axis (4th dimension of image stack)'};
            app.ImportFramesY.Value = 1;
            app.ImportFileSizeGrid = uigridlayout(app.ImportDatasetInfoGrid);
            app.ImportActualFilesizeLabel = uilabel(app.ImportFileSizeGrid);
            app.ImportActualFilesizeLabel.Text = 'Size:';
            app.ImportActualFilesize = uilabel(app.ImportFileSizeGrid);
            app.ImportActualFilesize.Tooltip = {'Actual physical file size'};
            app.ImportActualFilesize.Text = '';
            app.ImportEstimatedFilesizeLabel = uilabel(app.ImportFileSizeGrid);
            app.ImportEstimatedFilesizeLabel.Text = 'Est.:';
            app.ImportEstimatedFilesize = uilabel(app.ImportFileSizeGrid);
            app.ImportEstimatedFilesize.Tooltip = {'File size calculated from info above. Size after the image stack (the "data tail") is implicit, thus may not be accurate'};
            app.ImportEstimatedFilesize.Text = '';
            app.DatasetInfofromMetadataLabel = uilabel(app.ImportDatasetInfoGrid);
            app.DatasetInfofromMetadataLabel.Text = 'Dataset Info from Metadata';
            app.ImportFileMetadata = uitextarea(app.ImportDatasetInfoGrid);
            app.ImportFileMetadata.Editable = 'off';
            app.ImportOptionsPanel = uipanel(app.ImportGrid);
            app.ImportOptionsPanel.Title = 'Import Options';
            app.ImportOptionsGrid = uigridlayout(app.ImportOptionsPanel);
            app.MemoryGrid = uigridlayout(app.ImportOptionsGrid);
            app.MemoryType = uidropdown(app.MemoryGrid);
            app.MemoryType.Items = {'Physical Memory', 'Virtual Mapping'};
            app.MemoryType.Tooltip = {'VIRTUAL MEMORY is best for most large datasets as it does not physically load the dataset into RAM. This can be significantly faster depending on drive speed and file size. HOWEVER - it is currently only beneficial for datasets with no header of footer.'; ''; 'Virtual mapping to the GPU is not recommended at this point. It is extremely fast to load, but still requires significant GPU memory resources.'};
            app.MemoryType.Value = 'Physical Memory';
            app.GPU = uidropdown(app.MemoryGrid);
            app.GPU.Items = {'GPU off'};
            app.GPU.ValueChangedFcn = createCallbackFcn(app, @import_box_input, true);
            app.GPU.Tooltip = {'GPU acceleration'};
            app.GPU.Value = 'GPU off';
            app.ImportPartialPixels = uicheckbox(app.ImportOptionsGrid);
            app.ImportPartialPixels.ValueChangedFcn = createCallbackFcn(app, @import_box_input, true);
            app.ImportPartialPixels.Tag = 'Import Diffraction';
            app.ImportPartialPixels.Text = '    Diffraction Partial Import';
            app.ImportPartialFrames = uicheckbox(app.ImportOptionsGrid);
            app.ImportPartialFrames.ValueChangedFcn = createCallbackFcn(app, @import_box_input, true);
            app.ImportPartialFrames.Tag = 'Import Real';
            app.ImportPartialFrames.Text = '    Real-space Partial Import';
            app.DiffractionPartialImportGrid = uigridlayout(app.ImportOptionsGrid);
            app.BinningDistanceLabel = uilabel(app.DiffractionPartialImportGrid);
            app.BinningDistanceLabel.Text = 'Binning Distance';
            app.DiffractionPartialImportPixelsDist = uispinner(app.DiffractionPartialImportGrid);
            app.DiffractionPartialImportPixelsDist.RoundFractionalValues = 'on';
            app.DiffractionPartialImportPixelsDist.ValueDisplayFormat = '%.0f';
            app.DiffractionPartialImportPixelsDist.ValueChangedFcn = createCallbackFcn(app, @import_box_input, true);
            app.DiffractionPartialImportPixelsDist.Tag = 'Import Diffraction';
            app.DiffractionPartialImportPixelsDist.Tooltip = {'To bin n×n pixels into one (by averaging)'};
            app.DiffractionPartialImportPixelsDist.Value = 1;
            app.xsub1Label_5 = uilabel(app.DiffractionPartialImportGrid);
            app.xsub1Label_5.Interpreter = 'html';
            app.xsub1Label_5.Text = 'x<sub>1';
            app.DiffractionPartialImportXStart = uispinner(app.DiffractionPartialImportGrid);
            app.DiffractionPartialImportXStart.RoundFractionalValues = 'on';
            app.DiffractionPartialImportXStart.ValueDisplayFormat = '%.0f';
            app.DiffractionPartialImportXStart.ValueChangedFcn = createCallbackFcn(app, @import_box_input, true);
            app.DiffractionPartialImportXStart.Tag = 'Import Diffraction';
            app.DiffractionPartialImportXStart.Tooltip = {'Starting pixel on X (1st dimension of image stack) to import, in each frame'};
            app.DiffractionPartialImportXStart.Value = 1;
            app.ysub1Label_5 = uilabel(app.DiffractionPartialImportGrid);
            app.ysub1Label_5.Interpreter = 'html';
            app.ysub1Label_5.Text = 'y<sub>1';
            app.DiffractionPartialImportYStart = uispinner(app.DiffractionPartialImportGrid);
            app.DiffractionPartialImportYStart.RoundFractionalValues = 'on';
            app.DiffractionPartialImportYStart.ValueDisplayFormat = '%.0f';
            app.DiffractionPartialImportYStart.ValueChangedFcn = createCallbackFcn(app, @import_box_input, true);
            app.DiffractionPartialImportYStart.Tag = 'Import Diffraction';
            app.DiffractionPartialImportYStart.Tooltip = {'Starting pixel on Y (2nd dimension of image stack) to import, in each frame'};
            app.DiffractionPartialImportYStart.Value = 1;
            app.XLabel_3 = uilabel(app.DiffractionPartialImportGrid);
            app.XLabel_3.Text = 'X';
            app.DiffractionPartialImportX = uispinner(app.DiffractionPartialImportGrid);
            app.DiffractionPartialImportX.RoundFractionalValues = 'on';
            app.DiffractionPartialImportX.ValueDisplayFormat = '%.0f';
            app.DiffractionPartialImportX.ValueChangedFcn = createCallbackFcn(app, @import_box_input, true);
            app.DiffractionPartialImportX.Tag = 'Import Diffraction';
            app.DiffractionPartialImportX.Tooltip = {'Total pixels after binning on X (1st dimension of image stack), in each frame'};
            app.DiffractionPartialImportX.Value = 1;
            app.YLabel_3 = uilabel(app.DiffractionPartialImportGrid);
            app.YLabel_3.Text = 'Y';
            app.DiffractionPartialImportY = uispinner(app.DiffractionPartialImportGrid);
            app.DiffractionPartialImportY.RoundFractionalValues = 'on';
            app.DiffractionPartialImportY.ValueDisplayFormat = '%.0f';
            app.DiffractionPartialImportY.ValueChangedFcn = createCallbackFcn(app, @import_box_input, true);
            app.DiffractionPartialImportY.Tag = 'Import Diffraction';
            app.DiffractionPartialImportY.Tooltip = {'Total pixels after binning on Y (2nd dimension of image stack), in each frame'};
            app.DiffractionPartialImportY.Value = 1;
            app.RealPartialImportGrid = uigridlayout(app.ImportOptionsGrid);
            app.SamplingIntervalLabel_2 = uilabel(app.RealPartialImportGrid);
            app.SamplingIntervalLabel_2.Text = 'Sampling Interval';
            app.RealPartialImportFramesDist = uispinner(app.RealPartialImportGrid);
            app.RealPartialImportFramesDist.RoundFractionalValues = 'on';
            app.RealPartialImportFramesDist.ValueDisplayFormat = '%.0f';
            app.RealPartialImportFramesDist.ValueChangedFcn = createCallbackFcn(app, @import_box_input, true);
            app.RealPartialImportFramesDist.Tag = 'Import Real';
            app.RealPartialImportFramesDist.Tooltip = {'Distance between sampling frames in both X & Y directions (neighboring frames have distance of 1)'};
            app.RealPartialImportFramesDist.Value = 1;
            app.xsub1Label_6 = uilabel(app.RealPartialImportGrid);
            app.xsub1Label_6.Interpreter = 'html';
            app.xsub1Label_6.Text = 'x<sub>1';
            app.RealPartialImportXStart = uispinner(app.RealPartialImportGrid);
            app.RealPartialImportXStart.RoundFractionalValues = 'on';
            app.RealPartialImportXStart.ValueDisplayFormat = '%.0f';
            app.RealPartialImportXStart.ValueChangedFcn = createCallbackFcn(app, @import_box_input, true);
            app.RealPartialImportXStart.Tag = 'Import Real';
            app.RealPartialImportXStart.Tooltip = {'Starting frame on X (3rd dimension of image stack) to import'};
            app.RealPartialImportXStart.Value = 1;
            app.ysub1Label_6 = uilabel(app.RealPartialImportGrid);
            app.ysub1Label_6.Interpreter = 'html';
            app.ysub1Label_6.Text = 'y<sub>1';
            app.RealPartialImportYStart = uispinner(app.RealPartialImportGrid);
            app.RealPartialImportYStart.RoundFractionalValues = 'on';
            app.RealPartialImportYStart.ValueDisplayFormat = '%.0f';
            app.RealPartialImportYStart.ValueChangedFcn = createCallbackFcn(app, @import_box_input, true);
            app.RealPartialImportYStart.Tag = 'Import Real';
            app.RealPartialImportYStart.Tooltip = {'Starting frame on Y (4th dimension of image stack) to import'};
            app.RealPartialImportYStart.Value = 1;
            app.INFrXl = uilabel(app.RealPartialImportGrid);
            app.INFrXl.Text = 'X';
            app.RealPartialImportX = uispinner(app.RealPartialImportGrid);
            app.RealPartialImportX.RoundFractionalValues = 'on';
            app.RealPartialImportX.ValueDisplayFormat = '%.0f';
            app.RealPartialImportX.ValueChangedFcn = createCallbackFcn(app, @import_box_input, true);
            app.RealPartialImportX.Tag = 'Import Real';
            app.RealPartialImportX.Tooltip = {'Total frames after sampling on X (3rd dimension of image stack)'};
            app.RealPartialImportX.Value = 1;
            app.INFrYl = uilabel(app.RealPartialImportGrid);
            app.INFrYl.Text = 'Y';
            app.RealPartialImportY = uispinner(app.RealPartialImportGrid);
            app.RealPartialImportY.RoundFractionalValues = 'on';
            app.RealPartialImportY.ValueDisplayFormat = '%.0f';
            app.RealPartialImportY.ValueChangedFcn = createCallbackFcn(app, @import_box_input, true);
            app.RealPartialImportY.Tag = 'Import Real';
            app.RealPartialImportY.Tooltip = {'Total frames after sampling on Y (4th dimension of image stack)'};
            app.RealPartialImportY.Value = 1;
            app.ImportSummaryLabel = uilabel(app.ImportOptionsGrid);
            app.ImportSummaryLabel.Text = 'Import Summary';
            app.ImportSummary = uitextarea(app.ImportOptionsGrid);
            app.ImportSummary.Editable = 'off';
            app.ImportButtonGrid = uigridlayout(app.ImportGrid);
            app.ImportData = uibutton(app.ImportButtonGrid, 'push');
            app.ImportData.ButtonPushedFcn = createCallbackFcn(app, @import_callbacks, true);
            app.ImportData.Text = 'Import Data';
            app.SwapDataset = uicheckbox(app.ImportButtonGrid);
            app.SwapDataset.ValueChangedFcn = createCallbackFcn(app, @import_box_input, true);
            app.SwapDataset.Tooltip = {'Keep all current parameters/alignments/setups, and swap the already-imported dataset with another with exact (import) dimensions/datatype'};
            app.SwapDataset.Text = 'Swap Dataset';
            app.CancelImport = uibutton(app.ImportButtonGrid, 'push');
            app.CancelImport.ButtonPushedFcn = createCallbackFcn(app, @import_callbacks, true);
            app.CancelImport.Text = 'Cancel';
            app.SettingsPanel = uipanel(app.Quant4D_Fig);
            app.SettingsGrid = uigridlayout(app.SettingsPanel);
            app.SettingsTabGroup = uitabgroup(app.SettingsGrid);
            app.DisplayTab = uitab(app.SettingsTabGroup);
            app.DisplayTab.Title = 'Display';
            app.DisplayGrid = uigridlayout(app.DisplayTab);
            app.DisplayDropDownGrid = uigridlayout(app.DisplayGrid);
            app.ShowImageWindow = uibutton(app.DisplayDropDownGrid, 'push');
            app.ShowImageWindow.ButtonPushedFcn = createCallbackFcn(app, @show_window, true);
            app.ShowImageWindow.Icon = 'frontWindow.png';
            app.ShowImageWindow.Tooltip = {'Bring selected image to front'};
            app.ShowImageWindow.Text = '';
            app.ImageLabel = uilabel(app.DisplayDropDownGrid);
            app.ImageLabel.Text = 'Image';
            app.DisplayImage = uidropdown(app.DisplayDropDownGrid);
            app.DisplayImage.Items = {};
            app.DisplayImage.ValueChangedFcn = createCallbackFcn(app, @display_callbacks, true);
            app.DisplayImage.Value = {};
            app.DisplayLock = uibutton(app.DisplayDropDownGrid, 'state');
            app.DisplayLock.ValueChangedFcn = createCallbackFcn(app, @display_callbacks, true);
            app.DisplayLock.Tooltip = {'Stop changing the selected image when clicking on a new image'};
            app.DisplayLock.Icon = 'link.png';
            app.DisplayLock.Text = '';
            app.ColormapLabel = uilabel(app.DisplayDropDownGrid);
            app.ColormapLabel.Text = 'Colormap';
            app.DispColorMap = uidropdown(app.DisplayDropDownGrid);
            app.DispColorMap.Items = {'gray', 'hsv', 'jet', 'parula', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'bone', 'copper', 'pink'};
            app.DispColorMap.ValueChangedFcn = createCallbackFcn(app, @display_callbacks, true);
            app.DispColorMap.Value = 'gray';
            app.DispColorMapInvert = uibutton(app.DisplayDropDownGrid, 'state');
            app.DispColorMapInvert.ValueChangedFcn = createCallbackFcn(app, @display_callbacks, true);
            app.DispColorMapInvert.Tooltip = {'Invert colormap'};
            app.DispColorMapInvert.Icon = 'invertColors.png';
            app.DispColorMapInvert.Text = '';
            app.ImageStatistics = uitextarea(app.DisplayGrid);
            app.ImageStatistics.Editable = 'off';
            app.HistogramGrid = uigridlayout(app.DisplayGrid);
            app.HistogramAxes = uiaxes(app.HistogramGrid);
            app.HistogramAxes.XTick = [];
            app.HistogramAxes.YTick = [];
            app.HistogramAxes.ZTick = [];
            app.HistogramLog = uibutton(app.HistogramGrid, 'state');
            app.HistogramLog.ValueChangedFcn = createCallbackFcn(app, @display_callbacks, true);
            app.HistogramLog.Text = 'log';
            app.DispContrastsGrid = uigridlayout(app.DisplayGrid);
            app.DisplayBrightnessReset = uibutton(app.DispContrastsGrid, 'push');
            app.DisplayBrightnessReset.ButtonPushedFcn = createCallbackFcn(app, @display_callbacks, true);
            app.DisplayBrightnessReset.Icon = 'brightness.png';
            app.DisplayBrightnessReset.Tooltip = {'Reset Brightness'};
            app.DisplayBrightnessReset.Text = '';
            app.DisplayBrightness = uislider(app.DispContrastsGrid);
            app.DisplayBrightness.MajorTicks = [];
            app.DisplayBrightness.MajorTickLabels = {};
            app.DisplayBrightness.ValueChangedFcn = createCallbackFcn(app, @display_callbacks, true);
            app.DisplayBrightness.ValueChangingFcn = createCallbackFcn(app, @display_callbacks, true);
            app.DisplayBrightness.MinorTicks = [];
            app.DisplayBrightness.Value = 50;
            app.DisplayBrightnessSpinner = uispinner(app.DispContrastsGrid);
            app.DisplayBrightnessSpinner.ValueChangingFcn = createCallbackFcn(app, @display_callbacks, true);
            app.DisplayBrightnessSpinner.Limits = [0 100];
            app.DisplayBrightnessSpinner.ValueDisplayFormat = '%.4g%%';
            app.DisplayBrightnessSpinner.ValueChangedFcn = createCallbackFcn(app, @display_callbacks, true);
            app.DisplayBrightnessSpinner.Value = 50;
            app.DisplayContrastReset = uibutton(app.DispContrastsGrid, 'push');
            app.DisplayContrastReset.ButtonPushedFcn = createCallbackFcn(app, @display_callbacks, true);
            app.DisplayContrastReset.Icon = 'contrast.png';
            app.DisplayContrastReset.Tooltip = {'Reset Contrast'};
            app.DisplayContrastReset.Text = '';
            app.DisplayContrast = uislider(app.DispContrastsGrid);
            app.DisplayContrast.MajorTicks = [];
            app.DisplayContrast.MajorTickLabels = {};
            app.DisplayContrast.ValueChangedFcn = createCallbackFcn(app, @display_callbacks, true);
            app.DisplayContrast.ValueChangingFcn = createCallbackFcn(app, @display_callbacks, true);
            app.DisplayContrast.MinorTicks = [];
            app.DisplayContrast.Value = 50;
            app.DisplayContrastSpinner = uispinner(app.DispContrastsGrid);
            app.DisplayContrastSpinner.ValueChangingFcn = createCallbackFcn(app, @display_callbacks, true);
            app.DisplayContrastSpinner.Limits = [0 100];
            app.DisplayContrastSpinner.ValueDisplayFormat = '%.4g%%';
            app.DisplayContrastSpinner.ValueChangedFcn = createCallbackFcn(app, @display_callbacks, true);
            app.DisplayContrastSpinner.Value = 50;
            app.DisplayGammaReset = uibutton(app.DispContrastsGrid, 'push');
            app.DisplayGammaReset.ButtonPushedFcn = createCallbackFcn(app, @display_callbacks, true);
            app.DisplayGammaReset.Icon = 'gamma.png';
            app.DisplayGammaReset.Tooltip = {'Reset Gamma'};
            app.DisplayGammaReset.Text = '';
            app.DisplayGamma = uislider(app.DispContrastsGrid);
            app.DisplayGamma.Limits = [0 2];
            app.DisplayGamma.MajorTicks = [];
            app.DisplayGamma.MajorTickLabels = {};
            app.DisplayGamma.ValueChangedFcn = createCallbackFcn(app, @display_callbacks, true);
            app.DisplayGamma.ValueChangingFcn = createCallbackFcn(app, @display_callbacks, true);
            app.DisplayGamma.MinorTicks = [];
            app.DisplayGamma.Value = 1;
            app.DisplayGammaSpinner = uispinner(app.DispContrastsGrid);
            app.DisplayGammaSpinner.Step = 0.01;
            app.DisplayGammaSpinner.ValueChangingFcn = createCallbackFcn(app, @display_callbacks, true);
            app.DisplayGammaSpinner.Limits = [0 2];
            app.DisplayGammaSpinner.ValueDisplayFormat = '%.5g';
            app.DisplayGammaSpinner.ValueChangedFcn = createCallbackFcn(app, @display_callbacks, true);
            app.DisplayGammaSpinner.Value = 1;
            app.ShowMaskWindow = uibutton(app.DispContrastsGrid, 'push');
            app.ShowMaskWindow.ButtonPushedFcn = createCallbackFcn(app, @show_window, true);
            app.ShowMaskWindow.Icon = 'Aperture.png';
            app.ShowMaskWindow.Tooltip = {'Show Mask Image'};
            app.ShowMaskWindow.Text = '';
            app.DisplayMaskOpacitySlider = uislider(app.DispContrastsGrid);
            app.DisplayMaskOpacitySlider.MajorTicks = [];
            app.DisplayMaskOpacitySlider.ValueChangedFcn = createCallbackFcn(app, @display_callbacks, true);
            app.DisplayMaskOpacitySlider.ValueChangingFcn = createCallbackFcn(app, @display_callbacks, true);
            app.DisplayMaskOpacitySlider.MinorTicks = [];
            app.DisplayMaskOpacitySlider.Tooltip = {'Mask opacity displayed on pattern'};
            app.DisplayMaskOpacitySpinner = uispinner(app.DispContrastsGrid);
            app.DisplayMaskOpacitySpinner.ValueChangingFcn = createCallbackFcn(app, @display_callbacks, true);
            app.DisplayMaskOpacitySpinner.Limits = [0 100];
            app.DisplayMaskOpacitySpinner.ValueDisplayFormat = '%.4g%%';
            app.DisplayMaskOpacitySpinner.ValueChangedFcn = createCallbackFcn(app, @display_callbacks, true);
            app.WindowsTab = uitab(app.SettingsTabGroup);
            app.WindowsTab.Title = 'Windows';
            app.WindowsTabGrid = uigridlayout(app.WindowsTab);
            app.BandpassFilterPanel = uipanel(app.WindowsTabGrid);
            app.BandpassFilterPanel.Tooltip = {'Apply a bandpass, lowpass, or highpass filter. Units are in pixels applied in fourier space.'};
            app.BandpassFilterPanel.Title = 'iCoM / dCoM Bandpass Filter';
            app.BandpassFilterGrid = uigridlayout(app.BandpassFilterPanel);
            app.BandpassFilter = uislider(app.BandpassFilterGrid, 'range');
            app.BandpassFilter.MajorTicks = [];
            app.BandpassFilter.ValueChangedFcn = createCallbackFcn(app, @first_moment, true);
            app.BandpassFilter.ValueChangingFcn = createCallbackFcn(app, @first_moment, true);
            app.BandpassFilter.MinorTicks = [];
            app.BandpassFilterHigh = uispinner(app.BandpassFilterGrid);
            app.BandpassFilterHigh.ValueChangingFcn = createCallbackFcn(app, @first_moment, true);
            app.BandpassFilterHigh.Limits = [0 100];
            app.BandpassFilterHigh.RoundFractionalValues = 'on';
            app.BandpassFilterHigh.ValueDisplayFormat = '%d px';
            app.BandpassFilterHigh.ValueChangedFcn = createCallbackFcn(app, @first_moment, true);
            app.BandpassFilterHigh.Value = 100;
            app.BandpassFilterLow = uispinner(app.BandpassFilterGrid);
            app.BandpassFilterLow.ValueChangingFcn = createCallbackFcn(app, @first_moment, true);
            app.BandpassFilterLow.Limits = [0 100];
            app.BandpassFilterLow.RoundFractionalValues = 'on';
            app.BandpassFilterLow.ValueDisplayFormat = '%d px';
            app.BandpassFilterLow.ValueChangedFcn = createCallbackFcn(app, @first_moment, true);
            app.ShowImageWindowsPanel = uipanel(app.WindowsTabGrid);
            app.ShowImageWindowsPanel.Title = 'Show Image Windows';
            app.ShowImageWindowsGrid = uigridlayout(app.ShowImageWindowsPanel);
            app.ShowDiffractionWindow = uibutton(app.ShowImageWindowsGrid, 'push');
            app.ShowDiffractionWindow.ButtonPushedFcn = createCallbackFcn(app, @show_window, true);
            app.ShowDiffractionWindow.Tag = 'Diffraction';
            app.ShowDiffractionWindow.Tooltip = {'Show Diffraction Pattern'};
            app.ShowDiffractionWindow.Text = 'Diffraction';
            app.ShowDiffractionMaskWindow2 = uibutton(app.ShowImageWindowsGrid, 'push');
            app.ShowDiffractionMaskWindow2.ButtonPushedFcn = createCallbackFcn(app, @show_window, true);
            app.ShowDiffractionMaskWindow2.Tag = 'DiffractionMask';
            app.ShowDiffractionMaskWindow2.Icon = 'Aperture.png';
            app.ShowDiffractionMaskWindow2.Tooltip = {'Show Diffraction Mask'};
            app.ShowDiffractionMaskWindow2.Text = 'Diff. Mask';
            app.ShowRealWindow = uibutton(app.ShowImageWindowsGrid, 'push');
            app.ShowRealWindow.ButtonPushedFcn = createCallbackFcn(app, @show_window, true);
            app.ShowRealWindow.Tag = 'Real';
            app.ShowRealWindow.Tooltip = {'Show Real-space Image'};
            app.ShowRealWindow.Text = 'BF/DF';
            app.ShowRealMaskWindow2 = uibutton(app.ShowImageWindowsGrid, 'push');
            app.ShowRealMaskWindow2.ButtonPushedFcn = createCallbackFcn(app, @show_window, true);
            app.ShowRealMaskWindow2.Tag = 'RealMask';
            app.ShowRealMaskWindow2.Icon = 'Aperture.png';
            app.ShowRealMaskWindow2.Tooltip = {'Show Real-space Mask'};
            app.ShowRealMaskWindow2.Text = 'BF/DF Mask';
            app.ShowColorWheelWindow2 = uibutton(app.ShowImageWindowsGrid, 'push');
            app.ShowColorWheelWindow2.ButtonPushedFcn = createCallbackFcn(app, @show_window, true);
            app.ShowColorWheelWindow2.Tag = 'ColorWheel';
            app.ShowColorWheelWindow2.Icon = 'colorwheel.png';
            app.ShowColorWheelWindow2.Tooltip = {'Show Color Wheel for Phase Vector Field Direction'};
            app.ShowColorWheelWindow2.Text = 'φ';
            app.ShowCoMPhaseMagnitudeWindow = uibutton(app.ShowImageWindowsGrid, 'push');
            app.ShowCoMPhaseMagnitudeWindow.ButtonPushedFcn = createCallbackFcn(app, @show_window, true);
            app.ShowCoMPhaseMagnitudeWindow.Tag = 'CoMPhMag';
            app.ShowCoMPhaseMagnitudeWindow.Tooltip = {'Show CoM/DPC Phase (Magnitude-shaded) Image'};
            app.ShowCoMPhaseMagnitudeWindow.Text = '|CoM| φ';
            app.ShowCoMPhaseWindow = uibutton(app.ShowImageWindowsGrid, 'push');
            app.ShowCoMPhaseWindow.ButtonPushedFcn = createCallbackFcn(app, @show_window, true);
            app.ShowCoMPhaseWindow.Tag = 'CoMPh';
            app.ShowCoMPhaseWindow.Tooltip = {'Show CoM/DPC Phase Image'};
            app.ShowCoMPhaseWindow.Text = 'CoM φ';
            app.ShowCoMMagnitudeWindow = uibutton(app.ShowImageWindowsGrid, 'push');
            app.ShowCoMMagnitudeWindow.ButtonPushedFcn = createCallbackFcn(app, @show_window, true);
            app.ShowCoMMagnitudeWindow.Tag = 'CoMMag';
            app.ShowCoMMagnitudeWindow.Tooltip = {'Show CoM/DPC Magnitude Image'};
            app.ShowCoMMagnitudeWindow.Text = '|CoM|';
            app.ShowCoMXWindow = uibutton(app.ShowImageWindowsGrid, 'push');
            app.ShowCoMXWindow.ButtonPushedFcn = createCallbackFcn(app, @show_window, true);
            app.ShowCoMXWindow.Tag = 'CoMX';
            app.ShowCoMXWindow.Tooltip = {'Show CoM/DPC X Image'};
            app.ShowCoMXWindow.Text = 'CoM X';
            app.ShowCoMYWindow = uibutton(app.ShowImageWindowsGrid, 'push');
            app.ShowCoMYWindow.ButtonPushedFcn = createCallbackFcn(app, @show_window, true);
            app.ShowCoMYWindow.Tag = 'CoMY';
            app.ShowCoMYWindow.Tooltip = {'Show CoM/DPC Y Image'};
            app.ShowCoMYWindow.Text = 'CoM Y';
            app.ShowdCoMWindow = uibutton(app.ShowImageWindowsGrid, 'push');
            app.ShowdCoMWindow.ButtonPushedFcn = createCallbackFcn(app, @show_window, true);
            app.ShowdCoMWindow.Tag = 'dCoM';
            app.ShowdCoMWindow.Tooltip = {'Show dCoM/dDPC Image'};
            app.ShowdCoMWindow.Text = 'dCoM';
            app.ShowiCoMWindow = uibutton(app.ShowImageWindowsGrid, 'push');
            app.ShowiCoMWindow.ButtonPushedFcn = createCallbackFcn(app, @show_window, true);
            app.ShowiCoMWindow.Tag = 'iCoM';
            app.ShowiCoMWindow.Tooltip = {'Show iCoM/iDPC Image'};
            app.ShowiCoMWindow.Text = 'iCoM';
            app.VectorSamplingGrid = uigridlayout(app.ShowImageWindowsGrid);
            app.DispVecFieldLabel = uilabel(app.VectorSamplingGrid);
            app.DispVecFieldLabel.Text = 'Vector Sampling:';
            app.DisplayVectorField = uidropdown(app.VectorSamplingGrid);
            app.DisplayVectorField.Items = {'None', '1:1', '1:2', '1:4', '1:8', '1:16'};
            app.DisplayVectorField.ItemsData = [0 1 2 4 8 16];
            app.DisplayVectorField.ValueChangedFcn = createCallbackFcn(app, @display_callbacks, true);
            app.DisplayVectorField.Tooltip = {'Plot vector field on the current selected Real-space image'};
            app.DisplayVectorField.Value = 0;
            app.DisplayVectorColor = uibutton(app.VectorSamplingGrid, 'push');
            app.DisplayVectorColor.ButtonPushedFcn = createCallbackFcn(app, @display_callbacks, true);
            app.DisplayVectorColor.Icon = 'colorPicker.png';
            app.DisplayVectorColor.Tooltip = {'Change Vector Field color'};
            app.DisplayVectorColor.Text = '';
            app.ShowColorWheelWindow = uibutton(app.VectorSamplingGrid, 'push');
            app.ShowColorWheelWindow.ButtonPushedFcn = createCallbackFcn(app, @show_window, true);
            app.ShowColorWheelWindow.Tag = 'ColorWheel';
            app.ShowColorWheelWindow.Icon = 'colorwheel.png';
            app.ShowColorWheelWindow.Tooltip = {'Show Color Wheel for Phase Vector Field Direction'};
            app.ShowColorWheelWindow.Text = '';
            app.WindowResizePanel = uipanel(app.WindowsTabGrid);
            app.WindowResizePanel.Title = 'Window size and orientation';
            app.WindowResizeGrid = uigridlayout(app.WindowResizePanel);
            app.WindowResizeTabGroup = uitabgroup(app.WindowResizeGrid);
            app.DiffractionTab = uitab(app.WindowResizeTabGroup);
            app.DiffractionTab.Title = 'Diffraction';
            app.DiffractionTabGrid = uigridlayout(app.DiffractionTab);
            app.DiffractionWindowPanel = uipanel(app.DiffractionTabGrid);
            app.DiffractionWindowGrid = uigridlayout(app.DiffractionWindowPanel);
            app.DiffractionAxesGrid = uigridlayout(app.DiffractionWindowGrid);
            app.ShowDiffractionAxes = uibutton(app.DiffractionAxesGrid, 'state');
            app.ShowDiffractionAxes.ValueChangedFcn = createCallbackFcn(app, @axis_direction_callbacks, true);
            app.ShowDiffractionAxes.Tag = 'Diffraction';
            app.ShowDiffractionAxes.Tooltip = {'Show Axis Directions on Images'};
            app.ShowDiffractionAxes.Icon = 'axes.png';
            app.ShowDiffractionAxes.Text = '';
            app.ShowDiffractionAxes.Value = true;
            app.ReverseDiffractionX = uibutton(app.DiffractionAxesGrid, 'state');
            app.ReverseDiffractionX.ValueChangedFcn = createCallbackFcn(app, @axis_direction_callbacks, true);
            app.ReverseDiffractionX.Tag = 'Diffraction';
            app.ReverseDiffractionX.Tooltip = {'Reverse X-axis Direction'};
            app.ReverseDiffractionX.Icon = 'axis-x.png';
            app.ReverseDiffractionX.Text = '';
            app.ReverseDiffractionY = uibutton(app.DiffractionAxesGrid, 'state');
            app.ReverseDiffractionY.ValueChangedFcn = createCallbackFcn(app, @axis_direction_callbacks, true);
            app.ReverseDiffractionY.Tag = 'Diffraction';
            app.ReverseDiffractionY.Tooltip = {'Reverse Y-axis Direction'};
            app.ReverseDiffractionY.Icon = 'axis-y.png';
            app.ReverseDiffractionY.Text = '';
            app.ReverseDiffractionY.Value = true;
            app.RotateDiffractionIcon = uiimage(app.DiffractionAxesGrid);
            app.RotateDiffractionIcon.Tooltip = {'Rotate View'};
            app.RotateDiffractionIcon.ImageSource = 'right_rotate.png';
            app.RotateDiffraction = uidropdown(app.DiffractionAxesGrid);
            app.RotateDiffraction.Items = {'0°', '90°', '180°', '-90°'};
            app.RotateDiffraction.ItemsData = [0 90 180 -90];
            app.RotateDiffraction.ValueChangedFcn = createCallbackFcn(app, @axis_direction_callbacks, true);
            app.RotateDiffraction.Tag = 'Diffraction';
            app.RotateDiffraction.Tooltip = {'Rotate Image Display'};
            app.RotateDiffraction.Value = 0;
            app.WEditFieldLabel = uilabel(app.DiffractionWindowGrid);
            app.WEditFieldLabel.Text = 'W';
            app.SetDiffractionWindowWidth = uieditfield(app.DiffractionWindowGrid, 'numeric');
            app.SetDiffractionWindowWidth.Limits = [1 Inf];
            app.SetDiffractionWindowWidth.RoundFractionalValues = 'on';
            app.SetDiffractionWindowWidth.ValueDisplayFormat = '%.0f';
            app.SetDiffractionWindowWidth.ValueChangedFcn = createCallbackFcn(app, @resize_window, true);
            app.SetDiffractionWindowWidth.Tag = 'Diffraction';
            app.SetDiffractionWindowWidth.Value = 400;
            app.HEditFieldLabel = uilabel(app.DiffractionWindowGrid);
            app.HEditFieldLabel.Text = 'H';
            app.SetDiffractionWindowHeight = uieditfield(app.DiffractionWindowGrid, 'numeric');
            app.SetDiffractionWindowHeight.Limits = [1 Inf];
            app.SetDiffractionWindowHeight.RoundFractionalValues = 'on';
            app.SetDiffractionWindowHeight.ValueDisplayFormat = '%.0f';
            app.SetDiffractionWindowHeight.ValueChangedFcn = createCallbackFcn(app, @resize_window, true);
            app.SetDiffractionWindowHeight.Tag = 'Diffraction';
            app.SetDiffractionWindowHeight.Value = 400;
            app.SetDiffractionWindow = uibutton(app.DiffractionWindowGrid, 'push');
            app.SetDiffractionWindow.ButtonPushedFcn = createCallbackFcn(app, @resize_window, true);
            app.SetDiffractionWindow.Tag = 'Diffraction';
            app.SetDiffractionWindow.Text = 'Set';
            app.SetAllDiffractionWindows = uibutton(app.DiffractionWindowGrid, 'push');
            app.SetAllDiffractionWindows.ButtonPushedFcn = createCallbackFcn(app, @resize_window, true);
            app.SetAllDiffractionWindows.Tag = 'Diffraction';
            app.SetAllDiffractionWindows.Text = 'Set All';
            app.RealspaceTab = uitab(app.WindowResizeTabGroup);
            app.RealspaceTab.Title = 'Real-space';
            app.RealspaceTabGrid = uigridlayout(app.RealspaceTab);
            app.RealWindowPanel = uipanel(app.RealspaceTabGrid);
            app.RealWindowGrid = uigridlayout(app.RealWindowPanel);
            app.RealAxesGrid = uigridlayout(app.RealWindowGrid);
            app.ShowRealAxes = uibutton(app.RealAxesGrid, 'state');
            app.ShowRealAxes.ValueChangedFcn = createCallbackFcn(app, @axis_direction_callbacks, true);
            app.ShowRealAxes.Tag = 'Real';
            app.ShowRealAxes.Tooltip = {'Show Axis Directions on Images'};
            app.ShowRealAxes.Icon = 'axes.png';
            app.ShowRealAxes.Text = '';
            app.ShowRealAxes.Value = true;
            app.ReverseRealX = uibutton(app.RealAxesGrid, 'state');
            app.ReverseRealX.ValueChangedFcn = createCallbackFcn(app, @axis_direction_callbacks, true);
            app.ReverseRealX.Tag = 'Real';
            app.ReverseRealX.Tooltip = {'Reverse X-axis Direction'};
            app.ReverseRealX.Icon = 'axis-x.png';
            app.ReverseRealX.Text = '';
            app.ReverseRealY = uibutton(app.RealAxesGrid, 'state');
            app.ReverseRealY.ValueChangedFcn = createCallbackFcn(app, @axis_direction_callbacks, true);
            app.ReverseRealY.Tag = 'Real';
            app.ReverseRealY.Tooltip = {'Reverse Y-axis Direction'};
            app.ReverseRealY.Icon = 'axis-y.png';
            app.ReverseRealY.Text = '';
            app.ReverseRealY.Value = true;
            app.RotateRealIcon = uiimage(app.RealAxesGrid);
            app.RotateRealIcon.Tooltip = {'Rotate View'};
            app.RotateRealIcon.ImageSource = 'right_rotate.png';
            app.RotateReal = uidropdown(app.RealAxesGrid);
            app.RotateReal.Items = {'0°', '90°', '180°', '-90°'};
            app.RotateReal.ItemsData = [0 90 180 -90];
            app.RotateReal.ValueChangedFcn = createCallbackFcn(app, @axis_direction_callbacks, true);
            app.RotateReal.Tag = 'Real';
            app.RotateReal.Tooltip = {'Rotate Image Display'};
            app.RotateReal.Value = 0;
            app.HEditField_2Label = uilabel(app.RealWindowGrid);
            app.HEditField_2Label.Text = 'H';
            app.SetRealWindowHeight = uieditfield(app.RealWindowGrid, 'numeric');
            app.SetRealWindowHeight.Limits = [1 Inf];
            app.SetRealWindowHeight.RoundFractionalValues = 'on';
            app.SetRealWindowHeight.ValueDisplayFormat = '%.0f';
            app.SetRealWindowHeight.ValueChangedFcn = createCallbackFcn(app, @resize_window, true);
            app.SetRealWindowHeight.Tag = 'Real';
            app.SetRealWindowHeight.Value = 400;
            app.SetRealWindow = uibutton(app.RealWindowGrid, 'push');
            app.SetRealWindow.ButtonPushedFcn = createCallbackFcn(app, @resize_window, true);
            app.SetRealWindow.Tag = 'Real';
            app.SetRealWindow.Text = 'Set';
            app.SetAllRealWindows = uibutton(app.RealWindowGrid, 'push');
            app.SetAllRealWindows.ButtonPushedFcn = createCallbackFcn(app, @resize_window, true);
            app.SetAllRealWindows.Tag = 'Real';
            app.SetAllRealWindows.Text = 'Set All';
            app.WEditField_2Label = uilabel(app.RealWindowGrid);
            app.WEditField_2Label.Text = 'W';
            app.SetRealWindowWidth = uieditfield(app.RealWindowGrid, 'numeric');
            app.SetRealWindowWidth.Limits = [1 Inf];
            app.SetRealWindowWidth.RoundFractionalValues = 'on';
            app.SetRealWindowWidth.ValueDisplayFormat = '%.0f';
            app.SetRealWindowWidth.ValueChangedFcn = createCallbackFcn(app, @resize_window, true);
            app.SetRealWindowWidth.Tag = 'Real';
            app.SetRealWindowWidth.Value = 400;
            app.MiscTab = uitab(app.SettingsTabGroup);
            app.MiscTab.Title = 'Misc';
            app.MiscGrid = uigridlayout(app.MiscTab);
            app.LiveUpdateImagesGrid = uigridlayout(app.MiscGrid);
            app.CalculationPolicyIcon = uiimage(app.LiveUpdateImagesGrid);
            app.CalculationPolicyIcon.Tooltip = {''};
            app.CalculationPolicyIcon.ImageSource = 'refresh.png';
            app.CalculationPolicyDropDownLabel = uilabel(app.LiveUpdateImagesGrid);
            app.CalculationPolicyDropDownLabel.Tooltip = {''};
            app.CalculationPolicyDropDownLabel.Text = 'Calculation Policy';
            app.CalculationPolicy = uidropdown(app.LiveUpdateImagesGrid);
            app.CalculationPolicy.Items = {'Active', 'Reduced', 'Passive'};
            app.CalculationPolicy.ItemsData = [2 1 0];
            app.CalculationPolicy.ValueChangedFcn = createCallbackFcn(app, @update_images, true);
            app.CalculationPolicy.Tooltip = {'Policy to calculate/update images:'; '  1) Active - update in real-time.'; '  2) Reduced - update when interactions finish.'; '  3) Passive - update only when the "Update Images" Button (or F5) is pressed or "Diffraction Detector Mode" changes.'};
            app.CalculationPolicy.Value = 2;
            app.DetectorCoordinatePanel = uipanel(app.MiscGrid);
            app.DetectorCoordinatePanel.Title = 'Detector Coordinate Options';
            app.DetectorCoordinateGrid = uigridlayout(app.DetectorCoordinatePanel);
            app.DetectorCoordinateSystem = uidropdown(app.DetectorCoordinateGrid);
            app.DetectorCoordinateSystem.Items = {'Polar', 'Cartesian'};
            app.DetectorCoordinateSystem.ValueChangedFcn = createCallbackFcn(app, @detector_coordinates_callbacks, true);
            app.DetectorCoordinateSystem.Tooltip = {'Display diffraction coordinates with a Cartesian or polar system'};
            app.DetectorCoordinateSystem.Value = 'Polar';
            app.DetectorCoordinatePosition = uidropdown(app.DetectorCoordinateGrid);
            app.DetectorCoordinatePosition.Items = {'Relative', 'Absolute'};
            app.DetectorCoordinatePosition.ValueChangedFcn = createCallbackFcn(app, @detector_coordinates_callbacks, true);
            app.DetectorCoordinatePosition.Tooltip = {'Display diffraction coordinates as absolute positions or relative to the transmitted beam'};
            app.DetectorCoordinatePosition.Value = 'Relative';
            app.DetectorCoordinateUnit = uidropdown(app.DetectorCoordinateGrid);
            app.DetectorCoordinateUnit.Items = {'mrad', 'pixels'};
            app.DetectorCoordinateUnit.ItemsData = {'mrad', 'px'};
            app.DetectorCoordinateUnit.ValueChangedFcn = createCallbackFcn(app, @detector_coordinates_callbacks, true);
            app.DetectorCoordinateUnit.Tooltip = {'Display diffraction coordinates in mrad or pixel'};
            app.DetectorCoordinateUnit.Value = 'mrad';
            app.DatasetOptionPanel = uipanel(app.MiscGrid);
            app.DatasetOptionPanel.Title = 'Swap Dataset Byte Order and Dimensions';
            app.DatasetOptionGrid = uigridlayout(app.DatasetOptionPanel);
            app.SwapByteOrder = uibutton(app.DatasetOptionGrid, 'push');
            app.SwapByteOrder.ButtonPushedFcn = createCallbackFcn(app, @dataset_options_callbacks, true);
            app.SwapByteOrder.Tooltip = {'Swap the X/Y dimension sizes of the diffraction images, without re-importing'};
            app.SwapByteOrder.Text = 'Swap Byte Order';
            app.SwapDiffractionXY = uibutton(app.DatasetOptionGrid, 'push');
            app.SwapDiffractionXY.ButtonPushedFcn = createCallbackFcn(app, @dataset_options_callbacks, true);
            app.SwapDiffractionXY.Tag = 'Diffraction';
            app.SwapDiffractionXY.Tooltip = {'Swap the X/Y dimension sizes of the diffraction images, without re-importing'};
            app.SwapDiffractionXY.Text = 'Swap Diffraction X/Y';
            app.SwapRealXY = uibutton(app.DatasetOptionGrid, 'push');
            app.SwapRealXY.ButtonPushedFcn = createCallbackFcn(app, @dataset_options_callbacks, true);
            app.SwapRealXY.Tag = 'Real';
            app.SwapRealXY.Tooltip = {'Swap the X/Y dimension sizes of the real-space images, without re-importing'};
            app.SwapRealXY.Text = 'Swap Real-space X/Y';
            app.InfoTab = uitab(app.SettingsTabGroup);
            app.InfoTab.Title = 'Info';
            app.InfoGrid = uigridlayout(app.InfoTab);
            app.DatasetInfoTextAreaLabel = uilabel(app.InfoGrid);
            app.DatasetInfoTextAreaLabel.Text = 'Dataset Info';
            app.DatasetInfo = uitextarea(app.InfoGrid);
            app.DatasetInfo.Editable = 'off';
            app.ShowVariables = uibutton(app.InfoGrid, 'state');
            app.ShowVariables.ValueChangedFcn = createCallbackFcn(app, @variable_viewer, true);
            app.ShowVariables.Text = 'Show all variables';
            app.MathTab = uitab(app.SettingsTabGroup);
            app.MathTab.Title = 'Math';
            app.MathGrid = uigridlayout(app.MathTab);
            app.MathExampleGrid = uigridlayout(app.MathGrid);
            app.MathExample = uilabel(app.MathExampleGrid);
            app.MathExample.Interpreter = 'html';
            app.MathExample.Text = {'<center><b>Syntax Examples</b></center>• Union of B and C, then intersects with A:'; '<b style="font-family:''Consolas''">  A & (B | C)</b>'; '• A squared plus B times C (element-wisely), then transposed:'; '<b style="font-family:''Consolas''">  (A.^2 + B .* C)''</b>'; '• Concatenates (on X-dimension) A''s Rows 1-3  to B''s Rows 4-to-last:'; '<b style="font-family:''Consolas''">  [A(1:3,:) ; B(4:end,:)]</b>'};
            app.MathHelpIcon = uiimage(app.MathExampleGrid);
            app.MathHelpIcon.Tooltip = {'• MATLAB syntax/functions are used.'; '• The X/Y dimensions (in images) are corresponding to the 1st/2nd dimensions (Rows/Columns) of arrays, respectively.'};
            app.MathHelpIcon.ImageSource = 'help.png';
            app.FormulaLabel = uilabel(app.MathGrid);
            app.FormulaLabel.Text = 'Formula';
            app.MathFormula = uitextarea(app.MathGrid);
            app.EvaluateGrid = uigridlayout(app.MathGrid);
            app.Evaluate = uibutton(app.EvaluateGrid, 'push');
            app.Evaluate.ButtonPushedFcn = createCallbackFcn(app, @custom_detector_callbacks, true);
            app.Evaluate.Text = 'Evaluate';
            app.VariablesTab = uitab(app.SettingsTabGroup);
            app.VariablesTab.Title = 'Variables';
            app.VariablesGrid = uigridlayout(app.VariablesTab);
            app.VariablesTable = uitable(app.VariablesGrid);
            app.VariablesTable.ColumnName = {'Value'};
            app.VariablesTable.RowName = {};
            app.VariablesTree = uitree(app.VariablesGrid);
            app.VariablesTree.SelectionChangedFcn = createCallbackFcn(app, @variable_viewer, true);
            app.ModeTabGroup = uitabgroup(app.Quant4D_Fig);
            app.PreviewTab = uitab(app.ModeTabGroup);
            app.PreviewTab.Title = 'Pv';
            app.PreviewGrid = uigridlayout(app.PreviewTab);
            app.PreviewFramePanel = uipanel(app.PreviewGrid);
            app.PreviewFramePanel.Title = 'Preview Frame';
            app.PreviewButtonGrid = uigridlayout(app.PreviewFramePanel);
            app.PreviewFrame_1_1 = uibutton(app.PreviewButtonGrid, 'push');
            app.PreviewFrame_1_1.ButtonPushedFcn = createCallbackFcn(app, @preview_callbacks, true);
            app.PreviewFrame_1_1.Text = '[1,1]';
            app.PreviewFrame_X2_1 = uibutton(app.PreviewButtonGrid, 'push');
            app.PreviewFrame_X2_1.ButtonPushedFcn = createCallbackFcn(app, @preview_callbacks, true);
            app.PreviewFrame_X2_1.Text = '[X/2,1]';
            app.PreviewFrame_X_1 = uibutton(app.PreviewButtonGrid, 'push');
            app.PreviewFrame_X_1.ButtonPushedFcn = createCallbackFcn(app, @preview_callbacks, true);
            app.PreviewFrame_X_1.Text = '[X,1]';
            app.PreviewFrame_1_Y2 = uibutton(app.PreviewButtonGrid, 'push');
            app.PreviewFrame_1_Y2.ButtonPushedFcn = createCallbackFcn(app, @preview_callbacks, true);
            app.PreviewFrame_1_Y2.Text = '[1,Y/2]';
            app.PreviewFrame_X2_Y2 = uibutton(app.PreviewButtonGrid, 'push');
            app.PreviewFrame_X2_Y2.ButtonPushedFcn = createCallbackFcn(app, @preview_callbacks, true);
            app.PreviewFrame_X2_Y2.Text = '[X/2,Y/2]';
            app.PreviewFrame_X_Y2 = uibutton(app.PreviewButtonGrid, 'push');
            app.PreviewFrame_X_Y2.ButtonPushedFcn = createCallbackFcn(app, @preview_callbacks, true);
            app.PreviewFrame_X_Y2.Text = '[X,Y/2]';
            app.PreviewFrame_1_Y = uibutton(app.PreviewButtonGrid, 'push');
            app.PreviewFrame_1_Y.ButtonPushedFcn = createCallbackFcn(app, @preview_callbacks, true);
            app.PreviewFrame_1_Y.Text = '[1,Y]';
            app.PreviewFrame_X2_Y = uibutton(app.PreviewButtonGrid, 'push');
            app.PreviewFrame_X2_Y.ButtonPushedFcn = createCallbackFcn(app, @preview_callbacks, true);
            app.PreviewFrame_X2_Y.Text = '[X/2,Y]';
            app.PreviewFrame_X_Y = uibutton(app.PreviewButtonGrid, 'push');
            app.PreviewFrame_X_Y.ButtonPushedFcn = createCallbackFcn(app, @preview_callbacks, true);
            app.PreviewFrame_X_Y.Text = '[X,Y]';
            app.PreviewFrameGrid = uigridlayout(app.PreviewButtonGrid);
            app.PreviewButton = uibutton(app.PreviewFrameGrid, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @preview_callbacks, true);
            app.PreviewButton.Text = 'Preview';
            app.PvFrXLabel = uilabel(app.PreviewFrameGrid);
            app.PvFrXLabel.Text = 'X';
            app.PreviewFrameX = uispinner(app.PreviewFrameGrid);
            app.PreviewFrameX.RoundFractionalValues = 'on';
            app.PreviewFrameX.ValueDisplayFormat = '%.0f';
            app.PreviewFrameX.ValueChangedFcn = createCallbackFcn(app, @preview_callbacks, true);
            app.PreviewFrameX.Value = 1;
            app.PvFrYLabel = uilabel(app.PreviewFrameGrid);
            app.PvFrYLabel.Text = 'Y';
            app.PreviewFrameY = uispinner(app.PreviewFrameGrid);
            app.PreviewFrameY.RoundFractionalValues = 'on';
            app.PreviewFrameY.ValueDisplayFormat = '%.0f';
            app.PreviewFrameY.ValueChangedFcn = createCallbackFcn(app, @preview_callbacks, true);
            app.PreviewFrameY.Value = 1;
            app.AlignmentTab = uitab(app.ModeTabGroup);
            app.AlignmentTab.Title = 'Align';
            app.AlignmentGrid = uigridlayout(app.AlignmentTab);
            app.DiffractionCalibrationPanel = uipanel(app.AlignmentGrid);
            app.DiffractionCalibrationPanel.Title = 'Diffraction Calibration';
            app.DiffractionCalibrationGrid = uigridlayout(app.DiffractionCalibrationPanel);
            app.ConvergenceAngleLabel = uilabel(app.DiffractionCalibrationGrid);
            app.ConvergenceAngleLabel.Text = 'Convergence Angle';
            app.Alpha = uieditfield(app.DiffractionCalibrationGrid, 'numeric');
            app.Alpha.Limits = [0 Inf];
            app.Alpha.ValueDisplayFormat = '%.2f';
            app.Alpha.ValueChangedFcn = createCallbackFcn(app, @transmitted_beam_callbacks, true);
            app.Alpha.Value = 12;
            app.AlphaLabel = uilabel(app.DiffractionCalibrationGrid);
            app.AlphaLabel.Text = 'mrad';
            app.DiffractionScaleLabel = uilabel(app.DiffractionCalibrationGrid);
            app.DiffractionScaleLabel.Text = 'Diffraction Scale';
            app.mradPx = uieditfield(app.DiffractionCalibrationGrid, 'numeric');
            app.mradPx.Limits = [0 Inf];
            app.mradPx.ValueDisplayFormat = '%.4g';
            app.mradPx.ValueChangedFcn = createCallbackFcn(app, @transmitted_beam_callbacks, true);
            app.mradPx.Value = 1;
            app.mradPxLabel = uilabel(app.DiffractionCalibrationGrid);
            app.mradPxLabel.Text = 'mrad/px';
            app.TransBeamAlignPanel = uipanel(app.AlignmentGrid);
            app.TransBeamAlignPanel.Title = 'Transmitted Beam Position Alignment';
            app.TransBeamAlignGrid = uigridlayout(app.TransBeamAlignPanel);
            app.TBAutoAlign = uibutton(app.TransBeamAlignGrid, 'push');
            app.TBAutoAlign.ButtonPushedFcn = createCallbackFcn(app, @transmitted_beam_callbacks, true);
            app.TBAutoAlign.Text = 'Auto Align';
            app.TBCrossAlign = uibutton(app.TransBeamAlignGrid, 'push');
            app.TBCrossAlign.ButtonPushedFcn = createCallbackFcn(app, @transmitted_beam_callbacks, true);
            app.TBCrossAlign.Tooltip = {'Align transmitted beam location as the crossing point of two lines'};
            app.TBCrossAlign.Text = 'X';
            app.TBRLabel = uilabel(app.TransBeamAlignGrid);
            app.TBRLabel.Text = 'Radius';
            app.TransBeamRSpinner = uispinner(app.TransBeamAlignGrid);
            app.TransBeamRSpinner.Step = 0.25;
            app.TransBeamRSpinner.ValueChangingFcn = createCallbackFcn(app, @transmitted_beam_callbacks, true);
            app.TransBeamRSpinner.ValueDisplayFormat = '%.2f';
            app.TransBeamRSpinner.ValueChangedFcn = createCallbackFcn(app, @transmitted_beam_callbacks, true);
            app.TBR_NFLabel = uilabel(app.TransBeamAlignGrid);
            app.TBR_NFLabel.Text = 'px';
            app.TransBeamR = uislider(app.TransBeamAlignGrid);
            app.TransBeamR.MajorTicks = [];
            app.TransBeamR.ValueChangedFcn = createCallbackFcn(app, @transmitted_beam_callbacks, true);
            app.TransBeamR.ValueChangingFcn = createCallbackFcn(app, @transmitted_beam_callbacks, true);
            app.TransBeamR.MinorTicks = [];
            app.TBXLabel = uilabel(app.TransBeamAlignGrid);
            app.TBXLabel.Text = 'X';
            app.TransBeamXSpinner = uispinner(app.TransBeamAlignGrid);
            app.TransBeamXSpinner.Step = 0.25;
            app.TransBeamXSpinner.ValueChangingFcn = createCallbackFcn(app, @transmitted_beam_callbacks, true);
            app.TransBeamXSpinner.ValueDisplayFormat = '%.2f';
            app.TransBeamXSpinner.ValueChangedFcn = createCallbackFcn(app, @transmitted_beam_callbacks, true);
            app.TBX_NFLabel = uilabel(app.TransBeamAlignGrid);
            app.TBX_NFLabel.Text = 'px';
            app.TransBeamX = uislider(app.TransBeamAlignGrid);
            app.TransBeamX.MajorTicks = [];
            app.TransBeamX.ValueChangedFcn = createCallbackFcn(app, @transmitted_beam_callbacks, true);
            app.TransBeamX.ValueChangingFcn = createCallbackFcn(app, @transmitted_beam_callbacks, true);
            app.TransBeamX.MinorTicks = [];
            app.TBYLabel = uilabel(app.TransBeamAlignGrid);
            app.TBYLabel.Text = 'Y';
            app.TransBeamYSpinner = uispinner(app.TransBeamAlignGrid);
            app.TransBeamYSpinner.Step = 0.25;
            app.TransBeamYSpinner.ValueChangingFcn = createCallbackFcn(app, @transmitted_beam_callbacks, true);
            app.TransBeamYSpinner.ValueDisplayFormat = '%.2f';
            app.TransBeamYSpinner.ValueChangedFcn = createCallbackFcn(app, @transmitted_beam_callbacks, true);
            app.TBY_NFLabel = uilabel(app.TransBeamAlignGrid);
            app.TBY_NFLabel.Text = 'px';
            app.TransBeamY = uislider(app.TransBeamAlignGrid);
            app.TransBeamY.MajorTicks = [];
            app.TransBeamY.ValueChangedFcn = createCallbackFcn(app, @transmitted_beam_callbacks, true);
            app.TransBeamY.ValueChangingFcn = createCallbackFcn(app, @transmitted_beam_callbacks, true);
            app.TransBeamY.MinorTicks = [];
            app.AlignmentHelpIcon = uiimage(app.AlignmentGrid);
            app.AlignmentHelpIcon.Tooltip = {'1. Set "Convergence Angle"'; '2. Align the transmitted beam''s X/Y location'; '3. Switch from "Alignment" to another mode'};
            app.AlignmentHelpIcon.ImageSource = 'help.png';
            app.AnnularDetectorTab = uitab(app.ModeTabGroup);
            app.AnnularDetectorTab.Title = 'Ann';
            app.AnnularDetectorGrid = uigridlayout(app.AnnularDetectorTab);
            app.AnnularRadiiPanel = uipanel(app.AnnularDetectorGrid);
            app.AnnularRadiiPanel.Title = 'Annular Detector Radii';
            app.AnnularRadiiGrid = uigridlayout(app.AnnularRadiiPanel);
            app.AnnRiLabel = uilabel(app.AnnularRadiiGrid);
            app.AnnRiLabel.Text = 'Inner Radius';
            app.InnerAnnularRadiusSpinner = uispinner(app.AnnularRadiiGrid);
            app.InnerAnnularRadiusSpinner.Step = 0.25;
            app.InnerAnnularRadiusSpinner.ValueChangingFcn = createCallbackFcn(app, @annular_detector_callbacks, true);
            app.InnerAnnularRadiusSpinner.ValueDisplayFormat = '%.2f';
            app.InnerAnnularRadiusSpinner.ValueChangedFcn = createCallbackFcn(app, @annular_detector_callbacks, true);
            app.InnerAnnularRadiusSpinner.Tag = 'AnnDetr RI';
            app.AnnRi_NFLabel = uilabel(app.AnnularRadiiGrid);
            app.AnnRi_NFLabel.Text = 'mrad';
            app.InnerAnnularRadius = uislider(app.AnnularRadiiGrid);
            app.InnerAnnularRadius.MajorTicks = [];
            app.InnerAnnularRadius.ValueChangedFcn = createCallbackFcn(app, @annular_detector_callbacks, true);
            app.InnerAnnularRadius.ValueChangingFcn = createCallbackFcn(app, @annular_detector_callbacks, true);
            app.InnerAnnularRadius.MinorTicks = [];
            app.InnerAnnularRadius.Tag = 'AnnDetr RI';
            app.AnnularRadiusLink = uibutton(app.AnnularRadiiGrid, 'state');
            app.AnnularRadiusLink.ValueChangedFcn = createCallbackFcn(app, @annular_detector_callbacks, true);
            app.AnnularRadiusLink.Tag = 'AnnDetr';
            app.AnnularRadiusLink.Tooltip = {'Move inner/outer annular sliders together'};
            app.AnnularRadiusLink.Icon = 'link.png';
            app.AnnularRadiusLink.Text = '';
            app.AnnRoLabel = uilabel(app.AnnularRadiiGrid);
            app.AnnRoLabel.Text = 'Outer Radius';
            app.OuterAnnularRadiusSpinner = uispinner(app.AnnularRadiiGrid);
            app.OuterAnnularRadiusSpinner.Step = 0.25;
            app.OuterAnnularRadiusSpinner.ValueChangingFcn = createCallbackFcn(app, @annular_detector_callbacks, true);
            app.OuterAnnularRadiusSpinner.ValueDisplayFormat = '%.2f';
            app.OuterAnnularRadiusSpinner.ValueChangedFcn = createCallbackFcn(app, @annular_detector_callbacks, true);
            app.OuterAnnularRadiusSpinner.Tag = 'AnnDetr RO';
            app.AnnRo_NFLabel = uilabel(app.AnnularRadiiGrid);
            app.AnnRo_NFLabel.Text = 'mrad';
            app.OuterAnnularRadius = uislider(app.AnnularRadiiGrid);
            app.OuterAnnularRadius.MajorTicks = [];
            app.OuterAnnularRadius.ValueChangedFcn = createCallbackFcn(app, @annular_detector_callbacks, true);
            app.OuterAnnularRadius.ValueChangingFcn = createCallbackFcn(app, @annular_detector_callbacks, true);
            app.OuterAnnularRadius.MinorTicks = [];
            app.OuterAnnularRadius.Tag = 'AnnDetr RO';
            app.ScanDirectionPanel = uipanel(app.AnnularDetectorGrid);
            app.ScanDirectionPanel.Title = 'Scanning Direction';
            app.ScanDirectionPanel.Visible = 'off';
            app.ScanDirectionGrid = uigridlayout(app.ScanDirectionPanel);
            app.ScanDirectionLock = uibutton(app.ScanDirectionGrid, 'state');
            app.ScanDirectionLock.ValueChangedFcn = createCallbackFcn(app, @annular_detector_callbacks, true);
            app.ScanDirectionLock.Tag = 'ScanDir';
            app.ScanDirectionLock.Icon = 'unlock.png';
            app.ScanDirectionLock.Text = '';
            app.AutoCurl = uibutton(app.ScanDirectionGrid, 'push');
            app.AutoCurl.ButtonPushedFcn = createCallbackFcn(app, @annular_detector_callbacks, true);
            app.AutoCurl.Text = 'Auto';
            app.ScanDirLabel = uilabel(app.ScanDirectionGrid);
            app.ScanDirLabel.Text = 'Angle';

            app.ScanDirectionSpinner = uispinner(app.ScanDirectionGrid);
            app.ScanDirectionSpinner.Step = 4;
            app.ScanDirectionSpinner.ValueChangingFcn = createCallbackFcn(app, @annular_detector_callbacks, true);
            app.ScanDirectionSpinner.Limits = [-180 180];
            app.ScanDirectionSpinner.ValueDisplayFormat = '%.2f';
            app.ScanDirectionSpinner.ValueChangedFcn = createCallbackFcn(app, @annular_detector_callbacks, true);
            app.ScanDirectionSpinner.Tag = 'ScanDir';
            app.ScanDir_NFLabel = uilabel(app.ScanDirectionGrid);
            app.ScanDir_NFLabel.Text = '°';
            app.FlipScanDirectionY = uibutton(app.ScanDirectionGrid, 'state');
            app.FlipScanDirectionY.ValueChangedFcn = createCallbackFcn(app, @annular_detector_callbacks, true);
            app.FlipScanDirectionY.Tag = 'ScanDir';
            app.FlipScanDirectionY.Tooltip = {'Flip Scan Y Direction'};
            app.FlipScanDirectionY.Icon = 'axis-y.png';
            app.FlipScanDirectionY.Text = '';
            app.ScanDirectionSlider = uislider(app.ScanDirectionGrid);
            app.ScanDirectionSlider.Limits = [-180 180];
            app.ScanDirectionSlider.MajorTicks = [];
            app.ScanDirectionSlider.ValueChangedFcn = createCallbackFcn(app, @annular_detector_callbacks, true);
            app.ScanDirectionSlider.ValueChangingFcn = createCallbackFcn(app, @annular_detector_callbacks, true);
            app.ScanDirectionSlider.MinorTicks = [];
            app.ScanDirectionSlider.Tag = 'ScanDir';
            app.SegmentedDetectorPanel = uipanel(app.AnnularDetectorGrid);
            app.SegmentedDetectorPanel.Title = 'Segmented Detector Controls';
            app.SegmentedDetectorPanel.Visible = 'off';
            app.SegmentedDetectorGrid = uigridlayout(app.SegmentedDetectorPanel);
            app.SegmentsLabel = uilabel(app.SegmentedDetectorGrid);
            app.SegmentsLabel.Text = 'Segments';
            app.NSeg = uispinner(app.SegmentedDetectorGrid);
            app.NSeg.Limits = [2 32];
            app.NSeg.RoundFractionalValues = 'on';
            app.NSeg.ValueDisplayFormat = '%d';
            app.NSeg.ValueChangedFcn = createCallbackFcn(app, @annular_detector_callbacks, true);
            app.NSeg.Tag = 'SegDetr';
            app.NSeg.Value = 4;
            app.RungsLabel = uilabel(app.SegmentedDetectorGrid);
            app.RungsLabel.Text = 'Rungs';
            app.NRung = uispinner(app.SegmentedDetectorGrid);
            app.NRung.Limits = [1 16];
            app.NRung.RoundFractionalValues = 'on';
            app.NRung.ValueDisplayFormat = '%d';
            app.NRung.ValueChangedFcn = createCallbackFcn(app, @annular_detector_callbacks, true);
            app.NRung.Tag = 'SegDetr';
            app.NRung.Value = 1;
            app.DetRotLabel = uilabel(app.SegmentedDetectorGrid);
            app.DetRotLabel.Text = 'Rotation';
            app.DetectorRotationSpinner = uispinner(app.SegmentedDetectorGrid);
            app.DetectorRotationSpinner.Step = 4;
            app.DetectorRotationSpinner.ValueChangingFcn = createCallbackFcn(app, @annular_detector_callbacks, true);
            app.DetectorRotationSpinner.Limits = [-180 180];
            app.DetectorRotationSpinner.ValueDisplayFormat = '%.2f';
            app.DetectorRotationSpinner.ValueChangedFcn = createCallbackFcn(app, @annular_detector_callbacks, true);
            app.DetectorRotationSpinner.Tag = 'SegDetr';
            app.DetRot_NFLabel = uilabel(app.SegmentedDetectorGrid);
            app.DetRot_NFLabel.Text = '°';
            app.DetectorRotationSlider = uislider(app.SegmentedDetectorGrid);
            app.DetectorRotationSlider.Limits = [-180 180];
            app.DetectorRotationSlider.MajorTicks = [];
            app.DetectorRotationSlider.ValueChangedFcn = createCallbackFcn(app, @annular_detector_callbacks, true);
            app.DetectorRotationSlider.ValueChangingFcn = createCallbackFcn(app, @annular_detector_callbacks, true);
            app.DetectorRotationSlider.MinorTicks = [];
            app.DetectorRotationSlider.Tag = 'SegDetr';
            app.AnnularStepGrid = uigridlayout(app.AnnularDetectorGrid);
            app.AnnularIntegrationStepEditFieldLabel = uilabel(app.AnnularStepGrid);
            app.AnnularIntegrationStepEditFieldLabel.Text = 'Annular Integration Step';
            app.AnnularStep = uieditfield(app.AnnularStepGrid, 'numeric');
            app.AnnularStep.Limits = [1e-05 Inf];
            app.AnnularStep.ValueDisplayFormat = '%11.2f mrad';
            app.AnnularStep.ValueChangedFcn = createCallbackFcn(app, @detector_coordinates_callbacks, true);
            app.AnnularStep.Tooltip = {'Set fine/coarse step size for annular integration (in mrad). '};
            app.AnnularStep.Value = 0.5;
            app.VirtualApertureTab = uitab(app.ModeTabGroup);
            app.VirtualApertureTab.Title = 'Vr';
            app.VirtualApertureGrid = uigridlayout(app.VirtualApertureTab);
            app.VirtualApertureCoordinatesPanel = uipanel(app.VirtualApertureGrid);
            app.VirtualApertureCoordinatesPanel.Title = 'Aperture Coordinates';
            app.VirtualApertureCoordinatesGrid = uigridlayout(app.VirtualApertureCoordinatesPanel);
            app.VrApRLabel = uilabel(app.VirtualApertureCoordinatesGrid);
            app.VrApRLabel.Text = 'Radius';
            app.VirtualApertureRSpinner = uispinner(app.VirtualApertureCoordinatesGrid);
            app.VirtualApertureRSpinner.Step = 0.25;
            app.VirtualApertureRSpinner.ValueChangingFcn = createCallbackFcn(app, @virtual_aperture_callbacks, true);
            app.VirtualApertureRSpinner.ValueDisplayFormat = '%.2f';
            app.VirtualApertureRSpinner.ValueChangedFcn = createCallbackFcn(app, @virtual_aperture_callbacks, true);
            app.VrApR_NFLabel = uilabel(app.VirtualApertureCoordinatesGrid);
            app.VrApR_NFLabel.Text = 'mrad';
            app.VirtualApertureR = uislider(app.VirtualApertureCoordinatesGrid);
            app.VirtualApertureR.MajorTicks = [];
            app.VirtualApertureR.ValueChangedFcn = createCallbackFcn(app, @virtual_aperture_callbacks, true);
            app.VirtualApertureR.ValueChangingFcn = createCallbackFcn(app, @virtual_aperture_callbacks, true);
            app.VirtualApertureR.MinorTicks = [];
            app.VrApXLabel = uilabel(app.VirtualApertureCoordinatesGrid);
            app.VrApXLabel.Text = 'X';
            app.VirtualApertureXSpinner = uispinner(app.VirtualApertureCoordinatesGrid);
            app.VirtualApertureXSpinner.Step = 0.25;
            app.VirtualApertureXSpinner.ValueChangingFcn = createCallbackFcn(app, @virtual_aperture_callbacks, true);
            app.VirtualApertureXSpinner.ValueDisplayFormat = '%.2f';
            app.VirtualApertureXSpinner.ValueChangedFcn = createCallbackFcn(app, @virtual_aperture_callbacks, true);
            app.VrApX_NFLabel = uilabel(app.VirtualApertureCoordinatesGrid);
            app.VrApX_NFLabel.Text = 'mrad';
            app.VirtualApertureX = uislider(app.VirtualApertureCoordinatesGrid);
            app.VirtualApertureX.MajorTicks = [];
            app.VirtualApertureX.ValueChangedFcn = createCallbackFcn(app, @virtual_aperture_callbacks, true);
            app.VirtualApertureX.ValueChangingFcn = createCallbackFcn(app, @virtual_aperture_callbacks, true);
            app.VirtualApertureX.MinorTicks = [];
            app.VrApYLabel = uilabel(app.VirtualApertureCoordinatesGrid);
            app.VrApYLabel.Text = 'Y';
            app.VirtualApertureYSpinner = uispinner(app.VirtualApertureCoordinatesGrid);
            app.VirtualApertureYSpinner.Step = 0.25;
            app.VirtualApertureYSpinner.ValueChangingFcn = createCallbackFcn(app, @virtual_aperture_callbacks, true);
            app.VirtualApertureYSpinner.ValueDisplayFormat = '%.2f';
            app.VirtualApertureYSpinner.ValueChangedFcn = createCallbackFcn(app, @virtual_aperture_callbacks, true);
            app.VrApY_NFLabel = uilabel(app.VirtualApertureCoordinatesGrid);
            app.VrApY_NFLabel.Text = 'mrad';
            app.VirtualApertureY = uislider(app.VirtualApertureCoordinatesGrid);
            app.VirtualApertureY.MajorTicks = [];
            app.VirtualApertureY.ValueChangedFcn = createCallbackFcn(app, @virtual_aperture_callbacks, true);
            app.VirtualApertureY.ValueChangingFcn = createCallbackFcn(app, @virtual_aperture_callbacks, true);
            app.VirtualApertureY.MinorTicks = [];
            app.VirtualApertureRotationGrid = uigridlayout(app.VirtualApertureCoordinatesGrid);
            app.VirtualApertureReset = uibutton(app.VirtualApertureRotationGrid, 'push');
            app.VirtualApertureReset.ButtonPushedFcn = createCallbackFcn(app, @virtual_aperture_callbacks, true);
            app.VirtualApertureReset.Icon = 'home.png';
            app.VirtualApertureReset.Tooltip = {'Home'};
            app.VirtualApertureReset.Text = '';
            app.VirtualApertureRotateCCW = uibutton(app.VirtualApertureRotationGrid, 'push');
            app.VirtualApertureRotateCCW.ButtonPushedFcn = createCallbackFcn(app, @virtual_aperture_callbacks, true);
            app.VirtualApertureRotateCCW.Icon = 'left_rotate.png';
            app.VirtualApertureRotateCCW.Text = '';
            app.VirtualApertureRotationStep = uidropdown(app.VirtualApertureRotationGrid);
            app.VirtualApertureRotationStep.Items = {'90°', '60°', '45°', '30°', '22.5°', '10°', '5°', '2°', '1°', '0.5°', '0.25°'};
            app.VirtualApertureRotationStep.ItemsData = [90 60 45 30 22.5 10 5 2 1 0.5 0.25];
            app.VirtualApertureRotationStep.ValueChangedFcn = createCallbackFcn(app, @virtual_aperture_callbacks, true);
            app.VirtualApertureRotationStep.Value = 90;
            app.VirtualApertureRotateCW = uibutton(app.VirtualApertureRotationGrid, 'push');
            app.VirtualApertureRotateCW.ButtonPushedFcn = createCallbackFcn(app, @virtual_aperture_callbacks, true);
            app.VirtualApertureRotateCW.Icon = 'right_rotate.png';
            app.VirtualApertureRotateCW.Text = '';
            app.VirtualApertureInvert = uibutton(app.VirtualApertureRotationGrid, 'state');
            app.VirtualApertureInvert.ValueChangedFcn = createCallbackFcn(app, @virtual_aperture_callbacks, true);
            app.VirtualApertureInvert.Tooltip = {'Invert virtual aperture mask'};
            app.VirtualApertureInvert.Icon = 'invertColors.png';
            app.VirtualApertureInvert.Text = '';
            app.AnnularStepGrid_2 = uigridlayout(app.VirtualApertureRotationGrid);
            app.AnnularIntegrationStepEditFieldLabel_2 = uilabel(app.AnnularStepGrid_2);
            app.AnnularIntegrationStepEditFieldLabel_2.Text = 'Annular Integration Step';
            app.AnnularStep_2 = uieditfield(app.AnnularStepGrid_2, 'numeric');
            app.AnnularStep_2.Limits = [1e-05 Inf];
            app.AnnularStep_2.ValueDisplayFormat = '%11.2f mrad';
            app.AnnularStep_2.Value = 0.5;
            app.VirtualApertureSymmetryPanel = uipanel(app.VirtualApertureGrid);
            app.VirtualApertureSymmetryPanel.Title = 'Symmetry Operations';
            app.VirtualApertureSymmetryGrid = uigridlayout(app.VirtualApertureSymmetryPanel);
            app.VrApSymmLabel = uilabel(app.VirtualApertureSymmetryGrid);
            app.VrApSymmLabel.Text = 'Rotational Symmetry';
            app.VirtualApertureSymmetry = uidropdown(app.VirtualApertureSymmetryGrid);
            app.VirtualApertureSymmetry.Items = {'1', '2', '3', '4', '6'};
            app.VirtualApertureSymmetry.ItemsData = [1 2 3 4 6];
            app.VirtualApertureSymmetry.ValueChangedFcn = createCallbackFcn(app, @virtual_aperture_callbacks, true);
            app.VirtualApertureSymmetry.Value = 1;
            app.VirtualApertureMirrorGrid = uigridlayout(app.VirtualApertureSymmetryGrid);
            app.VirtualApertureMirror = uibutton(app.VirtualApertureMirrorGrid, 'state');
            app.VirtualApertureMirror.ValueChangedFcn = createCallbackFcn(app, @virtual_aperture_callbacks, true);
            app.VirtualApertureMirror.Tooltip = {'Mirrored'};
            app.VirtualApertureMirror.Icon = 'mirrored.png';
            app.VirtualApertureMirror.Text = '';
            app.VrApMirrRotLabel = uilabel(app.VirtualApertureMirrorGrid);
            app.VrApMirrRotLabel.Text = 'Mirror Rotation';
            app.VirtualApertureMirrorRotationSpinner = uispinner(app.VirtualApertureMirrorGrid);
            app.VirtualApertureMirrorRotationSpinner.Step = 4;
            app.VirtualApertureMirrorRotationSpinner.ValueChangingFcn = createCallbackFcn(app, @virtual_aperture_callbacks, true);
            app.VirtualApertureMirrorRotationSpinner.Limits = [-180 180];
            app.VirtualApertureMirrorRotationSpinner.ValueDisplayFormat = '%.2f';
            app.VirtualApertureMirrorRotationSpinner.ValueChangedFcn = createCallbackFcn(app, @virtual_aperture_callbacks, true);
            app.VrApMirrRot_NFLabel = uilabel(app.VirtualApertureMirrorGrid);
            app.VrApMirrRot_NFLabel.Text = '°';
            app.VirtualApertureMirrorRotation = uislider(app.VirtualApertureMirrorGrid);
            app.VirtualApertureMirrorRotation.Limits = [-180 180];
            app.VirtualApertureMirrorRotation.MajorTicks = [];
            app.VirtualApertureMirrorRotation.ValueChangedFcn = createCallbackFcn(app, @virtual_aperture_callbacks, true);
            app.VirtualApertureMirrorRotation.ValueChangingFcn = createCallbackFcn(app, @virtual_aperture_callbacks, true);
            app.VirtualApertureMirrorRotation.MinorTicks = [];
            app.CustomDetectorTab = uitab(app.ModeTabGroup);
            app.CustomDetectorTab.Title = 'C';
            app.CustomDetectorGrid = uigridlayout(app.CustomDetectorTab);
            app.CustomDetectorNewMaskGrid = uigridlayout(app.CustomDetectorGrid);
            app.CustomDetectorNewCircle = uibutton(app.CustomDetectorNewMaskGrid, 'push');
            app.CustomDetectorNewCircle.ButtonPushedFcn = createCallbackFcn(app, @custom_detector_callbacks, true);
            app.CustomDetectorNewCircle.Icon = 'circle.png';
            app.CustomDetectorNewCircle.Tooltip = {'Add circular mask'};
            app.CustomDetectorNewCircle.Text = '';
            app.CustomDetectorNewGrid = uibutton(app.CustomDetectorNewMaskGrid, 'push');
            app.CustomDetectorNewGrid.ButtonPushedFcn = createCallbackFcn(app, @custom_detector_callbacks, true);
            app.CustomDetectorNewGrid.Icon = fullfile(pathToMLAPP, 'icons', 'grid.png');
            app.CustomDetectorNewGrid.Tooltip = {'Add grid mask'};
            app.CustomDetectorNewGrid.Text = '';
            app.CustomDetectorNewBandpass = uibutton(app.CustomDetectorNewMaskGrid, 'push');
            app.CustomDetectorNewBandpass.ButtonPushedFcn = createCallbackFcn(app, @custom_detector_callbacks, true);
            app.CustomDetectorNewBandpass.Icon = 'bandpass.png';
            app.CustomDetectorNewBandpass.Tooltip = {'Add bandpass mask'};
            app.CustomDetectorNewBandpass.Text = '';
            app.CustomDetectorNewWedge = uibutton(app.CustomDetectorNewMaskGrid, 'push');
            app.CustomDetectorNewWedge.ButtonPushedFcn = createCallbackFcn(app, @custom_detector_callbacks, true);
            app.CustomDetectorNewWedge.Icon = 'wedge.png';
            app.CustomDetectorNewWedge.Tooltip = {'Add wedge mask'};
            app.CustomDetectorNewWedge.Text = '';
            app.CustomDetectorNewPolygon = uibutton(app.CustomDetectorNewMaskGrid, 'push');
            app.CustomDetectorNewPolygon.ButtonPushedFcn = createCallbackFcn(app, @custom_detector_callbacks, true);
            app.CustomDetectorNewPolygon.Icon = 'poly.png';
            app.CustomDetectorNewPolygon.Tooltip = {'Add polygon mask'};
            app.CustomDetectorNewPolygon.Text = '';
            app.CustomDetectorNewFromFile = uibutton(app.CustomDetectorNewMaskGrid, 'push');
            app.CustomDetectorNewFromFile.ButtonPushedFcn = createCallbackFcn(app, @custom_detector_callbacks, true);
            app.CustomDetectorNewFromFile.Icon = 'openMask.png';
            app.CustomDetectorNewFromFile.Tooltip = {'Add mask from file'};
            app.CustomDetectorNewFromFile.Text = '';
            app.CustomDetectorNewMath = uibutton(app.CustomDetectorNewMaskGrid, 'push');
            app.CustomDetectorNewMath.ButtonPushedFcn = createCallbackFcn(app, @custom_detector_callbacks, true);
            app.CustomDetectorNewMath.Icon = 'math.png';
            app.CustomDetectorNewMath.Tooltip = {'Add mask from formula'};
            app.CustomDetectorNewMath.Text = '';
            app.CustomDetectorNewGridNoCenter = uibutton(app.CustomDetectorNewMaskGrid, 'push');
            app.CustomDetectorNewGridNoCenter.ButtonPushedFcn = createCallbackFcn(app, @custom_detector_callbacks, true);
            app.CustomDetectorNewGridNoCenter.Icon = fullfile(pathToMLAPP, 'icons', 'grid_no_tb.png');
            app.CustomDetectorNewGridNoCenter.Tooltip = {'Add grid mask without including transmitted beam'};
            app.CustomDetectorNewGridNoCenter.Text = '';
            app.CustomDetectorInterMaskGrid = uigridlayout(app.CustomDetectorGrid);
            app.ShowDiffractionMaskWindow = uibutton(app.CustomDetectorInterMaskGrid, 'push');
            app.ShowDiffractionMaskWindow.ButtonPushedFcn = createCallbackFcn(app, @show_window, true);
            app.ShowDiffractionMaskWindow.Tag = 'DiffractionMask';
            app.ShowDiffractionMaskWindow.Icon = 'Aperture.png';
            app.ShowDiffractionMaskWindow.Tooltip = {'Show Diffraction Mask Image'};
            app.ShowDiffractionMaskWindow.Text = '';
            app.IntermaskLabel = uilabel(app.CustomDetectorInterMaskGrid);
            app.IntermaskLabel.Text = 'Inter-mask';
            app.CustomDetectorInterMask = uidropdown(app.CustomDetectorInterMaskGrid);
            app.CustomDetectorInterMask.Items = {'Color Mix', 'Union', 'Intersection', 'Additive', 'Current-only'};
            app.CustomDetectorInterMask.ValueChangedFcn = createCallbackFcn(app, @custom_detector_callbacks, true);
            app.CustomDetectorInterMask.Tooltip = {'Combine different custom masks as:'; 'Union, e.g. Final = A ∪ B ∪ C;'; 'Intersection, e.g. Final = A ∩ B ∩ C;'; 'Additive, e.g. Final = A*WtA + B*WtB + C*WtC;'; 'Current-only, i.e. only the selected mask.'; 'Zero-weighted masks are always ignored. Weightings have no effect in Union and Intersection. Weightings are applied unnormalised in Additive and Current-only.'};
            app.CustomDetectorInterMask.Value = 'Color Mix';
            app.CustomDetectorDeleteMask = uibutton(app.CustomDetectorInterMaskGrid, 'push');
            app.CustomDetectorDeleteMask.ButtonPushedFcn = createCallbackFcn(app, @custom_detector_callbacks, true);
            app.CustomDetectorDeleteMask.Icon = 'delete.png';
            app.CustomDetectorDeleteMask.Tooltip = {'Delete seleced mask'};
            app.CustomDetectorDeleteMask.Text = '';
            app.CustomDetectorTable = uitable(app.CustomDetectorGrid);
            app.CustomDetectorTable.ColumnName = {'ID'; 'Type'; 'Label'; '✅'; '👁'; 'μ'};
            app.CustomDetectorTable.RowName = {};
            app.CustomDetectorTable.ColumnSortable = [false true true false false false];
            app.CustomDetectorTable.ColumnEditable = [false false true true true true];
            app.CustomDetectorTable.CellEditCallback = createCallbackFcn(app, @custom_detector_callbacks, true);
            app.CustomDetectorTable.CellSelectionCallback = createCallbackFcn(app, @custom_detector_callbacks, true);
            app.CustomDetectorTable.Tooltip = {'✅: Enable mask.'; '👁: Show annotation on pattern.'; 'μ: Weighting.'};
            app.CustomDetectorDetailsPanel = uipanel(app.CustomDetectorGrid);
            app.CustomDetectorDetailsGrid = uigridlayout(app.CustomDetectorDetailsPanel);
            app.CustomDetectorRotationGrid = uigridlayout(app.CustomDetectorDetailsGrid);
            app.CDIntraCombLabel = uilabel(app.CustomDetectorRotationGrid);
            app.CDIntraCombLabel.Text = 'Intra-mask';
            app.CustomDetectorIntraMask = uidropdown(app.CustomDetectorRotationGrid);
            app.CustomDetectorIntraMask.Items = {'Union', 'Intersection', 'Additive'};
            app.CustomDetectorIntraMask.ValueChangedFcn = createCallbackFcn(app, @custom_detector_callbacks, true);
            app.CustomDetectorIntraMask.Tooltip = {'Combine different regions in mask as:'; 'Union, e.g. A = A1 ∪ A2 ∪ A3;'; 'Intersection, e.g. A = A1 ∩ A2 ∩ A3;'; 'Additive, e.g. A = A1 + A2 + A3.'};
            app.CustomDetectorIntraMask.Value = 'Union';
            app.CustomDetectorRotationStep = uidropdown(app.CustomDetectorRotationGrid);
            app.CustomDetectorRotationStep.Items = {'180°', '90°', '60°', '45°', '30°', '22.5°', '10°', '5°', '2°', '1°', '0.5°', '0.25°'};
            app.CustomDetectorRotationStep.ItemsData = [180 90 60 45 30 22.5 10 5 2 1 0.5 0.25];
            app.CustomDetectorRotationStep.Tooltip = {'Mask rotation step size'};
            app.CustomDetectorRotationStep.Value = 90;
            app.CustomDetectorRotateCCW = uibutton(app.CustomDetectorRotationGrid, 'push');
            app.CustomDetectorRotateCCW.ButtonPushedFcn = createCallbackFcn(app, @custom_detector_callbacks, true);
            app.CustomDetectorRotateCCW.Icon = 'left_rotate.png';
            app.CustomDetectorRotateCCW.Tooltip = {'Rotate mask'};
            app.CustomDetectorRotateCCW.Text = '';
            app.CustomDetectorRotateCW = uibutton(app.CustomDetectorRotationGrid, 'push');
            app.CustomDetectorRotateCW.ButtonPushedFcn = createCallbackFcn(app, @custom_detector_callbacks, true);
            app.CustomDetectorRotateCW.Icon = 'right_rotate.png';
            app.CustomDetectorRotateCW.Tooltip = {'Rotate mask'};
            app.CustomDetectorRotateCW.Text = '';
            app.CustomDetectorMirror = uibutton(app.CustomDetectorDetailsGrid, 'state');
            app.CustomDetectorMirror.ValueChangedFcn = createCallbackFcn(app, @custom_detector_callbacks, true);
            app.CustomDetectorMirror.Tooltip = {'Mirrored'};
            app.CustomDetectorMirror.Icon = 'mirrored.png';
            app.CustomDetectorMirror.Text = '';
            app.CustomDetectorDetailsTable = uitable(app.CustomDetectorDetailsGrid);
            app.CustomDetectorDetailsTable.ColumnName = {'X'; 'Y'; 'R'};
            app.CustomDetectorDetailsTable.RowName = {};
            app.CustomDetectorDetailsTable.ColumnSortable = true;
            app.CustomDetectorDetailsTable.ColumnEditable = true;
            app.CustomDetectorDetailsTable.CellEditCallback = createCallbackFcn(app, @custom_detector_callbacks, true);
            app.CustomDetectorInvert = uibutton(app.CustomDetectorDetailsGrid, 'state');
            app.CustomDetectorInvert.ValueChangedFcn = createCallbackFcn(app, @custom_detector_callbacks, true);
            app.CustomDetectorInvert.Tooltip = {'Invert mask'};
            app.CustomDetectorInvert.Icon = 'invertColors.png';
            app.CustomDetectorInvert.Text = '';
            app.CustomDetectorColor = uibutton(app.CustomDetectorDetailsGrid, 'push');
            app.CustomDetectorColor.ButtonPushedFcn = createCallbackFcn(app, @custom_detector_callbacks, true);
            app.CustomDetectorColor.Icon = 'colorPicker.png';
            app.CustomDetectorColor.Tooltip = {'Change color of current mask display'};
            app.CustomDetectorColor.Text = '';
            app.CustomDetectorFlipHorizontal = uibutton(app.CustomDetectorDetailsGrid, 'state');
            app.CustomDetectorFlipHorizontal.ValueChangedFcn = createCallbackFcn(app, @custom_detector_callbacks, true);
            app.CustomDetectorFlipHorizontal.Icon = fullfile(pathToMLAPP, 'icons', 'mirrorHorz.png');
            app.CustomDetectorFlipHorizontal.Text = '';
            app.CustomDetectorFlipVertical = uibutton(app.CustomDetectorDetailsGrid, 'state');
            app.CustomDetectorFlipVertical.ValueChangedFcn = createCallbackFcn(app, @custom_detector_callbacks, true);
            app.CustomDetectorFlipVertical.Icon = fullfile(pathToMLAPP, 'icons', 'mirrorVert.png');
            app.CustomDetectorFlipVertical.Text = '';
            app.CustomDetectorTranspose = uibutton(app.CustomDetectorDetailsGrid, 'state');
            app.CustomDetectorTranspose.ValueChangedFcn = createCallbackFcn(app, @custom_detector_callbacks, true);
            app.CustomDetectorTranspose.Icon = fullfile(pathToMLAPP, 'icons', 'transpose.png');
            app.CustomDetectorTranspose.Text = '';
            app.Quant4D_FigGrid = uigridlayout(app.Quant4D_Fig);
            app.ShortcutButtonGrid = uigridlayout(app.Quant4D_FigGrid);
            app.ShowImportWindow = uibutton(app.ShortcutButtonGrid, 'push');
            app.ShowImportWindow.ButtonPushedFcn = createCallbackFcn(app, @import_callbacks, true);
            app.ShowImportWindow.Icon = 'import.png';
            app.ShowImportWindow.Tooltip = {'Import Data'};
            app.ShowImportWindow.Text = 'Import';
            app.ShowSaveWindow = uibutton(app.ShortcutButtonGrid, 'push');
            app.ShowSaveWindow.ButtonPushedFcn = createCallbackFcn(app, @save_callbacks, true);
            app.ShowSaveWindow.Icon = 'save.png';
            app.ShowSaveWindow.Tooltip = {'Open the Saving/Export window'};
            app.ShowSaveWindow.Text = '';
            app.AutoSaveImage = uibutton(app.ShortcutButtonGrid, 'push');
            app.AutoSaveImage.ButtonPushedFcn = createCallbackFcn(app, @save_callbacks, true);
            app.AutoSaveImage.Icon = 'autoSave.png';
            app.AutoSaveImage.Tooltip = {'Repeat Previous Image Saving Operation'};
            app.AutoSaveImage.Text = '';
            app.UpdateImages = uibutton(app.ShortcutButtonGrid, 'push');
            app.UpdateImages.ButtonPushedFcn = createCallbackFcn(app, @update_images, true);
            app.UpdateImages.Icon = 'refresh.png';
            app.UpdateImages.Tooltip = {'Update Images (F5)'};
            app.UpdateImages.Text = '';
            app.ShowSettingsWindow = uibutton(app.ShortcutButtonGrid, 'push');
            app.ShowSettingsWindow.ButtonPushedFcn = createCallbackFcn(app, @show_window, true);
            app.ShowSettingsWindow.Tag = 'Settings';
            app.ShowSettingsWindow.Icon = fullfile(pathToMLAPP, 'icons', 'settings.png');
            app.ShowSettingsWindow.Tooltip = {'Show Detector Controls'};
            app.ShowSettingsWindow.Text = '';
            app.ModeGrid = uigridlayout(app.Quant4D_FigGrid);
            app.Mode = uidropdown(app.ModeGrid);
            app.Mode.Items = {'Import Preview', 'Alignment', 'Annular/Round', 'Segmented (DPC)', 'Center of Mass', 'Virtual Aperture', 'Custom Detectors'};
            app.Mode.ItemsData = {'Preview', 'Alignment', 'Annular', 'DPC', 'CoM', 'Virtual', 'Custom'};
            app.Mode.ValueChangedFcn = createCallbackFcn(app, @detector_mode_callbacks, true);
            app.Mode.Tooltip = {'Diffraction Detector Mode'};
            app.Mode.Value = 'Preview';
            app.ModePanel = uipanel(app.Quant4D_FigGrid);
            app.RealPanelGrid = uigridlayout(app.Quant4D_FigGrid);
            app.RealPanel = uipanel(app.RealPanelGrid);
            app.RealPanel.Tooltip = {'Real-space region of interest (ROI)'};
            app.RealPanel.Title = 'Real-space Controls';
            app.RealPanel.Visible = 'off';
            app.RealGrid = uigridlayout(app.RealPanel);
            app.ROIShapeLabel = uilabel(app.RealGrid);
            app.ROIShapeLabel.Text = 'ROI Shape';
            app.RealROIShape = uidropdown(app.RealGrid);
            app.RealROIShape.Items = {'Full Image', 'Point', 'Ellipse', 'Rectangle', 'Draw Polygon', 'From File'};
            app.RealROIShape.ItemsData = {'full', 'point', 'ellipse', 'rectangle', 'poly', 'file'};
            app.RealROIShape.ValueChangedFcn = createCallbackFcn(app, @realspace_ROI_callbacks, true);
            app.RealROIShape.Value = 'full';
            app.RealROIInvert = uibutton(app.RealGrid, 'state');
            app.RealROIInvert.ValueChangedFcn = createCallbackFcn(app, @realspace_ROI_callbacks, true);
            app.RealROIInvert.Tooltip = {'Invert real-space mask'};
            app.RealROIInvert.Icon = 'invertColors.png';
            app.RealROIInvert.Text = '';
            app.ShowRealMaskWindow = uibutton(app.RealGrid, 'push');
            app.ShowRealMaskWindow.ButtonPushedFcn = createCallbackFcn(app, @show_window, true);
            app.ShowRealMaskWindow.Tag = 'RealMask';
            app.ShowRealMaskWindow.Icon = 'Aperture.png';
            app.ShowRealMaskWindow.Tooltip = {'Show Real-space Mask'};
            app.ShowRealMaskWindow.Text = '';
            app.RealROIFrameLabel = uilabel(app.RealGrid);
            app.RealROIFrameLabel.Text = 'Frame';
            app.RealROIGrid = uigridlayout(app.RealGrid);
            app.RealXLabel = uilabel(app.RealROIGrid);
            app.RealXLabel.Text = 'X';
            app.RealROIFrameX = uispinner(app.RealROIGrid);
            app.RealROIFrameX.ValueChangingFcn = createCallbackFcn(app, @realspace_ROI_callbacks, true);
            app.RealROIFrameX.RoundFractionalValues = 'on';
            app.RealROIFrameX.ValueDisplayFormat = '%.0f';
            app.RealROIFrameX.ValueChangedFcn = createCallbackFcn(app, @realspace_ROI_callbacks, true);
            app.RealYLabel = uilabel(app.RealROIGrid);
            app.RealYLabel.Text = 'Y';
            app.RealROIFrameY = uispinner(app.RealROIGrid);
            app.RealROIFrameY.ValueChangingFcn = createCallbackFcn(app, @realspace_ROI_callbacks, true);
            app.RealROIFrameY.RoundFractionalValues = 'on';
            app.RealROIFrameY.ValueDisplayFormat = '%.0f';
            app.RealROIFrameY.ValueChangedFcn = createCallbackFcn(app, @realspace_ROI_callbacks, true);
            app.diffraction_dropdown = uidropdown(app.Quant4D_Fig);
            app.diffraction_dropdown.Items = {'sum', 'mean', 'max', 'std', 'sqrt', 'ln', 'log10'};
            app.diffraction_dropdown.Tooltip = {'Set how patterns are combined in real space. NOTE: max() and std() can be significantly slower when interactiving with a real space ROI!'};
            app.diffraction_dropdown.Value = 'sum';
            app.DebugContextMenu = uicontextmenu(app.Quant4D_Fig);
            app.Test1Menu = uimenu(app.DebugContextMenu);
            app.Test1Menu.MenuSelectedFcn = createCallbackFcn(app, @test1, true);
            app.Test1Menu.Text = 'Test1';
            app.Test2Menu = uimenu(app.DebugContextMenu);
            app.Test2Menu.MenuSelectedFcn = createCallbackFcn(app, @test2, true);
            app.Test2Menu.Text = 'Test2';
            app.SaveVecMenu = uimenu(app.DebugContextMenu);
            app.SaveVecMenu.MenuSelectedFcn = createCallbackFcn(app, @first_moment, true);
            app.SaveVecMenu.Text = 'SaveVec';
            app.ReimportMenu = uimenu(app.DebugContextMenu);
            app.ReimportMenu.MenuSelectedFcn = createCallbackFcn(app, @import_callbacks, true);
            app.ReimportMenu.Text = 'Re-import';
            app.ResetQuant4DMenu = uimenu(app.DebugContextMenu);
            app.ResetQuant4DMenu.MenuSelectedFcn = createCallbackFcn(app, @reset_Quant4D, true);
            app.ResetQuant4DMenu.Text = 'ResetQuant4D';
            app.EnableallUIsMenu = uimenu(app.DebugContextMenu);
            app.EnableallUIsMenu.MenuSelectedFcn = createCallbackFcn(app, @enable_all_UI, true);
            app.EnableallUIsMenu.Text = 'Enable all UIs';
            % Assign app.DebugContextMenu
            app.ShowImportWindow.ContextMenu = app.DebugContextMenu;
            app.CDDelContext = uicontextmenu(app.Quant4D_Fig);
            app.CDDelResetMenu = uimenu(app.CDDelContext);
            app.CDDelResetMenu.MenuSelectedFcn = createCallbackFcn(app, @custom_detector_callbacks, true);
            app.CDDelResetMenu.Text = 'Reset';
            % Assign app.CDDelContext
            app.CustomDetectorNewMaskGrid.ContextMenu = app.CDDelContext;
            app.CustomDetectorDeleteMask.ContextMenu = app.CDDelContext;
            % Show the figure after all components are created
            app.Quant4D_Fig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Quant4D(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Quant4D_Fig)

            % Execute the startup function
            runStartupFcn(app, @(app)startup_function(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Quant4D_Fig)
        end
    end
end